classdef CellDataTransfer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CDT_main                  matlab.ui.Figure
        source_set_selector       matlab.ui.control.DropDown
        destination_set_selector  matlab.ui.control.DropDown
        delete_set                matlab.ui.control.Button
        create_new_set            matlab.ui.control.Button
        cancel_button             matlab.ui.control.Button
        instructions_text         matlab.ui.control.Label
        finished_editing_button   matlab.ui.control.Button
        move_data_button          matlab.ui.control.Button
        source_cell_selector      matlab.ui.control.ListBox
        destination_cell_display  matlab.ui.control.ListBox
    end

    
    methods (Access = private)
        function SetCellList = CreateSummarySetCellList(app)
            
                    Summary = SummarizeTrackData(app, app.TrackData);
            
                    for TrackNum = 1:Summary.nTracks
                        SetCellList{TrackNum,1} = create_cell_display_list(app, app.TrackData,TrackNum); %#ok<AGROW>
                    end
        end
        
        function DestSetNum = DestinationSetSelection(app)
            
                    % Since the Destination Set Drop-Down Menu has the current Selected
                    % Set Num omitted we need to actually read what text is displayed
                    % in order to determin what destination Set Num is selected
            
                    ListArray = get(app.destination_set_selector,'String');
                    val = get(app.destination_set_selector,'Value');
                    DestSetText = ListArray{val};
                    DestSetNum = str2double(DestSetText(4:end));
        end
        
        function ListVal = FindTrackDestListVal(app, destination_track_list, DestTrackNum)
            
                    for k = 1:length(destination_track_list)
                        DestTrackNumbers(k,1) = str2double(destination_track_list{k,1}(4:end));
                    end
            
                    ListVal = find(DestTrackNumbers == DestTrackNum);
        end
        
        function nCells = MaxNumberCells(app, SingleTrackData)
                % Calculates Maximum Number of Cells for a single track Data set
                % {Subfunction of SummarizeTrackData)
            
                    nTime = length(SingleTrackData);
                    nCells = 0;
                    for T = 1:nTime
                        NumCells = length(SingleTrackData{T});
                        if NumCells > nCells
                            nCells = NumCells;
                        end
                    end
        end
        
        function NewTrackList = ReDistributeTrackData(app)
            
                    NewTrackList = [];
                    N = length(app.SetCellList);
                    oTrackData = app.OriginalTrackData;
            
                    for ListTrackNum = 1:N
                        ListNumCell = length(app.SetCellList{ListTrackNum});
            
                        for CellNum  = 1:ListNumCell
                                CellText = app.SetCellList{ListTrackNum}{CellNum};
                                % Token objects that we can use to pull information out of the string
                                t1 = strfind(CellText,'s');
                                t2 = strfind(CellText,'c');
                                t3 = strfind(CellText,' ');
                                t4 = strfind(CellText,'-');
            
                                oTrackNum = str2double(CellText(t1+1:t2-1));
                                oCellNum  = str2double(CellText(t2+1:t3-1));
                                oStartT   = str2double(CellText(t3+1:t4-1));
                                oEndT     = str2double(CellText(t4+1:end));
            
                                for Time = oStartT:oEndT
                                     [isdim notempty] = iscelldim(app, oTrackData,oTrackNum,Time,oCellNum);
                                     if isdim
                                        NewTrackList{ListTrackNum}{Time}{CellNum} = oTrackData{oTrackNum}{Time}{oCellNum };
                                     end
                                end
                        end
                    end
        end
        
        function Summary = SummarizeTrackData(app, TrackData)
            
                    if ~isempty(TrackData)
                        LogicArraySTC = create_STC_logical_array(app, TrackData);
                        [nS nT nC] = size(LogicArraySTC);
            
                        Summary.nTracks = nS;
                        Summary.Data = [];
            
                        for S = 1:nS
                            SetArray = squeeze(LogicArraySTC(S,:,:))';
                            data.MaxNumTimePts = find(any(SetArray), 1, 'last' );
                            data.MaxNumCells   = find(any(SetArray'), 1, 'last' );
                            data.StartTimes = zeros(nC,1);
                            data.EndTimes = zeros(nC,1);
                            data.MissingTimePoints = cell(nC,1);
                            for C = 1:nC
                                CellVector = (squeeze(LogicArraySTC(S,:,C)))';
                                if any(CellVector) % Skip if all time points are false
                                    indices = find(CellVector);
                                    data.StartTimes(C,1) = min(indices);
                                    data.EndTimes(C,1) = max(indices);
                                    data.MissingTimePoints{C,1} = find(~CellVector(data.StartTimes(C,1):data.EndTimes(C,1),1))...
                                                                                                    + data.StartTimes(C,1) - 1;
                                else
                                    data.StartTimes(C,1) = 0;
                                    data.EndTimes(C,1) = 0;
                                    data.MissingTimePoints{C,1} = [];
                                end
                            end
                            Summary.Data{S} = data;
                        end
                    else
                        Summary = [];
                    end
        end
        
        function LogicArray = create_STC_logical_array(app, TrackData)
            % Create logical Array with dimensions [nS nT nC]
            
                nSets = length(TrackData);
                TempMaxT = cellfun('length',TrackData);
                maxT = max(TempMaxT);
                TempMaxCell = zeros(nSets,1);
                for S = 1:nSets
                    TempMaxCell(S,1) = max(cellfun('length',TrackData{S}));
                end
                maxC = max(TempMaxCell);
                LogicArray = false(nSets,maxT,maxC);
            
                for S = 1:nSets
                    for T = 1:TempMaxT(S)
                        for C = 1:TempMaxCell(S)
                            [isdim notempty] = iscelldim(app, TrackData,S,T,C);
                            if isdim && notempty
                               LogicArray(S,T,C) = true;
                            end
                        end
                    end
                end
        end
        
        function cell_list = create_cell_display_list(app, TrackData, TrackNum)
            
                    DS = SummarizeTrackData(app, TrackData); % Create Track Data Summary
                    data = DS.Data{TrackNum}; % Grab Summary Data for Specified Track/Set Number
            
                    if ~isequal(data.MaxNumCells,0)
                        for CellNum = 1:data.MaxNumCells
                            cell_list{CellNum,1} = ['s' num2str(TrackNum)' 'c' num2str(CellNum) ' '...
                                                        num2str(data.StartTimes(CellNum,1)) '-'...
                                                        num2str(data.EndTimes(CellNum,1))]; %#ok<*AGROW>
                        end
                    else
                        cell_list = '';
                    end
        end
        
        function set_list = create_set_display_list(app, CurrentTrack, nTracks)
            
                    for S = 1:nTracks
                        set_list{S,1} = ['Set ' num2str(S)];
                    end
            
                    if ~isequal(-1,CurrentTrack) % Erase the Current Track Display for Destination Set List
                        set_list(CurrentTrack) = [];
                    end
        end
        
        function [isdim, notempty] = iscelldim(app, TrackData, TrackNum, Time, CellIndex)
                % Check existence of dimension of the nested cell array TrackData,
                % and if dimension exists, determine if it's empty
            
                    isdim = false;
                    notempty = false;
            
                    if TrackNum > 0 && Time > 0 && CellIndex > 0
                        LTD = length(TrackData);
                        if TrackNum <= LTD
                            LT = length(TrackData{TrackNum});
                            if Time <= LT
                                LC = length(TrackData{TrackNum}{Time});
                                if CellIndex <= LC
                                    isdim = true;
                                    if ~isempty(TrackData{TrackNum}{Time}{CellIndex})
                                        notempty = true;
                                    end
                                end
                            end
                        end
                    end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function CellDataTransfer_OpeningFcn(app, varargin)
               
                app.OriginalTrackData = varargin{1};
                app.TrackData = app.OriginalTrackData;
            
                app.DS = SummarizeTrackData(app, app.TrackData);
                app.SetCellList = CreateSummarySetCellList(app);
                app.nTracks = app.DS.nTracks;
            
                set(app.CDT_main,'WindowStyle','modal')
            
                if app.DS.nTracks < 2
                        set_list1 = create_set_display_list(app,-1,app.nTracks);
                        set(app.source_cell_selector,'Items',app.SetCellList{1},'Value',1,'BackgroundColor',[1 1 1],'Enable','on')
                        set(app.source_set_selector,'Items',set_list1,'Value',1,'BackgroundColor',default_color,'Enable','on')
                        set(app.CDT_main,'Color',default_color)
                        set(app.create_new_set,'BackgroundColor',default_color,'Enable','on')
                        set(app.delete_set,'BackgroundColor',default_color,'Enable','on')
                        set(app.instructions_text,'BackgroundColor',default_color,'Enable','on')
                        set(app.move_data_button,'BackgroundColor',default_color,'Enable','on')
                        set(app.destination_set_selector,'String',' ','Value',1,'BackgroundColor',default_color,'Enable','off')
                        set(app.destination_cell_display,'BackgroundColor',[1 1 1],'Enable','inactive')
                        set(app.finished_editing_button,'BackgroundColor',default_color)
                        set(app.cancel_button,'BackgroundColor',default_color)
            
                else
                        EnableStatus = 'on';
                        set_list1 = create_set_display_list(app,-1,app.nTracks);
                        set(app.source_set_selector,'String',set_list1,'Value',1)
                        set(app.source_cell_selector,'String',app.SetCellList{1},'Value',1)
            
                        set_list2 = create_set_display_list(app, 1,app.nTracks);
                        set(app.destination_cell_display,'String',app.SetCellList{2},'Min',0,'Max',2,'Value',[]) % Get Rid of Highlighted items in this listbox
                        set(app.destination_set_selector,'String',set_list2,'Value',1)
            
                        set(app.CDT_main,'Color',default_color)
                        set(app.create_new_set,'BackgroundColor',default_color,'Enable',EnableStatus)
                        set(app.delete_set,'BackgroundColor',default_color,'Enable',EnableStatus)
                        set(app.instructions_text,'BackgroundColor',default_color)
                        set(app.source_set_selector,'BackgroundColor',default_color,'Enable',EnableStatus)
                        set(app.source_cell_selector,'BackgroundColor',[1 1 1],'Enable',EnableStatus)
                        set(app.move_data_button,'BackgroundColor',default_color,'Enable',EnableStatus)
                        set(app.destination_set_selector,'BackgroundColor',default_color,'Enable',EnableStatus)
                        set(app.destination_cell_display,'BackgroundColor',[1 1 1],'Enable','inactive')
                        set(app.finished_editing_button,'BackgroundColor',default_color)
                        set(app.cancel_button,'BackgroundColor',default_color)
                end
                % app.output is the default variable sent to the workspace after the function closes
                app.output = -1;
            
                % UIWAIT makes select_directory wait for user response (see UIRESUME)
                uiwait(handles.CDT_main)

        end

        % Close request function: CDT_main
        function CDT_main_CloseRequestFcn(app, event)
                      
                    if isequal(get(hObject,'waitstatus'),'waiting')
                        uiresume(hObject)
                    else
                        delete(hObject);
                    end

        end

        % Button pushed function: cancel_button
        function cancel_button_Callback(app, event)
                       
                    app.output = -1;
                    uiresume(app.CDT_main)

        end

        % Button pushed function: create_new_set
        function create_new_set_Callback(app, event)
                       
                    app.nTracks = app.nTracks + 1;
                    app.SetCellList{app.nTracks} = [];
            
                    TrackNum = get(app.source_set_selector,'Value');
                    source_track_list = create_set_display_list(app, -1,app.nTracks);
                    set(app.source_set_selector,'String',source_track_list)
                    destination_track_list  = create_set_display_list(app, TrackNum,app.nTracks);
                    set(app.destination_cell_display,'String',app.SetCellList{end},'Min',0,'Max',2,'Value',[])
                    set(app.destination_set_selector,'String',destination_track_list,'Value',app.nTracks - 1,'Enable','on')

                   
        end

        % Button pushed function: delete_set
        function delete_set_Callback(app, event)
            
            
                 if app.nTracks > 2
                     source_set_list = create_set_display_list(app, -1,app.nTracks);
                     [Selection,ok] = listdlg('ListString',source_set_list,'SelectionMode',...
                         'single','Name','Delete Set','ListSize',[140 200],'OKString','Delete',...
                         'PromptString',[{'        WARNING!'} {'   Deleting a set will'} {'   delete any data in'} {'   that set.'} {' '}]);
                     if isequal(ok,1)
                         if ~isempty(app.SetCellList{Selection})
                            button = questdlg([{'This Set has data in it!'} {'Are you sure you want to delete it?'}],'Are you sure???','Yes','No','No');
                            if isequal(button,'Yes')
                                app.SetCellList(Selection) = [];
                                app.nTracks = app.nTracks - 1;
                            end
                         else
                             app.SetCellList(Selection) = [];
                             app.nTracks = app.nTracks - 1;
                         end
            
                         % Now update Display
            
                         source_set_list = create_set_display_list(app, -1,app.nTracks);
                         set(app.source_set_selector,'String',source_set_list,'Value',1)
                         set(app.source_cell_selector,'String',app.SetCellList{1},'Value',1)
            
                         destination_set_list = create_set_display_list(app, 1,app.nTracks);
                         set(app.destination_set_selector,'String',destination_set_list,'Value',1)
                         set(app.destination_cell_display,'String',app.SetCellList{2},'Min',0,'Max',2,'Value',[])
                     end
                 end
            
               
        end

        % Value changed function: destination_set_selector
        function destination_set_selector_Callback(app, event)
                        
                    DestSetNum = DestinationSetSelection(app);
                    set(app.destination_cell_display,'String',handles.SetCellList{DestSetNum},'Min',0,'Max',2,'Value',[])

        end

        % Button pushed function: finished_editing_button
        function finished_editing_button_Callback(app, event)
                      
                    app.NewTrackData = ReDistributeTrackData(app);
                    app.output = app.NewTrackData;
                    uiresume(app.CDT_main)

        end

        % Button pushed function: move_data_button
        function move_data_button_Callback(app, event)
                       
                    TrackNum = get(app.source_set_selector,'Value');
                    DestTrackNum = DestinationSetSelection(app);
                    GetSourceCellList = get(app.source_cell_selector,'String');
            
                    if ~isempty(GetSourceCellList)
                        SourceCellNum = get(app.source_cell_selector,'Value');
                        DV = length(app.SetCellList{DestTrackNum}); % Find Number of Cells Currently in the destination track
                        if isempty(app.SetCellList{DestTrackNum})
                            index = 1;
                        else
                            index = DV + 1;
                        end
            
                        app.SetCellList{DestTrackNum}{index,1} = app.SetCellList{TrackNum}{SourceCellNum};
                        app.SetCellList{TrackNum}(SourceCellNum) = [];
            
                        if ~isequal(SourceCellNum,1)
                            set(app.source_cell_selector,'String',app.SetCellList{TrackNum},'Value',SourceCellNum-1)
                        else
                            set(app.source_cell_selector,'String',app.SetCellList{TrackNum},'Value',1)
                        end
            
                        set(app.destination_cell_display,'String',handles.SetCellList{DestTrackNum},'Min',0,'Max',2,'Value',[])
                    end


        end

        % Value changed function: source_set_selector
        function source_set_selector_Callback(app, event)
           
            
                    TrackNum = get(handles.source_set_selector,'Value');
                    set(handles.source_cell_selector,'String',handles.SetCellList{TrackNum},'Value',1)
                    DestTrackNum = DestinationSetSelection(app, handles); % Grab Current Selected Destination Set Number
                    destination_track_list  = create_set_display_list(app, TrackNum,handles.nTracks);
                    %ListVal = get(handles.destination_set_selector,'Value');
            
                    if isequal(DestTrackNum,TrackNum)
                        if isequal(DestTrackNum,handles.nTracks)
                            DestVal = 1;
                            DestTrackNum = 1;
                        else
                            DestVal = FindTrackDestListVal(app, destination_track_list,TrackNum + 1);
                            DestTrackNum = TrackNum + 1;
                        end
                        destination_track_list = create_set_display_list(app, TrackNum,handles.nTracks);
                        set(handles.destination_cell_display,'String',handles.SetCellList{DestTrackNum},'Min',0,'Max',2,'Value',[])
                        set(handles.destination_set_selector,'String',destination_track_list,'Value',DestVal)
                    else
                        destination_track_list = create_set_display_list(app, TrackNum,handles.nTracks);
                        ListVal = FindTrackDestListVal(app, destination_track_list,DestTrackNum);
                        set(handles.destination_set_selector,'String',destination_track_list,'Value',ListVal)
                   end
            
               
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CDT_main = uifigure('Visible', 'off');
            colormap(app.CDT_main, 'parula');
            app.CDT_main.Name = 'Transfer cell data from one set to another';
            app.CDT_main.CloseRequestFcn = createCallbackFcn(app, @CDT_main_CloseRequestFcn, true);
            app.CDT_main.Tag = 'CDT_main';
            app.destination_cell_display = uilistbox(app.CDT_main);
            app.destination_cell_display.Items = {};
            app.destination_cell_display.Tag = 'destination_cell_display';
            app.destination_cell_display.Value = {};
            app.source_cell_selector = uilistbox(app.CDT_main);
            app.source_cell_selector.Items = {};
            app.source_cell_selector.Tag = 'source_cell_selector';
            app.source_cell_selector.Value = {};
            app.move_data_button = uibutton(app.CDT_main, 'push');
            app.move_data_button.ButtonPushedFcn = createCallbackFcn(app, @move_data_button_Callback, true);
            app.move_data_button.Tag = 'move_data_button';
            app.move_data_button.Text = '> Move Cell >';
            app.finished_editing_button = uibutton(app.CDT_main, 'push');
            app.finished_editing_button.ButtonPushedFcn = createCallbackFcn(app, @finished_editing_button_Callback, true);
            app.finished_editing_button.Tag = 'finished_editing_button';
            app.finished_editing_button.Text = 'Finished Editing';
            app.instructions_text = uilabel(app.CDT_main);
            app.instructions_text.Tag = 'instructions_text';
            app.instructions_text.Text = 'Select cell (Left Side) and press "Move Cell" to transfer the data to another set (Right Side)';
            app.cancel_button = uibutton(app.CDT_main, 'push');
            app.cancel_button.ButtonPushedFcn = createCallbackFcn(app, @cancel_button_Callback, true);
            app.cancel_button.Tag = 'cancel_button';
            app.cancel_button.Text = 'Cancel';
            app.create_new_set = uibutton(app.CDT_main, 'push');
            app.create_new_set.ButtonPushedFcn = createCallbackFcn(app, @create_new_set_Callback, true);
            app.create_new_set.Tag = 'create_new_set';
            app.create_new_set.Text = 'New Set';
            app.delete_set = uibutton(app.CDT_main, 'push');
            app.delete_set.ButtonPushedFcn = createCallbackFcn(app, @delete_set_Callback, true);
            app.delete_set.Tag = 'delete_set';
            app.delete_set.Text = 'Delete Set';
            app.destination_set_selector = uidropdown(app.CDT_main);
            app.destination_set_selector.Items = {'Set 03'};
            app.destination_set_selector.ValueChangedFcn = createCallbackFcn(app, @destination_set_selector_Callback, true);
            app.destination_set_selector.Tag = 'destination_set_selector';
            app.destination_set_selector.Value = 'Set 03';
            app.source_set_selector = uidropdown(app.CDT_main);
            app.source_set_selector.Items = {'Set 05'};
            app.source_set_selector.ValueChangedFcn = createCallbackFcn(app, @source_set_selector_Callback, true);
            app.source_set_selector.Tag = 'source_set_selector';
            app.source_set_selector.Value = 'Set 05';
            % Show the figure after all components are created
            app.CDT_main.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CellDataTransfer(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.CDT_main)

                % Execute the startup function
                runStartupFcn(app, @(app)CellDataTransfer_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.CDT_main)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CDT_main)
        end
    end
end