classdef CellSummaryDisplay < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CellSummaryMainFigure        matlab.ui.Figure
        selected_cell_color_button   matlab.ui.control.Button
        selected_cell_color_display  matlab.ui.container.Panel
        uipanel2                     matlab.ui.container.Panel
        MoveDeleteCellDataLabel      matlab.ui.control.Label
        ButtonGroup                  matlab.ui.container.ButtonGroup
        MoveDeleteSwitch             matlab.ui.control.Switch
        uipanel5                     matlab.ui.container.Panel
        destination_cell_set_num     matlab.ui.control.EditField
        text15                       matlab.ui.control.Label
        text16                       matlab.ui.control.Label
        destination_cell_cell_num    matlab.ui.control.EditField
        destination_cell_time1       matlab.ui.control.EditField
        destination_cell_time2       matlab.ui.control.EditField
        text17                       matlab.ui.control.Label
        text18                       matlab.ui.control.Label
        uipanel4                     matlab.ui.container.Panel
        text20                       matlab.ui.control.Label
        move_cell_time2              matlab.ui.control.EditField
        move_cell_time1              matlab.ui.control.EditField
        move_cell_cell_num           matlab.ui.control.EditField
        text19                       matlab.ui.control.Label
        move_cell_set_num            matlab.ui.control.EditField
        move_set_text                matlab.ui.control.Label
        text13                       matlab.ui.control.Label
        move_or_delete_button        matlab.ui.control.Button
        HighlightSelectedCells       matlab.ui.control.CheckBox
        close_window_button          matlab.ui.control.Button
        DeleteCellButton             matlab.ui.control.Button
        Cell_Summary_Display         matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        
    end
    
    properties (Access = public)
        CellSummaryList
        LTapp = [];
        selected_cell_color = [];
        DisplaySelectedCellsFromList = false;
        SelectedCellNumFromList = [];
        SelectedSetNumFromList = [];
    end

    
    methods (Access = private)
%-----------------------------------------------------------------------------------------------------------------------------------------           
        function NewImage8bit = ApplyFunctions(app,image8bit)
            
            
                                % Apply Threshold Function ---------------------------------
                                if app.LTapp.doThreshold
                                    image8bit = threshold(app, app.LTapp.threshold_value,image8bit);
                                end
            
                                % Apply Binarize Function ----------------------------------
                                if app.LTapp.doBinarize
                                   image8bit = uint8(binarize(app,image8bit));
                                end
            
                                % Apply Erode Function -------------------------------------
                                if app.LTapp.doErode
                                    image8bit = imfill(logical(image8bit),'holes');
                                    image8bit = imerode(logical(image8bit),app.SE);
                                    image8bit = uint8(255*bwmorph(image8bit,'spur'));
                                end
            
                                % Apply H-break
                                if app.LTapp.doHbreak
                                    image8bit = ~bwmorph(~logical(image8bit),'diag');
                                    image8bit = uint8(255*~bwmorph(~image8bit,'bridge'));
                                end
            
                                NewImage8bit = image8bit;
        end

%-----------------------------------------------------------------------------------------------------------------------------------------                   
        function SetCellList = CreateCompleteCellList(app, TrackDataSummary)
            
                if ~isempty(TrackDataSummary)
                    index = 0;
                    for S = 1:TrackDataSummary.nSets
                            for C = 1:TrackDataSummary.Data{S}.MaxNumCells
                               index = index + 1;
                               sTime = TrackDataSummary.Data{S}.StartTimes(C,1);
                               eTime = TrackDataSummary.Data{S}.EndTimes(C,1);
                               mTime = TrackDataSummary.Data{S}.MissingTimePoints{C,1};
                               if isempty(mTime)
                                    SetCellList{index,1} = ['s',sprintf('%02d',S),' c',sprintf('%03d',C),' t',num2str(sTime),'-',num2str(eTime)];     %#ok<AGROW>
                               else
                                   mString = '[';
                                   N = length(mTime);
                                   for k = 1:N
                                       if ~isequal(k,N)
                                            mString = [mString num2str(mTime(k)) ','];
                                       else
                                            mString = [mString num2str(mTime(k)) ']'];
                                       end
                                   end
                                   SetCellList{index,1} = ['s',sprintf('%02d',S),' c',sprintf('%03d',C),' t',num2str(sTime),'-',num2str(eTime),' ',mString];
                               end
                            end
                    end
                else
                    SetCellList = [];
                end

        end

%-----------------------------------------------------------------------------------------------------------------------------------------                   
        function nCells = MaxNumberCells(app, SingleTrackData)

                % Calculates Maximum Number of Cells for a single track Data set
                % {Subfunction of SummarizeTrackData)
            
                    nTime = length(SingleTrackData);
                    nCells = 0;
                    for T = 1:nTime
                        NumCells = length(SingleTrackData{T});
                        if NumCells > nCells
                            nCells = NumCells;
                        end
                    end

        end

%-----------------------------------------------------------------------------------------------------------------------------------------                   
        function [SetNum,CellNum] = ReadSelectedCellNames(app)
            
                    names = get(app.Cell_Summary_Display,'Items');
                    n     = get(app.Cell_Summary_Display,'Value');
                    CellText = names(n)';
                    L = length(CellText);
                    SetNum  = zeros(L,1);
                    CellNum = zeros(L,1);
                    for m = 1:length(CellText)
                        SetNum(m,1)  = str2double( CellText{m,1}(strfind(CellText{m,1},'s')+1:strfind(CellText{m,1},'s')+2) ); 
                        CellNum(m,1) = str2double( CellText{m,1}(strfind(CellText{m,1},'c')+1:strfind(CellText{m,1},'c')+3) ); 
                    end
        end

%-----------------------------------------------------------------------------------------------------------------------------------------                   
function Summary = SummarizeTrackData(app)                    
                                     
                    Summary.nSets = length(app.LTapp.TrackData);
                    Summary.Data = [];
                    nSets = length(app.LTapp.TrackData);
                    for S = 1:nSets
                            LogicArraySTC = create_STC_logical_array(app,S);
                            nC = size(LogicArraySTC,2);
                            
                            %SetArray = squeeze(LogicArraySTC(S,:,:))';
                            data.MaxNumCells   =  nC; 
                            data.StartTimes = zeros(nC,1);
                            data.EndTimes   = zeros(nC,1);
                            data.MissingTimePoints = cell(nC,1);
                            for C = 1:nC
                                CellVector = LogicArraySTC(:,C)';
                                if any(CellVector) % Skip if all time points are false
                                    indices = find(CellVector);
                                    data.StartTimes(C,1) = min(indices);
                                    data.EndTimes(C,1)   = max(indices);
                                    data.MissingTimePoints{C,1} = find( ~CellVector(data.StartTimes(C,1):data.EndTimes(C,1)) ) + data.StartTimes(C,1) - 1;
                                else
                                    data.StartTimes(C,1) = 0;
                                    data.EndTimes(C,1) = 0;
                                    data.MissingTimePoints{C,1} = [];
                                end
                            end
                            Summary.Data{S} = data;
                    end

        end

%-----------------------------------------------------------------------------------------------------------------------------------------                   
        function ImOut = binarize(app, ImIn)
            
                [r,c] = size(ImIn);
                blank = ones(r,c,'double');
                ImOut = uint8(255*imfill(blank.*logical(ImIn),'holes'));

        end

%-----------------------------------------------------------------------------------------------------------------------------------------                   
         function LogicArray = create_STC_logical_array(app,S)
            
                   % Create logical Array with dimensions [nT nC]
            
                   nCells = size(app.LTapp.TrackData{S}{1}, 2) ;
            
                   LogicArray = false(app.LTapp.nT,nCells);
                   for T = 1:app.LTapp.nT
                        for C = 1:nCells
                            [isdim, notempty] = iscelldim(app,app.LTapp.TrackData,S,T,C);
                            if isdim && notempty
                               LogicArray(T,C) = true;
                            end
                        end
                    end
                    
        end

%-----------------------------------------------------------------------------------------------------------------------------------------                   
        function edit5_CreateFcn(app, hObject, eventdata)
            
                if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                    set(hObject,'BackgroundColor','white');
                end
        end

%-----------------------------------------------------------------------------------------------------------------------------------------                   
        function edit6_CreateFcn(app, hObject, eventdata)
            
                if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                    set(hObject,'BackgroundColor','white');
                end
        end

%-----------------------------------------------------------------------------------------------------------------------------------------                   
        function edit7_CreateFcn(app, hObject, eventdata)
            
                if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                    set(hObject,'BackgroundColor','white');
                end
        end

%-----------------------------------------------------------------------------------------------------------------------------------------                   
        function edit8_CreateFcn(app, hObject, eventdata)
            
                if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                    set(hObject,'BackgroundColor','white');
                end
        end

%-----------------------------------------------------------------------------------------------------------------------------------------                   
        function highlight_selected_cells(app)
                            
                            LTH = app.LTapp;

                            TimePt = LTH.T;
                            CellIndex = LTH.CellIndex;
                            SetNum    = LTH.SetNum;
                            TrackData = LTH.TrackData;
                            SFMain = size(LTH.RGB_T,1)/size(LTH.Timage,1);
                            SFTP1  = size(LTH.RGB_TP1,1)/size(LTH.TP1image,1);
                            SFTM1  = size(LTH.RGB_TM1,1)/size(LTH.TM1image,1);
                            LW = LTH.line_width; % Specify Line Width for drawing cell boundaries
                            [isdim, notempty] = iscelldim(app, TrackData,SetNum,TimePt,CellIndex); % Check if DIM exists
            
                            % Add CURRENT cell selection to Figures --------------------------------------------------------------------------------
                            if isdim && notempty
                                    %------------------------------------------------------------------------------------
                                    % Plot Selected cell on Main Figure
                                    set(0,'CurrentFigure',LTH.main_panel)
                                    hold on
                                        fill(SFMain*(TrackData{SetNum}{TimePt}{CellIndex}.x_perimeter-0.25),...
                                             SFMain*(TrackData{SetNum}{TimePt}{CellIndex}.y_perimeter-0.25),...
                                             LTH.cell_fill_color)
                                        line(SFMain*(TrackData{SetNum}{TimePt}{CellIndex}.x_perimeter-0.25),...
                                             SFMain*(TrackData{SetNum}{TimePt}{CellIndex}.y_perimeter-0.25),...
                                             'LineWidth',LW,'Color',LTH.cell_outline_color)
                                    hold off
                                    %------------------------------------------------------------------------------------
                                    % Plot Selected cell on TP1_z Figure
                                    if ishandle(LTH.tp1_z_panel)
                                        set(0,'CurrentFigure',LTH.tp1_z_panel)
                                        hold on
                                            fill(SFTP1*(TrackData{SetNum}{TimePt}{CellIndex}.x_perimeter-0.25),...
                                                 SFTP1*(TrackData{SetNum}{TimePt}{CellIndex}.y_perimeter-0.25),...
                                                 LTH.cell_fill_color)
                                            line(SFTP1*(TrackData{SetNum}{TimePt}{CellIndex}.x_perimeter-0.25),...
                                                 SFTP1*(TrackData{SetNum}{TimePt}{CellIndex}.y_perimeter-0.25),...
                                                 'LineWidth',LW,'Color',LTH.cell_outline_color)
                                        hold off
                                    end
                                    %------------------------------------------------------------------------------------
                                    % Plot Selected cell on TM1_Z Figure
                                    if ishandle(LTH.tm1_z_panel)
                                        set(0,'CurrentFigure',LTH.tm1_z_panel)
                                        hold on
                                            fill(SFTM1*(TrackData{SetNum}{TimePt}{CellIndex}.x_perimeter-0.25),...
                                                 SFTM1*(TrackData{SetNum}{TimePt}{CellIndex}.y_perimeter-0.25),...
                                                 LTH.cell_fill_color)
                                            line(SFTM1*(TrackData{SetNum}{TimePt}{CellIndex}.x_perimeter-0.25),...
                                                 SFTM1*(TrackData{SetNum}{TimePt}{CellIndex}.y_perimeter-0.25),...
                                                 'LineWidth',LW,'Color',LTH.cell_outline_color)
                                        hold off
                                    end
                            end
            
                            % Add Cell Selection of PREVIOUS Time point ------------------------------------------------------------------------------
                            if LTH.show_previous
                                    for tpr = 1:LTH.NumPrevious
                                            [isdim, notempty] = iscelldim(app, TrackData,SetNum,TimePt-tpr,CellIndex); % Check if DIM exists
                                            if isdim && notempty %&& app.show_previous
                                                    %------------------------------------------------------------------------------------
                                                    % Plot selected cell on Main Figure
                                                    set(0,'CurrentFigure',LTH.main_panel)
                                                    hold on
                                                        line(SFMain*(TrackData{SetNum}{TimePt-tpr}{CellIndex}.x_perimeter-0.25),...
                                                             SFMain*(TrackData{SetNum}{TimePt-tpr}{CellIndex}.y_perimeter-0.25),...
                                                             'LineWidth',LW,'Color',LTH.previous_cell_outline_color)
                                                    hold off
                                                    %------------------------------------------------------------------------------------
                                                    % Plot Selected cell on tp1_z Figure
                                                    if ishandle(LTH.tp1_z_panel)
                                                        set(0,'CurrentFigure',LTH.tp1_z_panel)
                                                        hold on
                                                            line(SFTP1*(TrackData{SetNum}{TimePt-tpr}{CellIndex}.x_perimeter-0.25),...
                                                                 SFTP1*(TrackData{SetNum}{TimePt-tpr}{CellIndex}.y_perimeter-0.25),...
                                                                 'LineWidth',LW,'Color',LTH.previous_cell_outline_color)
                                                        hold off
                                                    end
                                                    %------------------------------------------------------------------------------------
                                                    % Plot Selected cell on tm1_z Figure
                                                    if ishandle(LTH.tm1_z_panel)
                                                        set(0,'CurrentFigure',LTH.tm1_z_panel)
                                                        hold on
                                                            line(SFTM1*(TrackData{SetNum}{TimePt-tpr}{CellIndex}.x_perimeter-0.25),...
                                                                 SFTM1*(TrackData{SetNum}{TimePt-tpr}{CellIndex}.y_perimeter-0.25),...
                                                                 'LineWidth',LW,'Color',LTH.previous_cell_outline_color)
                                                        hold off
                                                    end
                                            end
                                    end
                            end
                            % Add Cell Selection of POST Time point --------------------------------------------------
                            if LTH.show_post
                                for tpo = 1:LTH.NumPost
                                    [isdim, notempty] = iscelldim(app, TrackData,SetNum,TimePt+tpo,CellIndex); % Check if DIM exists
                                    if isdim && notempty %&& app.show_post
                                            %------------------------------------------------------------------------------------
                                            % Plot selected cell on Main Figure
                                            set(0,'CurrentFigure',LTH.main_panel)
                                            hold on
                                                line(SFMain*(TrackData{SetNum}{TimePt+tpo}{CellIndex}.x_perimeter-0.25),...
                                                     SFMain*(TrackData{SetNum}{TimePt+tpo}{CellIndex}.y_perimeter-0.25),...
                                                     'LineWidth',LW,'Color',LTH.post_cell_outline_color)
                                            hold off
                                            %------------------------------------------------------------------------------------
                                            % Plot Selected cell on tp1_z Figure
                                            if ishandle(LTH.tp1_z_panel)
                                                set(0,'CurrentFigure',LTH.tp1_z_panel)
                                                hold on
                                                    line(SFTP1*(TrackData{SetNum}{TimePt+tpo}{CellIndex}.x_perimeter-0.25),...
                                                         SFTP1*(TrackData{SetNum}{TimePt+tpo}{CellIndex}.y_perimeter-0.25),...
                                                         'LineWidth',LW,'Color',LTH.post_cell_outline_color)
                                                hold off
                                            end
                                            %------------------------------------------------------------------------------------
                                            % Plot Selected cell on tm1_z Figure
                                            if ishandle(LTH.tm1_z_panel)
                                                set(0,'CurrentFigure',LTH.tm1_z_panel)
                                                hold on
                                                    line(SFTM1*(TrackData{SetNum}{TimePt+tpo}{CellIndex}.x_perimeter-0.25),...
                                                         SFTM1*(TrackData{SetNum}{TimePt+tpo}{CellIndex}.y_perimeter-0.25),...
                                                         'LineWidth',LW,'Color',LTH.post_cell_outline_color)
                                                hold off
                                            end
                                    end
                                end
                            end
                            % Add Selected Cells from summary list -----------------------------------------------------------
                            if ishandle(LTH.CellSummaryWindow)
                                if LTH.DisplaySelectedCellsFromList
                                    N = length(LTH.SelectedSetNumFromList);
                                    for k = 1:N
                                        [isdim2, notempty2] = iscelldim(app, TrackData,LTH.SelectedSetNumFromList(k),TimePt,LTH.SelectedCellNumFromList(k));
                                        if isdim2 && notempty2
                                            set(0,'CurrentFigure',LTH.main_panel)
                                            hold on
                                                line(SFMain*(TrackData{LTH.SelectedSetNumFromList(k)}{TimePt}{LTH.SelectedCellNumFromList(k)}.x_perimeter-0.25),...
                                                     SFMain*(TrackData{LTH.SelectedSetNumFromList(k)}{TimePt}{LTH.SelectedCellNumFromList(k)}.y_perimeter-0.25),...
                                                     'LineWidth',LW,'Color',LTH.selected_cell_color)
                                            hold off
                                        end
                                    end
                                end
                            end

        end

%-----------------------------------------------------------------------------------------------------------------------------------------                   
        function [isdim, notempty] = iscelldim(app, TrackData, TrackNum, Time, CellIndex)
                % Check existence of dimension of the nested cell array TrackData,
                % and if dimension exists, determine if it's empty
            
                    isdim = false;
                    notempty = false;
            
                    if TrackNum > 0 && Time > 0 && CellIndex > 0
                        LTD = length(TrackData);
                        if TrackNum <= LTD
                            LT = length(TrackData{TrackNum});
                            if Time <= LT
                                LC = length(TrackData{TrackNum}{Time});
                                if CellIndex <= LC
                                    isdim = true;
                                    if ~isempty(TrackData{TrackNum}{Time}{CellIndex})
                                        notempty = true;
                                    end
                                end
                            end
                        end
                    end
        end

%-----------------------------------------------------------------------------------------------------------------------------------------                   
        function move_cell_num_CreateFcn(app, hObject, eventdata)
            
                if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                    set(hObject,'BackgroundColor','white');
                end
        end

%-----------------------------------------------------------------------------------------------------------------------------------------                   
        function move_set_num_CreateFcn(app, hObject, eventdata)
            
                if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                    set(hObject,'BackgroundColor','white');
                end
        end

%-----------------------------------------------------------------------------------------------------------------------------------------                   
        function move_time1_CreateFcn(app, hObject, eventdata)
            
                if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                    set(hObject,'BackgroundColor','white');
                end
        end

%-----------------------------------------------------------------------------------------------------------------------------------------                   
        function move_time2_CreateFcn(app, hObject, eventdata)
            
                if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                    set(hObject,'BackgroundColor','white');
                end
        end

%-----------------------------------------------------------------------------------------------------------------------------------------                   
        function ImOut = threshold(app, Tval, ImIn)
            
                if isa(ImIn,'uint8')
                    BW = im2bw(ImIn, Tval/255);
                    ImOut = ImIn.*uint8(BW);
                else
                    BW = im2bw(ImIn, Tval/255);
                    ImOut = ImIn.*BW;
                end
        end

%-----------------------------------------------------------------------------------------------------------------------------------------                   
        function TP = udigit(app, N, number)
                % !!! This can be replace by sprintf('%04d',x), where the 4 is the total digit width
                % TP = udigit(N, number);
                % This function returns a text string that has the desired number of digits
            
                NUM =num2str(number);
                k = length(NUM);
                D = N-k;
            
                if D <= 0
                   TP = NUM;
                else
                   zt = char(48*ones(1,D));
                   TP = [zt NUM];TrackNum
                end
        end

%-----------------------------------------------------------------------------------------------------------------------------------------                   
function LTH = update_display(app,LTH) % LTH = LongTrackerHandles
            
                    T = LTH.T;
                    Z = LTH.Z;
                    CellIndex = LTH.CellIndex;
            
                    if LTH.sa % IF ADVANCED RGB display is selected ||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||
                            % Create RED Channel Image and apply Functions
                            if ~LTH.show_r_channel % if red channel is turned off create empty channel
                                   LTH.red_T   = zeros(LTH.ImHeight,LTH.ImWidth,3,'double');
                                   LTH.red_TM1 = zeros(LTH.ImHeight,LTH.ImWidth,3,'double');
                                   LTH.red_TP1 = zeros(LTH.ImHeight,LTH.ImWidth,3,'double');
                            else
                                   LTH.red_T =   ind2rgb(LTH.Timage(:,:,1),LTH.r_color);
                                   LTH.red_TM1 = ind2rgb(LTH.TM1image(:,:,1),LTH.r_color);
                                   LTH.red_TP1 = ind2rgb(LTH.TP1image(:,:,1),LTH.r_color);
                            end
            
                            % Create GREEN Channel Image and apply Functions
                            if ~LTH.show_g_channel % If green channel is turned off create empty channel
                                   LTH.green_T = zeros(LTH.ImHeight,LTH.ImWidth,3,'double');
                            else
                                   % Create Green Channel images for T
                                   if ~isempty(LTH.line_break_mask) % Apply Line Break Function if it's turned on
                                       LB = uint8(LTH.line_break_mask);
                                       LTH.GREEN = LB.*ApplyFunctions(app,LTH.Timage(:,:,2)); % This image is used with SELECT CELL
                                       LTH.green_T = ind2rgb(LTH.GREEN,LTH.g_color); % This one is for display purposes
                                   else
                                       LTH.GREEN = ApplyFunctions(app,LTH.Timage(:,:,2)); % This image is used with SELECT CELL
                                       LTH.green_T = ind2rgb(LTH.GREEN,LTH.g_color);
                                   end
                                   % Create Green Channel images for T-1
                                   if LTH.show_tm1 && LTH.ApplyAll
                                       LTH.green_TM1 = ind2rgb(ApplyFunctions(app,LTH.TM1image(:,:,2)),LTH.g_color);
                                   elseif LTH.show_tm1
                                       LTH.green_TM1 = ind2rgb(LTH.TM1image(:,:,2),LTH.g_color);
                                   else
                                       LTH.green_TM1 = zeros(LTH.ImHeight,LTH.ImWidth,3,'double');
                                   end
                                   % Create Green Channel images for T+1
                                   if LTH.show_tp1 && LTH.ApplyAll
                                       LTH.green_TP1 = ind2rgb(ApplyFunctions(app,LTH.TP1image(:,:,2)),LTH.g_color);
                                   elseif LTH.show_tp1
                                       LTH.green_TP1 = ind2rgb(LTH.TP1image(:,:,2),LTH.g_color);
                                   else
                                       LTH.green_TP1 = zeros(LTH.ImHeight,LTH.ImWidth,3,'double');
                                   end
                            end
                            % Create BLUE Channel Image and apply Functions
                            if ~LTH.show_b_channel % If blue channel is turned off create empty channel
                                   LTH.blue_T   = zeros(LTH.ImHeight,LTH.ImWidth,3,'double');
                                   LTH.blue_TM1 = zeros(LTH.ImHeight,LTH.ImWidth,3,'double');
                                   LTH.blue_TP1 = zeros(LTH.ImHeight,LTH.ImWidth,3,'double');
                            else
                                   LTH.blue_T =   ind2rgb(LTH.Timage(:,:,3),LTH.b_color);
                                   LTH.blue_TM1 = ind2rgb(LTH.TM1image(:,:,3),LTH.b_color);
                                   LTH.blue_TP1 = ind2rgb(LTH.TP1image(:,:,3),LTH.b_color);
                            end
                            %--------------------------------------------------------------------------------------------------------------------------------------
                            % Combine Red Green and Blue channels and create RGB images, and resize as well.
                            LTH.RGB_T   = imresize(imlincomb(1,LTH.red_T,  1,LTH.green_T,  1,LTH.blue_T),  LTH.main_window_size/100,'nearest');
                            LTH.RGB_TM1 = imresize(imlincomb(1,LTH.red_TM1,1,LTH.green_TM1,1,LTH.blue_TM1),LTH.tm1_z_window_size/100,'nearest');
                            LTH.RGB_TP1 = imresize(imlincomb(1,LTH.red_TP1,1,LTH.green_TP1,1,LTH.blue_TP1),LTH.tp1_z_window_size/100,'nearest');
                    else  % Otherwise do SIMPLE RGB display |||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||
                            % Create RED Channel Image and apply Functions
                            if ~LTH.show_r_channel % if red channel is turned off create empty channel
                                   LTH.red_T   = zeros(LTH.ImHeight,LTH.ImWidth,1,'uint8');
                                   LTH.red_TM1 = zeros(LTH.ImHeight,LTH.ImWidth,1,'uint8');
                                   LTH.red_TP1 = zeros(LTH.ImHeight,LTH.ImWidth,1,'uint8');
                            else
                                   LTH.red_T   = LTH.Timage(:,:,1);
                                   LTH.red_TM1 = LTH.TM1image(:,:,1);
                                   LTH.red_TP1 = LTH.TP1image(:,:,1);
                            end
                            % Create GREEN Channel Image and apply Functions
                            if ~LTH.show_g_channel % If green channel is turned off create empty channel
                                   LTH.green_T   = zeros(LTH.ImHeight,LTH.ImWidth,1,'uint8');
                                   LTH.green_TM1 = zeros(LTH.ImHeight,LTH.ImWidth,1,'uint8');
                                   LTH.green_TP1 = zeros(LTH.ImHeight,LTH.ImWidth,1,'uint8');
                            else
                                   % Create Green Channel images for T   -------------------------------------------------
                                   if ~isempty(LTH.line_break_mask)
                                       LB = uint8(LTH.line_break_mask);
                                      LTH.green_T =  LB.*ApplyFunctions(app,LTH.Timage(:,:,2));
                                       LTH.GREEN = LTH.green_T;
                                   else
                                       LTH.green_T = ApplyFunctions(app,LTH.Timage(:,:,2));
                                       LTH.GREEN = LTH.green_T;
                                   end
                                   % Create Green Channel images for T-1 -------------------------------------------------
                                   if LTH.show_tm1 && LTH.ApplyAll
                                        LTH.green_TM1 = ApplyFunctions(app,LTH.TM1image(:,:,2));
                                   elseif LTH.show_tm1
                                        LTH.green_TM1 = LTH.TM1image(:,:,2);
                                   else
                                        LTH.green_TM1 = zeros(LTH.ImHeight,LTH.ImWidth,1,'uint8');
                                   end
                                   % Create Green Channel images for T+1 -------------------------------------------------
                                   if LTH.show_tp1 && LTH.ApplyAll
                                        LTH.green_TP1 = ApplyFunctions(app,LTH.TP1image(:,:,2));
                                   elseif LTH.show_tp1
                                        LTH.green_TP1 = LTH.TP1image(:,:,2);
                                   else
                                        LTH.green_TP1 = zeros(LTH.ImHeight,LTH.ImWidth,1,'uint8');
                                   end
            
                            end
            
                            % Create BLUE Channel Image and apply Functions
                            if ~LTH.show_b_channel % If blue channel is turned off create empty channel
                                   LTH.blue_T   = zeros(LTH.ImHeight,LTH.ImWidth,1,'uint8');
                                   LTH.blue_TM1 = zeros(LTH.ImHeight,LTH.ImWidth,1,'uint8');
                                   LTH.blue_TP1 = zeros(LTH.ImHeight,LTH.ImWidth,1,'uint8');
                            else
                                   LTH.blue_T   = LTH.Timage(:,:,3);
                                   LTH.blue_TM1 = LTH.TM1image(:,:,3);
                                   LTH.blue_TP1 = LTH.TP1image(:,:,3);
                            end
            
                            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                            LTH.RGB_T   = imresize(cat(3,LTH.red_T,  LTH.green_T,  LTH.blue_T)  ,LTH.main_window_size/100,'nearest');
                            LTH.RGB_TM1 = imresize(cat(3,LTH.red_TM1,LTH.green_TM1,LTH.blue_TM1),LTH.tm1_z_window_size/100,'nearest');
                            LTH.RGB_TP1 = imresize(cat(3,LTH.red_TP1,LTH.green_TP1,LTH.blue_TP1),LTH.tp1_z_window_size/100,'nearest');
            
                    end
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % update scale factors
                    [RM,CM,DM] = size(LTH.RGB_T);
                    LTH.SFMain = RM/LTH.ImHeight;
            
            
                    % Update main window figure --------------------------
                    if ishandle(LTH.main_panel)
                        set(0,'CurrentFigure',LTH.main_panel)
                        pos = get(LTH.main_panel,'Position');
                        [H,W,D] = size(LTH.RGB_T);
                        set(LTH.main_panel,'name',['   t = ',num2str(T),'   z = ',num2str(Z),...
                                           '        set: ',num2str(LTH.SetNum),'     cell: ',...
                                           num2str(CellIndex),'  of  ',num2str(LTH.nCells)])
                        imshow(LTH.RGB_T)
                        set(LTH.main_panel,'Position',[pos(1,1:2) W H])
                    else
                        app = create_figure(1,app);
                        set(0,'CurrentFigure',LTH.main_panel)
                        set(LTH.main_panel,'name',['   t = ' num2str(T) '   z = ' num2str(Z)...
                                           '        Cell ' num2str(CellIndex) '  of  ' num2str(LTH.nCells)...
                                           '        Set ' num2str(LTH.TrackNum)])
                        imshow(LTH.RGB_T)
                    end
            
                    % Update t+1,z figure --------------------------------
                    if LTH.show_tp1
                        if ishandle(LTH.tp1_z_panel)
                            set(0,'CurrentFigure',LTH.tp1_z_panel)
                            pos = get(LTH.tp1_z_panel,'Position');
                            [H,W,D] = size(LTH.RGB_TP1);
                            imshow(LTH.RGB_TP1)
                            set(LTH.tp1_z_panel,'Position',[pos(1,1:2),W,H])
                        else
                            app = create_figure(3,app);
                            set(0,'CurrentFigure',LTH.tp1_z_panel)
                            imshow(LTH.RGB_TP1)
                        end
                    end
            
                    % Update t-1,z figure --------------------------------
                    if LTH.show_tm1
                        if ishandle(LTH.tm1_z_panel)
                            set(0,'CurrentFigure',LTH.tm1_z_panel)
                            pos = get(LTH.tm1_z_panel,'Position');
                            [H,W,D] = size(LTH.RGB_TM1);
                            imshow(LTH.RGB_TM1)
                            set(LTH.tm1_z_panel,'Position',[pos(1,1:2),W,H])
                        else
                            app = create_figure(2,app);
                            set(0,'CurrentFigure',LTH.tm1_z_panel)
                            imshow(LTH.RGB_TM1)
                        end
                    end
            
                    % Now add cell outlines ------------------------------------
                    highlight_selected_cells(app)
                    % ----------------------------------------------------------
                    drawnow
        end
%-----------------------------------------------------------------------------------------------------------------------------------------                   
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function CellSummaryDisplay_OpeningFcn(app, MainApp, CellSummaryList)
            

                app.CellSummaryList        = CellSummaryList;
                app.LTapp                  = MainApp;
                app.selected_cell_color    = MainApp.selected_cell_color;
            
                set(app.CellSummaryMainFigure,'WindowStyle','normal')
                if ~isempty(app.CellSummaryList)
                    set(app.Cell_Summary_Display,'Items',CellSummaryList,'ItemsData',1:length(CellSummaryList),'Value',1,'Multiselect','on')
                else
                    set(app.Cell_Summary_Display,'Items',{''})
                end
                set(app.HighlightSelectedCells,'Value',0)
                set(app.selected_cell_color_button, 'Enable','off')
                set(app.selected_cell_color_display,'BackgroundColor',app.selected_cell_color,'Visible','off')
                set(app.MoveDeleteSwitch,'Items',{'Move','Delete'},'ItemsData',[true,false],'Value',true)
                set(app.uipanel5,'Enable','on')
%                 set(app.movetoButton,'Value',1)
%                 set(app.deleteButton,'Value',0)
            
                set(app.move_cell_set_num, 'Value','')
                set(app.move_cell_cell_num,'Value','')
                set(app.move_cell_time1,'Value','')
                set(app.move_cell_time2,'Value','')

                set(app.destination_cell_set_num, 'Value','')
                set(app.destination_cell_cell_num,'Value','')
                set(app.destination_cell_time1,'Value','')
                set(app.destination_cell_time2,'Value','')

                app.DisplaySelectedCellsFromList = false;
                app.SelectedCellNumFromList = [];
                app.SelectedSetNumFromList = [];

                
        end

        % Value changed function: Cell_Summary_Display
        function Cell_Summary_Display_Callback(app, event)
            
                if app.HighlightSelectedCells.Value
                    [app.SelectedSetNumFromList, app.SelectedCellNumFromList] = ReadSelectedCellNames(app);
                    app.LTapp.SelectedSetNumFromList  = app.SelectedSetNumFromList ;
                    app.LTapp.SelectedCellNumFromList = app.SelectedCellNumFromList;
                    app.LTapp = update_display(app,app.LTapp);
                end

        end

        % Button pushed function: DeleteCellButton
        function DeleteCellButton_Callback(app, event)
                       
                CellList = get(app.Cell_Summary_Display,'Items');
            
                if ~isempty(CellList)
                    button = questdlg('Are you sure you want to delete cell data?','Verify Cell Deletion','Yes','No','No');
                    if isequal('Yes',button)
                        LongTrackerHandles = app.LTapp; % Grab handles and public variables from main LongTracker window (it's not ncessary to change variable name but visually convenient)
                        val = get(app.Cell_Summary_Display,'Value');
            
                        L = length(CellList);
                        CellText  = CellList{val};
                        SetNum    = str2double( CellText(strfind(CellText,'s')+1:strfind(CellText,'s')+2) ); % Set number of cell selected for deletion
                        CellNum   = str2double( CellText(strfind(CellText,'c')+1:strfind(CellText,'c')+3) ); % Cell number of cell selected for deletion
                        TrackData = LongTrackerHandles.TrackData;     
            
                        %------- Now Delete Selected Cell Data -----------------------------------------
                        nT = length(TrackData{SetNum});
                        if numel(TrackData{SetNum}{1}) == 1  % if there's only one cell in the set, just delete the whole set
                             TrackData(SetNum) = [];
                        else                                 % Otehrwise delete the cell from each timepoint
                            for T = 1:nT
                                TrackData{SetNum}{T}(CellNum) = []; % Delete cell data at timepoint T
                            end
                        end
%                         
%                         if  size(TrackData{SetNum}{1},2) == 0
%                             TrackData(SetNum) = [];
%                         end
                        %------- End of Delete Selected Cell Data --------------------------------------
                        LongTrackerHandles.TrackData = TrackData;
                        Summary = SummarizeTrackData(app);
                        NewCellList = CreateCompleteCellList(app,Summary);
                        

                        % Adjust Set#, nCells, and "go to cell" values ---------------------------------
                        if (Summary.nSets < LongTrackerHandles.SetNum)
                            LongTrackerHandles.SetNum = Summary.nSets;
                            LongTrackerHandles.nCells = Summary.Data{LongTrackerHandles.SetNum}.MaxNumCells;
                            LongTrackerHandles.CellIndex = 1;
                            set(LongTrackerHandles.set_num_display,'Value',num2str(LongTrackerHandles.SetNum))
                            set(LongTrackerHandles.ncells_display, 'Value',num2str(LongTrackerHandles.nCells))
                            set(LongTrackerHandles.CellIndexSpinner,'Value',LongTrackerHandles.CellIndex)
                        else
                            LongTrackerHandles.nCells = Summary.Data{LongTrackerHandles.SetNum}.MaxNumCells;
                            LongTrackerHandles.CellIndex = 1;
                            set(LongTrackerHandles.ncells_display,'Value',num2str(LongTrackerHandles.nCells))
                            set(LongTrackerHandles.CellIndexSpinner,'Value',LongTrackerHandles.CellIndex)
                        end                        

                        T = LongTrackerHandles.T;
                        Z = LongTrackerHandles.Z;
                        CellIndex = LongTrackerHandles.CellIndex;
        
                        % Update Main Figure Window as well --------------------------------------------
                        if ishandle(LongTrackerHandles.main_panel)
                            set(0,'CurrentFigure',LongTrackerHandles.main_panel)
                            pos = get(LongTrackerHandles.main_panel,'Position');
                            [H,W,~] = size(LongTrackerHandles.RGB_T);
                            set(LongTrackerHandles.main_panel,'Position',[pos(1,1:2),W,H],...
                                    'name', ['   t = ' num2str(T) '   z = ' num2str(Z)...
                                    '        Cell ' num2str(CellIndex) '  of  ' num2str(LongTrackerHandles.nCells)...
                                    '        Set ' num2str(LongTrackerHandles.SetNum)])
                            imshow(LongTrackerHandles.RGB_T)
                            set(LongTrackerHandles.main_panel,'Position',[pos(1,1:2),W,H])
                        end
                        % ------------------------------------------------------------------------------

                        if isequal(L,val) && ~isequal(val,1)
                            set(app.Cell_Summary_Display,'Value',val-1)
                        end

                        % Update Cell Track List -------------------------------------------------------
                        set(app.Cell_Summary_Display,'Items',NewCellList,'ItemsData',1:length(NewCellList))
                    end
                end


        end

        % Button pushed function: close_window_button
        function close_window_button_Callback(app, event)
               
                    CellSummaryMainFigureCloseRequest(app, event)

        end

        % Value changed function: destination_cell_time1
        function destination_cell_time1_Callback(app, event)
                       
%             
%                         MoveCell_ST = get(app.move_cell_time1,'Value');
%                         MoveCell_ET = get(app.move_cell_time2,'Value');
%                         DestCell_ST = get(app.destination_cell_time1,'Value');
%                         DestCell_ET = get(app.destination_cell_time2,'Value');
%                         L1 = isstrprop(MoveCell_ST,'digit');
%                         L2 = isstrprop(MoveCell_ET,'digit');
%                         L3 = isstrprop(DestCell_ST,'digit');
%                         % L4 = isstrprop(DestCell_ET, 'digit');
%                         L = all([L1,L2,L3]);
%             
%                         if ~L
%                              Message = 'One or more inputs is not valid. Make sure all inputs are numerical and positive';
%                              h = msgbox(Message,'Non-numeric input','error');
%                         end
%             
%                         MCST = str2double(MoveCell_ST);
%                         MCET = str2double(MoveCell_ET);
%                         DCST = str2double(DestCell_ST);
%                         Diff = MCET-MCST;
%                         set(app.destination_cell_time2,'Value',num2str(DCST+Diff))

  
        end

        % Value changed function: HighlightSelectedCells
        function HighlightSelectedCells_Callback(app, event)
           
                    LongTrackerHandles = app.LTapp; % Grab handles and public variables from main LongTracker window (it's not ncessary to change variable name but visually convenient)
                    
                    if logical(get(app.HighlightSelectedCells,'Value'))
                        % TRUE - intialize displaying selected cells, and display which ever one is currently selected
                        set(app.selected_cell_color_button, 'Enable' ,'on')
                        set(app.selected_cell_color_display,'Visible','on')
            
                        app.DisplaySelectedCellsFromList = true;

                        val = get(app.Cell_Summary_Display,'Value');
                        CellList  = get(app.Cell_Summary_Display,'Items');
                        CellText  = CellList{val};
                        SetNum    = str2double( CellText(strfind(CellText,'s')+1:strfind(CellText,'s')+2) ); 
                        CellIndex   = str2double( CellText(strfind(CellText,'c')+1:strfind(CellText,'c')+3) ); 
                        
                        app.SelectedCellNumFromList = CellIndex;
                        app.SelectedSetNumFromList  = SetNum;
                        LongTrackerHandles.SelectedCellNumFromList = CellIndex;
                        LongTrackerHandles.SelectedSetNumFromList  = SetNum;
                        LongTrackerHandles.DisplaySelectedCellsFromList = true;
            
                        LongTrackerHandles = update_display(app, LongTrackerHandles);

                    else
                        % FALSE - unintialize displaying selected cells, and update display without selected cell
                        set(app.selected_cell_color_button, 'Enable' ,'off')
                        set(app.selected_cell_color_display,'Visible','off')
            
                        app.DisplaySelectedCellsFromList = false;
                        LongTrackerHandles.SelectedCellNumFromList = [];
                        LongTrackerHandles.SelectedSetNumFromList  = [];
                        LongTrackerHandles.DisplaySelectedCellsFromList = false;

                        LongTrackerHandles = update_display(app, LongTrackerHandles);

                    end
            
                 
        end

        % Button pushed function: move_or_delete_button
        function move_or_delete_button_Callback(app, event)
          
            
                        LongTrackerHandles = app.LTapp; % Grab handles and public variables from main LongTracker window (it's not ncessary to change variable name but visually convenient)
                        TrackData = LongTrackerHandles.TrackData;
                        
                        DoMove = app.MoveDeleteSwitch.Value;
            
                        GrabCell_ST = get(app.move_cell_time1,'Value');
                        GrabCell_ET = get(app.move_cell_time2,'Value');
                        DestCell_ST = get(app.destination_cell_time1,'Value');
                        DestCell_ET = get(app.destination_cell_time2,'Value');
                        GrabSetNum  = get(app.move_cell_set_num,'Value');
                        GrabCellNum = get(app.move_cell_cell_num,'Value');
                        DestSetNum  = get(app.destination_cell_set_num,'Value');
                        DestCellNum = get(app.destination_cell_cell_num,'Value');
            
                        L1 = isstrprop(GrabCell_ST, 'digit'); if isempty(L1); L1 = false; end
                        L2 = isstrprop(GrabCell_ET, 'digit'); if isempty(L2); L2 = false; end
                        L3 = isstrprop(DestCell_ST, 'digit'); if isempty(L3); L3 = false; end
                        L4 = isstrprop(DestCell_ET, 'digit'); if isempty(L4); L4 = false; end
                        L5 = isstrprop(GrabSetNum,  'digit'); if isempty(L5); L5 = false; end
                        L6 = isstrprop(GrabCellNum, 'digit'); if isempty(L6); L6 = false; end
                        L7 = isstrprop(DestSetNum,  'digit'); if isempty(L7); L7 = false; end
                        L8 = isstrprop(DestCellNum, 'digit'); if isempty(L8); L8 = false; end
            
                        if DoMove
                            L = all([L1,L2,L3,L4,L5,L6,L7,L8]);
                        else
                            L = all([L1,L2,L5,L6]);
                        end
            
                        %---------------------------------------------------------------
                        if ~L
                             Message = 'One or more inputs is not valid. Make sure all inputs are numerical and positive';
                             h1 = msgbox(Message,'Non-numeric input','error');
                        else
                             GCST = str2double(GrabCell_ST);
                             GCET = str2double(GrabCell_ET);
                             GSN  = str2double(GrabSetNum);
                             GCN  = str2double(GrabCellNum);
            
                             DIFF1 = GCET - GCST;
                             DIFF2 = DIFF1;
            
                             if DoMove
                                DCST = str2double(DestCell_ST);
                                DCET = str2double(DestCell_ET);
                                DSN  = str2double(DestSetNum);
                                DCN  = str2double(DestCellNum);
                                DIFF2 = DCET - DCST;
                             end
            
                             %--------------------------------------------------------------
                             if ~isequal(DIFF1,DIFF2)
                                 Message = 'The time range for "Grab Cell" is not the same as the time range for "Destination" cell';
                                 h2 = msgbox(Message,'Non-numeric input','error');
                             else
                                 % Check to make sure set and cell are
                                 % defined from main Longtracker app
                                 TS = SummarizeTrackData(app);
                                 if DSN <= TS.nSets && DCN <= TS.Data{DSN}.MaxNumCells
                                     % IF set number is less than nSets and cell number DCN is less than the
                                     % maxNum of cell for set DSN then: 
                                     % Move data -------------------------------------------------
                                         for T = 0:(DIFF1)
                                            [isdim,~] = iscelldim(app,TrackData,GSN,GCST+T,GCN); %#ok<NASGU>
                                            if isdim
                                                if DoMove % Move contents to different cell track
                                                    TrackData{DSN}{DCST+T}{DCN} = TrackData{GSN}{GCST+T}{GCN}; % Transfer data
                                                    %TrackData{GSN}{GCST+T}{GCN} = zeros(0); % Clear original location of data
                                                else % Delete contents at one or more points of a cell track
                                                    TrackData{GSN}{GCST+T}{GCN} = zeros(0);
                                                end
                                            end
                                         end
                                         app.LTapp.TrackData = TrackData;
                                         Summary = SummarizeTrackData(app);
                                         NewCellList = CreateCompleteCellList(app,Summary);
                                         set(app.Cell_Summary_Display,'Items',NewCellList,'ItemsData',1:length(NewCellList),'Value',1)
                                 else
                                         warndlg([{'Destination set or cell number does not exist yet.'},...
                                                   {'Create in main Longtracker window first.'}],'Create destination first')
                                 end


                             end
                        end

        end

        % Button pushed function: selected_cell_color_button
        function selected_cell_color_button_Callback(app, event)
                        
             c = uisetcolor;
             if ~isequal(c,0)
                  app.selected_cell_color = c;
                  set(app.selected_cell_color_display,'BackgroundColor',c)
                  app.LTapp.selected_cell_color = app.selected_cell_color;
                  app.LTapp = update_display(app, app.LTapp);
             end
             app.selected_cell_color = app.LTapp.selected_cell_color;

        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
                         
            selectedButton = app.ButtonGroup.SelectedObject;

        end

        % Close request function: CellSummaryMainFigure
        function CellSummaryMainFigureCloseRequest(app, event)
                        
            delete(app)
            
        end

        % Value changed function: destination_cell_time2
        function destination_cell_time2ValueChanged(app, event)
                       
            value = app.destination_cell_time2.Value;
            
        end

        % Value changed function: move_cell_time1
        function move_cell_time1ValueChanged(app, event)
            
            value = app.move_cell_time1.Value;
            
        end

        % Value changed function: move_cell_time2
        function move_cell_time2ValueChanged(app, event)
            
            value = app.move_cell_time2.Value;
            
        end

        % Value changed function: move_cell_cell_num
        function move_cell_cell_numValueChanged(app, event)
            
            value = app.move_cell_cell_num.Value;
            
        end

        % Value changed function: move_cell_set_num
        function move_cell_set_numValueChanged(app, event)
            
            value = app.move_cell_set_num.Value;
            
        end

        % Value changed function: destination_cell_set_num
        function destination_cell_set_numValueChanged(app, event)
            
            value = app.destination_cell_set_num.Value;
            
        end

        % Value changed function: destination_cell_cell_num
        function destination_cell_cell_numValueChanged(app, event)
            
            value = app.destination_cell_cell_num.Value;
            
        end

        % Value changed function: MoveDeleteSwitch
        function MoveDeleteSwitchValueChanged(app, event)
           
            if app.MoveDeleteSwitch.Value
                set(app.uipanel5,'Enable','on')
            else
                set(app.uipanel5,'Enable','off')
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CellSummaryMainFigure = uifigure('Visible', 'off');
            colormap(app.CellSummaryMainFigure, 'parula');
            app.CellSummaryMainFigure.Name = 'Cell Summary';
            app.CellSummaryMainFigure.CloseRequestFcn = createCallbackFcn(app, @CellSummaryMainFigureCloseRequest, true);
            app.CellSummaryMainFigure.Tag = 'CellSummaryMainFigure';
            app.Cell_Summary_Display = uilistbox(app.CellSummaryMainFigure);
            app.Cell_Summary_Display.Items = {'s20c30 134-345'};
            app.Cell_Summary_Display.ValueChangedFcn = createCallbackFcn(app, @Cell_Summary_Display_Callback, true);
            app.Cell_Summary_Display.Tag = 'Cell_Summary_Display';
            app.Cell_Summary_Display.Value = {};
            app.DeleteCellButton = uibutton(app.CellSummaryMainFigure, 'push');
            app.DeleteCellButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteCellButton_Callback, true);
            app.DeleteCellButton.Tag = 'DeleteCellButton';
            app.DeleteCellButton.Text = 'Delete Cell';
            app.close_window_button = uibutton(app.CellSummaryMainFigure, 'push');
            app.close_window_button.ButtonPushedFcn = createCallbackFcn(app, @close_window_button_Callback, true);
            app.close_window_button.Tag = 'close_window_button';
            app.close_window_button.Text = 'close window';
            app.HighlightSelectedCells = uicheckbox(app.CellSummaryMainFigure);
            app.HighlightSelectedCells.ValueChangedFcn = createCallbackFcn(app, @HighlightSelectedCells_Callback, true);
            app.HighlightSelectedCells.Tag = 'display_selected_cell_check';
            app.HighlightSelectedCells.Text = 'Highlight Selected Cells';
            app.uipanel2 = uipanel(app.CellSummaryMainFigure);
            app.uipanel2.Tag = 'uipanel2';
            app.move_or_delete_button = uibutton(app.uipanel2, 'push');
            app.move_or_delete_button.ButtonPushedFcn = createCallbackFcn(app, @move_or_delete_button_Callback, true);
            app.move_or_delete_button.Tag = 'move_or_delete_button';
            app.move_or_delete_button.Text = 'Edit';
            app.uipanel4 = uipanel(app.uipanel2);
            app.uipanel4.Title = 'Grab Cell:';
            app.uipanel4.Tag = 'uipanel4';
            app.text13 = uilabel(app.uipanel4);
            app.text13.Tag = 'text13';
            app.text13.Text = '-';
            app.move_set_text = uilabel(app.uipanel4);
            app.move_set_text.Tag = 'move_set_text';
            app.move_set_text.Text = 's';
            app.move_cell_set_num = uieditfield(app.uipanel4, 'text');
            app.move_cell_set_num.ValueChangedFcn = createCallbackFcn(app, @move_cell_set_numValueChanged, true);
            app.move_cell_set_num.Tag = 'move_cell_set_num';
            app.move_cell_set_num.Value = '305';
            app.text19 = uilabel(app.uipanel4);
            app.text19.Tag = 'text19';
            app.text19.Text = 'c';
            app.move_cell_cell_num = uieditfield(app.uipanel4, 'text');
            app.move_cell_cell_num.ValueChangedFcn = createCallbackFcn(app, @move_cell_cell_numValueChanged, true);
            app.move_cell_cell_num.Tag = 'move_cell_cell_num';
            app.move_cell_cell_num.Value = '305';
            app.move_cell_time1 = uieditfield(app.uipanel4, 'text');
            app.move_cell_time1.ValueChangedFcn = createCallbackFcn(app, @move_cell_time1ValueChanged, true);
            app.move_cell_time1.Tag = 'move_cell_time1';
            app.move_cell_time1.Value = '1000';
            app.move_cell_time2 = uieditfield(app.uipanel4, 'text');
            app.move_cell_time2.ValueChangedFcn = createCallbackFcn(app, @move_cell_time2ValueChanged, true);
            app.move_cell_time2.Tag = 'move_cell_time2';
            app.move_cell_time2.Value = '1000';
            app.text20 = uilabel(app.uipanel4);
            app.text20.Tag = 'text20';
            app.text20.Text = 't';
            app.uipanel5 = uipanel(app.uipanel2);
            app.uipanel5.Title = 'Destination:';
            app.uipanel5.Tag = 'uipanel5';
            app.text18 = uilabel(app.uipanel5);
            app.text18.Tag = 'text18';
            app.text18.Text = '-';
            app.text17 = uilabel(app.uipanel5);
            app.text17.Tag = 'text17';
            app.text17.Text = 't';
            app.destination_cell_time2 = uieditfield(app.uipanel5, 'text');
            app.destination_cell_time2.ValueChangedFcn = createCallbackFcn(app, @destination_cell_time2ValueChanged, true);
            app.destination_cell_time2.Tag = 'destination_cell_time2';
            app.destination_cell_time1 = uieditfield(app.uipanel5, 'text');
            app.destination_cell_time1.ValueChangedFcn = createCallbackFcn(app, @destination_cell_time1_Callback, true);
            app.destination_cell_time1.Tag = 'destination_cell_time1';
            app.destination_cell_cell_num = uieditfield(app.uipanel5, 'text');
            app.destination_cell_cell_num.ValueChangedFcn = createCallbackFcn(app, @destination_cell_cell_numValueChanged, true);
            app.destination_cell_cell_num.Tag = 'destination_cell_cell_num';
            app.text16 = uilabel(app.uipanel5);
            app.text16.Tag = 'text16';
            app.text16.Text = 'c';
            app.text15 = uilabel(app.uipanel5);
            app.text15.Tag = 'text15';
            app.text15.Text = 's';
            app.destination_cell_set_num = uieditfield(app.uipanel5, 'text');
            app.destination_cell_set_num.ValueChangedFcn = createCallbackFcn(app, @destination_cell_set_numValueChanged, true);
            app.destination_cell_set_num.Tag = 'destination_cell_set_num';
            app.ButtonGroup = uibuttongroup(app.uipanel2);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.MoveDeleteSwitch = uiswitch(app.ButtonGroup, 'slider');
            app.MoveDeleteSwitch.Items = {'move', 'delete'};
            app.MoveDeleteSwitch.ValueChangedFcn = createCallbackFcn(app, @MoveDeleteSwitchValueChanged, true);
            app.MoveDeleteSwitch.Value = 'move';
            app.MoveDeleteCellDataLabel = uilabel(app.uipanel2);
            app.MoveDeleteCellDataLabel.Text = 'Move/Delete Cell Data';
            app.selected_cell_color_display = uipanel(app.CellSummaryMainFigure);
            app.selected_cell_color_display.Tag = 'selected_cell_color_display';
            app.selected_cell_color_button = uibutton(app.CellSummaryMainFigure, 'push');
            app.selected_cell_color_button.ButtonPushedFcn = createCallbackFcn(app, @selected_cell_color_button_Callback, true);
            app.selected_cell_color_button.Tag = 'selected_cell_color_button';
            app.selected_cell_color_button.Text = 'Color';
            % Show the figure after all components are created
            app.CellSummaryMainFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CellSummaryDisplay(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.CellSummaryMainFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)CellSummaryDisplay_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.CellSummaryMainFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CellSummaryMainFigure)
        end
    end
end