classdef ImagePreProcessing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LongtrackerimagedatapreprocessingappUIFigure  matlab.ui.Figure
        StartStopPanel                  matlab.ui.container.Panel
        STOPButton                      matlab.ui.control.StateButton
        STARTButton                     matlab.ui.control.Button
        EndingTimePointSelector         matlab.ui.control.NumericEditField
        StartingtimepointLabel_2        matlab.ui.control.Label
        StartingTimePointSelector       matlab.ui.control.NumericEditField
        StartingtimepointLabel          matlab.ui.control.Label
        RawdatabitdepthLabel            matlab.ui.control.Label
        ImageBitDepth                   matlab.ui.control.DropDown
        eglsmczilifornd2Label           matlab.ui.control.Label
        ShowImagePanel                  matlab.ui.container.Panel
        RotateDropDown                  matlab.ui.control.DropDown
        RotateLabel                     matlab.ui.control.Label
        DataloadingprocessingLamp       matlab.ui.control.Lamp
        DataloadingprocessingLampLabel  matlab.ui.control.Label
        DataSeriesSpinner               matlab.ui.control.Spinner
        DataseriesLabel                 matlab.ui.control.Label
        XSpinner                        matlab.ui.control.Spinner
        XSpinnerLabel                   matlab.ui.control.Label
        XSliceSlider                    matlab.ui.control.Slider
        ZSpinner                        matlab.ui.control.Spinner
        ZSpinnerLabel                   matlab.ui.control.Label
        TSpinner                        matlab.ui.control.Spinner
        TSpinnerLabel                   matlab.ui.control.Label
        ZLayerSlider                    matlab.ui.control.Slider
        TimePointSlider                 matlab.ui.control.Slider
        TabGroup                        matlab.ui.container.TabGroup
        REDTab                          matlab.ui.container.Tab
        RED3DSubtractRedimagefromGreenImageCheckBox  matlab.ui.control.CheckBox
        fontnamehelveticabfSuggestedfunctionsrm23and4Label  matlab.ui.control.Label
        RED3DGaussianSmoothZSigma       matlab.ui.control.NumericEditField
        zsigmaEditFieldLabel            matlab.ui.control.Label
        RED3DGaussianSmoothXYSigma      matlab.ui.control.NumericEditField
        RED3DGaussiansmoothingsigmaCheckBox  matlab.ui.control.CheckBox
        Label_2                         matlab.ui.control.Label
        REDPercentImageSaturation       matlab.ui.control.NumericEditField
        RED2DGaussianSmoothXYSigma      matlab.ui.control.NumericEditField
        RED2DGaussiansmoothingsigmaCheckBox  matlab.ui.control.CheckBox
        REDImageSaturationsaturationCheckBox  matlab.ui.control.CheckBox
        FortrackingnucleitheredchannelshouldbethemembranechannelLabel  matlab.ui.control.Label
        REDAdaptiveHistogramEqualizationredCheckBox  matlab.ui.control.CheckBox
        RedImageDropDown                matlab.ui.control.DropDown
        RedimageLabel                   matlab.ui.control.Label
        ImageProcessingTabs             matlab.ui.container.Tab
        Suggestedfunctions145and6Label  matlab.ui.control.Label
        SubtractRedimagefromGreenImageVar2CheckBox  matlab.ui.control.CheckBox
        GREEN3DGaussianSmoothZSigma     matlab.ui.control.NumericEditField
        zsigmaEditFieldLabel_2          matlab.ui.control.Label
        GREEN3DGaussianSmoothXYSigma    matlab.ui.control.NumericEditField
        GREEN3DGaussiansmoothingsigmaCheckBox  matlab.ui.control.CheckBox
        SubtractRedimagefromGreenImageVar1CheckBox  matlab.ui.control.CheckBox
        Label_3                         matlab.ui.control.Label
        GREENPercentImageSaturation     matlab.ui.control.NumericEditField
        GREEN2DGaussianSmoothSigma      matlab.ui.control.NumericEditField
        GREEN2DGaussiansmoothingsigmaCheckBox  matlab.ui.control.CheckBox
        GREENImageSaturationsaturationCheckBox  matlab.ui.control.CheckBox
        GREENAdaptiveHistogramEqualizationredCheckBox  matlab.ui.control.CheckBox
        FortrackingnucleithegreenchannelshouldbethenuclearchannelLabel  matlab.ui.control.Label
        GreenImageDropDown              matlab.ui.control.DropDown
        GreenimageLabel                 matlab.ui.control.Label
        BLUETab                         matlab.ui.container.Tab
        Suggestedfunctions145and6Label_2  matlab.ui.control.Label
        CellposeInputParametersPanel    matlab.ui.container.Panel
        modelDropDown                   matlab.ui.control.DropDown
        modelDropDownLabel              matlab.ui.control.Label
        CellposeSegmentationOnLamp      matlab.ui.control.Lamp
        ExecutionEnvironmentlDropDown   matlab.ui.control.DropDown
        AuxchannelLabel_2               matlab.ui.control.Label
        VerifyCellposeInstallationButton  matlab.ui.control.Button
        AuxillaryChannelDropDown        matlab.ui.control.DropDown
        AuxchannelLabel                 matlab.ui.control.Label
        averagecelldiametervoxelsEditField  matlab.ui.control.NumericEditField
        averagecelldiametervoxelsEditFieldLabel  matlab.ui.control.Label
        ApplyCellposeSegmentationCheckBox  matlab.ui.control.CheckBox
        Label_5                         matlab.ui.control.Label
        BLUEPercentImageSaturation      matlab.ui.control.NumericEditField
        BLUE2DGaussianSmoothSigma       matlab.ui.control.NumericEditField
        BLUE2DGaussiansmoothingsigmaCheckBox  matlab.ui.control.CheckBox
        BLUEImageSaturationsaturationCheckBox  matlab.ui.control.CheckBox
        BLUEAdaptiveHistogramEqualizationredCheckBox  matlab.ui.control.CheckBox
        Label_4                         matlab.ui.control.Label
        BlueImageDropDown               matlab.ui.control.DropDown
        GreenimageLabel_2               matlab.ui.control.Label
        ImageDataStatsDisplay           matlab.ui.control.TextArea
        FileLocationDisplay             matlab.ui.control.TextArea
        Label                           matlab.ui.control.Label
        FileLocationButton              matlab.ui.control.Button
    end

    
    properties (Access = public)

        FileLocation     = []; % Description
        BioFormatsReader = [];
        DisplayFigure    = -1;
        DisplayAxes      = -1;
        S                =  1;   % Current selected data set in file
        T                =  1;   % Current selected data time point in file
        Z                =  1;   % Current selected z-slice in file
        X                =  1;   % Current selected X-slice of image volume
        Y                =  1;   % Current selected Y-slice of image volume

        nDataSeries = [];        % number of data sets in file
        ImHeight  = [];          % image height (pixels)
        ImWidth   = [];          % image width  (pixels)
        nZ        = [];          % number of Z-slices
        nT        = [];          % number of time points
        nC        = [];          % number of channels
        BitDepth  = [];          % get image bit depth
        nP        = [];          % total number of images

        RedRaw3D   = [];
        GreenRaw3D = [];
        BlueRaw3D  = [];

        ProcIMR3D = [];
        ProcIMG3D = [];
        ProcIMB3D = [];

        LampOnColor  = [0.06,1.00,1.00];
        LampOffColor = [0,0,0.3];
        
        CPmodel = [];
    end

    
    methods (Access = private)
        
%====================================================================================================================================================    
        function LoadImagesForCurrentTimePoint(app,t)
                    
                    % iPlane = reader.getIndex(z-1,c-1,t-1) + 1;  % Convert to plane number
                    % IM = double( bfGetPlane(reader, iPlane) );  % Get image data and convert to double precision

                    % Images are loaded in using using the Bio_Formats toolbox: https://www.openmicroscopy.org/bio-formats/downloads/
                    % If images are not loaded properly the toolbox may need to be updated

                    iSeries = app.DataSeriesSpinner.Value;
                    app.BioFormatsReader.setSeries(iSeries - 1); % Set image series to read from

                    % Read in full z-stack for each channel at timepoint T
                    BitDepth = app.ImageBitDepth.Value;

                    app.RedRaw3D   = [];
                    app.GreenRaw3D = [];
                    app.BlueRaw3D  = [];

                    
                    switch app.RotateDropDown.Value
                        case 0;     Width = app.ImWidth;   Height = app.ImHeight;
                        case 90;    Width = app.ImHeight;  Height = app.ImWidth;   
                        case 180;   Width = app.ImWidth;   Height = app.ImHeight;
                        case 270;   Width = app.ImHeight;  Height = app.ImWidth;  
                    end

                    app.RedRaw3D = zeros(Height,Width,app.nZ,'uint8');
                    if app.RedImageDropDown.Value ~= 0
                        for z = 1:app.nZ
                            c = app.RedImageDropDown.Value;
                            iPlane = app.BioFormatsReader.getIndex(z-1,c-1,t-1) + 1;  % Convert to plane number
                            IMREDraw = imrotate(  uint8(  255*( double(  bfGetPlane(app.BioFormatsReader, iPlane) )./BitDepth)  ),  app.RotateDropDown.Value); 
                            app.RedRaw3D(:,:,z) = IMREDraw;
                        end   
                    end
    
                    app.GreenRaw3D = zeros(Height,Width,app.nZ,'uint8');
                    if app.GreenImageDropDown.Value ~= 0
                        for z = 1:app.nZ
                            c = app.GreenImageDropDown.Value;
                            iPlane = app.BioFormatsReader.getIndex(z-1,c-1,t-1) + 1;  % Convert to plane number
                            IMGREENraw = imrotate(  uint8(  255*( double(  bfGetPlane(app.BioFormatsReader, iPlane) )./BitDepth)  ),  app.RotateDropDown.Value); 
                            app.GreenRaw3D(:,:,z) = IMGREENraw;
                        end   
                    end
    
                    app.BlueRaw3D = zeros(Height,Width,app.nZ,'uint8');
                    if app.BlueImageDropDown.Value ~= 0
                        for z = 1:app.nZ
                            c = app.BlueImageDropDown.Value;
                            iPlane = app.BioFormatsReader.getIndex(z-1,c-1,t-1) + 1;  % Convert to plane number
                            IMBLUEraw = imrotate(  uint8(  255*( double(  bfGetPlane(app.BioFormatsReader, iPlane) )./BitDepth)  ),  app.RotateDropDown.Value); 
                            app.BlueRaw3D(:,:,z) = IMBLUEraw;
                        end   
                    end


        end
        
%====================================================================================================================================================       
        function [IMR3D, IMG3D, IMB3D] = ProcessImages(app)

                 T = app.T;
                 Z = app.Z;
                 X = app.X;
                 Y = app.Y;

                 %----------------------------------------------------------------------------------------------------------------

                switch app.RotateDropDown.Value
                    case 0;     Width = app.ImWidth;   Height = app.ImHeight;
                    case 90;    Width = app.ImHeight;  Height = app.ImWidth;   
                    case 180;   Width = app.ImWidth;   Height = app.ImHeight;
                    case 270;   Width = app.ImHeight;  Height = app.ImWidth;  
                end


                 IMR3D = zeros(Height,Width,app.nZ,'uint8');

                 if app.RedImageDropDown.Value ~= 0
                            
                            % Apply 3D smoothing to Red Channel
                            if app.RED3DGaussiansmoothingsigmaCheckBox.Value % But if the user selected 3D smoothing, apply 3D smoothing to this volume before moving on to the 2D processing
                                IMR3D = imgaussfilt3(app.RedRaw3D,[app.RED3DGaussianSmoothXYSigma.Value,...
                                                                   app.RED3DGaussianSmoothXYSigma.Value,...
                                                                   app.RED3DGaussianSmoothZSigma.Value]);
                            else
                                IMR3D = app.RedRaw3D;
                            end
                            
                            % Record Red channel for variation 1 of Subtraction from Green channel
                            IMR3Dsubtract = IMR3D;

                            % Apply 2D red smoothing
                            if app.RED2DGaussiansmoothingsigmaCheckBox.Value  
                                for z = 1:app.nZ % 2D processing steps
                                    IMR3D(:,:,z) = imgaussfilt(IMR3D(:,:,z),app.RED2DGaussianSmoothXYSigma.Value);    
                                    Red3DWatershed(:,:,z) = watershed(IMR3D(:,:,z));
                                end
                            end

                            % Apply Red channel adaptive histogram equalization                             
                            if app.REDAdaptiveHistogramEqualizationredCheckBox.Value
                                for z = 1:app.nZ % 2D processing steps
                                    IMR3D(:,:,z) = adapthisteq(IMR3D(:,:,z),'NumTiles',[8,8],'Distribution','rayleigh');
                                end
                            end

                            % Apply Red channel saturation
                            if app.REDImageSaturationsaturationCheckBox.Value  
                                p = app.REDPercentImageSaturation.Value;
                                for z = 1:app.nZ % 2D processing steps
                                    IMR3D(:,:,z) = imadjust( IMR3D(:,:,z), stretchlim(IMR3D(:,:,z), [p/100 (100-p)/100]) );
                                end
                            end                     
                 end

                 %----------------------------------------------------------------------------------------------------------------
                 IMG3D = zeros(Height,Width,app.nZ,'uint8');

                 if app.GreenImageDropDown.Value ~= 0
                            
                            % Apply 3D smooothing
                            if app.GREEN3DGaussiansmoothingsigmaCheckBox.Value
                                IMG3D = imgaussfilt3(app.GreenRaw3D,[app.GREEN3DGaussianSmoothXYSigma.Value,...
                                                                     app.GREEN3DGaussianSmoothXYSigma.Value,...
                                                                     app.GREEN3DGaussianSmoothZSigma.Value]);
                            else 
                                IMG3D = app.GreenRaw3D;
                            end
                            
                            % Apply 2D smoothing
                            if app.GREEN2DGaussiansmoothingsigmaCheckBox.Value
                                Gsigma = app.GREEN2DGaussianSmoothSigma.Value;
                                for z = 1:app.nZ                                         
                                    IMG3D(:,:,z) = imgaussfilt(IMG3D(:,:,z),Gsigma);  
                                end
                            end

                            % Subtract Red (at most 3D smoothed) from Green (variation 1)
                            if app.SubtractRedimagefromGreenImageVar1CheckBox.Value
                                IMG3D = IMG3D - IMR3Dsubtract;
                            end                            
                            
                            % Apply Addaptive histogram Equalization to Green    
                            if app.GREENAdaptiveHistogramEqualizationredCheckBox.Value
                                for z = 1:app.nZ
                                    IMG3D(:,:,z) = adapthisteq(IMG3D(:,:,z),'NumTiles',[8,8],'Distribution','rayleigh');
                                end
                            end
                            
                            % Apply Saturation to Green
                            if app.GREENImageSaturationsaturationCheckBox.Value  
                                p = app.GREENPercentImageSaturation.Value;
                                for z = 1:app.nZ
                                    IMG3D(:,:,z) = imadjust(IMG3D(:,:,z),stretchlim(IMG3D(:,:,z),[p/100 (100-p)/100]));
                                end
                            end                            

                            % Subtract Red from Green (varitaion 2)
                            if app.SubtractRedimagefromGreenImageVar2CheckBox.Value
                                IMG3D = IMG3D - IMR3D;
                            end 

                 end


                 % Apply Subtract Green from Red channel -------------------------------------------------------------------------
                 if app.RED3DSubtractRedimagefromGreenImageCheckBox.Value
                        IMR3D = IMR3D - IMG3D;    
                 end


                 %----------------------------------------------------------------------------------------------------------------
                 IMB3D = zeros(Height,Width,app.nZ,'uint8');

                 if app.BlueImageDropDown.Value ~= 0
                            IMB3D = app.BlueRaw3D;                            
                            
                            % Apply 2D smoothing
                            if app.BLUE2DGaussiansmoothingsigmaCheckBox.Value
                                Bsigma = app.BLUE2DGaussianSmoothSigma.Value;
                                for z = 1:app.nZ                                         
                                    IMB3D(:,:,z) = imgaussfilt(IMB3D(:,:,z),Bsigma);  
                                end
                            end                                               
                            
                            % Apply Addaptive histogram Equalization to Blue    
                            if app.BLUEAdaptiveHistogramEqualizationredCheckBox.Value
                                for z = 1:app.nZ
                                    IMB3D(:,:,z) = adapthisteq(IMB3D(:,:,z),'NumTiles',[8,8],'Distribution','rayleigh');
                                end
                            end
                            
                            % Apply Saturation to Blue
                            if app.BLUEImageSaturationsaturationCheckBox.Value  
                                p = app.BLUEPercentImageSaturation.Value;
                                for z = 1:app.nZ
                                    IMB3D(:,:,z) = imadjust(IMB3D(:,:,z),stretchlim(IMB3D(:,:,z),[p/100 (100-p)/100]));
                                end
                            end     

                            % Apply Cellpose segmentation
                            if app.ApplyCellposeSegmentationCheckBox.Value & strcmp(app.STARTButton.Enable,'off')
                                IMB3D = ApplyCellposeSegmentation(app, IMR3D, IMG3D, IMB3D);
                            end
                 end
                 %----------------------------------------------------------------------------------------------------------------


        end


%====================================================================================================================================================            
        function UpdateImageDisplay(app,DoProcessing)

                if DoProcessing                        
                    [app.ProcIMR3D, app.ProcIMG3D, app.ProcIMB3D] = ProcessImages(app);                        
                end
                 
                switch app.RotateDropDown.Value
                    case 0;     Width = app.ImWidth;   Height = app.ImHeight;
                    case 90;    Width = app.ImHeight;  Height = app.ImWidth;   
                    case 180;   Width = app.ImWidth;   Height = app.ImHeight;
                    case 270;   Width = app.ImHeight;  Height = app.ImWidth;  
                end

                 DividerWidth = 5;
                 RGBimageXY = cat(3, squeeze(app.ProcIMR3D(:,:,app.Z)), squeeze(app.ProcIMG3D(:,:,app.Z)), squeeze(app.ProcIMB3D(:,:,app.Z)) );
                 RGBimageXY(:,app.X,:) = 255*cat(3,zeros(Height,1,'uint8'), ones(Height,1,'uint8'),ones(Height,1,'uint8'));

                 RGBimageYZ = cat(3, squeeze(app.ProcIMR3D(:,app.X,:)), squeeze(app.ProcIMG3D(:,app.X,:)), squeeze(app.ProcIMB3D(:,app.X,:)) );   
                 RGBimageYZ = imresize(RGBimageYZ, [Height, 2*app.nZ]);
                 RGBimageYZ(:,2*app.Z,:) = 255*cat(3,zeros(Height,1,'uint8'), ones(Height,1,'uint8'),ones(Height,1,'uint8'));
                 RGBdivider = 255*ones(Height,DividerWidth,3,'uint8');

                 RGB = cat(2,RGBimageYZ,RGBdivider,RGBimageXY);

                 if ~isgraphics(app.DisplayFigure)
                     app.DisplayFigure = figure;
                     set(app.DisplayFigure,'Color','w')
                     imshow(RGB)
                     app.DisplayAxes  = gca;
                     set(app.DisplayAxes,'Units','normalized','InnerPosition',[0,0,1,1])
                     axtoolbar(app.DisplayAxes,{});
                     % axis tight
                 else
                     imshow(RGB,'Parent',app.DisplayAxes)
                     axtoolbar(app.DisplayAxes,{});
                     %tightfig(RGB)
                 end

        end


%====================================================================================================================================================
        function BinaryMap = ApplyCellposeSegmentation(app, IMR3D, IMG3D, IMB3D)
            
                if strcmp(app.AuxillaryChannelDropDown.Value,'none')
                    AuxData = [];
                elseif strcmp(app.AuxillaryChannelDropDown.Value,'red')
                    AuxData = IMR3D;
                elseif strcmp(app.AuxillaryChannelDropDown.Value,'green')
                    AuxData = IMG3D;
                end
    
                Diameter = app.averagecelldiametervoxelsEditField.Value; 
                LABELS = segmentCells3D( app.CPmodel, IMB3D, ImageCellDiameter = Diameter, AuxiliaryChannelImage = AuxData);
                [FX,FY,FZ] = gradient( LABELS );
                BinaryMap = uint8( 255*logical( sqrt( FX.^2 + FY.^2 + FZ.^2) ) );
            
        end


%====================================================================================================================================================            
        function ProcessAndWriteImages(app,path,file)
            
                
                app.STARTButton.Enable = 'off';
                app.STOPButton.Enable = "on";
                app.STOPButton.Value = 0;
                app.ImageBitDepth.Enable = 'off';
    
                BitDepth = app.ImageBitDepth.Value;
    
                H = waitbar(0);
                waitbar( 1/app.nT, H, ['Timepoint ' num2str(1) '/' num2str(app.nT)], 'Name','Writing files...')
                H.Name = 'Writing image files...';
                
                RawDataFolder = fullfile(path,'RAW_DATA_8bitformat');
    
                if ~isfolder( RawDataFolder )
                    [status,msg] = mkdir( RawDataFolder );
                else
                    status = true;
                end
    
                % If Cellpose segmentation selected, start cellpose model ---------------------
                if app.ApplyCellposeSegmentationCheckBox.Value                
                    ModelName = app.modelDropDown.Value; % "cyto2" or "cyto3"
                    ExecEnv = app.ExecutionEnvironmentlDropDown.Value;
                    app.CPmodel = cellpose( Model = ModelName, ExecutionEnvironment = ExecEnv);  % Download cyto3 model here: https://www.cellpose.org/models/cyto3
                else
                    app.CPmodel = [];
                end
    
                Tstart = app.StartingTimePointSelector.Value;
                Tend   = app.EndingTimePointSelector.Value;

                if Tend < Tstart
                    Tend = app.StartingTimePointSelector.Value;
                    Tstart   = app.EndingTimePointSelector.Value;
                end

                nTimepoints = Tend - Tstart + 1;
                count = 0;

                for t = Tstart:Tend
                         tic
                         count = count + 1;

                         if isgraphics(H)
                            waitbar(t/app.nT,H,['Timepoint ' num2str(count) ' of ' num2str(nTimepoints)])
                         else
                            H = waitbar(0);
                            waitbar(t/app.nT,H,['Timepoint ' num2str(count) ' of ' num2str(nTimepoints)])
                         end
                         
                         drawnow
                         if logical(app.STOPButton.Value)
                            break
                         end
                          
                        % Load in images and process them -----------------------------------------------------------------------------------------------------------------
                        LoadImagesForCurrentTimePoint(app,t)
                        [IMR3D, IMG3D, IMB3D] = ProcessImages(app);
                      
                         % Check if file already exists. If so delete it --------------------------------------------------------------------------------------------------
                         FullFileNameRGB = fullfile(path,[file,'_T',sprintf('%05d',t),'.tif']); 
                         if isfile(FullFileNameRGB) 
                             delete(FullFileNameRGB)
                         end
    
                         FullFileNamesRaw = cell(app.nC,1);
                         for c = 1:app.nC 
                             FullFileNamesRaw{c,1} = fullfile(RawDataFolder,[file,'_RawImage_Ch',sprintf('%02d',c),'_T',sprintf('%05d',t),'.tif']); 
                             if isfile(FullFileNamesRaw{c,1}) 
                                delete(FullFileNamesRaw{c,1})
                             end
                         end
        
                         % WRITE IMAGE FILES -----------------------------------------------------------------------------------------------------------------------------
                         for z = 1:app.nZ
                                    % Write RGB z-Stack for each timestep ----------------------------------------------------------------------------------------------------
                                    RGBimageXY = cat(3, squeeze(IMR3D(:,:,z)), squeeze(IMG3D(:,:,z)), squeeze(IMB3D(:,:,z)) );
        
                                    if z == 1;    WriteMode = 'overwrite';
                                    else;         WriteMode = 'append';
                                    end
                                    
                                    imwrite(RGBimageXY, FullFileNameRGB ,'tif','WriteMode',WriteMode,'Compression','none','RowsPerStrip',size(RGBimageXY,1))
                                    
                                    % Write raw data files as 8-bit ----------------------------------------------------------------------------------------------------------
                                    if status
                                        for c = 1:app.nC 
                                            iPlane = app.BioFormatsReader.getIndex( z-1, c-1, t-1)  +  1;   % Convert to plane number
                                            ImageRaw = uint8(  255*( double(  bfGetPlane(app.BioFormatsReader, iPlane) )./BitDepth )  ); 
                                            imwrite( ImageRaw, FullFileNamesRaw{c,1} ,'tif','WriteMode',WriteMode,'Compression','none','RowsPerStrip',size(ImageRaw,1))                                    
                                        end
                                    end
                         end
                         %-------------------------------------------------------------------------------------------------------------------------------------------------
                        
                         T = toc;
                         if T < 120
                            disp(['Processing time: ', sprintf('%d seconds',round(T))])
                         else
                            disp(['Processing time: ', sprintf('%0.1f minutes',T/60)])
                         end
                end
    
                if isgraphics(H)
                    delete(H)
                end
              
                app.STARTButton.Visible  = 'on';
                app.STARTButton.Enable   = 'on';
                app.STOPButton.Visible   = 'on';
                app.STOPButton.Enable    = "off";
                app.STOPButton.Value     = false;
                app.ImageBitDepth.Enable = 'on';
        end


    end
%====================================================================================================================================================                
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            

            app.TabGroup.Visible = 'off';
            app.ImageDataStatsDisplay.Visible = 'off';
            app.StartStopPanel.Visible = "off";
            app.ShowImagePanel.Visible = 'off';
            app.STARTButton.Visible = 'off';
            app.STARTButton.Enable = 'on';
            app.STOPButton.Visible = 'off';
            app.STOPButton.Enable = "off";
            app.STOPButton.Value = 0;
            app.ImageBitDepth.Enable = 'off';
            app.ImageBitDepth.Items = {'8','12','14','16'};
            app.ImageBitDepth.ItemsData = [255, 4095, 16383, 65535];
            app.ImageBitDepth.Value = 255;    
            app.DataloadingprocessingLamp.Color = app.LampOffColor;
            app.CellposeSegmentationOnLamp.Color = [0.2, 0, 0];


        end

        % Button pushed function: FileLocationButton
        function FileLocationButtonPushed(app, event)
            
                [FileName,PathName] = uigetfile('*.*','Select image data file.');
                
                if ~isequal(FileName,0)
                        app.FileLocation = fullfile( PathName, FileName );
                        app.FileLocationDisplay.Value = app.FileLocation;
                        drawnow

                        d = uiprogressdlg(app.LongtrackerimagedatapreprocessingappUIFigure,...
                            'Title','Please Wait...','Message','Please wait...reading image stats.','Indeterminate','on');

                        try
                                %---------------------------------------------------------------------------------------
                                % bfGetReader is a function from the Bio-Formats toolbox: https://www.openmicroscopy.org/bio-formats/downloads/
                                    reader   = bfGetReader(app.FileLocation); % creates an empty Bio-Formats reader extending
                                    app.BioFormatsReader = reader; % record java reader object
                                % use command methods(reader) or methodsview(reader) at MATLAB prompt to get all data field names of java reader object
                                    app.nDataSeries = reader.getSeriesCount;        % Number of series (movies) in the file
                                    app.ImWidth     = reader.getSizeX;              % image height (pixels)
                                    app.ImHeight    = reader.getSizeY;              % image width  (pixels)
                                    app.nZ          = reader.getSizeZ;              % number of Z-slices
                                    app.nT          = reader.getSizeT;              % number of time points
                                    app.nC          = reader.getSizeC;              % number of channels
                                    app.BitDepth    = reader.getBitsPerPixel;       % get image bit depth
                                %---------------------------------------------------------------------------------------   
        
                                app.ImageDataStatsDisplay.Value = ...
                                        [ { 'IMAGE FILE STATS ' };...  
                                          {['nData series: ', sprintf('%4.0f',app.nDataSeries)] };...  
                                          {['Image height: ', sprintf('%4.0f',app.ImHeight)] };...    
                                          {['Image width:  ', sprintf('%4.0f',app.ImWidth)]  };...
                                          {['nZ layers:    ', sprintf('%4.0f',app.nZ)]       };...
                                          {['nTime pts:    ', sprintf('%4.0f',app.nT)]       };... 
                                          {['nChannels:    ', sprintf('%4.0f',app.nC)]       } ];

                                app.EndingTimePointSelector.Value = app.nT;
                                app.EndingTimePointSelector.Limits = [1,app.nT];
                                
                                % Update buttons and controls with stat information ----------------------------------------------
                                app.TabGroup.Visible = 'on';
                                app.ImageDataStatsDisplay.Visible = 'on';
                                app.StartStopPanel.Visible = "on";
                                app.ShowImagePanel.Visible = 'on';
                                app.STARTButton.Enable = 'on';
                                app.STARTButton.Visible = 'on';
                                app.STOPButton.Visible = 'on';
                                app.STOPButton.Value = 0;
                                app.STOPButton.Enable = "off";
                                app.ImageBitDepth.Enable = 'on';
        
                                app.TimePointSlider.Value = 1;
                                app.TSpinner.Value = 1;
                                if app.nT == 1
                                    app.TimePointSlider.Limits = [1,100];
                                    app.TimePointSlider.Enable = 'off';
                                    app.TSpinner.Enable = 'off';
                                else
                                    app.TimePointSlider.Limits = [1,app.nT];
                                    app.TimePointSlider.Enable = 'on';
                                    app.TSpinner.Enable = 'on';
                                    if app.nT >50
                                        app.TimePointSlider.MajorTicks = unique([1, 50:50:app.nT]);
                                        app.TimePointSlider.MinorTicks = (10:10:app.nT)'; 
                                    else
                                        app.TimePointSlider.MajorTicks = unique([1,5:5:app.nT]);
                                        app.TimePointSlider.MinorTicks = []; 
                                    end
                                end
        
                                
                                app.ZLayerSlider.Value  = 1;
                                app.ZSpinner.Value = 1;
                                if app.nZ == 1
                                    app.ZLayerSlider = [1,100];
                                    app.ZLayerSlider.Enable = 'off';
                                    app.ZSpinner.Enable = 'off';
                                    app.ZSpinner.Limits = [1,1];
                                else
                                    app.ZLayerSlider.Limits = [1,app.nZ ];
                                    app.ZLayerSlider.Enable = 'on';
                                    app.ZSpinner.Enable = 'on';
                                    app.ZSpinner.Limits = [1,app.nZ];
                                    if app.nZ  >50
                                        app.ZLayerSlider.MajorTicks = unique([1, 50:50:app.nZ ]);
                                        app.ZLayerSlider.MinorTicks = (10:10:app.nZ)'; 
                                    else
                                        app.ZLayerSlider.MajorTicks = unique([1,5:5:app.nZ ]);
                                        app.ZLayerSlider.MinorTicks = []; 
                                    end
                                end
        
        
                                app.X = floor(app.ImWidth/2);
                                app.XSpinner.Value = app.X;
                                app.XSpinner.Limits = [1,app.ImWidth];
                                app.XSliceSlider.Limits = [1,app.ImWidth];
                                app.XSliceSlider.Value  = floor(app.ImWidth/2);
                                app.XSliceSlider.MajorTicks = unique([1, 200:200:app.ImWidth]);
                                app.XSliceSlider.MinorTicks = (50:50:app.ImWidth)'; 
        
                                % app.Y = floor(app.ImHeight/2);
                                % app.YSpinner.Value = app.Y;
                                % app.YSpinner.Limits = [1,app.ImHeight];
                                % app.YSliceSlider.Limits = [1,app.ImHeight];
                                % app.YSliceSlider.Value  = floor(app.ImHeight/2);
                                % app.YSliceSlider.MajorTicks = unique([1, 200:200:app.ImHeight]);
                                % app.YSliceSlider.MinorTicks = (50:50:app.ImHeight)'; 
        
                                app.RedImageDropDown.Items = [{'none'}; compose('Ch %1.0f',(1:app.nC)')];
                                app.RedImageDropDown.ItemsData = (0:app.nC)';
                                app.RedImageDropDown.Value = 0;
        
                                app.GreenImageDropDown.Items = [{'none'}; compose('Ch %1.0f',(1:app.nC)')];
                                app.GreenImageDropDown.ItemsData = (0:app.nC)';
                                app.GreenImageDropDown.Value = 0;
        
                                app.BlueImageDropDown.Items = [{'none'}; compose('Ch %1.0f',(1:app.nC)')];
                                app.BlueImageDropDown.ItemsData = (0:app.nC)';
                                app.BlueImageDropDown.Value = 0;
                                
                                app.DataSeriesSpinner.Limits = [1,app.nDataSeries];
                                app.DataSeriesSpinner.Value = 1;        

                                LoadImagesForCurrentTimePoint(app,1)
                                UpdateImageDisplay(app,true)
                                close(d)

                        catch ME
                                close(d)
                                uialert(app.LongtrackerimagedatapreprocessingappUIFigure,"Error reading file","Error reading file.");
                                rethrow(ME)
                        end
        
                else
%                         app.TabGroup.Visible = 'off';
%                         app.ImageDataStatsDisplay.Visible = 'off';
%                         app.ShowImagePanel.Visible = 'off';
%                         app.STARTButton.Visible = 'on';
%                         app.STARTButton.Enable = 'on';
%                         app.STOPButton.Visible = 'off';
%                         app.STOPButton.Enable = "off";
%                         app.STOPButton.Value = 0;
                end


        end

        % Value changed function: GreenImageDropDown
        function GreenImageDropDownValueChanged(app, event)
            
            app.DataloadingprocessingLamp.Color = app.LampOnColor;   drawnow

            LoadImagesForCurrentTimePoint(app,app.T)
            UpdateImageDisplay(app,true)
            app.DataloadingprocessingLamp.Color = app.LampOffColor;
            
        end

        % Value changed function: 
        % GREENAdaptiveHistogramEqualizationredCheckBox
        function GREENAdaptiveHistogramEqualizationredCheckBoxValueChanged(app, event)
            
            app.DataloadingprocessingLamp.Color = app.LampOnColor;   drawnow
            UpdateImageDisplay(app,true)
            app.DataloadingprocessingLamp.Color = app.LampOffColor;
            
        end

        % Value changed function: GREENImageSaturationsaturationCheckBox
        function GREENImageSaturationsaturationCheckBoxValueChanged(app, event)
            
            app.DataloadingprocessingLamp.Color = app.LampOnColor;   drawnow
            UpdateImageDisplay(app,true)
            app.DataloadingprocessingLamp.Color = app.LampOffColor;
            
        end

        % Value changed function: GREENPercentImageSaturation
        function GREENPercentImageSaturationValueChanged(app, event)
            
            app.DataloadingprocessingLamp.Color = app.LampOnColor;   drawnow
            UpdateImageDisplay(app,true)
            app.DataloadingprocessingLamp.Color = app.LampOffColor;

        end

        % Value changed function: GREEN2DGaussiansmoothingsigmaCheckBox
        function GREEN2DGaussiansmoothingsigmaCheckBoxValueChanged(app, event)
            
            app.DataloadingprocessingLamp.Color = app.LampOnColor;   drawnow
            UpdateImageDisplay(app,true)
            app.DataloadingprocessingLamp.Color = app.LampOffColor;
            
        end

        % Value changed function: GREEN2DGaussianSmoothSigma
        function GREEN2DGaussianSmoothSigmaValueChanged(app, event)
            
            app.DataloadingprocessingLamp.Color = app.LampOnColor;   drawnow
            UpdateImageDisplay(app,true)
            app.DataloadingprocessingLamp.Color = app.LampOffColor;
            
        end

        % Value changed function: 
        % SubtractRedimagefromGreenImageVar1CheckBox
        function SubtractRedimagefromGreenImageVar1CheckBoxValueChanged(app, event)
            
            % If variation 2 is turned on, turn it off
            if app.SubtractRedimagefromGreenImageVar2CheckBox.Value
                app.SubtractRedimagefromGreenImageVar2CheckBox.Value = false;
            end
            
            app.DataloadingprocessingLamp.Color = app.LampOnColor;   drawnow
            UpdateImageDisplay(app,true)
            app.DataloadingprocessingLamp.Color = app.LampOffColor;
            
        end

        % Value changed function: RedImageDropDown
        function RedImageDropDownValueChanged(app, event)
            
            app.DataloadingprocessingLamp.Color = app.LampOnColor;       drawnow
            LoadImagesForCurrentTimePoint(app,app.T)
            UpdateImageDisplay(app,true)
            app.DataloadingprocessingLamp.Color = app.LampOffColor;
            
        end

        % Value changed function: 
        % REDAdaptiveHistogramEqualizationredCheckBox
        function REDAdaptiveHistogramEqualizationredCheckBoxValueChanged(app, event)
            
            app.DataloadingprocessingLamp.Color = app.LampOnColor;   drawnow
            UpdateImageDisplay(app,true)
            app.DataloadingprocessingLamp.Color = app.LampOffColor;
            
        end

        % Value changed function: REDImageSaturationsaturationCheckBox
        function REDImageSaturationsaturationCheckBoxValueChanged(app, event)
            
            app.DataloadingprocessingLamp.Color = app.LampOnColor;       drawnow
            UpdateImageDisplay(app,true)
            app.DataloadingprocessingLamp.Color = app.LampOffColor;
           
            
        end

        % Value changed function: REDPercentImageSaturation
        function REDPercentImageSaturationValueChanged(app, event)
            
            app.DataloadingprocessingLamp.Color = app.LampOnColor;   drawnow            
            UpdateImageDisplay(app,true)
            app.DataloadingprocessingLamp.Color = app.LampOffColor;
            
        end

        % Value changed function: RED2DGaussiansmoothingsigmaCheckBox
        function RED2DGaussiansmoothingsigmaCheckBoxValueChanged(app, event)
            
            app.DataloadingprocessingLamp.Color = app.LampOnColor;   drawnow
            UpdateImageDisplay(app,true)
            app.DataloadingprocessingLamp.Color = app.LampOffColor;
            
        end

        % Value changed function: RED2DGaussianSmoothXYSigma
        function RED2DGaussianSmoothXYSigmaValueChanged(app, event)
            
            app.DataloadingprocessingLamp.Color = app.LampOnColor;   drawnow            
            UpdateImageDisplay(app,true)
            app.DataloadingprocessingLamp.Color = app.LampOffColor;
            
        end

        % Value changed function: TimePointSlider
        function TimePointSliderValueChanged(app, event)
           
            app.DataloadingprocessingLamp.Color = app.LampOnColor;    drawnow

            app.STARTButton.Enable = 'on';
            value = round(app.TimePointSlider.Value);
            app.TimePointSlider.Value = value;
            app.TSpinner.Value = value;
            app.T = value;
            LoadImagesForCurrentTimePoint(app,app.T)
            UpdateImageDisplay(app,true)

            app.DataloadingprocessingLamp.Color = app.LampOffColor;
            
        end

        % Value changed function: ZLayerSlider
        function ZLayerSliderValueChanged(app, event)
            
            value = round(app.ZLayerSlider.Value);
            app.ZLayerSlider.Value = value;
            app.ZSpinner.Value = value;
            app.Z = value;
            UpdateImageDisplay(app,false)
            
        end

        % Value changed function: TSpinner
        function TSpinnerValueChanged(app, event)
   
            app.DataloadingprocessingLamp.Color = app.LampOnColor;   drawnow

            value = app.TSpinner.Value;
            app.TimePointSlider.Value = value;
            app.T = value;
            LoadImagesForCurrentTimePoint(app,app.T)
            UpdateImageDisplay(app,true)

            app.DataloadingprocessingLamp.Color = app.LampOffColor;

        end

        % Value changed function: ZSpinner
        function ZSpinnerValueChanged(app, event)
            
            value = app.ZSpinner.Value;
            app.ZLayerSlider.Value = value;
            app.Z = value;
            UpdateImageDisplay(app,false)
            
        end

        % Close request function: 
        % LongtrackerimagedatapreprocessingappUIFigure
        function LongtrackerimagedatapreprocessingappUIFigureCloseRequest(app, event)
            
            if isgraphics(app.DisplayFigure)
                delete(app.DisplayFigure)
            end
            delete(app)
            
        end

        % Value changed function: FileLocationDisplay
        function FileLocationDisplayValueChanged(app, event)
            
            value = app.FileLocationDisplay.Value;
            
        end

        % Value changed function: RED3DGaussiansmoothingsigmaCheckBox
        function RED3DGaussiansmoothingsigmaCheckBoxValueChanged(app, event)
            
            app.DataloadingprocessingLamp.Color = app.LampOnColor;   drawnow
            UpdateImageDisplay(app,true)
            app.DataloadingprocessingLamp.Color = app.LampOffColor;
            
        end

        % Value changed function: RED3DGaussianSmoothXYSigma
        function RED3DGaussianSmoothXYSigmaValueChanged(app, event)
            
            app.DataloadingprocessingLamp.Color = app.LampOnColor;   drawnow
            UpdateImageDisplay(app,true)
            app.DataloadingprocessingLamp.Color = app.LampOffColor;
            
        end

        % Value changed function: RED3DGaussianSmoothZSigma
        function RED3DGaussianSmoothZSigmaValueChanged(app, event)
            
            app.DataloadingprocessingLamp.Color = app.LampOnColor;   drawnow
            UpdateImageDisplay(app,true)
            app.DataloadingprocessingLamp.Color = app.LampOffColor;
            
        end

        % Value changed function: GREEN3DGaussiansmoothingsigmaCheckBox
        function GREEN3DGaussiansmoothingsigmaCheckBoxValueChanged(app, event)
            
            app.DataloadingprocessingLamp.Color = app.LampOnColor;   drawnow
            UpdateImageDisplay(app,true)
            app.DataloadingprocessingLamp.Color = app.LampOffColor;
            
        end

        % Value changed function: GREEN3DGaussianSmoothXYSigma
        function GREEN3DGaussianSmoothXYSigmaValueChanged(app, event)
            
            app.DataloadingprocessingLamp.Color = app.LampOnColor;   drawnow            
            UpdateImageDisplay(app,true)
            app.DataloadingprocessingLamp.Color = app.LampOffColor;
            
        end

        % Value changed function: GREEN3DGaussianSmoothZSigma
        function GREEN3DGaussianSmoothZSigmaValueChanged(app, event)
            
            app.DataloadingprocessingLamp.Color = app.LampOnColor;   drawnow
            UpdateImageDisplay(app,true)
            app.DataloadingprocessingLamp.Color = app.LampOffColor;

        end

        % Value changed function: XSpinner
        function XSpinnerValueChanged(app, event)
            
            value = app.XSpinner.Value;
            app.XSliceSlider.Value = value;
            app.X = value;
            UpdateImageDisplay(app,false)

        end

        % Value changed function: XSliceSlider
        function XSliceSliderValueChanged(app, event)
            
            value = round(app.XSliceSlider.Value);
            app.XSliceSlider.Value = value;
            app.XSpinner.Value = value;
            app.X = value;
            UpdateImageDisplay(app,false)

        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            

            [~,name,~] = fileparts(app.FileLocation);
            [file,path] = uiputfile([name,'.'],'Select write location');            

            if ~isequal(path,0) && ~isequal(file,0)
                if isfolder(path)
                    [~,file,~] = fileparts(file);
                    ProcessAndWriteImages(app,path,file)
                else
                    warndlg('WRITE directory is invalid or not yet specified.','Warning!');
                end
            end
            

        end

        % Value changed function: STOPButton
        function STOPButtonValueChanged(app, event)
            
            pause(1)
            app.STARTButton.Enable = 'on';
            
            
        end

        % Value changed function: ImageBitDepth
        function ImageBitDepthValueChanged(app, event)
            
            LoadImagesForCurrentTimePoint(app,app.T)
            UpdateImageDisplay(app,true)
            
        end

        % Value changed function: DataSeriesSpinner
        function DataSeriesSpinnerValueChanged(app, event)
            %value = app.DataSeriesSpinner.Value;
            
            app.DataloadingprocessingLamp.Color = app.LampOnColor;    drawnow
            app.STARTButton.Enable = 'on';          
            LoadImagesForCurrentTimePoint(app,app.T)
            UpdateImageDisplay(app,true)
            app.DataloadingprocessingLamp.Color = app.LampOffColor;


        end

        % Value changed function: 
        % SubtractRedimagefromGreenImageVar2CheckBox
        function SubtractRedimagefromGreenImageVar2CheckBoxValueChanged(app, event)
            
            % If variation 1 is turned on, turn it off
            if app.SubtractRedimagefromGreenImageVar1CheckBox.Value
                app.SubtractRedimagefromGreenImageVar1CheckBox.Value = false;
            end
            
            app.DataloadingprocessingLamp.Color = app.LampOnColor;   drawnow
            UpdateImageDisplay(app,true)
            app.DataloadingprocessingLamp.Color = app.LampOffColor;            
            

        end

        % Value changed function: RotateDropDown
        function RotateDropDownValueChanged(app, event)
            
                switch app.RotateDropDown.Value
                    case 0;     Width = app.ImWidth;   Height = app.ImHeight;
                    case 90;    Width = app.ImHeight;  Height = app.ImWidth;   
                    case 180;   Width = app.ImWidth;   Height = app.ImHeight;
                    case 270;   Width = app.ImHeight;  Height = app.ImWidth;  
                end
            
                app.DataloadingprocessingLamp.Color = app.LampOnColor;   drawnow

                app.X = floor(Width/2);
                app.XSpinner.Value = app.X;
                app.XSpinner.Limits = [1,Width];
                app.XSliceSlider.Limits = [1,Width];
                app.XSliceSlider.Value  = floor(Width/2);
                app.XSliceSlider.MajorTicks = unique([1, 200:200:Width]);
                app.XSliceSlider.MinorTicks = (50:50:Width)'; 

                LoadImagesForCurrentTimePoint(app,app.T)
                UpdateImageDisplay(app,true)

                app.DataloadingprocessingLamp.Color = app.LampOffColor;


        end

        % Value changed function: 
        % RED3DSubtractRedimagefromGreenImageCheckBox
        function RED3DSubtractRedimagefromGreenImageCheckBoxValueChanged(app, event)
            
            app.DataloadingprocessingLamp.Color = app.LampOnColor;   drawnow
            UpdateImageDisplay(app,true)
            app.DataloadingprocessingLamp.Color = app.LampOffColor;
            
        end

        % Value changed function: BLUE2DGaussiansmoothingsigmaCheckBox
        function BLUE2DGaussiansmoothingsigmaCheckBoxValueChanged(app, event)
            
            app.DataloadingprocessingLamp.Color = app.LampOnColor;   drawnow
            UpdateImageDisplay(app,true)
            app.DataloadingprocessingLamp.Color = app.LampOffColor;
            
        end

        % Value changed function: 
        % BLUEAdaptiveHistogramEqualizationredCheckBox
        function BLUEAdaptiveHistogramEqualizationredCheckBoxValueChanged(app, event)
            
            app.DataloadingprocessingLamp.Color = app.LampOnColor;   drawnow
            UpdateImageDisplay(app,true)
            app.DataloadingprocessingLamp.Color = app.LampOffColor;
            
        end

        % Value changed function: BLUE2DGaussianSmoothSigma
        function BLUE2DGaussianSmoothSigmaValueChanged(app, event)
            
            app.DataloadingprocessingLamp.Color = app.LampOnColor;   drawnow
            UpdateImageDisplay(app,true)
            app.DataloadingprocessingLamp.Color = app.LampOffColor;
            
        end

        % Value changed function: BLUEImageSaturationsaturationCheckBox
        function BLUEImageSaturationsaturationCheckBoxValueChanged(app, event)
            
            app.DataloadingprocessingLamp.Color = app.LampOnColor;   drawnow
            UpdateImageDisplay(app,true)
            app.DataloadingprocessingLamp.Color = app.LampOffColor;
            
        end

        % Value changed function: BlueImageDropDown
        function BlueImageDropDownValueChanged2(app, event)
            
            app.DataloadingprocessingLamp.Color = app.LampOnColor;   drawnow
            LoadImagesForCurrentTimePoint(app,app.T)
            UpdateImageDisplay(app,true)
            app.DataloadingprocessingLamp.Color = app.LampOffColor;
            
        end

        % Value changed function: ApplyCellposeSegmentationCheckBox
        function ApplyCellposeSegmentationCheckBoxValueChanged(app, event)
                        
            if app.ApplyCellposeSegmentationCheckBox.Value
                app.CellposeSegmentationOnLamp.Color = [0,1,0];
            else
                app.CellposeSegmentationOnLamp.Color = [0,0.2,0];
            end
            

        end

        % Button pushed function: VerifyCellposeInstallationButton
        function VerifyCellposeInstallationButtonPushed(app, event)
            

            d = uiprogressdlg(app.LongtrackerimagedatapreprocessingappUIFigure,...
                              'Title','Please Wait...','Message','Please wait...checking if cellpose is installed.','Indeterminate','on');  
            warning off

            try
                    userpath("reset")
                    cp = cellpose;   
                    % message = ["\fontsize{10}\fontname{Helvetica}Cellpose is installed!", ...
                    %            "    ", ...
                    %           ["\rm\fontsize{10}\fontname{Helvetica}In addition, be sure to download the cyto3 model at: ", ...
                    %            "\bf\fontsize{10}\fontname{Helvetica}http://www.cellpose.org/models/cyto2torch_3"], ...
                    %            "    ", ...
                    %            "\rm\fontsize{10}\fontname{Helvetica}After download, add the cyto2torch_3 model to your",... 
                    %            "\rm\fontsize{10}\fontname{Helvetica}main cellpose model directory.",...
                    %            "\rm\fontsize{10}\fontname{Helvetica}Type \bf\fontsize{10}\fontname{Helvetica}userpath \rm\fontsize{10}\fontname{Helvetica}at prompt to find location of", ...
                    %            "\rm\fontsize{10}\fontname{Helvetica}\bfcellposeModels \rm\fontsize{10}\fontname{Helvetica}folder."];

                    message = ["\fontsize{11}\fontname{Helvetica}Cellpose is installed!", ...
                               "    ", ...
                               "\fontsize{11}\fontname{Helvetica}Also, be sure to download listed models from:", ...
                               "\fontsize{11}\fontname{Helvetica}\bfhttp://www.cellpose.org/models/\it<model>", ...
                               "    ", ...
                               "\fontsize{11}\fontname{Helvetica}{\rmAfter download, add the downloaded models to your main cellpose model directory. Type {\bfuserpath} at prompt to find location of {\bfcellposeModels} folder.}"];

                    uialert( app.LongtrackerimagedatapreprocessingappUIFigure, message, "Success", "Icon", "success","Interpreter","tex");    
                    close(d)                    

            catch ME                    
                    rethrow(ME) 
                    close(d)

                    message = ["\bf\fontsize{12}\fontname{Helvetica}Medical Imaging Toolbox Interface for Cellpose Library \rmis not installed properly.",...
                               "    ", ...
                              string(['\fontsize{12}\fontname{Helvetica}Install toolbox by going to:',...
                               '\bfMATLAB command window > ',...
                               '\bfHOME tab > ',...
                               '\bfAdd Ons.']) ]; 

                    uialert( app.LongtrackerimagedatapreprocessingappUIFigure, message, "Success", "Icon", "warning","Interpreter","tex");    

            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LongtrackerimagedatapreprocessingappUIFigure = uifigure('Visible', 'off');
            app.LongtrackerimagedatapreprocessingappUIFigure.Name = 'Longtracker image data pre-processing app';
            app.LongtrackerimagedatapreprocessingappUIFigure.CloseRequestFcn = createCallbackFcn(app, @LongtrackerimagedatapreprocessingappUIFigureCloseRequest, true);
            app.FileLocationButton = uibutton(app.LongtrackerimagedatapreprocessingappUIFigure, 'push');
            app.FileLocationButton.ButtonPushedFcn = createCallbackFcn(app, @FileLocationButtonPushed, true);
            app.FileLocationButton.Text = {'Select image'; 'data file:'};
            app.Label = uilabel(app.LongtrackerimagedatapreprocessingappUIFigure);
            app.Label.Text = '';
            app.FileLocationDisplay = uitextarea(app.LongtrackerimagedatapreprocessingappUIFigure);
            app.FileLocationDisplay.ValueChangedFcn = createCallbackFcn(app, @FileLocationDisplayValueChanged, true);
            app.ImageDataStatsDisplay = uitextarea(app.LongtrackerimagedatapreprocessingappUIFigure);
            app.ImageDataStatsDisplay.Value = {'IMAGE FILE STATS'; 'nData series:   1'; 'Image height:  512'; 'Image width:   512'; 'nZ layers:     103'; 'nTime pts:     144'; 'nChannels:       2'};
            app.TabGroup = uitabgroup(app.LongtrackerimagedatapreprocessingappUIFigure);
            app.REDTab = uitab(app.TabGroup);
            app.REDTab.Title = 'RED';
            app.RedimageLabel = uilabel(app.REDTab);
            app.RedimageLabel.Text = 'Red image:';
            app.RedImageDropDown = uidropdown(app.REDTab);
            app.RedImageDropDown.Items = {'none'};
            app.RedImageDropDown.ValueChangedFcn = createCallbackFcn(app, @RedImageDropDownValueChanged, true);
            app.RedImageDropDown.Value = 'none';
            app.REDAdaptiveHistogramEqualizationredCheckBox = uicheckbox(app.REDTab);
            app.REDAdaptiveHistogramEqualizationredCheckBox.ValueChangedFcn = createCallbackFcn(app, @REDAdaptiveHistogramEqualizationredCheckBoxValueChanged, true);
            app.REDAdaptiveHistogramEqualizationredCheckBox.Text = '3. 2D Adaptive Histogram Equalization';
            app.FortrackingnucleitheredchannelshouldbethemembranechannelLabel = uilabel(app.REDTab);
            app.FortrackingnucleitheredchannelshouldbethemembranechannelLabel.Text = {'For tracking nuclei, the red channel '; 'should be the membrane channel'};
            app.REDImageSaturationsaturationCheckBox = uicheckbox(app.REDTab);
            app.REDImageSaturationsaturationCheckBox.ValueChangedFcn = createCallbackFcn(app, @REDImageSaturationsaturationCheckBoxValueChanged, true);
            app.REDImageSaturationsaturationCheckBox.Text = '4. 2D Image Saturation:';
            app.RED2DGaussiansmoothingsigmaCheckBox = uicheckbox(app.REDTab);
            app.RED2DGaussiansmoothingsigmaCheckBox.ValueChangedFcn = createCallbackFcn(app, @RED2DGaussiansmoothingsigmaCheckBoxValueChanged, true);
            app.RED2DGaussiansmoothingsigmaCheckBox.Text = '2. 2D Gaussian smoothing:  xy-sigma';
            app.RED2DGaussianSmoothXYSigma = uieditfield(app.REDTab, 'numeric');
            app.RED2DGaussianSmoothXYSigma.Limits = [0.1 5];
            app.RED2DGaussianSmoothXYSigma.ValueDisplayFormat = '%2.1f';
            app.RED2DGaussianSmoothXYSigma.ValueChangedFcn = createCallbackFcn(app, @RED2DGaussianSmoothXYSigmaValueChanged, true);
            app.RED2DGaussianSmoothXYSigma.Value = 1;
            app.REDPercentImageSaturation = uieditfield(app.REDTab, 'numeric');
            app.REDPercentImageSaturation.Limits = [0.001 10];
            app.REDPercentImageSaturation.ValueDisplayFormat = '%5.3f';
            app.REDPercentImageSaturation.ValueChangedFcn = createCallbackFcn(app, @REDPercentImageSaturationValueChanged, true);
            app.REDPercentImageSaturation.Value = 0.1;
            app.Label_2 = uilabel(app.REDTab);
            app.Label_2.Text = '%';
            app.RED3DGaussiansmoothingsigmaCheckBox = uicheckbox(app.REDTab);
            app.RED3DGaussiansmoothingsigmaCheckBox.ValueChangedFcn = createCallbackFcn(app, @RED3DGaussiansmoothingsigmaCheckBoxValueChanged, true);
            app.RED3DGaussiansmoothingsigmaCheckBox.Text = '1. 3D Gaussian smoothing:  xy-sigma';
            app.RED3DGaussianSmoothXYSigma = uieditfield(app.REDTab, 'numeric');
            app.RED3DGaussianSmoothXYSigma.Limits = [0.1 5];
            app.RED3DGaussianSmoothXYSigma.ValueDisplayFormat = '%2.1f';
            app.RED3DGaussianSmoothXYSigma.ValueChangedFcn = createCallbackFcn(app, @RED3DGaussianSmoothXYSigmaValueChanged, true);
            app.RED3DGaussianSmoothXYSigma.Value = 1;
            app.zsigmaEditFieldLabel = uilabel(app.REDTab);
            app.zsigmaEditFieldLabel.Text = 'z-sigma';
            app.RED3DGaussianSmoothZSigma = uieditfield(app.REDTab, 'numeric');
            app.RED3DGaussianSmoothZSigma.Limits = [0 5];
            app.RED3DGaussianSmoothZSigma.ValueDisplayFormat = '%2.1f';
            app.RED3DGaussianSmoothZSigma.ValueChangedFcn = createCallbackFcn(app, @RED3DGaussianSmoothZSigmaValueChanged, true);
            app.RED3DGaussianSmoothZSigma.Value = 0.5;
            app.fontnamehelveticabfSuggestedfunctionsrm23and4Label = uilabel(app.REDTab);
            app.fontnamehelveticabfSuggestedfunctionsrm23and4Label.Interpreter = 'tex';
            app.fontnamehelveticabfSuggestedfunctionsrm23and4Label.Text = '\fontname{helvetica}\bfSuggested functions: \rm 2, 3, and 4.';
            app.RED3DSubtractRedimagefromGreenImageCheckBox = uicheckbox(app.REDTab);
            app.RED3DSubtractRedimagefromGreenImageCheckBox.ValueChangedFcn = createCallbackFcn(app, @RED3DSubtractRedimagefromGreenImageCheckBoxValueChanged, true);
            app.RED3DSubtractRedimagefromGreenImageCheckBox.Text = '5. 3D Subtract Green image from Red image (Post red processing)';
            app.ImageProcessingTabs = uitab(app.TabGroup);
            app.ImageProcessingTabs.Title = 'GREEN';
            app.GreenimageLabel = uilabel(app.ImageProcessingTabs);
            app.GreenimageLabel.Text = 'Green image:';
            app.GreenImageDropDown = uidropdown(app.ImageProcessingTabs);
            app.GreenImageDropDown.Items = {'none'};
            app.GreenImageDropDown.ValueChangedFcn = createCallbackFcn(app, @GreenImageDropDownValueChanged, true);
            app.GreenImageDropDown.Value = 'none';
            app.FortrackingnucleithegreenchannelshouldbethenuclearchannelLabel = uilabel(app.ImageProcessingTabs);
            app.FortrackingnucleithegreenchannelshouldbethenuclearchannelLabel.Text = {'For tracking nuclei, the green channel'; 'should be the nuclear channel'};
            app.GREENAdaptiveHistogramEqualizationredCheckBox = uicheckbox(app.ImageProcessingTabs);
            app.GREENAdaptiveHistogramEqualizationredCheckBox.ValueChangedFcn = createCallbackFcn(app, @GREENAdaptiveHistogramEqualizationredCheckBoxValueChanged, true);
            app.GREENAdaptiveHistogramEqualizationredCheckBox.Text = '4. 2D Adaptive Histogram Equalization';
            app.GREENImageSaturationsaturationCheckBox = uicheckbox(app.ImageProcessingTabs);
            app.GREENImageSaturationsaturationCheckBox.ValueChangedFcn = createCallbackFcn(app, @GREENImageSaturationsaturationCheckBoxValueChanged, true);
            app.GREENImageSaturationsaturationCheckBox.Text = '5. 2D Image Saturation:';
            app.GREEN2DGaussiansmoothingsigmaCheckBox = uicheckbox(app.ImageProcessingTabs);
            app.GREEN2DGaussiansmoothingsigmaCheckBox.ValueChangedFcn = createCallbackFcn(app, @GREEN2DGaussiansmoothingsigmaCheckBoxValueChanged, true);
            app.GREEN2DGaussiansmoothingsigmaCheckBox.Text = '2. 2D Gaussian smoothing:  xy-sigma';
            app.GREEN2DGaussianSmoothSigma = uieditfield(app.ImageProcessingTabs, 'numeric');
            app.GREEN2DGaussianSmoothSigma.Limits = [0.1 3];
            app.GREEN2DGaussianSmoothSigma.ValueDisplayFormat = '%2.1f';
            app.GREEN2DGaussianSmoothSigma.ValueChangedFcn = createCallbackFcn(app, @GREEN2DGaussianSmoothSigmaValueChanged, true);
            app.GREEN2DGaussianSmoothSigma.Value = 1;
            app.GREENPercentImageSaturation = uieditfield(app.ImageProcessingTabs, 'numeric');
            app.GREENPercentImageSaturation.Limits = [0.001 10];
            app.GREENPercentImageSaturation.ValueDisplayFormat = '%5.3f';
            app.GREENPercentImageSaturation.ValueChangedFcn = createCallbackFcn(app, @GREENPercentImageSaturationValueChanged, true);
            app.GREENPercentImageSaturation.Value = 0.1;
            app.Label_3 = uilabel(app.ImageProcessingTabs);
            app.Label_3.Text = '%';
            app.SubtractRedimagefromGreenImageVar1CheckBox = uicheckbox(app.ImageProcessingTabs);
            app.SubtractRedimagefromGreenImageVar1CheckBox.ValueChangedFcn = createCallbackFcn(app, @SubtractRedimagefromGreenImageVar1CheckBoxValueChanged, true);
            app.SubtractRedimagefromGreenImageVar1CheckBox.Text = '3. 3D Subtract Red image from Green image (Variation 1)';
            app.GREEN3DGaussiansmoothingsigmaCheckBox = uicheckbox(app.ImageProcessingTabs);
            app.GREEN3DGaussiansmoothingsigmaCheckBox.ValueChangedFcn = createCallbackFcn(app, @GREEN3DGaussiansmoothingsigmaCheckBoxValueChanged, true);
            app.GREEN3DGaussiansmoothingsigmaCheckBox.Text = '1. 3D Gaussian smoothing:  xy-sigma';
            app.GREEN3DGaussianSmoothXYSigma = uieditfield(app.ImageProcessingTabs, 'numeric');
            app.GREEN3DGaussianSmoothXYSigma.Limits = [0.1 5];
            app.GREEN3DGaussianSmoothXYSigma.ValueDisplayFormat = '%2.1f';
            app.GREEN3DGaussianSmoothXYSigma.ValueChangedFcn = createCallbackFcn(app, @GREEN3DGaussianSmoothXYSigmaValueChanged, true);
            app.GREEN3DGaussianSmoothXYSigma.Value = 1;
            app.zsigmaEditFieldLabel_2 = uilabel(app.ImageProcessingTabs);
            app.zsigmaEditFieldLabel_2.Text = 'z-sigma';
            app.GREEN3DGaussianSmoothZSigma = uieditfield(app.ImageProcessingTabs, 'numeric');
            app.GREEN3DGaussianSmoothZSigma.Limits = [0 5];
            app.GREEN3DGaussianSmoothZSigma.ValueDisplayFormat = '%2.1f';
            app.GREEN3DGaussianSmoothZSigma.ValueChangedFcn = createCallbackFcn(app, @GREEN3DGaussianSmoothZSigmaValueChanged, true);
            app.GREEN3DGaussianSmoothZSigma.Value = 0.5;
            app.SubtractRedimagefromGreenImageVar2CheckBox = uicheckbox(app.ImageProcessingTabs);
            app.SubtractRedimagefromGreenImageVar2CheckBox.ValueChangedFcn = createCallbackFcn(app, @SubtractRedimagefromGreenImageVar2CheckBoxValueChanged, true);
            app.SubtractRedimagefromGreenImageVar2CheckBox.Text = '6. 3D Subtract Red image from Green image (Variation 2)';
            app.Suggestedfunctions145and6Label = uilabel(app.ImageProcessingTabs);
            app.Suggestedfunctions145and6Label.Interpreter = 'tex';
            app.Suggestedfunctions145and6Label.Text = {'\fontname{helvetica}\bfSuggested functions: \rm2, 4, 5, and 6.'; ''; '\fontname{helvetica}\bfVariation 1: \rmRed channel, with at '; '\fontname{helvetica}most function 1 applied, is subtracted from '; '\fontname{helvetica}Green channel.'; '\fontname{helvetica}\bfVariation 2: \rmRed channel, after all selected'; '\fontname{helvetica}functions applied, is subtracted from Green channel.'};
            app.BLUETab = uitab(app.TabGroup);
            app.BLUETab.Title = 'BLUE';
            app.GreenimageLabel_2 = uilabel(app.BLUETab);
            app.GreenimageLabel_2.Text = 'Blue image:';
            app.BlueImageDropDown = uidropdown(app.BLUETab);
            app.BlueImageDropDown.Items = {'none'};
            app.BlueImageDropDown.ValueChangedFcn = createCallbackFcn(app, @BlueImageDropDownValueChanged2, true);
            app.BlueImageDropDown.Value = 'none';
            app.Label_4 = uilabel(app.BLUETab);
            app.Label_4.Text = {'For tracking nuclei, the blue channel'; 'is typically kept blank.'};
            app.BLUEAdaptiveHistogramEqualizationredCheckBox = uicheckbox(app.BLUETab);
            app.BLUEAdaptiveHistogramEqualizationredCheckBox.ValueChangedFcn = createCallbackFcn(app, @BLUEAdaptiveHistogramEqualizationredCheckBoxValueChanged, true);
            app.BLUEAdaptiveHistogramEqualizationredCheckBox.Text = '2. 2D Adaptive Histogram Equalization';
            app.BLUEImageSaturationsaturationCheckBox = uicheckbox(app.BLUETab);
            app.BLUEImageSaturationsaturationCheckBox.ValueChangedFcn = createCallbackFcn(app, @BLUEImageSaturationsaturationCheckBoxValueChanged, true);
            app.BLUEImageSaturationsaturationCheckBox.Text = '3. 2D Image Saturation:';
            app.BLUE2DGaussiansmoothingsigmaCheckBox = uicheckbox(app.BLUETab);
            app.BLUE2DGaussiansmoothingsigmaCheckBox.ValueChangedFcn = createCallbackFcn(app, @BLUE2DGaussiansmoothingsigmaCheckBoxValueChanged, true);
            app.BLUE2DGaussiansmoothingsigmaCheckBox.Text = '1. 2D Gaussian smoothing:  xy-sigma';
            app.BLUE2DGaussianSmoothSigma = uieditfield(app.BLUETab, 'numeric');
            app.BLUE2DGaussianSmoothSigma.Limits = [0.1 3];
            app.BLUE2DGaussianSmoothSigma.ValueDisplayFormat = '%2.1f';
            app.BLUE2DGaussianSmoothSigma.ValueChangedFcn = createCallbackFcn(app, @BLUE2DGaussianSmoothSigmaValueChanged, true);
            app.BLUE2DGaussianSmoothSigma.Value = 1;
            app.BLUEPercentImageSaturation = uieditfield(app.BLUETab, 'numeric');
            app.BLUEPercentImageSaturation.Limits = [0.001 10];
            app.BLUEPercentImageSaturation.ValueDisplayFormat = '%5.3f';
            app.BLUEPercentImageSaturation.Value = 0.1;
            app.Label_5 = uilabel(app.BLUETab);
            app.Label_5.Text = '%';
            app.CellposeInputParametersPanel = uipanel(app.BLUETab);
            app.ApplyCellposeSegmentationCheckBox = uicheckbox(app.CellposeInputParametersPanel);
            app.ApplyCellposeSegmentationCheckBox.ValueChangedFcn = createCallbackFcn(app, @ApplyCellposeSegmentationCheckBoxValueChanged, true);
            app.ApplyCellposeSegmentationCheckBox.Text = '4. Apply Cellpose 3D segmentation';
            app.averagecelldiametervoxelsEditFieldLabel = uilabel(app.CellposeInputParametersPanel);
            app.averagecelldiametervoxelsEditFieldLabel.Text = 'average cell diameter (voxels):';
            app.averagecelldiametervoxelsEditField = uieditfield(app.CellposeInputParametersPanel, 'numeric');
            app.averagecelldiametervoxelsEditField.Limits = [5 500];
            app.averagecelldiametervoxelsEditField.Value = 20;
            app.AuxchannelLabel = uilabel(app.CellposeInputParametersPanel);
            app.AuxchannelLabel.Text = {'auxillary channel'; '(nuclear):'};
            app.AuxillaryChannelDropDown = uidropdown(app.CellposeInputParametersPanel);
            app.AuxillaryChannelDropDown.Items = {'none', 'red', 'green'};
            app.AuxillaryChannelDropDown.Value = 'green';
            app.VerifyCellposeInstallationButton = uibutton(app.CellposeInputParametersPanel, 'push');
            app.VerifyCellposeInstallationButton.ButtonPushedFcn = createCallbackFcn(app, @VerifyCellposeInstallationButtonPushed, true);
            app.VerifyCellposeInstallationButton.Text = 'Verify cellpose installed';
            app.AuxchannelLabel_2 = uilabel(app.CellposeInputParametersPanel);
            app.AuxchannelLabel_2.Text = 'environment:';
            app.ExecutionEnvironmentlDropDown = uidropdown(app.CellposeInputParametersPanel);
            app.ExecutionEnvironmentlDropDown.Items = {'cpu', 'gpu'};
            app.ExecutionEnvironmentlDropDown.Value = 'gpu';
            app.CellposeSegmentationOnLamp = uilamp(app.CellposeInputParametersPanel);
            app.modelDropDownLabel = uilabel(app.CellposeInputParametersPanel);
            app.modelDropDownLabel.Text = 'model:';
            app.modelDropDown = uidropdown(app.CellposeInputParametersPanel);
            app.modelDropDown.Items = {'cyto2torch_0', 'cyto2torch_1', 'cyto2torch_2', 'cyto2torch_3'};
            app.modelDropDown.Value = 'cyto2torch_3';
            app.Suggestedfunctions145and6Label_2 = uilabel(app.BLUETab);
            app.Suggestedfunctions145and6Label_2.Interpreter = 'tex';
            app.Suggestedfunctions145and6Label_2.Text = {'\fontname{helvetica}\bfSuggested functions: \rm2 and 4.'; ''; '\fontname{helvetica}\bfWarning! \rmApplying Cellpose 3D segmentation '; '\fontname{helvetica}\rmwill increase processing time significantly. For example, processing time for a 512 x 384 x 85 voxel image volume may take ~5-20 minutes per timepoint.'};
            app.ShowImagePanel = uipanel(app.LongtrackerimagedatapreprocessingappUIFigure);
            app.TimePointSlider = uislider(app.ShowImagePanel);
            app.TimePointSlider.Limits = [1 100];
            app.TimePointSlider.ValueChangedFcn = createCallbackFcn(app, @TimePointSliderValueChanged, true);
            app.TimePointSlider.Value = 1;
            app.ZLayerSlider = uislider(app.ShowImagePanel);
            app.ZLayerSlider.Limits = [1 100];
            app.ZLayerSlider.ValueChangedFcn = createCallbackFcn(app, @ZLayerSliderValueChanged, true);
            app.ZLayerSlider.Value = 1;
            app.TSpinnerLabel = uilabel(app.ShowImagePanel);
            app.TSpinnerLabel.Text = 'T';
            app.TSpinner = uispinner(app.ShowImagePanel);
            app.TSpinner.Limits = [1 Inf];
            app.TSpinner.ValueDisplayFormat = '%6d';
            app.TSpinner.ValueChangedFcn = createCallbackFcn(app, @TSpinnerValueChanged, true);
            app.TSpinner.Value = 2000;
            app.ZSpinnerLabel = uilabel(app.ShowImagePanel);
            app.ZSpinnerLabel.Text = 'Z';
            app.ZSpinner = uispinner(app.ShowImagePanel);
            app.ZSpinner.Limits = [1 Inf];
            app.ZSpinner.ValueDisplayFormat = '%6d';
            app.ZSpinner.ValueChangedFcn = createCallbackFcn(app, @ZSpinnerValueChanged, true);
            app.ZSpinner.Value = 500;
            app.XSliceSlider = uislider(app.ShowImagePanel);
            app.XSliceSlider.Limits = [1 100];
            app.XSliceSlider.ValueChangedFcn = createCallbackFcn(app, @XSliceSliderValueChanged, true);
            app.XSliceSlider.Value = 1;
            app.XSpinnerLabel = uilabel(app.ShowImagePanel);
            app.XSpinnerLabel.Text = 'X';
            app.XSpinner = uispinner(app.ShowImagePanel);
            app.XSpinner.Limits = [1 Inf];
            app.XSpinner.ValueDisplayFormat = '%6d';
            app.XSpinner.ValueChangedFcn = createCallbackFcn(app, @XSpinnerValueChanged, true);
            app.XSpinner.Value = 2048;
            app.DataseriesLabel = uilabel(app.ShowImagePanel);
            app.DataseriesLabel.Text = 'Data series:';
            app.DataSeriesSpinner = uispinner(app.ShowImagePanel);
            app.DataSeriesSpinner.ValueChangedFcn = createCallbackFcn(app, @DataSeriesSpinnerValueChanged, true);
            app.DataSeriesSpinner.Value = 10;
            app.DataloadingprocessingLampLabel = uilabel(app.ShowImagePanel);
            app.DataloadingprocessingLampLabel.Text = 'Data loading/processing';
            app.DataloadingprocessingLamp = uilamp(app.ShowImagePanel);
            app.RotateLabel = uilabel(app.ShowImagePanel);
            app.RotateLabel.Text = 'Rotate:';
            app.RotateDropDown = uidropdown(app.ShowImagePanel);
            app.RotateDropDown.Items = {'0', '90', '180', '270'};
            app.RotateDropDown.ItemsData = [0 90 180 270];
            app.RotateDropDown.ValueChangedFcn = createCallbackFcn(app, @RotateDropDownValueChanged, true);
            app.RotateDropDown.Value = 0;
            app.eglsmczilifornd2Label = uilabel(app.LongtrackerimagedatapreprocessingappUIFigure);
            app.eglsmczilifornd2Label.Text = 'e.g. .lsm, .czi, .lif, or .nd2';
            app.ImageBitDepth = uidropdown(app.LongtrackerimagedatapreprocessingappUIFigure);
            app.ImageBitDepth.Items = {'8', '12', '16'};
            app.ImageBitDepth.ItemsData = {'255', '4095', '65535'};
            app.ImageBitDepth.ValueChangedFcn = createCallbackFcn(app, @ImageBitDepthValueChanged, true);
            app.ImageBitDepth.Value = '255';
            app.RawdatabitdepthLabel = uilabel(app.LongtrackerimagedatapreprocessingappUIFigure);
            app.RawdatabitdepthLabel.Text = 'Raw data bit depth:';
            app.StartStopPanel = uipanel(app.LongtrackerimagedatapreprocessingappUIFigure);
            app.StartingtimepointLabel = uilabel(app.StartStopPanel);
            app.StartingtimepointLabel.Text = 'Starting time point:';
            app.StartingTimePointSelector = uieditfield(app.StartStopPanel, 'numeric');
            app.StartingTimePointSelector.Limits = [1 Inf];
            app.StartingTimePointSelector.Value = 1;
            app.StartingtimepointLabel_2 = uilabel(app.StartStopPanel);
            app.StartingtimepointLabel_2.Text = 'Ending time point:';
            app.EndingTimePointSelector = uieditfield(app.StartStopPanel, 'numeric');
            app.EndingTimePointSelector.Limits = [1 Inf];
            app.EndingTimePointSelector.Value = 1000;
            app.STARTButton = uibutton(app.StartStopPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.STOPButton = uibutton(app.StartStopPanel, 'state');
            app.STOPButton.ValueChangedFcn = createCallbackFcn(app, @STOPButtonValueChanged, true);
            app.STOPButton.Text = 'STOP';
            % Show the figure after all components are created
            app.LongtrackerimagedatapreprocessingappUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImagePreProcessing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LongtrackerimagedatapreprocessingappUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LongtrackerimagedatapreprocessingappUIFigure)
        end
    end
end