classdef Lgtker_ImageDataPreProcessing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LongtrackerimagedatapreprocessingappUIFigure  matlab.ui.Figure
        RawdatabitdepthLabel            matlab.ui.control.Label
        ImageBitDepth                   matlab.ui.control.DropDown
        eglsmczilifornd2Label           matlab.ui.control.Label
        STOPButton                      matlab.ui.control.StateButton
        STARTButton                     matlab.ui.control.Button
        magedatafilestatsLabel          matlab.ui.control.Label
        ShowImagePanel                  matlab.ui.container.Panel
        YSpinner                        matlab.ui.control.Spinner
        YSpinnerLabel                   matlab.ui.control.Label
        YSliceSlider                    matlab.ui.control.Slider
        XSpinner                        matlab.ui.control.Spinner
        XSpinnerLabel                   matlab.ui.control.Label
        XSliceSlider                    matlab.ui.control.Slider
        ZSpinner                        matlab.ui.control.Spinner
        ZSpinnerLabel                   matlab.ui.control.Label
        TimeSpinner                     matlab.ui.control.Spinner
        TimeSpinnerLabel                matlab.ui.control.Label
        ZLayerSlider                    matlab.ui.control.Slider
        TimePointSlider                 matlab.ui.control.Slider
        TabGroup                        matlab.ui.container.TabGroup
        REDTab                          matlab.ui.container.Tab
        Suggestedfunctions13and4Label   matlab.ui.control.Label
        RED3DGaussianSmoothZSigma       matlab.ui.control.NumericEditField
        zsigmaEditFieldLabel            matlab.ui.control.Label
        RED3DGaussianSmoothXYSigma      matlab.ui.control.NumericEditField
        RED3DGaussiansmoothingsigmaCheckBox  matlab.ui.control.CheckBox
        Label_2                         matlab.ui.control.Label
        REDPercentImageSaturation       matlab.ui.control.NumericEditField
        RED2DGaussianSmoothXYSigma      matlab.ui.control.NumericEditField
        RED2DGaussiansmoothingsigmaCheckBox  matlab.ui.control.CheckBox
        REDImageSaturationsaturationCheckBox  matlab.ui.control.CheckBox
        FortrackingnucleitheredchannelshouldbethemembranechannelLabel  matlab.ui.control.Label
        REDAdaptiveHistogramEqualizationredCheckBox  matlab.ui.control.CheckBox
        RedImageDropDown                matlab.ui.control.DropDown
        RedimageLabel                   matlab.ui.control.Label
        ImageProcessingTabs             matlab.ui.container.Tab
        Suggestedfunctions134and5Label  matlab.ui.control.Label
        GREEN3DGaussianSmoothZSigma     matlab.ui.control.NumericEditField
        zsigmaEditFieldLabel_2          matlab.ui.control.Label
        GREEN3DGaussianSmoothXYSigma    matlab.ui.control.NumericEditField
        GREEN3DGaussiansmoothingsigmaCheckBox  matlab.ui.control.CheckBox
        SubtractRedimagefromGreenimageCheckBox  matlab.ui.control.CheckBox
        Label_3                         matlab.ui.control.Label
        GREENPercentImageSaturation     matlab.ui.control.NumericEditField
        GREEN2DGaussianSmoothSigma      matlab.ui.control.NumericEditField
        GREEN2DGaussiansmoothingsigmaCheckBox  matlab.ui.control.CheckBox
        GREENImageSaturationsaturationCheckBox  matlab.ui.control.CheckBox
        GREENAdaptiveHistogramEqualizationredCheckBox  matlab.ui.control.CheckBox
        FortrackingnucleithegreenchannelshouldbethenuclearchannelLabel  matlab.ui.control.Label
        GreenImageDropDown              matlab.ui.control.DropDown
        GreenimageLabel                 matlab.ui.control.Label
        BLUETab                         matlab.ui.container.Tab
        BlueImageDropDown               matlab.ui.control.DropDown
        BlueimageLabel                  matlab.ui.control.Label
        ImageDataStatsDisplay           matlab.ui.control.TextArea
        FileLocationDisplay             matlab.ui.control.TextArea
        Label                           matlab.ui.control.Label
        FileLocationButton              matlab.ui.control.Button
    end

    
    properties (Access = public)

        FileLocation     = []; % Description
        BioFormatsReader = [];
        DisplayFigure    = -1;
        DisplayAxes      = -1;
        T                =  1;
        Z                =  1;
        X                =  1;
        Y                =  1;

        ImHeight = [];           % image height (pixels)
        ImWidth  = [];           % image width  (pixels)
        nZ       = [];           % number of Z-slices
        nT       = [];           % number of time points
        nC       = [];           % number of channels
        BitDepth = [];           % get image bit depth
        nP       = [];           % total number of images

        RedRaw3D   = [];
        GreenRaw3D = [];
        BlueRaw3D  = [];

    end

    
    methods (Access = private)
%====================================================================================================================================================    
        function LoadImagesForCurrentTimePoint(app,T)
                

                    % Images are loaded in using using the Bio_Formats toolbox: https://www.openmicroscopy.org/bio-formats/downloads/
                    % If images are not loaded properly the toolbox may need to be updated
    
                    % Read in full z-stack for each channel at timepoint T
                    BitDepth = app.ImageBitDepth.Value;
                    app.RedRaw3D   = [];
                    app.GreenRaw3D = [];
                    app.BlueRaw3D  = [];
                    
                    app.RedRaw3D = zeros(app.ImHeight,app.ImWidth,app.nZ,'uint8');
                    if app.RedImageDropDown.Value ~= 0
                        for z = 1:app.nZ
                            IMREDraw = uint8(  255*( double(bfGetPlaneAtZCT(app.BioFormatsReader, z, app.RedImageDropDown.Value, T) )./BitDepth)  ); 
                            app.RedRaw3D(:,:,z) = IMREDraw;
                        end   
                    end
    
                    app.GreenRaw3D = zeros(app.ImHeight,app.ImWidth,app.nZ,'uint8');
                    if app.GreenImageDropDown.Value ~= 0
                        for z = 1:app.nZ
                            IMGREENraw = uint8( 255*( double(bfGetPlaneAtZCT(app.BioFormatsReader, z, app.GreenImageDropDown.Value, T) )./BitDepth)  ); 
                            app.GreenRaw3D(:,:,z) = IMGREENraw;
                        end   
                    end
    
                    app.BlueRaw3D = zeros(app.ImHeight,app.ImWidth,app.nZ,'uint8');
                    if app.BlueImageDropDown.Value ~= 0
                        for z = 1:app.nZ
                            IMBLUEraw = uint8(  255*( double(bfGetPlaneAtZCT(app.BioFormatsReader, z, app.BlueImageDropDown.Value, T) )./BitDepth)  ); 
                            app.BlueRaw3D(:,:,z) = IMBLUEraw;
                        end   
                    end


        end

%====================================================================================================================================================       
        function [IMR3D, IMG3D, IMB3D] = ProcessImages(app)

                 T = app.T;
                 Z = app.Z;
                 X = app.X;
                 Y = app.Y;

                 %----------------------------------------------------------------------------------------------------------------
                 IMR3D = zeros(app.ImHeight,app.ImWidth,app.nZ,'uint8');

                 if app.RedImageDropDown.Value ~= 0
                            RedVolume = app.RedRaw3D; % all the Z-slices for timepoint T are loaded when LoadImagesForCurrentTimePoint is called
                            if app.RED3DGaussiansmoothingsigmaCheckBox.Value % But if the user selected 3D smoothing, apply 3D smoothing to this volume before moving on to the 2D processing
                                RedVolume = imgaussfilt3(RedVolume,[app.RED3DGaussianSmoothXYSigma.Value,...
                                                                    app.RED3DGaussianSmoothXYSigma.Value,...
                                                                    app.RED3DGaussianSmoothZSigma.Value]);
                            end

                            for z = 1:app.nZ % 2D processing steps
                                IMR = RedVolume(:,:,z);
                                if app.RED2DGaussiansmoothingsigmaCheckBox.Value
                                    Rsigma = app.RED2DGaussianSmoothXYSigma.Value;
                                    IMR = imgaussfilt(IMR,Rsigma);    
                                end
                                if app.REDAdaptiveHistogramEqualizationredCheckBox.Value
                                    IMR = adapthisteq(IMR,'NumTiles',[16,16],'Distribution','rayleigh');
                                end
                                if app.REDImageSaturationsaturationCheckBox.Value
                                    p = app.REDPercentImageSaturation.Value;
                                    IMR = imadjust(IMR,stretchlim(IMR,[p/100 (100-p)/100]));
                                end
                                IMR3D(:,:,z) = IMR;
                            end                            
                 end

                 %----------------------------------------------------------------------------------------------------------------
                 IMG3D = zeros(app.ImHeight,app.ImWidth,app.nZ,'uint8');

                 if app.GreenImageDropDown.Value ~= 0
                            GreenVolume = app.GreenRaw3D;
                            if app.GREEN3DGaussiansmoothingsigmaCheckBox.Value
                                GreenVolume = imgaussfilt3(GreenVolume,[app.GREEN3DGaussianSmoothXYSigma.Value,...
                                                                        app.GREEN3DGaussianSmoothXYSigma.Value,...
                                                                        app.GREEN3DGaussianSmoothZSigma.Value]);
                            end

                            for z = 1:app.nZ
                                IMG = GreenVolume(:,:,z);
                                if app.GREEN2DGaussiansmoothingsigmaCheckBox.Value
                                    Gsigma = app.GREEN2DGaussianSmoothSigma.Value;
                                    IMG = imgaussfilt(IMG,Gsigma);    
                                end
                                IMG3D(:,:,z) = IMG;
                            end

                            if app.SubtractRedimagefromGreenimageCheckBox.Value
                                IMG3D = IMG3D - IMR3D;
                            end
                            
                            for z = 1:app.nZ
                                IMG = IMG3D(:,:,z);
                                if app.GREENAdaptiveHistogramEqualizationredCheckBox.Value
                                    IMG = adapthisteq(IMG,'NumTiles',[16,16],'Distribution','rayleigh');
                                end
                                if app.GREENImageSaturationsaturationCheckBox.Value
                                    p = app.GREENPercentImageSaturation.Value;
                                    IMG = imadjust(IMG,stretchlim(IMG,[p/100 (100-p)/100]));
                                end
                                IMG3D(:,:,z) = IMG;
                            end


                 end

                 %----------------------------------------------------------------------------------------------------------------
                 IMB3D = zeros(app.ImHeight,app.ImWidth,app.nZ,'uint8');
%                  if app.BlueImageDropDown.Value ~= 0
%                             IMBLUEraw = double( bfGetPlaneAtZCT(app.BioFormatsReader, Z, app.BlueImageDropDown.Value, T) ); 
%                             IMB = uint8( 255*IMBLUEraw./max(IMBLUEraw(:)) );
%                  else
%                             IMB = zeros(app.ImHeight,app.ImWidth,'uint8');
%                  end
                
                 %----------------------------------------------------------------------------------------------------------------

        end
%====================================================================================================================================================            
        function UpdateImageDisplay(app)

                [IMR3D, IMG3D, IMB3D] = ProcessImages(app);
                 
                 DividerWidth = 5;
                 RGBimageXY = cat(3, squeeze(IMR3D(:,:,app.Z)), squeeze(IMG3D(:,:,app.Z)), squeeze(IMB3D(:,:,app.Z)) );
                 RGBimageXY(:,app.X,:) = 255*cat(3,zeros(app.ImHeight,1,'uint8'), ones(app.ImHeight,1,'uint8'),ones(app.ImHeight,1,'uint8'));
                 RGBimageXY(app.Y,:,:) = 255*cat(3,zeros(1,app.ImWidth,'uint8'), ones(1,app.ImWidth,'uint8'),ones(1,app.ImWidth,'uint8'));

                 RGBimageYZ = cat(3, squeeze(IMR3D(:,app.X,:)), squeeze(IMG3D(:,app.X,:)), squeeze(IMB3D(:,app.X,:)) );   
                 RGBimageYZ = imresize(RGBimageYZ, [app.ImHeight, 2*app.nZ]);
                 RGBimageYZ(:,2*app.Z,:) = 255*cat(3,zeros(app.ImHeight,1,'uint8'), ones(app.ImHeight,1,'uint8'),ones(app.ImHeight,1,'uint8'));
                 RGBimageYZ(app.Y,:,:) = 255*cat(3,zeros(1,2*app.nZ,'uint8'), ones(1,2*app.nZ,'uint8'),ones(1,2*app.nZ,'uint8'));
                 RGBdivider = 255*ones(app.ImHeight,DividerWidth,3,'uint8');

                 RGB = cat(2,RGBimageYZ,RGBdivider,RGBimageXY);

                 if ~isgraphics(app.DisplayFigure)
                     app.DisplayFigure = figure;
                     set(app.DisplayFigure,'Color','w')
                     imshow(RGB)
                     app.DisplayAxes  = gca;
                     axtoolbar(app.DisplayAxes,{});
                 else
                     imshow(RGB,'Parent',app.DisplayAxes)
                     axtoolbar(app.DisplayAxes,{});
                 end
        end

%====================================================================================================================================================            
        function ProcessAndWriteImages(app,path,file)
            
           
            app.STARTButton.Enable = 'off';
            app.STOPButton.Enable = "on";
            app.STOPButton.Value = 0;
            app.ImageBitDepth.Enable = 'off';

            BitDepth = app.ImageBitDepth.Value;

            H = waitbar(0);
            waitbar(1/app.nT,H,['Timepoint ' num2str(1) '/' num2str(app.nT)],'Name','Writing files...')
            H.Name = 'Writing image files...';
            
            RawDataFolder = fullfile(path,'RAW_DATA_8bitformat');
            if ~isfolder( RawDataFolder )
                [status,msg] = mkdir( RawDataFolder );
            else
                status = true;
            end

            for t = 1:app.nT
                     if isgraphics(H)
                        waitbar(t/app.nT,H,['Timepoint ' num2str(t) '/' num2str(app.nT)])
                     else
                        H = waitbar(0);
                        waitbar(t/app.nT,H,['Timepoint ' num2str(t) '/' num2str(app.nT)])
                     end
                     
                     drawnow
                     if logical(app.STOPButton.Value)
                        break
                     end
                      
                    % Load in images and process them -----------------------------------------------------------------------------------------------------------------
                    LoadImagesForCurrentTimePoint(app,t)
                    [IMR3D, IMG3D, IMB3D] = ProcessImages(app);
                  
                     % Check if file already exists. If so delete it --------------------------------------------------------------------------------------------------
                     FullFileNameRGB = fullfile(path,[file,'_T',sprintf('%05d',t),'.tif']); 
                     if isfile(FullFileNameRGB) 
                         delete(FullFileNameRGB)
                     end

                     FullFileNamesRaw = cell(app.nC,1);
                     for c = 1:app.nC 
                         FullFileNamesRaw{c,1} = fullfile(RawDataFolder,[file,'_RawImage_Ch',sprintf('%02d',c),'_T',sprintf('%05d',t),'.tif']); 
                         if isfile(FullFileNamesRaw{c,1}) 
                            delete(FullFileNamesRaw{c,1})
                         end
                     end
    
                     % WRITE IMAGE FILES -----------------------------------------------------------------------------------------------------------------------------
                     for z = 1:app.nZ
                            % Write RGB z-Stack for each timestep ----------------------------------------------------------------------------------------------------
                            RGBimageXY = cat(3, squeeze(IMR3D(:,:,z)), squeeze(IMG3D(:,:,z)), squeeze(IMB3D(:,:,z)) );
                            imwrite(RGBimageXY, FullFileNameRGB ,'tif','WriteMode','append','Compression','none','RowsPerStrip',size(RGBimageXY,1))
                            % Write raw data files -----------------------------------------------------------------------
                            if status
                                for c = 1:app.nC 
                                    ImageRaw = uint8(  255*( double(bfGetPlaneAtZCT(app.BioFormatsReader, z, c, t) )./BitDepth)  ); 
                                    imwrite( ImageRaw, FullFileNamesRaw{c,1} ,'tif','WriteMode','append','Compression','none','RowsPerStrip',size(ImageRaw,1))
                                end
                            end
                     end
                     %-------------------------------------------------------------------------------------------------------------------------------------------------
            end

            if isgraphics(H)
                delete(H)
            end
          
            app.STARTButton.Visible = 'on';
            app.STARTButton.Enable = 'on';
            app.STOPButton.Visible = 'on';
            app.STOPButton.Enable = "off";
            app.STOPButton.Value = false;
            app.ImageBitDepth.Enable = 'on';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.TabGroup.Visible = 'off';
            app.ImageDataStatsDisplay.Visible = 'off';
            app.ShowImagePanel.Visible = 'off';
            app.magedatafilestatsLabel.Visible = 'off';
            app.STARTButton.Visible = 'off';
            app.STARTButton.Enable = 'on';
            app.STOPButton.Visible = 'off';
            app.STOPButton.Enable = "off";
            app.STOPButton.Value = 0;
            app.ImageBitDepth.Enable = 'off';
            app.ImageBitDepth.Items = {'8','12','14','16'};
            app.ImageBitDepth.ItemsData = [255, 4095, 16383, 65535];
            app.ImageBitDepth.Value = 255;            

        end

        % Button pushed function: FileLocationButton
        function FileLocationButtonPushed(app, event)
            
                [FileName,PathName] = uigetfile('*.*','Select image data file.');
                
                if ~isequal(FileName,0)
                        app.FileLocation = fullfile( PathName, FileName );
                        app.FileLocationDisplay.Value = app.FileLocation;

                        %---------------------------------------------------------------------------------------
                        % bfGetReader is a function from the Bio-Formats toolbox: https://www.openmicroscopy.org/bio-formats/downloads/
                        reader = bfGetReader(app.FileLocation); % creates an empty Bio-Formats reader extending
                        omeMeta = reader.getMetadataStore();
                        ImHeight = omeMeta.getPixelsSizeY(0).getValue();           % image height (pixels)
                        ImWidth  = omeMeta.getPixelsSizeX(0).getValue();           % image width  (pixels)
                        nZ       = omeMeta.getPixelsSizeZ(0).getValue();           % number of Z-slices
                        nT       = omeMeta.getPixelsSizeT(0).getValue();           % number of time points
                        nC       = omeMeta.getPixelsSizeC(0).getValue();           % number of channels
                        BitDepth = omeMeta.getPixelsSignificantBits(0).getValue(); % get image bit depth
                        nP       = omeMeta.getPlaneCount(0);                       % total number of images
                        %---------------------------------------------------------------------------------------
                        
                        app.BioFormatsReader = reader;
                        app.ImHeight = ImHeight;
                        app.ImWidth  = ImWidth;
                        app.nZ       = nZ;
                        app.nT       = nT;
                        app.nC       = nC;
                        app.BitDepth = BitDepth;
                        app.nP       = nP;

                        app.ImageDataStatsDisplay.Value = ...
                                [ {['Image height: ', sprintf('%4.0f',ImHeight)] };...    
                                  {['Image width:  ', sprintf('%4.0f',ImWidth)]  };...
                                  {['nZ layers:    ', sprintf('%4.0f',nZ)]       };...
                                  {['nTime pts:    ', sprintf('%4.0f',nT)]       };... 
                                  {['nChannels:    ', sprintf('%4.0f',nC)]       } ];

                        % Update buttons and controls with stat information ----------------------------------------------
                        app.TabGroup.Visible = 'on';
                        app.ImageDataStatsDisplay.Visible = 'on';
                        app.ShowImagePanel.Visible = 'on';
                        app.STARTButton.Enable = 'on';
                        app.STARTButton.Visible = 'on';
                        app.STOPButton.Visible = 'on';
                        app.STOPButton.Value = 0;
                        app.STOPButton.Enable = "off";
                        app.ImageBitDepth.Enable = 'on';

                        app.TimePointSlider.Value = 1;
                        app.TimeSpinner.Value = 1;
                        if app.nT == 1
                            app.TimePointSlider.Limits = [1,100];
                            app.TimePointSlider.Enable = 'off';
                            app.TimeSpinner.Enable = 'off';
                        else
                            app.TimePointSlider.Limits = [1,nT];
                            app.TimePointSlider.Enable = 'on';
                            app.TimeSpinner.Enable = 'on';
                            if nT >50
                                app.TimePointSlider.MajorTicks = unique([1, 50:50:nT]);
                                app.TimePointSlider.MinorTicks = [10:10:nT]'; 
                            else
                                app.TimePointSlider.MajorTicks = unique([1,5:5:nT]);
                                app.TimePointSlider.MinorTicks = []; 
                            end
                        end

                        
                        app.ZLayerSlider.Value  = 1;
                        app.ZSpinner.Value = 1;
                        if app.nZ == 1
                            app.ZLayerSlider = [1,100];
                            app.ZLayerSlider.Enable = 'off';
                            app.ZSpinner.Enable = 'off';
                        else
                            app.ZLayerSlider.Limits = [1,nZ];
                            app.ZLayerSlider.Enable = 'on';
                            app.ZSpinner.Enable = 'on';
                            if nZ >50
                                app.ZLayerSlider.MajorTicks = unique([1, 50:50:nZ]);
                                app.ZLayerSlider.MinorTicks = [10:10:nZ]'; 
                            else
                                app.ZLayerSlider.MajorTicks = unique([1,5:5:nZ]);
                                app.ZLayerSlider.MinorTicks = []; 
                            end
                        end


                        app.X = floor(app.ImWidth/2);
                        app.XSpinner.Value = app.X;
                        app.XSliceSlider.Limits = [1,app.ImWidth];
                        app.XSliceSlider.Value  = floor(app.ImWidth/2);
                        app.XSliceSlider.MajorTicks = unique([1, 200:200:app.ImWidth]);
                        app.XSliceSlider.MinorTicks = [50:50:app.ImWidth]'; 

                        app.Y = floor(app.ImHeight/2);
                        app.YSpinner.Value = app.Y;
                        app.YSliceSlider.Limits = [1,app.ImHeight];
                        app.YSliceSlider.Value  = floor(app.ImHeight/2);
                        app.YSliceSlider.MajorTicks = unique([1, 200:200:app.ImHeight]);
                        app.YSliceSlider.MinorTicks = [50:50:app.ImHeight]'; 

                        app.magedatafilestatsLabel.Visible = 'on';

                        app.RedImageDropDown.Items = [{'none'}; compose('Ch %1.0f',(1:nC)')];
                        app.RedImageDropDown.ItemsData = (0:nC)';
                        app.RedImageDropDown.Value = 0;

                        app.GreenImageDropDown.Items = [{'none'}; compose('Ch %1.0f',(1:nC)')];
                        app.GreenImageDropDown.ItemsData = (0:nC)';
                        app.GreenImageDropDown.Value = 0;

                        app.BlueImageDropDown.Items = [{'none'}; compose('Ch %1.0f',(1:nC)')];
                        app.BlueImageDropDown.ItemsData = (0:nC)';
                        app.BlueImageDropDown.Value = 0;
                        
   
                        LoadImagesForCurrentTimePoint(app,1)
                        UpdateImageDisplay(app)


                else
%                         app.TabGroup.Visible = 'off';
%                         app.ImageDataStatsDisplay.Visible = 'off';
%                         app.ShowImagePanel.Visible = 'off';
%                         app.STARTButton.Visible = 'on';
%                         app.STARTButton.Enable = 'on';
%                         app.STOPButton.Visible = 'off';
%                         app.STOPButton.Enable = "off";
%                         app.STOPButton.Value = 0;
                end


        end

        % Callback function
        function WriteLocationButtonPushed(app, event)
            
                DirName = uigetdir([],'Select processed-image-data write location.');
                
                if ~isequal(DirName,0)
                        app.WriteLocation = DirName;
                        app.WriteLocationDisplay.Value = app.WriteLocation;
                end


        end

        % Value changed function: GreenImageDropDown
        function GreenImageDropDownValueChanged(app, event)
            
            LoadImagesForCurrentTimePoint(app,app.T)
            UpdateImageDisplay(app)
            
        end

        % Value changed function: 
        % GREENAdaptiveHistogramEqualizationredCheckBox
        function GREENAdaptiveHistogramEqualizationredCheckBoxValueChanged(app, event)
            
            UpdateImageDisplay(app)
            
        end

        % Value changed function: GREENImageSaturationsaturationCheckBox
        function GREENImageSaturationsaturationCheckBoxValueChanged(app, event)
            
            UpdateImageDisplay(app)
            
        end

        % Value changed function: GREENPercentImageSaturation
        function GREENPercentImageSaturationValueChanged(app, event)
            
            UpdateImageDisplay(app)

        end

        % Value changed function: GREEN2DGaussiansmoothingsigmaCheckBox
        function GREEN2DGaussiansmoothingsigmaCheckBoxValueChanged(app, event)
            
            UpdateImageDisplay(app)
            
        end

        % Value changed function: GREEN2DGaussianSmoothSigma
        function GREEN2DGaussianSmoothSigmaValueChanged(app, event)
            
            UpdateImageDisplay(app)
            
        end

        % Value changed function: SubtractRedimagefromGreenimageCheckBox
        function SubtractRedimagefromGreenimageCheckBoxValueChanged(app, event)
            
            UpdateImageDisplay(app)
            
        end

        % Value changed function: RedImageDropDown
        function RedImageDropDownValueChanged(app, event)
            
            LoadImagesForCurrentTimePoint(app,app.T)
            UpdateImageDisplay(app)
            
        end

        % Value changed function: 
        % REDAdaptiveHistogramEqualizationredCheckBox
        function REDAdaptiveHistogramEqualizationredCheckBoxValueChanged(app, event)
            
            UpdateImageDisplay(app)
            
        end

        % Value changed function: REDImageSaturationsaturationCheckBox
        function REDImageSaturationsaturationCheckBoxValueChanged(app, event)
            
            UpdateImageDisplay(app)
            
        end

        % Value changed function: REDPercentImageSaturation
        function REDPercentImageSaturationValueChanged(app, event)
            
            UpdateImageDisplay(app)
            
        end

        % Value changed function: RED2DGaussiansmoothingsigmaCheckBox
        function RED2DGaussiansmoothingsigmaCheckBoxValueChanged(app, event)
            
            UpdateImageDisplay(app)
            
        end

        % Value changed function: RED2DGaussianSmoothXYSigma
        function RED2DGaussianSmoothXYSigmaValueChanged(app, event)
            
            UpdateImageDisplay(app)
            
        end

        % Value changed function: BlueImageDropDown
        function BlueImageDropDownValueChanged(app, event)
            
            LoadImagesForCurrentTimePoint(app,app.T)
            UpdateImageDisplay(app)
            
        end

        % Value changed function: TimePointSlider
        function TimePointSliderValueChanged(app, event)
           
            app.STARTButton.Enable = 'on';
            value = round(app.TimePointSlider.Value);
            app.TimePointSlider.Value = value;
            app.TimeSpinner.Value = value;
            app.T = value;
            LoadImagesForCurrentTimePoint(app,value)
            UpdateImageDisplay(app)
            
        end

        % Value changed function: ZLayerSlider
        function ZLayerSliderValueChanged(app, event)
            
            value = round(app.ZLayerSlider.Value);
            app.ZLayerSlider.Value = value;
            app.ZSpinner.Value = value;
            app.Z = value;
            UpdateImageDisplay(app)
            
        end

        % Value changed function: TimeSpinner
        function TimeSpinnerValueChanged(app, event)
   
            value = app.TimeSpinner.Value;
            app.TimePointSlider.Value = value;
            app.T = value;
            LoadImagesForCurrentTimePoint(app,app.T)
            UpdateImageDisplay(app)

        end

        % Value changed function: ZSpinner
        function ZSpinnerValueChanged(app, event)
            
            value = app.ZSpinner.Value;
            app.ZLayerSlider.Value = value;
            app.Z = value;
            UpdateImageDisplay(app)
            
        end

        % Close request function: 
        % LongtrackerimagedatapreprocessingappUIFigure
        function LongtrackerimagedatapreprocessingappUIFigureCloseRequest(app, event)
            
            if isgraphics(app.DisplayFigure)
                delete(app.DisplayFigure)
            end
            delete(app)
            
        end

        % Value changed function: FileLocationDisplay
        function FileLocationDisplayValueChanged(app, event)
            value = app.FileLocationDisplay.Value;
            
        end

        % Value changed function: RED3DGaussiansmoothingsigmaCheckBox
        function RED3DGaussiansmoothingsigmaCheckBoxValueChanged(app, event)
            
            UpdateImageDisplay(app)
            
        end

        % Value changed function: RED3DGaussianSmoothXYSigma
        function RED3DGaussianSmoothXYSigmaValueChanged(app, event)
            
            UpdateImageDisplay(app)
            
        end

        % Value changed function: RED3DGaussianSmoothZSigma
        function RED3DGaussianSmoothZSigmaValueChanged(app, event)
            
            UpdateImageDisplay(app)
            
        end

        % Value changed function: GREEN3DGaussiansmoothingsigmaCheckBox
        function GREEN3DGaussiansmoothingsigmaCheckBoxValueChanged(app, event)
            
            UpdateImageDisplay(app)
            
        end

        % Value changed function: GREEN3DGaussianSmoothXYSigma
        function GREEN3DGaussianSmoothXYSigmaValueChanged(app, event)
            
            UpdateImageDisplay(app)
            
        end

        % Value changed function: GREEN3DGaussianSmoothZSigma
        function GREEN3DGaussianSmoothZSigmaValueChanged(app, event)
            
            UpdateImageDisplay(app)

        end

        % Value changed function: XSpinner
        function XSpinnerValueChanged(app, event)
            
            value = app.XSpinner.Value;
            app.XSliceSlider.Value = value;
            app.X = value;
            UpdateImageDisplay(app)

        end

        % Value changed function: XSliceSlider
        function XSliceSliderValueChanged(app, event)
            
            value = round(app.XSliceSlider.Value);
            app.XSliceSlider.Value = value;
            app.XSpinner.Value = value;
            app.X = value;
            UpdateImageDisplay(app)


        end

        % Value changed function: YSliceSlider
        function YSliceSliderValueChanged(app, event)

            value = round(app.YSliceSlider.Value);
            app.YSliceSlider.Value = value;
            app.YSpinner.Value = value;
            app.Y = value;
            UpdateImageDisplay(app)

        end

        % Value changed function: YSpinner
        function YSpinnerValueChanged(app, event)
           
            value = app.YSpinner.Value;
            app.YSliceSlider.Value = value;
            app.Y = value;
            UpdateImageDisplay(app)
            
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            
            [~,name,~] = fileparts(app.FileLocation);
            [file,path] = uiputfile([name,'.'],'Select write location');
            
            if ~isequal(path,0) && ~isequal(file,0)
                if isfolder(path)
                    [~,file,~] = fileparts(file);
                    ProcessAndWriteImages(app,path,file)
                else
                    warndlg('WRITE directory is invalid or not yet specified.','Warning!');
                end
            end
            

        end

        % Value changed function: STOPButton
        function STOPButtonValueChanged(app, event)
            
            pause(1)
            app.STARTButton.Enable = 'on';
            
            
        end

        % Value changed function: ImageBitDepth
        function ImageBitDepthValueChanged(app, event)
            
            LoadImagesForCurrentTimePoint(app,app.T)
            UpdateImageDisplay(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LongtrackerimagedatapreprocessingappUIFigure = uifigure('Visible', 'off');
            app.LongtrackerimagedatapreprocessingappUIFigure.Name = 'Longtracker image data pre-processing app';
            app.LongtrackerimagedatapreprocessingappUIFigure.CloseRequestFcn = createCallbackFcn(app, @LongtrackerimagedatapreprocessingappUIFigureCloseRequest, true);
            app.FileLocationButton = uibutton(app.LongtrackerimagedatapreprocessingappUIFigure, 'push');
            app.FileLocationButton.ButtonPushedFcn = createCallbackFcn(app, @FileLocationButtonPushed, true);
            app.FileLocationButton.Text = {'Select image'; 'data file:'};
            app.Label = uilabel(app.LongtrackerimagedatapreprocessingappUIFigure);
            app.Label.Text = '';
            app.FileLocationDisplay = uitextarea(app.LongtrackerimagedatapreprocessingappUIFigure);
            app.FileLocationDisplay.ValueChangedFcn = createCallbackFcn(app, @FileLocationDisplayValueChanged, true);
            app.ImageDataStatsDisplay = uitextarea(app.LongtrackerimagedatapreprocessingappUIFigure);
            app.ImageDataStatsDisplay.Value = {'Image height:  512'; 'Image width:   512'; 'nZ layers:     103'; 'nTime pts:     144'; 'nChannels:       2'};
            app.TabGroup = uitabgroup(app.LongtrackerimagedatapreprocessingappUIFigure);
            app.REDTab = uitab(app.TabGroup);
            app.REDTab.Title = 'RED';
            app.RedimageLabel = uilabel(app.REDTab);
            app.RedimageLabel.Text = 'Red image:';
            app.RedImageDropDown = uidropdown(app.REDTab);
            app.RedImageDropDown.Items = {'none'};
            app.RedImageDropDown.ValueChangedFcn = createCallbackFcn(app, @RedImageDropDownValueChanged, true);
            app.RedImageDropDown.Value = 'none';
            app.REDAdaptiveHistogramEqualizationredCheckBox = uicheckbox(app.REDTab);
            app.REDAdaptiveHistogramEqualizationredCheckBox.ValueChangedFcn = createCallbackFcn(app, @REDAdaptiveHistogramEqualizationredCheckBoxValueChanged, true);
            app.REDAdaptiveHistogramEqualizationredCheckBox.Text = '3. Adaptive Histogram Equalization';
            app.FortrackingnucleitheredchannelshouldbethemembranechannelLabel = uilabel(app.REDTab);
            app.FortrackingnucleitheredchannelshouldbethemembranechannelLabel.Text = {'For tracking nuclei, the red channel should '; 'be the membrane channel'};
            app.REDImageSaturationsaturationCheckBox = uicheckbox(app.REDTab);
            app.REDImageSaturationsaturationCheckBox.ValueChangedFcn = createCallbackFcn(app, @REDImageSaturationsaturationCheckBoxValueChanged, true);
            app.REDImageSaturationsaturationCheckBox.Text = '4. Image Saturation:';
            app.RED2DGaussiansmoothingsigmaCheckBox = uicheckbox(app.REDTab);
            app.RED2DGaussiansmoothingsigmaCheckBox.ValueChangedFcn = createCallbackFcn(app, @RED2DGaussiansmoothingsigmaCheckBoxValueChanged, true);
            app.RED2DGaussiansmoothingsigmaCheckBox.Text = '2. 2D Gaussian smoothing:  xy-sigma';
            app.RED2DGaussianSmoothXYSigma = uieditfield(app.REDTab, 'numeric');
            app.RED2DGaussianSmoothXYSigma.Limits = [0.1 5];
            app.RED2DGaussianSmoothXYSigma.ValueDisplayFormat = '%2.1f';
            app.RED2DGaussianSmoothXYSigma.ValueChangedFcn = createCallbackFcn(app, @RED2DGaussianSmoothXYSigmaValueChanged, true);
            app.RED2DGaussianSmoothXYSigma.Value = 1;
            app.REDPercentImageSaturation = uieditfield(app.REDTab, 'numeric');
            app.REDPercentImageSaturation.Limits = [0.001 10];
            app.REDPercentImageSaturation.ValueDisplayFormat = '%5.3f';
            app.REDPercentImageSaturation.ValueChangedFcn = createCallbackFcn(app, @REDPercentImageSaturationValueChanged, true);
            app.REDPercentImageSaturation.Value = 1;
            app.Label_2 = uilabel(app.REDTab);
            app.Label_2.Text = '%';
            app.RED3DGaussiansmoothingsigmaCheckBox = uicheckbox(app.REDTab);
            app.RED3DGaussiansmoothingsigmaCheckBox.ValueChangedFcn = createCallbackFcn(app, @RED3DGaussiansmoothingsigmaCheckBoxValueChanged, true);
            app.RED3DGaussiansmoothingsigmaCheckBox.Text = '1. 3D Gaussian smoothing:  xy-sigma';
            app.RED3DGaussianSmoothXYSigma = uieditfield(app.REDTab, 'numeric');
            app.RED3DGaussianSmoothXYSigma.Limits = [0.1 5];
            app.RED3DGaussianSmoothXYSigma.ValueDisplayFormat = '%2.1f';
            app.RED3DGaussianSmoothXYSigma.ValueChangedFcn = createCallbackFcn(app, @RED3DGaussianSmoothXYSigmaValueChanged, true);
            app.RED3DGaussianSmoothXYSigma.Value = 1;
            app.zsigmaEditFieldLabel = uilabel(app.REDTab);
            app.zsigmaEditFieldLabel.Text = 'z-sigma';
            app.RED3DGaussianSmoothZSigma = uieditfield(app.REDTab, 'numeric');
            app.RED3DGaussianSmoothZSigma.Limits = [0 5];
            app.RED3DGaussianSmoothZSigma.ValueDisplayFormat = '%2.1f';
            app.RED3DGaussianSmoothZSigma.ValueChangedFcn = createCallbackFcn(app, @RED3DGaussianSmoothZSigmaValueChanged, true);
            app.RED3DGaussianSmoothZSigma.Value = 0.5;
            app.Suggestedfunctions13and4Label = uilabel(app.REDTab);
            app.Suggestedfunctions13and4Label.Text = 'Suggested functions: 1, 3, and 4.';
            app.ImageProcessingTabs = uitab(app.TabGroup);
            app.ImageProcessingTabs.Title = 'GREEN';
            app.GreenimageLabel = uilabel(app.ImageProcessingTabs);
            app.GreenimageLabel.Text = 'Green image:';
            app.GreenImageDropDown = uidropdown(app.ImageProcessingTabs);
            app.GreenImageDropDown.Items = {'none'};
            app.GreenImageDropDown.ValueChangedFcn = createCallbackFcn(app, @GreenImageDropDownValueChanged, true);
            app.GreenImageDropDown.Value = 'none';
            app.FortrackingnucleithegreenchannelshouldbethenuclearchannelLabel = uilabel(app.ImageProcessingTabs);
            app.FortrackingnucleithegreenchannelshouldbethenuclearchannelLabel.Text = {'For tracking nuclei, the green channel'; 'should be the nuclear channel'};
            app.GREENAdaptiveHistogramEqualizationredCheckBox = uicheckbox(app.ImageProcessingTabs);
            app.GREENAdaptiveHistogramEqualizationredCheckBox.ValueChangedFcn = createCallbackFcn(app, @GREENAdaptiveHistogramEqualizationredCheckBoxValueChanged, true);
            app.GREENAdaptiveHistogramEqualizationredCheckBox.Text = '4. Adaptive Histogram Equalization';
            app.GREENImageSaturationsaturationCheckBox = uicheckbox(app.ImageProcessingTabs);
            app.GREENImageSaturationsaturationCheckBox.ValueChangedFcn = createCallbackFcn(app, @GREENImageSaturationsaturationCheckBoxValueChanged, true);
            app.GREENImageSaturationsaturationCheckBox.Text = '5. Image Saturation:';
            app.GREEN2DGaussiansmoothingsigmaCheckBox = uicheckbox(app.ImageProcessingTabs);
            app.GREEN2DGaussiansmoothingsigmaCheckBox.ValueChangedFcn = createCallbackFcn(app, @GREEN2DGaussiansmoothingsigmaCheckBoxValueChanged, true);
            app.GREEN2DGaussiansmoothingsigmaCheckBox.Text = '2. 2D Gaussian smoothing:  xy-sigma';
            app.GREEN2DGaussianSmoothSigma = uieditfield(app.ImageProcessingTabs, 'numeric');
            app.GREEN2DGaussianSmoothSigma.Limits = [0.1 3];
            app.GREEN2DGaussianSmoothSigma.ValueDisplayFormat = '%2.1f';
            app.GREEN2DGaussianSmoothSigma.ValueChangedFcn = createCallbackFcn(app, @GREEN2DGaussianSmoothSigmaValueChanged, true);
            app.GREEN2DGaussianSmoothSigma.Value = 1;
            app.GREENPercentImageSaturation = uieditfield(app.ImageProcessingTabs, 'numeric');
            app.GREENPercentImageSaturation.Limits = [0.001 10];
            app.GREENPercentImageSaturation.ValueDisplayFormat = '%5.3f';
            app.GREENPercentImageSaturation.ValueChangedFcn = createCallbackFcn(app, @GREENPercentImageSaturationValueChanged, true);
            app.GREENPercentImageSaturation.Value = 0.1;
            app.Label_3 = uilabel(app.ImageProcessingTabs);
            app.Label_3.Text = '%';
            app.SubtractRedimagefromGreenimageCheckBox = uicheckbox(app.ImageProcessingTabs);
            app.SubtractRedimagefromGreenimageCheckBox.ValueChangedFcn = createCallbackFcn(app, @SubtractRedimagefromGreenimageCheckBoxValueChanged, true);
            app.SubtractRedimagefromGreenimageCheckBox.Text = '3. Subtract Red image from Green image';
            app.GREEN3DGaussiansmoothingsigmaCheckBox = uicheckbox(app.ImageProcessingTabs);
            app.GREEN3DGaussiansmoothingsigmaCheckBox.ValueChangedFcn = createCallbackFcn(app, @GREEN3DGaussiansmoothingsigmaCheckBoxValueChanged, true);
            app.GREEN3DGaussiansmoothingsigmaCheckBox.Text = '1. 3D Gaussian smoothing:  xy-sigma';
            app.GREEN3DGaussianSmoothXYSigma = uieditfield(app.ImageProcessingTabs, 'numeric');
            app.GREEN3DGaussianSmoothXYSigma.Limits = [0.1 5];
            app.GREEN3DGaussianSmoothXYSigma.ValueDisplayFormat = '%2.1f';
            app.GREEN3DGaussianSmoothXYSigma.ValueChangedFcn = createCallbackFcn(app, @GREEN3DGaussianSmoothXYSigmaValueChanged, true);
            app.GREEN3DGaussianSmoothXYSigma.Value = 2;
            app.zsigmaEditFieldLabel_2 = uilabel(app.ImageProcessingTabs);
            app.zsigmaEditFieldLabel_2.Text = 'z-sigma';
            app.GREEN3DGaussianSmoothZSigma = uieditfield(app.ImageProcessingTabs, 'numeric');
            app.GREEN3DGaussianSmoothZSigma.Limits = [0 5];
            app.GREEN3DGaussianSmoothZSigma.ValueDisplayFormat = '%2.1f';
            app.GREEN3DGaussianSmoothZSigma.ValueChangedFcn = createCallbackFcn(app, @GREEN3DGaussianSmoothZSigmaValueChanged, true);
            app.GREEN3DGaussianSmoothZSigma.Value = 0.5;
            app.Suggestedfunctions134and5Label = uilabel(app.ImageProcessingTabs);
            app.Suggestedfunctions134and5Label.Text = 'Suggested functions: 1, 3, 4, and 5.';
            app.BLUETab = uitab(app.TabGroup);
            app.BLUETab.Title = 'BLUE';
            app.BlueimageLabel = uilabel(app.BLUETab);
            app.BlueimageLabel.Enable = 'off';
            app.BlueimageLabel.Text = 'Blue image:';
            app.BlueImageDropDown = uidropdown(app.BLUETab);
            app.BlueImageDropDown.Items = {'none'};
            app.BlueImageDropDown.ValueChangedFcn = createCallbackFcn(app, @BlueImageDropDownValueChanged, true);
            app.BlueImageDropDown.Enable = 'off';
            app.BlueImageDropDown.Value = 'none';
            app.ShowImagePanel = uipanel(app.LongtrackerimagedatapreprocessingappUIFigure);
            app.TimePointSlider = uislider(app.ShowImagePanel);
            app.TimePointSlider.Limits = [1 100];
            app.TimePointSlider.ValueChangedFcn = createCallbackFcn(app, @TimePointSliderValueChanged, true);
            app.TimePointSlider.Value = 1;
            app.ZLayerSlider = uislider(app.ShowImagePanel);
            app.ZLayerSlider.Limits = [1 100];
            app.ZLayerSlider.ValueChangedFcn = createCallbackFcn(app, @ZLayerSliderValueChanged, true);
            app.ZLayerSlider.Value = 1;
            app.TimeSpinnerLabel = uilabel(app.ShowImagePanel);
            app.TimeSpinnerLabel.Text = 'Time';
            app.TimeSpinner = uispinner(app.ShowImagePanel);
            app.TimeSpinner.Limits = [1 Inf];
            app.TimeSpinner.ValueDisplayFormat = '%6d';
            app.TimeSpinner.ValueChangedFcn = createCallbackFcn(app, @TimeSpinnerValueChanged, true);
            app.TimeSpinner.Value = 2000;
            app.ZSpinnerLabel = uilabel(app.ShowImagePanel);
            app.ZSpinnerLabel.Text = 'Z';
            app.ZSpinner = uispinner(app.ShowImagePanel);
            app.ZSpinner.Limits = [1 Inf];
            app.ZSpinner.ValueDisplayFormat = '%6d';
            app.ZSpinner.ValueChangedFcn = createCallbackFcn(app, @ZSpinnerValueChanged, true);
            app.ZSpinner.Value = 500;
            app.XSliceSlider = uislider(app.ShowImagePanel);
            app.XSliceSlider.Limits = [1 100];
            app.XSliceSlider.ValueChangedFcn = createCallbackFcn(app, @XSliceSliderValueChanged, true);
            app.XSliceSlider.Value = 1;
            app.XSpinnerLabel = uilabel(app.ShowImagePanel);
            app.XSpinnerLabel.Text = 'X';
            app.XSpinner = uispinner(app.ShowImagePanel);
            app.XSpinner.Limits = [1 Inf];
            app.XSpinner.ValueDisplayFormat = '%6d';
            app.XSpinner.ValueChangedFcn = createCallbackFcn(app, @XSpinnerValueChanged, true);
            app.XSpinner.Value = 2048;
            app.YSliceSlider = uislider(app.ShowImagePanel);
            app.YSliceSlider.Limits = [1 100];
            app.YSliceSlider.ValueChangedFcn = createCallbackFcn(app, @YSliceSliderValueChanged, true);
            app.YSliceSlider.Value = 1;
            app.YSpinnerLabel = uilabel(app.ShowImagePanel);
            app.YSpinnerLabel.Text = 'Y';
            app.YSpinner = uispinner(app.ShowImagePanel);
            app.YSpinner.Limits = [1 Inf];
            app.YSpinner.ValueDisplayFormat = '%6d';
            app.YSpinner.ValueChangedFcn = createCallbackFcn(app, @YSpinnerValueChanged, true);
            app.YSpinner.Value = 2048;
            app.magedatafilestatsLabel = uilabel(app.LongtrackerimagedatapreprocessingappUIFigure);
            app.magedatafilestatsLabel.Text = 'Image data file stats:';
            app.STARTButton = uibutton(app.LongtrackerimagedatapreprocessingappUIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.STOPButton = uibutton(app.LongtrackerimagedatapreprocessingappUIFigure, 'state');
            app.STOPButton.ValueChangedFcn = createCallbackFcn(app, @STOPButtonValueChanged, true);
            app.STOPButton.Text = 'STOP';
            app.eglsmczilifornd2Label = uilabel(app.LongtrackerimagedatapreprocessingappUIFigure);
            app.eglsmczilifornd2Label.Text = 'e.g. .lsm, .czi, .lif, or .nd2';
            app.ImageBitDepth = uidropdown(app.LongtrackerimagedatapreprocessingappUIFigure);
            app.ImageBitDepth.Items = {'8', '12', '16'};
            app.ImageBitDepth.ItemsData = {'255', '4095', '65535'};
            app.ImageBitDepth.ValueChangedFcn = createCallbackFcn(app, @ImageBitDepthValueChanged, true);
            app.ImageBitDepth.Value = '255';
            app.RawdatabitdepthLabel = uilabel(app.LongtrackerimagedatapreprocessingappUIFigure);
            app.RawdatabitdepthLabel.Text = 'Raw data bit depth:';
            % Show the figure after all components are created
            app.LongtrackerimagedatapreprocessingappUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lgtker_ImageDataPreProcessing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LongtrackerimagedatapreprocessingappUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LongtrackerimagedatapreprocessingappUIFigure)
        end
    end
end