classdef SelectColor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SCmain                      matlab.ui.Figure
        current_color_display       matlab.ui.control.Image
        colormap_display            matlab.ui.control.Image
        save_color_button           matlab.ui.control.Button
        load_colors_button          matlab.ui.control.Button
        export_button               matlab.ui.control.Button
        default_colors_button       matlab.ui.control.Button
        edit_color_button           matlab.ui.control.Button
        Selected_cell_list_display  matlab.ui.control.ListBox
        cancel_button               matlab.ui.control.Button
    end


%||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||    
    properties (Access = public)
        LTapp 
        SEFapp
        SDapp
        SelectedCellList
        TrackDataSummary
        nColors
        ColorSelection
    end


%||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||
    methods (Access = private)
%--------------------------------------------------------------------------------------------            
        function colors = AutoColors(app, N)
            
                    switch N
                        case 1
                            colors = [0 1 0];
                        case 2
                            colors = [[1 0 0];[0 1 0]];
                        case 3
                            colors = [[1 0 0];[0 1 0];[0 0 1]];
                        case 4
                            colors = [[1 0 0];[1 1 0];[0 1 0];[0 0 1]];
                        case 5
                            colors = [[1 0 0];[1 1 0];[0 1 0];[0 1 1];[0 0 1]];
                        otherwise
                            colors = jet(N);
                    end
        end
%--------------------------------------------------------------------------------------------            
        function ColorBarImage = CreateColorBarData(app, colors)
            
                    rgb = zeros([size(colors,1) 1 size(colors,2)],'double');
                    rgb(:,1,:) = colors;
                    RGBim = repmat(rgb,[1 50 1]);
                    ColorBarImage = imresize(RGBim, [440 30],'nearest');
        end
%--------------------------------------------------------------------------------------------            
        function CImage = CreateCurrentColorImage(app, CurrentColor)
            
                    rgb = zeros(1,1,3);
                    rgb(1,1,:) = CurrentColor;
                    CImage = repmat(rgb,[40 50]);
        end
%--------------------------------------------------------------------------------------------            
        function [isdim, notempty] = iscolordim(app, LoadedColors, SetNum, CellIndex)
                % Check existence of dimension of the nested cell array TrackData,
                % and if dimension exists, determine if it's empty
            
                    isdim = false;
                    notempty = false;
            
                    if SetNum > 0 && CellIndex > 0
                        LTD = length(LoadedColors);
                        if SetNum <= LTD
                            LC = length(LoadedColors{SetNum});
                            if CellIndex <= LC
                                isdim = true;
                                if ~isempty(LoadedColors{SetNum}{CellIndex})
                                        notempty = true;
                                end
                            end
                        end
                    end

        end
%--------------------------------------------------------------------------------------------      

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function SelectColor_OpeningFcn(app, varargin)
                        
            
                    app.LTapp  = varargin{1}.LTapp;
                    app.SEFapp = varargin{1}.SEFapp;
                    app.SDapp  = varargin{1}.SDmain;
                    app.SelectedCellList = varargin{1}.SelectedCellList;
                    app.nColors = length(app.SelectedCellList);
                    app.ColorSelection = AutoColors(app,app.nColors);
            
                    ColorData    = CreateColorBarData(app, app.ColorSelection);
                    CurrentColor = CreateCurrentColorImage(app, app.ColorSelection(1,:));

                    set(app.Selected_cell_list_display,'Items',app.SelectedCellList,'ItemsData',1:app.nColors,'Value',1) 
                    set(app.colormap_display,'ImageSource',ColorData)
                    set(app.current_color_display,'ImageSource',CurrentColor)
                    set(app.edit_color_button,'Text',[{'Edit'};{'color'}])
                    
                    app.SCmain.WindowStyle = 'modal'; % 'modal' makes SelectColor wait for user response
            
                    % UIWAIT makes SelectColor wait for user response (see UIRESUME)
                    % uiwait(app.SCmain);

        end

        % Close request function: SCmain
        function SCmain_CloseRequestFcn(app, event)
                       
                    app.LTapp.SEFoutput{2} = -1;        
                    delete(app.SCmain)

        end

        % Button pushed function: cancel_button
        function cancel_button_Callback(app, event)
                       
                    app.LTapp.SEFoutput{2} = -1;
                    app.SDapp.WindowStyle = 'modal'; % 'modal' makes SelectColor wait for user response
                    delete(app.SCmain)

        end

        % Value changed function: Selected_cell_list_display
        function Selected_cell_list_display_Callback(app, event)
                      
                    val = get(app.Selected_cell_list_display,'Value');
                    CurrentColor = CreateCurrentColorImage(app, app.ColorSelection(val,:));
                    set(app.current_color_display,'ImageSource',CurrentColor)
                
        end

        % Button pushed function: default_colors_button
        function default_colors_button_Callback(app, event)
                      
                    val = get(app.Selected_cell_list_display,'Value');
                    app.ColorSelection = AutoColors(app, app.nColors);
                    ColorData = CreateColorBarData(app, app.ColorSelection);
                    CurrentColor = CreateCurrentColorImage(app, app.ColorSelection(val,:));
            
                    set(app.colormap_display,'ImageSource',ColorData)
                    set(app.current_color_display,'ImageSource',CurrentColor)
                    
                 
        end

        % Button pushed function: edit_color_button
        function edit_color_button_Callback(app, event)
                    
                    val = get(app.Selected_cell_list_display,'Value');
                    c = uisetcolor(app.ColorSelection(val,:));

                    app.SCmain.WindowStyle = 'modal'; % 'modal' makes SelectColor wait for user response
                    app.ColorSelection(val,:) = c;
            
                    ColorData = CreateColorBarData(app, app.ColorSelection);
                    CurrentColor = CreateCurrentColorImage(app, c);
            
                    set(app.colormap_display,'ImageSource',ColorData)
                    set(app.current_color_display,'ImageSource',CurrentColor)
                 
        end

        % Button pushed function: export_button
        function export_button_Callback(app, event)
                                 
                    app.LTapp.SEFoutput{2} = app.ColorSelection;
                    delete(app.SEFapp)
                    delete(app.SDapp)
                    delete(app.SCmain)

        end

        % Button pushed function: load_colors_button
        function load_colors_button_Callback(app, event)
          
                    [FileName,PathName,FilterIndex] = uigetfile('*.cls','Load Color Assignments','*.cls');
            
                    fid = fopen([PathName FileName],'r');
                    A = textscan(fid,'%s','delimiter','\n');
                    A = A{:};
                    f = fclose(fid); %#ok<NASGU>
            
                    % Put color info into an easily referenced variable called LoadedColors
                    for n = 1:size(A,1)
                        S = str2double(A{n}(2:3));
                        C = str2double(A{n}(6:8));
                        r = str2double(A{n}(12:20));
                        g = str2double(A{n}(22:30));
                        b = str2double(A{n}(32:40));
                        LoadedColors{S,1}{C} = [r,g,b]; %#ok<AGROW>
                    end
            
                    CellList = app.SelectedCellList;
                    % Now put loaded-colors into old-color selection
                    for N = 1:size(CellList,1)
                        S = str2double(CellList{N}(2:3));
                        C = str2double(CellList{N}(6:8));
            
                        [isdim,notempty] = iscolordim(app, LoadedColors,S,C);
            
                        if isdim && notempty
                            app.ColorSelection(N,:) = LoadedColors{S}{C};
                        else
                            app.ColorSelection(N,:) = [0 0 0];
                        end
                    end
            
                    val = get(app.Selected_cell_list_display,'Value');
                    ColorData    = CreateColorBarData(app, app.ColorSelection);
                    CurrentColor = CreateCurrentColorImage(app, app.ColorSelection(val,:));
            
                    set(app.colormap_display,'ImageSource',ColorData)
                    set(app.current_color_display,'ImageSource',CurrentColor)
            
                  
        end

        % Button pushed function: save_color_button
        function save_color_button_Callback(app, event)
          
            
                    N = length(app.SelectedCellList);
            
                    [FileName,PathName,FilterIndex] = uiputfile('*.cls','Save Color Assignments','ColorAssignments.cls');
            
                    fid = fopen([PathName FileName],'w');
            
                    Colors = app.ColorSelection;
            
                    for n = 1:N
                        CellName = app.SelectedCellList{n}(1:8);
                        red = Colors(n,1);
                        gre = Colors(n,2);
                        blu = Colors(n,3);
            
                        fprintf(fid,'%s  [%1.7f %1.7f %1.7f]\r\n', CellName,red,gre,blu);
                    end
            
                    f = fclose(fid);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SCmain = uifigure('Visible', 'off');
            colormap(app.SCmain, 'parula');
            app.SCmain.Name = 'SelectColor';
            app.SCmain.CloseRequestFcn = createCallbackFcn(app, @SCmain_CloseRequestFcn, true);
            app.SCmain.Tag = 'SCmain';
            app.cancel_button = uibutton(app.SCmain, 'push');
            app.cancel_button.ButtonPushedFcn = createCallbackFcn(app, @cancel_button_Callback, true);
            app.cancel_button.Tag = 'cancel_button';
            app.cancel_button.Text = 'Cancel';
            app.Selected_cell_list_display = uilistbox(app.SCmain);
            app.Selected_cell_list_display.Items = {};
            app.Selected_cell_list_display.ValueChangedFcn = createCallbackFcn(app, @Selected_cell_list_display_Callback, true);
            app.Selected_cell_list_display.Tag = 'complete_cell_list_display';
            app.Selected_cell_list_display.Value = {};
            app.edit_color_button = uibutton(app.SCmain, 'push');
            app.edit_color_button.ButtonPushedFcn = createCallbackFcn(app, @edit_color_button_Callback, true);
            app.edit_color_button.Tag = 'edit_color_button';
            app.edit_color_button.Text = 'Edit';
            app.default_colors_button = uibutton(app.SCmain, 'push');
            app.default_colors_button.ButtonPushedFcn = createCallbackFcn(app, @default_colors_button_Callback, true);
            app.default_colors_button.Tag = 'default_colors_button';
            app.default_colors_button.Text = 'Auto Colors';
            app.export_button = uibutton(app.SCmain, 'push');
            app.export_button.ButtonPushedFcn = createCallbackFcn(app, @export_button_Callback, true);
            app.export_button.Tag = 'export_button';
            app.export_button.Text = 'Export';
            app.load_colors_button = uibutton(app.SCmain, 'push');
            app.load_colors_button.ButtonPushedFcn = createCallbackFcn(app, @load_colors_button_Callback, true);
            app.load_colors_button.Tag = 'load_colors_button';
            app.load_colors_button.Text = 'Import Color Assignment';
            app.save_color_button = uibutton(app.SCmain, 'push');
            app.save_color_button.ButtonPushedFcn = createCallbackFcn(app, @save_color_button_Callback, true);
            app.save_color_button.Tag = 'save_color_button';
            app.save_color_button.Text = 'Save Color Assignment';
            app.colormap_display = uiimage(app.SCmain);
            app.current_color_display = uiimage(app.SCmain);
            % Show the figure after all components are created
            app.SCmain.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SelectColor(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SCmain)

                % Execute the startup function
                runStartupFcn(app, @(app)SelectColor_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.SCmain)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SCmain)
        end
    end
end