classdef SelectData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SDmain                      matlab.ui.Figure
        text2                       matlab.ui.control.Label
        text1                       matlab.ui.control.Label
        add_all_cells_button        matlab.ui.control.Button
        select_colors_button        matlab.ui.control.Button
        add_cell_button             matlab.ui.control.Button
        selected_cell_list_display  matlab.ui.control.ListBox
        cancel_button               matlab.ui.control.Button
        remove_cells_button         matlab.ui.control.Button
        complete_cell_list_display  matlab.ui.control.ListBox
    end

    
    properties (Access = public)
        LTapp 
        SEFapp
        CompleteCellList
        SelectedCellList
        TrackDataSummary
    end
    
    methods (Access = private)
        function SetCellList = CreateCompleteCellList(app, TrackDataSummary)
            
                 if ~isempty(TrackDataSummary)
                    index = 0;
                    for S = 1:TrackDataSummary.nSets
                        for C = 1:TrackDataSummary.Data{S}.MaxNumCells
                           index = index + 1;
                           sTime = TrackDataSummary.Data{S}.StartTimes(C,1);
                           eTime = TrackDataSummary.Data{S}.EndTimes(C,1);
                           mTime = TrackDataSummary.Data{S}.MissingTimePoints{C,1};
                           if isempty(mTime)
                                SetCellList{index,1} = ['s' udigit(app, 2,S) ' c' udigit(app, 3,C) ' t' num2str(sTime) '-' num2str(eTime)];             %#ok<AGROW>
                           else
                               mString = '[';
                               N = length(mTime);
                               for k = 1:N
                                   if ~isequal(k,N)
                                        mString = [mString num2str(mTime(k)) ','];
                                   else
                                        mString = [mString num2str(mTime(k)) ']'];
                                   end
                               end
                               SetCellList{index,1} = ['s' udigit(app, 2,S) ' c' udigit(app, 3,C) ' t' num2str(sTime) '-' num2str(eTime) ' ' mString];
                           end
                        end
                    end
                else
                    SetCellList = [];
                end
        end
        
        function TP = udigit(app, N, number)
            
                    % TP = udigit(N, number);
                    % This function returns a text string that has the desired number of digits
            
                    NUM =num2str(number);
                    k = length(NUM);
                    D = N-k;
            
                    if D <= 0;
                        TP = NUM;
                    else
                        zt = char(48*ones(1,D));
                        TP = [zt NUM];
                    end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function SelectData_OpeningFcn(app, varargin)
          
            
                    app.TrackDataSummary = varargin{1}.TrackDataSummary;
                    app.LTapp  = varargin{1}.LTapp;
                    app.SEFapp = varargin{1}.SEFmain;
                    app.CompleteCellList = CreateCompleteCellList(app, app.TrackDataSummary);
                    app.SelectedCellList = cell(1,0);
                    nL = size(app.CompleteCellList,1);
                    set(app.complete_cell_list_display,'Items',app.CompleteCellList,'ItemsData',1:nL,'Value',1) % Get Rid of Highlighted items in this listbox
                    set(app.selected_cell_list_display,'Items',app.SelectedCellList)
                    
                    EF = app.LTapp.SEFoutput{3}; % app.LTapp.SEFoutput{3}; % app.ExportFormat;
                    if isequal(EF,1) || isequal(EF,2) || isequal(EF,3) || isequal(EF,5) || isequal(EF,10)
                        set(app.select_colors_button,'Text','Assign Cell Colors','Enable','off')
                    else
                        set(app.select_colors_button,'Text','Export Data','Enable','off')
                    end
            
                    app.SDmain.WindowStyle = 'modal'; % 'modal' makes SelectData wait for user response
                                
                    % UIWAIT makes SelectData wait for user response (see UIRESUME)
                    uiwait(app.SDmain);


        end

        % Close request function: SDmain
        function SDmain_CloseRequestFcn(app, event)
                                
                    app.LTapp.SEFoutput{1} = -1; % Data Selection
                    %app.LTapp.SEFoutput{2} = -1; % Color Selection  
                    % app.LTapp.SEFoutput{3} = -1; % Export Format

                    if isequal(app.SDmain,'waitstatus','waiting')
                        uiresume(app.SDmain)
                    end

                    delete(app.SDmain);


        end

        % Button pushed function: add_all_cells_button
        function add_all_cells_button_Callback(app, event)
            

                    app.SelectedCellList = app.CompleteCellList;
                    nL = size(app.SelectedCellList,1);
                    set(app.selected_cell_list_display,'Items',app.SelectedCellList,'ItemsData',1:nL,'Value',1)
                    set(app.select_colors_button,'Enable','on')


        end

        % Button pushed function: add_cell_button
        function add_cell_button_Callback(app, event)
                        
                    val = get(app.complete_cell_list_display,'Value');
            
                    if ~isempty(val)
                        temp_list = app.CompleteCellList(val);
                        temp_list2 = [app.SelectedCellList; temp_list];
                        app.SelectedCellList = unique(temp_list2);
                        nL = size(app.SelectedCellList,1);
                        set(app.selected_cell_list_display,'Items',app.SelectedCellList,'ItemsData',1:nL,'Value',1)
                        set(app.select_colors_button,'Enable','on')
                    end


        end

        % Button pushed function: cancel_button
        function cancel_button_Callback(app, event)
                        
                    app.LTapp.SEFoutput{1} = -1; % Data Selection
                    %app.LTapp.SEFoutput{2} = -1; % Color Selection  
                    % app.LTapp.SEFoutput{3} = -1; % Export Format

                    if isequal(app.SDmain,'waitstatus','waiting')
                        uiresume(app.SDmain)
                    end

                    delete(app.SDmain);

        end

        % Button pushed function: remove_cells_button
        function remove_cells_button_Callback(app, event)
            
                     val = get(app.selected_cell_list_display,'Value');
            
                     if ~isempty(val)
                         app.SelectedCellList(val) = [];
                         nL = length(app.SelectedCellList);
                         if val == 1 && nL == 0
                            val = cell(0,0);
                            set(app.selected_cell_list_display,'Items',app.SelectedCellList)
                         elseif val > nL
                            val = nL;
                            set(app.selected_cell_list_display,'Items',app.SelectedCellList,'ItemsData',1:nL,'Value',val)
                         else
                            set(app.selected_cell_list_display,'Items',app.SelectedCellList,'ItemsData',1:nL,'Value',val)
                         end
                         
                         if isequal(length(app.SelectedCellList),0)
                             set(app.select_colors_button,'Enable','off')
                         end
                     end

                    
        end

        % Button pushed function: select_colors_button
        function select_colors_button_Callback(app, event)
                      
                    EF = app.LTapp.SEFoutput{3}; %  app.ExportFormat;
                        
                    if isequal(EF,1) || isequal(EF,2) || isequal(EF,3) || isequal(EF,5) || isequal(EF,10)
                        app.LTapp.SEFoutput{2} = -1;
                        app.LTapp.SEFoutput{1} = app.SelectedCellList;
                        CShandles = SelectColor(app);
                    else
                        app.LTapp.SEFoutput{2} = 'AutoColorSelection';
                        app.LTapp.SEFoutput{1} = app.SelectedCellList;
                        delete(app.SEFapp)
                        delete(app.SDmain)
                    end

        end

        % Value changed function: selected_cell_list_display
        function selected_cell_list_displayValueChanged(app, event)
            value = app.selected_cell_list_display.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SDmain = uifigure('Visible', 'off');
            colormap(app.SDmain, 'parula');
            app.SDmain.Name = 'Select Data to Export';
            app.SDmain.CloseRequestFcn = createCallbackFcn(app, @SDmain_CloseRequestFcn, true);
            app.SDmain.Tag = 'SDmain';
            app.complete_cell_list_display = uilistbox(app.SDmain);
            app.complete_cell_list_display.Items = {};
            app.complete_cell_list_display.Tag = 'complete_cell_list_display';
            app.complete_cell_list_display.Value = {};
            app.remove_cells_button = uibutton(app.SDmain, 'push');
            app.remove_cells_button.ButtonPushedFcn = createCallbackFcn(app, @remove_cells_button_Callback, true);
            app.remove_cells_button.Tag = 'pushbutton1';
            app.remove_cells_button.Text = 'Remove cell from list';
            app.cancel_button = uibutton(app.SDmain, 'push');
            app.cancel_button.ButtonPushedFcn = createCallbackFcn(app, @cancel_button_Callback, true);
            app.cancel_button.Tag = 'cancel_button';
            app.cancel_button.Text = 'Cancel';
            app.selected_cell_list_display = uilistbox(app.SDmain);
            app.selected_cell_list_display.Items = {'s50c200 100-500'};
            app.selected_cell_list_display.ValueChangedFcn = createCallbackFcn(app, @selected_cell_list_displayValueChanged, true);
            app.selected_cell_list_display.Tag = 'selected_cell_list_display';
            app.selected_cell_list_display.Value = 's50c200 100-500';
            app.add_cell_button = uibutton(app.SDmain, 'push');
            app.add_cell_button.ButtonPushedFcn = createCallbackFcn(app, @add_cell_button_Callback, true);
            app.add_cell_button.Tag = 'add_cell_button';
            app.add_cell_button.Text = '>> Add >>';
            app.select_colors_button = uibutton(app.SDmain, 'push');
            app.select_colors_button.ButtonPushedFcn = createCallbackFcn(app, @select_colors_button_Callback, true);
            app.select_colors_button.Tag = 'select_colors_button';
            app.select_colors_button.Text = 'Select Cell Colors';
            app.add_all_cells_button = uibutton(app.SDmain, 'push');
            app.add_all_cells_button.ButtonPushedFcn = createCallbackFcn(app, @add_all_cells_button_Callback, true);
            app.add_all_cells_button.Tag = 'add_all_cells_button';
            app.add_all_cells_button.Text = 'Add All';
            app.text1 = uilabel(app.SDmain);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Complete Set/Cell List';
            app.text2 = uilabel(app.SDmain);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Set/Cell Selection';
            % Show the figure after all components are created
            app.SDmain.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SelectData(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SDmain)

                % Execute the startup function
                runStartupFcn(app, @(app)SelectData_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.SDmain)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SDmain)
        end
    end
end