classdef SelectExportFormat < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SEFmain                        matlab.ui.Figure
        FormatDescription              matlab.ui.control.TextArea
        FormatdescriptionLabel         matlab.ui.control.Label
        SelectExportFormatButtonGroup  matlab.ui.container.ButtonGroup
        Button10                       matlab.ui.control.RadioButton
        Button9                        matlab.ui.control.RadioButton
        Button8                        matlab.ui.control.RadioButton
        Button7                        matlab.ui.control.RadioButton
        Button6                        matlab.ui.control.RadioButton
        Button5                        matlab.ui.control.RadioButton
        Button4                        matlab.ui.control.RadioButton
        Button3                        matlab.ui.control.RadioButton
        Button2                        matlab.ui.control.RadioButton
        Button1                        matlab.ui.control.RadioButton
        cancel_button                  matlab.ui.control.Button
        select_data_button             matlab.ui.control.Button
    end

    
    properties (Access = public)
        TrackDataSummary
        LTapp
    end
    
    methods (Access = private)
        
        function UpdateFormatDescription(app,ButtonIdx)
            
                switch(ButtonIdx)
                    case 1; app.FormatDescription.Value = ['nT z-Multi tiff Format: For each timepoint a z-multi tiff file is created ' ...
                                                           'containing RGB-colored masks for all exported cells. Mask color is unique to cell path.'];
                    case 2; app.FormatDescription.Value = ['nSet z-Multi tiff Format: For each set a time-independent z-multi tiff file '...
                                                           'is created containing RGB-colored masks for all the cells within the set. Mask color is unique to cell path.'];
                    case 3; app.FormatDescription.Value = ['1 z-Multi tiff Format: One time-independent z-multi tiff file is created '...
                                                           'containing RGB-colored masks for all exported cells. Mask color is unique to cell path.'];
                    case 4; app.FormatDescription.Value = ['Text File Format: a text file is created containing the absolute position ',....
                                                           'of all exported cells. Default units for the [x,y,z] coordinates are [pixel,pixel,z-step].'];
                    case 5; app.FormatDescription.Value = ['nSets nT nCells Format: For each set, timepoint, and cell, a tiff file is created containing '...
                                                           'a mask of a cell. Set number, timepoint, and cell number information are contained in the file name. Mask color is unique to cell path.'];
                    case 6; app.FormatDescription.Value = ['TIME, nSet z-Multi tiff Format: For each set a z-multi tiff file is created containing RGB-colored'...
                                                           'masks where the colors designate the each cells position in time relative to all exported cells'...
                                                           '(red = first, blue = last).  Mask color is unique to relative time (red = begining, blue = end).'];
                    case 7; app.FormatDescription.Value = ['TIME, 1 z-Multi tiff: One z-multi tiff file is created containing RGB-colored'...
                                                           'masks where the colors designate the each cells position in time relative to all exported cells'...
                                                           '(red = first, blue = last). Mask color is unique to relative time (red = begining, blue = end)'];
                    case 8; app.FormatDescription.Value = ['SPEED, nSet z-Multi tiff Format: For each set a z-multi tiff file is created containing RGB-colored'...
                                                           'masks where the colors designate the each cells speed relative to all exported cells'...
                                                           '(red = first, blue = last). Mask color is unique to absolute speed (colormap exported as separate file).'];
                    case 9; app.FormatDescription.Value = ['SPEED, 1 z-Multi tiff: One z-multi tiff file is created containing RGB-colored'...
                                                           'masks where the colors designate the each cells position in time relative to all exported cells'...
                                                           '(red = first, blue = last). Mask color is unique to absolute speed (colormap exported as separate file).'];
                    case 10; app.FormatDescription.Value = 'Same as format 1 except that the z-stack at each timepoint contains masks from all the previous timepoints as well.';    
                end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function SelectExportFormat_OpeningFcn(app, varargin)
                       
            
                  app.TrackDataSummary = varargin{2};
                  app.LTapp = varargin{1};

                  ButtonIdx = find( cell2mat({app.SelectExportFormatButtonGroup.Buttons(:).Value}') );  
                  UpdateFormatDescription(app,ButtonIdx)
            
                  app.SEFmain.WindowStyle = 'modal'; % 'modal' makes SelectExportFormat wait for user response

                  app.LTapp.SEFoutput{1} = -1; % Data Selection
                  app.LTapp.SEFoutput{2} = -1; % Color Selection  
                  app.LTapp.SEFoutput{3} =  2; % Export Format
            
                % UIWAIT makes SelectExportFormat wait for user response (see UIRESUME)
                % uiwait(app.SEFmain)


        end

        % Close request function: SEFmain
        function SEFmain_CloseRequestFcn(app, event)
            
                app.LTapp.SEFoutput{1} = -1; % Data Selection
                app.LTapp.SEFoutput{2} = -1; % Color Selection  
                app.LTapp.SEFoutput{3} = -1; % Export Format

                if isequal(get(app.SEFmain,'waitstatus'),'waiting')
                    uiresume(app.SEFmain)
                end
                delete(app.SEFmain)

        end

        % Button pushed function: cancel_button
        function cancel_button_Callback(app, event)
                       
                app.LTapp.SEFoutput{1} = -1; % Data Selection
                app.LTapp.SEFoutput{2} = -1; % Color Selection  
                app.LTapp.SEFoutput{3} = -1; % Export Format
                
                if isequal(get(app.SEFmain,'waitstatus'),'waiting')
                    uiresume(app.SEFmain)
                end
                delete(app.SEFmain)

        end

        % Button pushed function: select_data_button
        function select_data_button_Callback(app, event)
                       
                app.LTapp.SEFoutput{3} = find( cell2mat({app.SelectExportFormatButtonGroup.Buttons(:).Value}') );  
                SDhandle = SelectData(app); %,app.LTapp,app.SEFmain); % SelectData(app.TrackDataSummary,ExportFormat);

                % uiwait(SDhandle.SDmain)
%                 if ~isequal(app.SEFoutput{1},-1) && ~isequal(app.SEFoutput{2},-1)
%                     uiresume(app.SEFmain)
%                 end


        end

        % Selection changed function: SelectExportFormatButtonGroup
        function SelectExportFormatButtonGroupSelectionChanged(app, event)
            
                % selectedButton = app.SelectExportFormatButtonGroup.SelectedObject;
                ButtonIdx = find( cell2mat({app.SelectExportFormatButtonGroup.Buttons(:).Value}') ); % Selected Button 
                UpdateFormatDescription(app,ButtonIdx)
                    
                EF = ButtonIdx; % app.ExportFormat;

                if ~isequal(EF,1) && ~isequal(EF,2) && ~isequal(EF,3) && ~isequal(EF,5)
                    app.LTapp.SEFoutput{2} = 'AutoColorSelection';
                else
                    app.LTapp.SEFoutput{2} = -1;
                end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SEFmain = uifigure('Visible', 'off');
            colormap(app.SEFmain, 'parula');
            app.SEFmain.Name = 'Export';
            app.SEFmain.CloseRequestFcn = createCallbackFcn(app, @SEFmain_CloseRequestFcn, true);
            app.SEFmain.Tag = 'SEFmain';
            app.select_data_button = uibutton(app.SEFmain, 'push');
            app.select_data_button.ButtonPushedFcn = createCallbackFcn(app, @select_data_button_Callback, true);
            app.select_data_button.Tag = 'select_data_button';
            app.select_data_button.Text = 'Select Data';
            app.cancel_button = uibutton(app.SEFmain, 'push');
            app.cancel_button.ButtonPushedFcn = createCallbackFcn(app, @cancel_button_Callback, true);
            app.cancel_button.Tag = 'cancel_button';
            app.cancel_button.Text = 'Cancel';
            app.SelectExportFormatButtonGroup = uibuttongroup(app.SEFmain);
            app.SelectExportFormatButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectExportFormatButtonGroupSelectionChanged, true);
            app.SelectExportFormatButtonGroup.Title = 'Select export format:';
            app.Button1 = uiradiobutton(app.SelectExportFormatButtonGroup);
            app.Button1.Text = '1. nT zMulti tiff';
            app.Button2 = uiradiobutton(app.SelectExportFormatButtonGroup);
            app.Button2.Text = '2. nSet zMulti tiff';
            app.Button2.Value = true;
            app.Button3 = uiradiobutton(app.SelectExportFormatButtonGroup);
            app.Button3.Text = '3. zMulti tiff';
            app.Button4 = uiradiobutton(app.SelectExportFormatButtonGroup);
            app.Button4.Text = '4. Text File: Absolute Position';
            app.Button5 = uiradiobutton(app.SelectExportFormatButtonGroup);
            app.Button5.Text = '5. MASK: nSets nT nCells';
            app.Button6 = uiradiobutton(app.SelectExportFormatButtonGroup);
            app.Button6.Text = '6. TIME: nMulti tiff = nSets';
            app.Button7 = uiradiobutton(app.SelectExportFormatButtonGroup);
            app.Button7.Text = '7. TIME: nMulti tiff = 1';
            app.Button8 = uiradiobutton(app.SelectExportFormatButtonGroup);
            app.Button8.Text = '8. SPEED, nMulti tiff = nSets';
            app.Button9 = uiradiobutton(app.SelectExportFormatButtonGroup);
            app.Button9.Text = '9. SPEED, nMulti tiff = 1';
            app.Button10 = uiradiobutton(app.SelectExportFormatButtonGroup);
            app.Button10.Text = '10. nT zMulti tiff (cumulative time)';
            app.FormatdescriptionLabel = uilabel(app.SEFmain);
            app.FormatdescriptionLabel.Text = 'Format description:';
            app.FormatDescription = uitextarea(app.SEFmain);
            % Show the figure after all components are created
            app.SEFmain.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SelectExportFormat(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SEFmain)

                % Execute the startup function
                runStartupFcn(app, @(app)SelectExportFormat_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.SEFmain)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SEFmain)
        end
    end
end