classdef VelocityFilteringApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VFAmain                        matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        CurrentFilteredVelocityMaxMin  matlab.ui.control.Label
        CurrentFilteredVelocityMaxMinText  matlab.ui.control.Label
        YaxisandcolormapunitsLabel     matlab.ui.control.Label
        UnitsSwitch                    matlab.ui.control.Switch
        nPointsInput                   matlab.ui.control.NumericEditField
        nPointsinmovingaverageLabel    matlab.ui.control.Label
        SetcolormaplimitsPanel         matlab.ui.container.Panel
        LowerlimitEditField            matlab.ui.control.NumericEditField
        LowerlimitEditFieldLabel       matlab.ui.control.Label
        UpperlimitEditField            matlab.ui.control.NumericEditField
        UpperlimitEditFieldLabel       matlab.ui.control.Label
        MATLABfunctionsmoothdatawithgaussiansmoothingmethodLabel  matlab.ui.control.Label
        CellSpinner                    matlab.ui.control.Spinner
        CellSpinnerLabel               matlab.ui.control.Label
        ContinueButton                 matlab.ui.control.Button
        UpdateVelocityPlotButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        TimeVector 
        VelocityData
        LTapp
        VelocityFigureHandle = -1;
        AxisHandle = -1;
    end
    
    properties (Access = public)
        %Property2 % Description
    end
    
    methods (Access = private)
        
        % function results = func(app)
        % 
        % end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
                 
                  app.SetcolormaplimitsPanel.Title = 'Set colormap limits';
                  app.UnitsSwitch.Items = [{[char(956),'m/min']},{'nm/s'}];
                  app.UnitsSwitch.ItemsData = [1,2];
                  app.LTapp = varargin{1};
                  app.TimeVector = varargin{2};
                  app.VelocityData = varargin{3};
                  %app.VFAmain.WindowStyle = 'modal'; % 'modal' makes SelectExportFormat wait for user response
                  %app.LTapp.VFAoutput{1} = app.SmoothingMethodTab.SelectedTab.Title;
                  app.LTapp.VFAoutput{1} = app.nPointsInput.Value; % Set default number of points
                  SF = 60;
                  app.LTapp.VFAoutput{2} = [min(SF*app.VelocityData,[],'omitnan'),max(SF*app.VelocityData,[],'omitnan')]; % Multiply by 60 because um/min is the default unt selection
                  app.LowerlimitEditField.Value = min(SF*app.VelocityData(:),[],'omitnan');
                  app.UpperlimitEditField.Value = max(SF*app.VelocityData(:),[],'omitnan');
                  app.LTapp.VFAoutput{3} = 1;
                  app.CurrentFilteredVelocityMaxMinText.Text = 'Filtered velocity [max, min]:';
                  app.CellSpinner.Value = 1;
                  if size(app.VelocityData,2) > 1
                    app.CellSpinner.Enable = true;
                    app.CellSpinner.Limits = [1,size(app.VelocityData,2)]; 
                  else
                    app.CellSpinner.Enable = false;
                  end

        end

        % Close request function: VFAmain
        function VFAmainCloseRequest(app, event)
            
                if isgraphics(app.VelocityFigureHandle)
                    close(app.VelocityFigureHandle)
                end

                delete(app)
            
        end

        % Button pushed function: UpdateVelocityPlotButton
        function UpdateVelocityPlotButtonPushed(app, event)
            
            
            CellNum = app.CellSpinner.Value;
                speed = app.VelocityData(:,CellNum);
                dt = app.LTapp.time_interval/60;
            
                t = app.TimeVector;

                Sidx = find( ~isnan(speed),1,'first');
                Eidx = find( ~isnan(speed),1,'last');
                speed(Sidx:Eidx,1) = fillmissing(speed(Sidx:Eidx,1),'linear');   
                speed = interp1( t(Sidx:Eidx)+0.5, speed(Sidx:Eidx,1), t, 'linear', 'extrap');
                nT = length(speed);

                nPoints = app.nPointsInput.Value;
                app.LTapp.VFAoutput{1} = nPoints;
                FilteredSpeed = smoothdata(speed,"gaussian",nPoints);

                if Sidx > 1
                    FilteredSpeed(1:Sidx-1) = NaN; % the interp1 function will extrapolate past the timepionts of the start and end of tracking, so return them to NaN
                    speed(1:Sidx-1) = NaN;
                end
                if Eidx+1 > nT
                    FilteredSpeed(Eidx+2:end) = NaN; % the interp1 function will extrapolate past the timepionts of the start and end of tracking, so return them to NaN
                    speed(Eidx+2:end) = NaN;
                end
                
                if ~isgraphics(app.VelocityFigureHandle)
                    app.VelocityFigureHandle = figure;
                    set(app.VelocityFigureHandle,'Color','w')
                    app.AxisHandle = axes('Parent',app.VelocityFigureHandle);
                end
                
                if app.UnitsSwitch.Value == 1
                    SF = 60; % Conversion factor for microns/min
                    YaxisLabel = 'Velocity (\mum/min)';
                else
                    SF = 1000; % conversion factor for nm/s
                    YaxisLabel = 'Velocity (nm/s)';
                end

                plot(app.AxisHandle, (t-1).*dt, SF*speed,         '.-','LineWidth',1,'MarkerEdgeColor','k'); 
                hold(app.AxisHandle,'on')
                plot(app.AxisHandle, (t-1).*dt, SF*FilteredSpeed, '-r','LineWidth',2); 
                hold(app.AxisHandle,'off')
                set(app.AxisHandle,'LineWidth',2,'FontSize',16)
                xlabel(app.AxisHandle,'Time (min)','FontSize',20)
                ylabel(app.AxisHandle,YaxisLabel,'FontSize',20)
                legend(app.AxisHandle,{'Raw velocity','Filtered velocity'},'FontSize',16,'Location','northoutside','NumColumns',2)
                xlim(app.AxisHandle,[ (t(1)-2)*app.LTapp.time_interval/60, (t(end)+1)*app.LTapp.time_interval/60 ])
                
                % Set Colormap limits based on Filtered velocity max and min and record
                MaxV = max(SF*FilteredSpeed,[],'omitnan');
                MinV = min(SF*FilteredSpeed,[],'omitnan');
                app.CurrentFilteredVelocityMaxMin.Text = {['[ ',sprintf('%2.3f',MaxV),', ', sprintf('%2.3f',MinV),' ]']};

                app.LTapp.VFAoutput{2} = [app.LowerlimitEditField.Value, app.UpperlimitEditField.Value];


        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            
                VFAmainCloseRequest(app, event)

        end

        % Value changed function: UpperlimitEditField
        function UpperlimitEditFieldValueChanged(app, event)
                       
            app.LTapp.VFAoutput{2} = [app.LowerlimitEditField.Value, app.UpperlimitEditField.Value];

        end

        % Value changed function: LowerlimitEditField
        function LowerlimitEditFieldValueChanged(app, event)
            
            app.LTapp.VFAoutput{2} = [app.LowerlimitEditField.Value, app.UpperlimitEditField.Value];
            
        end

        % Value changed function: UnitsSwitch
        function UnitsSwitchValueChanged(app, event)
            
            value = app.UnitsSwitch.Value;
            app.LTapp.VFAoutput{3} = value;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VFAmain = uifigure('Visible', 'off');
            app.VFAmain.Name = 'Velocity Smoothing App';
            app.VFAmain.CloseRequestFcn = createCallbackFcn(app, @VFAmainCloseRequest, true);
            app.UpdateVelocityPlotButton = uibutton(app.VFAmain, 'push');
            app.UpdateVelocityPlotButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateVelocityPlotButtonPushed, true);
            app.UpdateVelocityPlotButton.Text = 'Update sample velocity plot';
            app.ContinueButton = uibutton(app.VFAmain, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.CellSpinnerLabel = uilabel(app.VFAmain);
            app.CellSpinnerLabel.Text = 'Cell #:';
            app.CellSpinner = uispinner(app.VFAmain);
            app.CellSpinner.Limits = [1 100];
            app.CellSpinner.ValueDisplayFormat = '%.0f';
            app.CellSpinner.Value = 1;
            app.MATLABfunctionsmoothdatawithgaussiansmoothingmethodLabel = uilabel(app.VFAmain);
            app.MATLABfunctionsmoothdatawithgaussiansmoothingmethodLabel.Text = {'MATLAB function: smoothdata'; 'with ''gaussian'' smoothing method'};
            app.Panel = uipanel(app.VFAmain);
            app.SetcolormaplimitsPanel = uipanel(app.Panel);
            app.SetcolormaplimitsPanel.Title = 'Set colormap limits';
            app.UpperlimitEditFieldLabel = uilabel(app.SetcolormaplimitsPanel);
            app.UpperlimitEditFieldLabel.Text = 'Upper limit:';
            app.UpperlimitEditField = uieditfield(app.SetcolormaplimitsPanel, 'numeric');
            app.UpperlimitEditField.ValueDisplayFormat = '%.3f';
            app.UpperlimitEditField.ValueChangedFcn = createCallbackFcn(app, @UpperlimitEditFieldValueChanged, true);
            app.LowerlimitEditFieldLabel = uilabel(app.SetcolormaplimitsPanel);
            app.LowerlimitEditFieldLabel.Text = 'Lower limit:';
            app.LowerlimitEditField = uieditfield(app.SetcolormaplimitsPanel, 'numeric');
            app.LowerlimitEditField.ValueDisplayFormat = '%.3f';
            app.LowerlimitEditField.ValueChangedFcn = createCallbackFcn(app, @LowerlimitEditFieldValueChanged, true);
            app.nPointsinmovingaverageLabel = uilabel(app.Panel);
            app.nPointsinmovingaverageLabel.Text = {'nPoints in gaussian '; 'moving average:'};
            app.nPointsInput = uieditfield(app.Panel, 'numeric');
            app.nPointsInput.Limits = [3 51];
            app.nPointsInput.ValueDisplayFormat = '%.0f';
            app.nPointsInput.Value = 21;
            app.UnitsSwitch = uiswitch(app.Panel, 'slider');
            app.UnitsSwitch.Items = {'um/min', 'nm/s'};
            app.UnitsSwitch.ValueChangedFcn = createCallbackFcn(app, @UnitsSwitchValueChanged, true);
            app.UnitsSwitch.Value = 'um/min';
            app.YaxisandcolormapunitsLabel = uilabel(app.Panel);
            app.YaxisandcolormapunitsLabel.Text = 'Y-axis and colormap units';
            app.CurrentFilteredVelocityMaxMinText = uilabel(app.Panel);
            app.CurrentFilteredVelocityMaxMinText.Text = 'Filtered velocity [max, min]:';
            app.CurrentFilteredVelocityMaxMin = uilabel(app.Panel);
            app.CurrentFilteredVelocityMaxMin.Text = '[ 20.456, 13.456 ]';
            % Show the figure after all components are created
            app.VFAmain.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VelocityFilteringApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VFAmain)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VFAmain)
        end
    end
end