classdef longtracker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        main                           matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        TrackingTab                    matlab.ui.container.Tab
        TabGroup2                      matlab.ui.container.TabGroup
        AppearanceTab                  matlab.ui.container.Tab
        cell_color_panel               matlab.ui.container.Panel
        line_width_listbox             matlab.ui.control.DropDown
        prev_cell_color_display        matlab.ui.container.Panel
        post_cell_color_display        matlab.ui.container.Panel
        cell_fill_display              matlab.ui.container.Panel
        prev_cell_color_button         matlab.ui.control.Button
        post_cell_color_button         matlab.ui.control.Button
        cell_fill_color_button         matlab.ui.control.Button
        cell_outline_color_display     matlab.ui.container.Panel
        cell_outline_color_button      matlab.ui.control.Button
        outline_width_text             matlab.ui.control.Label
        rgb_panel                      matlab.ui.container.Panel
        turnon_red_channel             matlab.ui.control.CheckBox
        turnon_green_channel           matlab.ui.control.CheckBox
        turnon_blue_channel            matlab.ui.control.CheckBox
        r_channel_color_button         matlab.ui.control.Button
        g_channel_color_button         matlab.ui.control.Button
        b_channel_color_button         matlab.ui.control.Button
        red_color_display              matlab.ui.container.Panel
        green_color_display            matlab.ui.container.Panel
        blue_color_display             matlab.ui.container.Panel
        sa_rgb_check                   matlab.ui.control.CheckBox
        cross_section_display_panel    matlab.ui.container.Panel
        z_line_color_display           matlab.ui.container.Panel
        z_line_color_button            matlab.ui.control.Button
        turnon_magnet_function         matlab.ui.control.StateButton
        XYZTrecordTab                  matlab.ui.container.Tab
        PressckeytorecordTXYZpointLabel  matlab.ui.control.Label
        Recorded_TXYZ_values           matlab.ui.control.Table
        COPY_TXYZ_array                matlab.ui.control.Button
        CLEAR_TXYZ_array               matlab.ui.control.Button
        other_figures_panel            matlab.ui.container.Panel
        apply_to_all                   matlab.ui.control.CheckBox
        tm1_slider                     matlab.ui.control.Slider
        tp1_slider                     matlab.ui.control.Slider
        tp1_figure                     matlab.ui.control.CheckBox
        tm1_figure                     matlab.ui.control.CheckBox
        tracking_panel                 matlab.ui.container.Panel
        show_post_display              matlab.ui.control.EditField
        show_post_decrease_button      matlab.ui.control.Button
        show_post_increase_button      matlab.ui.control.Button
        show_previous_display          matlab.ui.control.EditField
        show_previous_decrease_button  matlab.ui.control.Button
        show_previous_increase_button  matlab.ui.control.Button
        show_H_cross_sections_check    matlab.ui.control.CheckBox
        TB_placement                   matlab.ui.control.CheckBox
        LR_placement                   matlab.ui.control.CheckBox
        cross_section_width_text       matlab.ui.control.Label
        cross_section_width_slider     matlab.ui.control.Slider
        show_V_cross_sections_check    matlab.ui.control.CheckBox
        show_post_check                matlab.ui.control.CheckBox
        show_previous_check            matlab.ui.control.CheckBox
        time_and_z_panel               matlab.ui.container.Panel
        AutoSelectWhenChangingZCheck   matlab.ui.control.CheckBox
        CellIndexSpinner               matlab.ui.control.Spinner
        CellIndexSpinnerLabel          matlab.ui.control.Label
        track_num_text                 matlab.ui.control.Label
        set_num_display                matlab.ui.control.EditField
        set_index_down                 matlab.ui.control.Button
        set_index_up                   matlab.ui.control.Button
        ncells_text                    matlab.ui.control.Label
        ncells_display                 matlab.ui.control.EditField
        ncells_down                    matlab.ui.control.Button
        ncells_up                      matlab.ui.control.Button
        auto_load_z_check              matlab.ui.control.CheckBox
        main_window_slider             matlab.ui.control.Slider
        main_window_slider_text        matlab.ui.control.Label
        z_display                      matlab.ui.control.EditField
        t_display                      matlab.ui.control.EditField
        z_up                           matlab.ui.control.Button
        z_down                         matlab.ui.control.Button
        t_up                           matlab.ui.control.Button
        t_down                         matlab.ui.control.Button
        functions                      matlab.ui.container.Panel
        cell_summary_button            matlab.ui.control.Button
        erase_line_breaks              matlab.ui.control.Button
        drawline_button                matlab.ui.control.Button
        turnon_hbreak                  matlab.ui.control.CheckBox
        erode_slider                   matlab.ui.control.Slider
        turnon_erode                   matlab.ui.control.CheckBox
        turnon_binarize                matlab.ui.control.CheckBox
        threshold_slider               matlab.ui.control.Slider
        turnon_threshold               matlab.ui.control.CheckBox
        DataIOTab                      matlab.ui.container.Tab
        ChangeImageFolderButton        matlab.ui.control.Button
        Panel                          matlab.ui.container.Panel
        microns_per_pixel_text_2       matlab.ui.control.Label
        ImagedatapreprocessingButton   matlab.ui.control.Button
        about_longtracker_button       matlab.ui.control.Button
        keyboard_short_cuts_button     matlab.ui.control.Button
        nZ_display                     matlab.ui.control.Label
        location_button                matlab.ui.control.Button
        images_directory_display       matlab.ui.control.Label
        save_panel                     matlab.ui.container.Panel
        time_interval_text             matlab.ui.control.Label
        time_interval_display          matlab.ui.control.EditField
        z_section_step_size_text       matlab.ui.control.Label
        z_section_step_size_display    matlab.ui.control.EditField
        microns_per_pixel_text         matlab.ui.control.Label
        microns_per_pixel_display      matlab.ui.control.EditField
        export_button                  matlab.ui.control.Button
        save_button                    matlab.ui.control.Button
        saveas_button                  matlab.ui.control.Button
        load_button                    matlab.ui.control.Button
        reset_button                   matlab.ui.control.Button
    end

    
    properties (Access = private)

    end
    
    properties (Access = public)

             directory = [];
             nT = [];
             nZ = [];   
             ImWidth  = [];
             ImHeight = [];
             filenames = [];
             
             doThreshold = false;
             doBinarize = false;
             doErode = false;
             doHbreak = false;
             
             SetNum = 1; % Used to be called TrackNum
             nCells = 1;
             CellIndex = 1;
    
             T = 1;
             Tprev = [];
             %Told = 1;
             Z = 1;
             %Zold = 1;
             main_panel  = -1;
             tm1_z_panel = -1;
             tp1_z_panel = -1;
             vertical_cs_panel   = -1;
             horizontal_cs_panel = -1;
             LR = true;
             TB = true;
             threshold_value = 100;
             nErode = 1;
             SE = strel('disk',1);
             SFMain = [];
             position = [];
             
             show_previous = false;
             NumPrevious = 1;
             show_post = false;
             NumPost = 1;
             show_V_cross_section = false;
             show_H_cross_section = false;
             cross_section_window_width = 150;  % percent
             main_window_size = 100;
             tp1_z_window_size = 100;
             show_tp1 = false;
             tm1_z_window_size = 100;
             show_tm1 = false;
             ApplyAll = false;
    
             show_r_channel = true;
             show_g_channel = true;
             show_b_channel = false;
             do_magnet = true;
    
             sa = false;
             r_color = (0:(1/255):1)'*[1 0 0];
             g_color = (0:(1/255):1)'*[0 1 0];
             b_color = (0:(1/255):1)'*[0 0 1];
             z_color = [1 1 0];
             cell_fill_color = [1 1 1];
             cell_outline_color = [1 1 1];
             previous_cell_outline_color = [0 1 1];
             post_cell_outline_color = [1 1 0];
             selected_summary_cell_color = [1 0 1];
    
             export_format = 2;
             line_break_mask = [];
             auto_load_z = true;
             savename = [];
             line_width = 2;
    
             microns_per_pixel = -1;
             z_step_size = -1;
             time_interval = -1;
    
             CellSummaryWindow = -1;
     
             Timage    = uint8([]);
             TP1image  = uint8([]);
             TM1image  = uint8([]);
             red_T     = uint8([]);
             red_TM1   = uint8([]);
             red_TP1   = uint8([]);
             green_T   = uint8([]);
             GREEN     = uint8([]);
             green_TM1 = uint8([]);
             green_TP1 = uint8([]);
             blue_T    = uint8([]);
             blue_TM1  = uint8([]);
             blue_TP1  = uint8([]);
             RGB_T     = uint8([]);
             RGB_TM1   = uint8([]);
             RGB_TP1   = uint8([]);
             
             FigFuncHandles = [];
             FirstPoint = [];
             SecondPoint = [];
             show_cross_section = false;

             TrackData = [];
             selected_cell_color = [0 0 1];
             SelectedCellNumFromList = [];
             SelectedSetNumFromList = [];
             DisplaySelectedCellsFromList = false;

             SEFoutput = cell(3,1); % Output from SelectExportFormat.mlapp subGUI
             VFAoutput = cell(1,1);
             VelocityColormapRange = NaN;
             FilteredTrackVelocities = NaN;

             CallbackPlayCount = 0;
    end
    
    %|||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||
    %|||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||
    
    methods (Access = private)
%-----------------------------------------------------------------------------------------------------------------------------------------     
    
        function FileNames = MakeSureImageFilesAreSortedByTimestamp(app,FileNames)

                    % DotIdx = cellfun(@(x) find(isstrprop(x,'punct'),1,'last'),files,'UniformOutput',false);  % get index of last '.' in filename
                    % NumStartStop = @(x,idx) [ strfind([0,isstrprop(x(1:idx),'digit')],[0,1])',...  % Define function that gets starting indices of numeric regions in filename
                    %                           strfind([isstrprop(x(1:idx),'digit'),0],[1,0])' ];   % Define function that gets stopping indices of numeric regions in filename
                    % 
                    % NumericIDs = cellfun(NumStartStop,files,DotIdx,'UniformOutput',false); % In each cell: Start (column 1) and Stop (column 2) of numeric regions in the file name  
                    % 
                    % % Make sure file names are sorted according to TimeStamp: This is a probelm if numeric timestamp width changes (no zero padding)
                    % TimeStamps = cellfun(@(x,Idx) str2double(x(Idx(end,1):Idx(end,2))), files, NumericIDs);
                    % [~,SortIdx] = sortrows(TimeStamps);
                    % files = files(SortIdx,1);


                                if numel(FileNames) > 1
                                    [nRows,nCols] = size(FileNames);
                                    if nCols > nRows
                                        FileNames = FileNames'; % If needed, convert to column vector of cells for processing
                                    end
                    
                                    % Find numeric pattern in filename that represents time
                                        pat = digitsPattern;
                                        % Grab all the numeric patterns in the file name
                                        NumPatterns = cellfun(@str2double,extract(FileNames,pat)); 
                                        [nR,nC] = size(NumPatterns);
                                        % Assume that column related to time is the only column with all unique values.
                                        % So the difference of values in that column should all be one and others zero.
                                        C = mat2cell( NumPatterns ,nR,ones(1,nC) );
                                        TimeColumnIdx = find( logical(  cellfun(@length, cellfun(@unique,C,'UniformOutput',false) ) - 1 )    );
                                        if numel(TimeColumnIdx) == 1
                                            % Now sort TimeColumnIdx and apply sorting order to filenames
                                            [~,sIdx] = sort(NumPatterns(:,TimeColumnIdx));
                                            FileNames = FileNames(sIdx,1);
                                        elseif isempty(TimeColumnIdx)
                                            error('No verying numeric region found in file names.')
                                        else
                                            error('Files names must not contain more than one verying numeric region.')
                                        end
                    
                                    if nCols > nRows
                                        FileNames = FileNames'; % If needed, convert back to row vector if that is how it was input
                                    end    
                                else
                                    disp('Files variable must have a 1D length of at least 2')
                                end

        end
%--------------------------------------------------------------------------------------------------------------------------------      


        function NewImage8bit = ApplyFunctions(app, image8bit)
            
                        % Apply Threshold Function ---------------------------------
                        if app.doThreshold
                            image8bit = threshold(app, app.threshold_value,image8bit);
                        end
    
                        % Apply Binarize Function ----------------------------------
                        if app.doBinarize
                           image8bit = uint8(binarize(app, image8bit));
                        end
    
                        % Apply Erode Function -------------------------------------
                        if app.doErode
                            image8bit = imfill(logical(image8bit),'holes');
                            image8bit = imerode(logical(image8bit),app.SE);
                            image8bit = uint8(255*bwmorph(image8bit,'spur'));
                        end
    
                        % Apply H-break
                        if app.doHbreak
                            image8bit = ~bwmorph(~logical(image8bit),'diag');
                            image8bit = uint8(255*~bwmorph(~image8bit,'bridge'));
                            DT = -bwdist(~image8bit);
                            BW = imextendedmin(DT,1);
                            DT(BW) = -Inf;
                            L = uint8(255*watershed(DT));
                            L(~image8bit) = 0;
                            image8bit = L;
                        end
    
                        NewImage8bit = image8bit;
                        
        end
        
%-----------------------------------------------------------------------------------------------------------------------------------------           
        function SetCellList = CreateCompleteCellList(app, TrackDataSummary)
            
                if ~isempty(TrackDataSummary)
                    index = 0;
                    for S = 1:TrackDataSummary.nSets
                            for C = 1:TrackDataSummary.Data{S}.MaxNumCells
                               index = index + 1;
                               sTime = TrackDataSummary.Data{S}.StartTimes(C,1);
                               eTime = TrackDataSummary.Data{S}.EndTimes(C,1);
                               mTime = TrackDataSummary.Data{S}.MissingTimePoints{C,1};
                               if isempty(mTime)
                                   SetCellList{index,1} = ['s' udigit(app, 2,S) ' c' udigit(app, 3,C) ' t' num2str(sTime) '-' num2str(eTime)];             %#ok<AGROW>
                               else
                                   mString = '[';
                                   N = length(mTime);
                                   for k = 1:N
                                       if ~isequal(k,N)
                                            mString = [mString num2str(mTime(k)) ','];
                                       else
                                            mString = [mString num2str(mTime(k)) ']'];
                                       end
                                   end
                                   SetCellList{index,1} = ['s' udigit(app, 2,S) ' c' udigit(app, 3,C) ' t' num2str(sTime) '-' num2str(eTime) ' ' mString];
                               end
                            end
                    end
                else
                    SetCellList = [];
                end
                
        end
        
%-----------------------------------------------------------------------------------------------------------------------------------------     
        function FillInMissingCellsWithEmptyCells(app) 
    
                  
                    nSets = length(app.TrackData);
                    
                    for S = 1:nSets
                            % Found out the most number of cells the current set in the old TrackData format ----------------
                            nC = 1; % assume
                            for T = 1:app.nT
                                try
                                    nCells = length(app.TrackData{S}{T});
                                    if nCells > nC 
                                        nC = nCells;
                                    end
                                end
                            end
                            %------------------------------------------------------------------------------------------------
                            for T = 1:app.nT
                                for C = 1:nC
                                    try % If cell dimension does not exist along the whole time line then add an empty cell
                                        data = app.TrackData{S}{T}{C}; 
                                    catch
                                        app.TrackData{S}{T}{C} = [];
                                    end
                                end
                            end
                            %-------------------------------------------------------------------------------------------------
                    end
        
        end
%-----------------------------------------------------------------------------------------------------------------------------------------     
        function ES = CreateExportDataSummary(app, DataSelection)
            
                    % Create Array where:
                    %   Column 1 = Set Number
                    %   Column 2 = Cell Number
                    %   Column 3 = Start Time
                    %   Column 4 = End Time
            
                    ES = [];
                    nCells = length(DataSelection);
            
                    for N = 1:nCells
                            CellText = DataSelection{N};
                            % Token objects that we can use to pull information out of the string
        %                   t1 = strfind(CellText,'s');
        %                   t2 = strfind(CellText,'c');
                            t3 = strfind(CellText,'t');
                            t4 = strfind(CellText,'-');
                            t5 = strfind(CellText,' ');
        
                            if isequal(length(t5),2)
                                t5(3) = length(CellText)+1;
                            end
        
                            SetNum   = str2double(CellText(2:3));
                            CellNum  = str2double(CellText(6:8));
                            StartT   = str2double(CellText(t3+1:t4-1));
                            EndT     = str2double(CellText(t4+1:t5(3)-1));
        
                            ES(N,1:4) = [SetNum,CellNum,StartT,EndT];                    %#ok<AGROW>
                    end
        end


%-----------------------------------------------------------------------------------------------------------------------------------------           
        function nCells = MaxNumberCells(app, SingleTrackData)
                % Calculates Maximum Number of Cells for a single track Data set
                % {Subfunction of SummarizeTrackData)
            
                    nTime = length(SingleTrackData);
                    nCells = 0;
                    for T = 1:nTime
                        NumCells = length(SingleTrackData{T});
                        if NumCells > nCells
                            nCells = NumCells;
                        end
                    end
        end
        
%-----------------------------------------------------------------------------------------------------------------------------------------
%-----------------------------------------------------------------------------------------------------------------------------------------
%-----------------------------------------------------------------------------------------------------------------------------------------
        function FunHandles = FigureFunctionHandles(app)
                   
                    % function handle for: FigureFunctionHandles |||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||
                    %|||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||              
                    function MainPanelRollOver(src, event, app)
                        
                              update_cross_section_display(app)

                    end
                            
                    % function handle for: FigureFunctionHandles |||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||
                    %|||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||       
                    function MouseWheel(src, event,app)
                                    
                                Z = app.Z;
                                %Zold = app.Zold; % This variable is used to keep this Mouse Wheel function from executing before it has finished with a previous execution.
                                nZ = app.nZ;
                        
                                if isequal(event.VerticalScrollCount,-1) %&& isequal(Zold,Z)

                                            if Z > 1
                                                Z = Z - 1; % Increase Z number by 1
                                                app.Z = Z;
                                                % will catch that this Function hasn't finished executing (by comparing Zold and Z
                                                set(app.z_display,'Value',num2str(Z)) % Update figure header
                                                app.line_break_mask = []; % clear line break mask if there was one
                                            end
                        
                                            load_images(app) % load images
                                            update_display(app) % update figure window
                                            update_cross_section_display(app) % update cross section display
                                            [isdim, notempty] = iscelldim(app, app.TrackData,app.SetNum,app.T,app.CellIndex); % check to see if there is already a selected cell
                        
                                            if notempty && app.AutoSelectWhenChangingZCheck.Value % if there is something selected, use the already obtained XY coords as a selection for a possible unerlying cell in this new Z slice
                                                SelectCell('MouseWheel','normal',app) % SelectCell(app, 'normal',1)
                                            end                                           
                        
                                elseif isequal(event.VerticalScrollCount,1) %&& isequal(Zold,Z)

                                            if Z < nZ
                                                Z = Z + 1;
                                                app.Z = Z;
                                                set(app.z_display,'Value',num2str(Z))
                                                app.line_break_mask = [];
                                            end
                        
                                            load_images(app)
                                            update_display(app)
                                            update_cross_section_display(app)
                        
                                            [~,notempty] = iscelldim(app, app.TrackData,app.SetNum,app.T,app.CellIndex);
                                            if notempty && app.AutoSelectWhenChangingZCheck.Value
                                                SelectCell('MouseWheel','normal',app) % SelectCell(app, 'normal',1)
                                            end
                                end
                    end
                    
                    % function handle for: FigureFunctionHandles |||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||
                    %|||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||      
                    function SelectCell(src, event, app)
                               
                                if ~isequal(src,app.main)
                                            TimePt = app.T;
                                            Z = app.Z;

                                            Ncells    = app.nCells;
                                            CellIndex = app.CellIndex;
                                            SetNum  = app.SetNum;
                                            TrackData = app.TrackData;
                                            [R,C,D]   = size(app.RGB_T);

                                            if isequal(app.main_panel,src)    
                                                mouse_button = get(src,'SelectionType');
                                                position     = get(app.main_panel,'CurrentPoint');
                                                app.position = position;

                                            else 
                                                mouse_button = 'normal';
                                                position     = app.position;
                                                if isempty(position)
                                                    position = get(app.main_panel,'CurrentPoint');
                                                end
                                            end

                                                                        
                                            if position(1) > C || position(2) > R
                                                PositionGood = false;
                                            else
                                                PositionGood = true;
                                            end
                                           
                            
                                            if ~isempty(app.line_break_mask)
                                                LB = uint8(app.line_break_mask);
                                            else
                                                LB = ones(app.ImHeight,app.ImWidth,'uint8');
                                            end

                            
                                            switch mouse_button
                                                case 'normal' % LEFT CLICK Mouse selection ///////////////////////////
                            
                                                            % Rescale Selected Position to Original Scale
                                                            position = [app.ImWidth*position(1)/C   app.ImHeight*position(2)/R];
                                                            % Find region connected to selection point in the original un-resized image
                                                            if PositionGood
                                                                [x,y,BW1,idx,xi,yi] = bwselect(LB.*app.GREEN,position(1,1),app.ImHeight-position(1,2),4);
                                                                [y_area, x_area] = ind2sub([app.ImHeight app.ImWidth],idx); % Create X and Y coordinates of all pixels in the area
                                                                % Clear old data if is exists
                                                                TrackData{SetNum}{TimePt}{CellIndex} = [];
                                                            else
                                                                idx = [];
                                                            end
                            
                                                            % Grab region data
                                                            if ~isempty(idx)
                                                                    % Find perimeter points of selected region
                                                                    try
                                                                        BW2 = bwtraceboundary(BW1,[y_area(1,1) x_area(1,1)],'E');
                                                                    catch
                                                                        BW2 = bwtraceboundary(BW1,[y_area(1,1) x_area(1,1)],'W');
                                                                    end
                            
                                                                    x_cen = mean(x_area);
                                                                    y_cen = mean(y_area);
                                                                    x_perim = BW2(:,2);
                                                                    y_perim = BW2(:,1);
                                                                    RawGreen = app.Timage(:,:,2);
                                                                    MeanPixelVal = mean(RawGreen(idx));
                                                                    Area = bwarea(BW1);
                            
                                                                  % Record in TrackData variable
                            
                                                                    TrackData{SetNum}{TimePt}{CellIndex}.x_centroid     = x_cen;
                                                                    TrackData{SetNum}{TimePt}{CellIndex}.y_centroid     = y_cen;
                                                                    TrackData{SetNum}{TimePt}{CellIndex}.Z              = Z;
                                                                    TrackData{SetNum}{TimePt}{CellIndex}.x_perimeter    = x_perim;
                                                                    TrackData{SetNum}{TimePt}{CellIndex}.y_perimeter    = y_perim;
                                                                    TrackData{SetNum}{TimePt}{CellIndex}.MeanPixelValue = MeanPixelVal;
                                                                    TrackData{SetNum}{TimePt}{CellIndex}.Area           = Area;
                            
                                                                    test = app.SFMain*[x_cen app.ImHeight-y_cen];
                                                            end
                            
                                                            app.TrackData = TrackData;
                            
                                                            if isgraphics(app.CellSummaryWindow)
                                                                DS = SummarizeTrackData(app);
                                                                CellSummaryList = CreateCompleteCellList(app, DS);
                                                                CellSummaryListBoxHandle = findobj(app.CellSummaryWindow.Children,'Tag','Cell_Summary_Display');
                                                                set(CellSummaryListBoxHandle,'Items',CellSummaryList,'ItemsData',1:length(CellSummaryList),'Value',1)
                                                            end
                            
                                                case 'alt' % RIGHT CLICK Mouse selection ////////////////////////////////
                            
                                                            if isequal(Ncells,CellIndex) || CellIndex > Ncells
                                                                app.CellIndex = 1;
                                                                CellIndex = 1;
                                                            elseshortcuts
                                                                app.CellIndex = CellIndex + 1;
                                                                CellIndex = app.CellIndex;
                                                            end
                            
                                                            if app.auto_load_z
                                                                [isdim notempty] = iscelldim(app, TrackData,SetNum,TimePt,CellIndex);
                                                                if isdim && notempty
                                                                    app.Z = TrackData{SetNum}{TimePt}{CellIndex}.Z;
                                                                    set(app.z_display,'Value',num2str(app.Z))
                                                                elseif TimePt > 1 % If we advance the cell number and no cell has been picked yet, load in the z from the
                                                                    % previous time point for that cell number
                                                                    [isdim notempty] = iscelldim(app, TrackData,SetNum,TimePt-1,CellIndex);
                                                                    if isdim && notempty
                                                                        app.Z = TrackData{SetNum}{TimePt-1}{CellIndex}.Z;
                                                                        set(app.z_display,'Value',num2str(app.Z))
                                                                    end
                                                                end
                                                            end
                                            end
                                           
                                            load_images(app)
                                            update_display(app)
                                end
                     end

                    
                    % function handle for: FigureFunctionHandles |||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||
                    %|||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||        
                    function ShortCuts(src, event, app)


                                drawnow nocallbacks % This prevents another Buttondown callback to queue while this one is still executing.
                                                    % This resolved the problem of timepoints skipping too fast while the arrow button is pressed.
                                                    % callbacks are allowed again after "drawnow" is called again at the end of this function.


%                                 set(app.main_panel,'WindowButtonDownFcn',  {app.FigFuncHandles{3,1},app},... % {@SelectCell,app},...
%                                                    'WindowButtonMotionFcn',{app.FigFuncHandles{1,1},app},... % {@MainPanelRollOver,app},...
%                                                    'WindowScrollWheelFcn', {app.FigFuncHandles{2,1},app},... % {@MouseWheel,app},... % Only allow main window to have "cell selection"
%                                                    'KeyPressFcn',          '');   % {@ShortCuts,app}

                               
                                if ~isempty(event) && any(strcmp(fieldnames(event),'Character'))
                                    key = double(lower(event.Character));
                        
                                    if     isequal(key, 97) || isequal(key,65) || strcmp(event.Key,'leftarrow');  key = 28; % Add 'A' and 'a' to Left Arrow shortcut
                                    elseif isequal(key,100) || isequal(key,68) || strcmp(event.Key,'rightarrow'); key = 29; % Add 'D' and 'd' to Right Arrow shortcut
                                    elseif isequal(key,119) || isequal(key,87) || strcmp(event.Key,'uparrow');    key = 30; % Add 'W' and 'w' to Up Arrow shortcut
                                    elseif isequal(key,115) || isequal(key,83) || strcmp(event.Key,'downarrow');  key = 31; % Add 'S' and 's' to Down Arrow shortcut
                                    elseif isequal(key,61); key = 43;   % Add '+' to '=' shortcut
                                    elseif isequal(key,45); key = 95;   % Add '_' to '-' shortcut
                                    elseif strcmp(event.Key,'shift'); key = 1000;
                                    end
                        

                                    if isnumeric(key) && ~isempty(key)
                                        switch key

                                            case 114 % toggle red channel
                                                 v = get(app.turnon_red_channel,'Value');
                                                 v = ~v;
                                                 set(app.turnon_red_channel,'Value',v)
                                                 app.show_r_channel = v;
                                                 update_display(app)
                                                 update_cross_section_display(app)

                                            case 103 % toggle green channel
                                                 v = get(app.turnon_green_channel,'Value');
                                                 v = ~v;
                                                 set(app.turnon_green_channel,'Value',v)
                                                 app.show_g_channel = v;
                                                 update_display(app)
                                                 update_cross_section_display(app)

                                            case 98 % toggle blue channel
                                                 v = get(app.turnon_blue_channel,'Value');
                                                 v = ~v;
                                                 set(app.turnon_blue_channel,'Value',v)
                                                 app.show_b_channel = v;
                                                 update_display(app)
                                                 update_cross_section_display(app)

                                            case 28 %Left Arrow - Decrease T
                                                T = app.T;
                                                if T > 1
                                                    T = T - 1;
                                                    app.T = T;
                                                    set(app.t_display,'Value',num2str(T))
                                                    app.line_break_mask = [];
                                                end
            
                                                if app.auto_load_z
                                                    [isdim, notempty] = iscelldim(app, app.TrackData,app.SetNum,T,app.CellIndex);
                                                    if isdim && notempty
                                                        app.Z = app.TrackData{app.SetNum}{T}{app.CellIndex}.Z;
                                                        set(app.z_display,'Value',num2str(app.Z))
                                                    elseif T > 1
                                                        [isdim, notempty] = iscelldim(app, app.TrackData,app.SetNum,T-1,app.CellIndex);
                                                        if isdim && notempty
                                                            app.Z = app.TrackData{app.SetNum}{T-1}{app.CellIndex}.Z;
                                                            set(app.z_display,'Value',num2str(app.Z))
                                                        end
                                                    end
                                                end
            
                                                load_images(app)
                                                update_display(app)
                                                update_cross_section_display(app)
                                                
                                        case 29 %Right Arrow - Increase T
                                                T = app.T;
                                                nT = app.nT;
                                                if T < nT
                                                    T = T + 1;
                                                    app.T = T;
                                                    set(app.t_display,'Value',num2str(T))
                                                    app.line_break_mask = [];
                                                end
            
                                                if app.auto_load_z
                                                    [isdim, notempty] = iscelldim(app, app.TrackData,app.SetNum,T,app.CellIndex);
                                                    if isdim && notempty
                                                        app.Z = app.TrackData{app.SetNum}{T}{app.CellIndex}.Z;
                                                        set(app.z_display,'Value',num2str(app.Z))
                                                    elseif T > 1
                                                        [isdim, notempty] = iscelldim(app, app.TrackData,app.SetNum,T-1,app.CellIndex);
                                                        if isdim && notempty
                                                            app.Z = app.TrackData{app.SetNum}{T-1}{app.CellIndex}.Z;
                                                            set(app.z_display,'Value',num2str(app.Z))
                                                        end
                                                    end
                                                end
            
                                                load_images(app)
                                                update_display(app)
                                                update_cross_section_display(app)
                                                
                                        case 31 %Up Arrow - Increase Z
                                                Z = app.Z;
                                                nZ = app.nZ;

                                                if Z < nZ
                                                    Z = Z + 1;
                                                    app.Z = Z;
                                                    set(app.z_display,'Value',num2str(Z))
                                                    app.line_break_mask = [];
                                                end
                
                                                load_images(app)
                                                update_display(app)
                                                update_cross_section_display(app)
                
                                                [isdim, notempty] = iscelldim(app, app.TrackData,app.SetNum,app.T,app.CellIndex);
                
                                                if notempty && app.AutoSelectWhenChangingZCheck.Value
                                                    SelectCell('UpArrow', 'normal',app) %SelectCell(app, 'normal',1)
                                                end
                                                %-------------------------------------------
                                                
                                        case 30 % Down Arrow - Decrase Z
                                                Z = app.Z;

                                                if Z > 1
                                                    Z = Z - 1;
                                                    app.Z = Z;
                                                    set(app.z_display,'Value',num2str(Z))
                                                    app.line_break_mask = [];
                                                end
                
                                                load_images(app)
                                                update_display(app)
                                                update_cross_section_display(app)
                
                                                [isdim, notempty] = iscelldim(app, app.TrackData,app.SetNum,app.T,app.CellIndex);

                                                if notempty && app.AutoSelectWhenChangingZCheck.Value
                                                    SelectCell('DownArrow', 'normal',app) % SelectCell(app, 'normal',1)
                                                end
                    
                                                
                                        case 44  % Increase Threshold ">" key
                                                if logical(get(app.turnon_threshold,'Value')) && app.threshold_value < 255
                                                    app.threshold_value = app.threshold_value + 5;
                                                    app.threshold_value(app.threshold_value > 255) = 255;
                                                    set(app.threshold_slider,'Value',app.threshold_value)
                                                    set(app.turnon_threshold,'Text',['Threshold: ' num2str(app.threshold_value)])
                                                end
                                               update_display(app)
                                               update_cross_section_display(app)
                                               
                                        case 46 % Decrease Threshold  "<" key
                                                if logical(get(app.turnon_threshold,'Value')) && app.threshold_value > 0
                                                    app.threshold_value = app.threshold_value - 5;
                                                    app.threshold_value(app.threshold_value < 0) = 0;
                                                    set(app.threshold_slider,'Value',app.threshold_value)
                                                    set(app.turnon_threshold,'Text',['Threshold: ' num2str(app.threshold_value)])
                                                end
                                                update_display(app)
                                                update_cross_section_display(app)
                                                
                                        case 112 % Toggle Show Previous Cell - "P" key
                                                val = logical(get(app.show_previous_check,'Value'));
                                                if val
                                                    set(app.show_previous_check,'Value',0)
                                                    app.show_previous = false;
                                                else
                                                    set(app.show_previous_check,'Value',1)
                                                    app.show_previous = true;
                                                end
                                                update_display(app)
                                                update_cross_section_display(app)
                                                
                                        case 108 % Toggle Show Post Cell - "L" key
                                                val = logical(get(app.show_post_check,'Value'));
                                                if val
                                                    set(app.show_post_check,'Value',0)
                                                    app.show_post = false;
                                                else
                                                    set(app.show_post_check,'Value',1)
                                                    app.show_post = true;
                                                end
                                                update_display(app)
                                                update_cross_section_display(app)
                                                
                                        case 1000 % Initiate Draw Line Function
                                                %drawline_button_Callback(app.drawline_button, [], app)
                                                drawline_button_Callback(app, event)

                                        case 43 % Increase Cell Index
                                                T = app.T;
                                                Z = app.Z;
                                                Ncells = app.nCells;
                                                CellIndex = app.CellIndex;
                                                SetNum  = app.SetNum;
                                                TrackData = app.TrackData;
                    
                                                % Cancel Line mask if it exists
                                                if ~isempty(app.line_break_mask)
                                                    LB = uint8(app.line_break_mask);
                                                else
                                                    LB = ones(app.ImHeight,app.ImWidth,'uint8');
                                                end
                    
                                                % Increase Cell Index ////////////////////////////////
                                                if isequal(Ncells,CellIndex) || CellIndex > Ncells
                                                    app.CellIndex = 1;
                                                    CellIndex = 1;
                                                    app.CellIndexSpinner.Value = app.CellIndex;
                                                else
                                                    app.CellIndex = CellIndex + 1;
                                                    CellIndex = app.CellIndex;
                                                    app.CellIndexSpinner.Value = app.CellIndex;
                                                end
                                                % Apply Auto Load Z option
                                                if app.auto_load_z
                                                    [isdim notempty] = iscelldim(app, TrackData,SetNum,T,CellIndex);
                                                    if isdim && notempty
                                                        app.Z = TrackData{SetNum}{T}{CellIndex}.Z;
                                                        set(app.z_display,'Value',num2str(app.Z))
                                                    elseif T > 1 % If we advance the cell number and no cell has been picked yet, load in the z from the
                                                        % previous time point for that cell number
                                                        [isdim notempty] = iscelldim(app, TrackData,SetNum,T-1,CellIndex);
                                                        if isdim && notempty 
                                                            app.Z = TrackData{SetNum}{T-1}{CellIndex}.Z;
                                                            set(app.z_display,'Value',num2str(app.Z))
                                                        end
                                                    end
                                                end
                    
                                                load_images(app)
                                                update_display(app)
                                                update_cross_section_display(app)
                                                
                                        case 95 % Decrease Cell Index
                                                T = app.T;
                                                Z = app.Z;
                                                Ncells = app.nCells;
                                                CellIndex = app.CellIndex;
                                                SetNum = app.SetNum;
                                                TrackData = app.TrackData;
                    
                                                % Cancel Line mask if it exists
                                                if ~isempty(app.line_break_mask)
                                                    LB = uint8(app.line_break_mask);
                                                else
                                                    LB = ones(app.ImHeight,app.ImWidth,'uint8');
                                                end
                    
                                                % Increase Cell Index ////////////////////////////////
                                                if isequal(1,CellIndex) || CellIndex < 0
                                                    app.CellIndex = Ncells;
                                                    CellIndex = Ncells;
                                                    app.CellIndexSpinner.Value = app.CellIndex;
                                                else
                                                    app.CellIndex = CellIndex - 1;
                                                    CellIndex = app.CellIndex;
                                                    app.CellIndexSpinner.Value = app.CellIndex;
                                                end
                                                % Apply Auto Load Z option
                                                if app.auto_load_z
                                                    [isdim, notempty] = iscelldim(app, TrackData,SetNum,T,CellIndex);
                                                    if isdim && notempty
                                                        app.Z = TrackData{SetNum}{T}{CellIndex}.Z;
                                                        set(app.z_display,'Value',num2str(app.Z))
                                                    elseif T > 1 % If we decrease the cell number and no cell has been picked yet, load in the z from the
                                                        % post time point for that cell number
                                                        [isdim, notempty] = iscelldim(app, TrackData,SetNum,T+1,CellIndex);
                                                        if isdim && notempty
                                                            app.Z = TrackData{SetNum}{T+1}{CellIndex}.Z;
                                                            set(app.z_display,'Value',num2str(app.Z))
                                                        end
                                                    end
                                                end
                    
                                                load_images(app)
                                                update_display(app)
                                                update_cross_section_display(app)

                                            case 99 % "c" key: copy current TXYZ data and add to TXYZ array recorde values
                                                    

                                                XY = get(app.main_panel,'CurrentPoint');
      
                                                RT = app.Recorded_TXYZ_values.Data;
                                                RT.X(app.T,1) = round(XY(1));
                                                RT.Y(app.T,1) = round(XY(2));
                                                RT.Z(app.T,1) = app.Z;
                                                    
                                                app.Recorded_TXYZ_values.Data = RT;

                                                row = app.T;

                                                pos = app.Recorded_TXYZ_values.Position;
                                                parent = app.Recorded_TXYZ_values.Parent;
                                                data = app.Recorded_TXYZ_values.Data;  
                                                app.Recorded_TXYZ_values.Selection = [app.T 1;app.T 2;app.T 3;app.T 4];
                                                app.Recorded_TXYZ_values.ColumnWidth = {'1x','2x','2x','2x'};
                                                scroll(app.Recorded_TXYZ_values,'row',app.T);


                                        end
                                    end
                                end
                                
                                drawnow
%                                 pause(0.2)
% 
%                                 set(app.main_panel,'WindowButtonDownFcn',  {app.FigFuncHandles{3,1},app},... % {@SelectCell,app},...
%                                                    'WindowButtonMotionFcn',{app.FigFuncHandles{1,1},app},... % {@MainPanelRollOver,app},...
%                                                    'WindowScrollWheelFcn', {app.FigFuncHandles{2,1},app},... % {@MouseWheel,app},... % Only allow main window to have "cell selection"
%                                                    'KeyPressFcn',          {app.FigFuncHandles{4,1},app});   % {@ShortCuts,app}
% 
                                set(groot,'CurrentFigure',app.main_panel)
                                shg

                    end
                    
                    
                    % function handle for: FigureFunctionHandles |||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||
                    %|||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||        
                    
                    function close_window(src, event, app)
                        
                            if isequal(app.tp1_z_panel,src)
                                delete(app.tp1_z_panel)
                                app.tp1_z_panel = -1;
                                app.show_tp1 = false;
                                set(app.tp1_figure,'Value',0)
                                set(app.tp1_slider,'Enable','off')
                    
                            elseif isequal(app.tm1_z_panel,src)
                                delete(app.tm1_z_panel)
                                app.tm1_z_panel = -1;
                                app.show_tm1 = false;
                                set(app.tm1_figure,'Value',0)
                                set(app.tm1_slider,'Enable','off')
                    
                            elseif isequal(app.vertical_cs_panel,src)
                                delete(app.vertical_cs_panel)
                                app.vertical_cs_panel = -1;
                                app.show_cross_section = false;
                                set(app.show_V_cross_sections_check,'Value',0)
                                set(app.show_H_cross_sections_check,'Value',0)
                                set(app.cross_section_width_text,  'Enable','off')%,'Value','Cross-Section Widen')
                                set(app.cross_section_width_slider,'Enable','off')
                                if ishandle(app.horizontal_cs_panel)
                                     delete(app.horizontal_cs_panel)
                                     app.horizontal_cs_panel = -1;
                                end
                    
                            elseif isequal(app.horizontal_cs_panel,src)
                                delete(app.horizontal_cs_panel)
                                app.horizontal_cs_panel = -1;
                                app.show_cross_section = false;
                                set(app.show_H_cross_sections_check,'Value',0)
                                set(app.show_V_cross_sections_check,'Value',0)
                                set(app.cross_section_width_text,  'Enable','off')%,'Value','Cross-Section Widen')
                                set(app.cross_section_width_slider,'Enable','off')
                                if ishandle(app.vertical_cs_panel)
                                     delete(app.vertical_cs_panel)
                                     app.vertical_cs_panel = -1;
                                end
                            end
                    end
        
                    % function handle for: FigureFunctionHandles |||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||
                    %|||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||  
                    function DrawLine(src, event, app)
                        
                            H = size(app.RGB_T,1);
                            x1 = app.FirstPoint(1);
                            y1 = app.FirstPoint(2);
                            update_display(app);
                            pos = get(app.main_panel,'CurrentPoint');
                            x2 = pos(1); 
                            y2 = H-pos(2);
                            hold on
                            plot([x1 x2],[y1 y2],'-w')
                            hold off
                    end
                    
                    % function handle for: FigureFunctionHandles |||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||
                    %|||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||        
                    function GrabSecondPoint(src, event, app)
                                    
                            H = size(app.RGB_T,1);
                            T = app.T;
                            Z = app.Z;
                            CellIndex = app.CellIndex;
                            ncells    = app.nCells;
                            SetNum    = app.SetNum;
                        
                            pos = get(app.main_panel,'CurrentPoint');
                            app.SecondPoint = pos;
                        
                            set(app.main_panel,'WindowButtonDownFcn',  {app.FigFuncHandles{3,1},app},...
                                               'WindowButtonMotionFcn',{app.FigFuncHandles{1,1},app},'WindowStyle','Normal')
                    end
                    
                    
                    %-------------------------------------------------------
                    FunHandles{1,1} = @MainPanelRollOver;
                    FunHandles{2,1} = @MouseWheel;
                    FunHandles{3,1} = @SelectCell;
                    FunHandles{4,1} = @ShortCuts;
                    FunHandles{5,1} = @close_window;
                    FunHandles{6,1} = @DrawLine;
                    FunHandles{7,1} = @GrabSecondPoint;
                    %-------------------------------------------------------
                    
        end         % END of FigureFunctionHandles


%-----------------------------------------------------------------------------------------------------------------------------------------           

%-----------------------------------------------------------------------------------------------------------------------------------------           
        function Summary = SummarizeTrackData(app)                    
                                 
                    Summary.nSets = length(app.TrackData);
                    Summary.Data = [];
                    nSets = length(app.TrackData);
                    for S = 1:nSets
                            LogicArraySTC = create_STC_logical_array(app,S);
                            nC = size(LogicArraySTC,2);
                            
                            %SetArray = squeeze(LogicArraySTC(S,:,:))';
                            data.MaxNumCells   =  nC; 
                            data.StartTimes = zeros(nC,1);
                            data.EndTimes   = zeros(nC,1);
                            data.MissingTimePoints = cell(nC,1);
                            for C = 1:nC
                                CellVector = LogicArraySTC(:,C)';
                                if any(CellVector) % Skip if all time points are false
                                    indices = find(CellVector);
                                    data.StartTimes(C,1) = min(indices);
                                    data.EndTimes(C,1)   = max(indices);
                                    data.MissingTimePoints{C,1} = find( ~CellVector(data.StartTimes(C,1):data.EndTimes(C,1)) ) + data.StartTimes(C,1) - 1;
                                else
                                    data.StartTimes(C,1) = 0;
                                    data.EndTimes(C,1) = 0;
                                    data.MissingTimePoints{C,1} = [];
                                end
                            end
                            Summary.Data{S} = data;
                    end

        end
        
%-----------------------------------------------------------------------------------------------------------------------------------------           
        function ImOut = binarize(app, ImIn)
            
                    [r,c] = size(ImIn);
                    blank = ones(r,c,'double');
                    ImOut = uint8(255*imfill(blank.*logical(ImIn),'holes'));
        end

%-----------------------------------------------------------------------------------------------------------------------------------------           
                 
        function crossHair = createCrossHair(app, fig)
                    % Create thin uicontrols with black backgrounds to simulate fullcrosshair pointer.
                    % 1: horizontal left, 2: horizontal right, 3: vertical bottom, 4: vertical top
                    for k = 1:4
                        crossHair(k) = uicontrol(fig, 'Style', 'text', 'Visible', 'off', 'Units', 'pixels', 'BackgroundColor', [1,1,1], 'HandleVisibility', 'off', 'HitTest', 'off'); %#ok<AGROW>
                    end
        end
        
%-----------------------------------------------------------------------------------------------------------------------------------------           
        function LogicArray = create_STC_logical_array(app,S)
            
                   % Create logical Array with dimensions [nT nC]
            
                   nCells = size(app.TrackData{S}{1}, 2) ;
            
                   LogicArray = false(app.nT,nCells);
                   for T = 1:app.nT
                        for C = 1:nCells
                            [isdim, notempty] = iscelldim(app,app.TrackData,S,T,C);
                            if isdim && notempty
                               LogicArray(T,C) = true;
                            end
                        end
                    end
                    
        end


%-----------------------------------------------------------------------------------------------------------------------------------------           
        function create_figure(app, panel)

                    %-------------------------------------------------
                    T = app.T;
                    Z = app.Z;
                    win = get(0,'ScreenSize');
                    height = win(4);
                    sH = height/2;
                    width = win(3);
                    sW = width/2;
                    left = win(1);
                    bottom = win(2);
        
                    blank = zeros(app.ImHeight,app.ImWidth,'uint8');
        
                    %--------------- Create Custom Pointer Symbol -------------------
                    PA = NaN(16);
                    r = 7;
                    shift = r+1;
                    for x = 1:16
                        for y = 1:16
                            R = ((x-shift).^2 + (y-shift).^2).^.5;
                            D = abs(R-r);
                            if D <= .5; PA(x,y) = 2; end
                        end
                    end
                    PA(8,1:15) = 2;
                    PA(1:15,8) = 2;
                    %----------------------------------------------------------------
                    
%                     This is excuted in longtracker_OpeningFcn and assigned to app.FigFuncHandles
%
%                     FunHandles = FigureFunctionHandles(app); %  FunHandles{1,1} = @MainPanelRollOver;  
%                                                              %  FunHandles{2,1} = @MouseWheel;  
%                                                              %  FunHandles{3,1} = @SelectCell;
%                                                              %  FunHandles{4,1} = @ShortCuts;    
%                                                              %  FunHandles{5,1} = @close_window; 
%
        
                    switch panel
                        case 1 % Create main figure window
                             titletext = ['   t = ' num2str(T) '   z = ' num2str(Z)];
                             scale = get(app.main_window_slider,'Value');
                             B = imresize(blank, scale/100);
                             [iH, iW] = size(B);
                             pos = [sW-iW/2 sH-iH/2 iW iH];
                             h = figure('WindowButtonDownFcn',  {app.FigFuncHandles{3,1},app},... % {@SelectCell,app},...
                                        'WindowButtonMotionFcn',{app.FigFuncHandles{1,1},app},... % {@MainPanelRollOver,app},...
                                        'WindowScrollWheelFcn', {app.FigFuncHandles{2,1},app},... % {@MouseWheel,app},... % Only allow main window to have "cell selection"
                                        'KeyPressFcn',{app.FigFuncHandles{4,1},app},'Resize','off','Position',pos,'numbertitle','off','menubar','none',...
                                        'name',titletext,'color',[0 0 0],'Units','pixels','Pointer','custom','PointerShapeCData',PA,'PointerShapeHotSpot',[8,8]);
                             imshow(B)
                             app.main_panel = h;
                             set(gca,'Units','normalized')
                             iptsetpref('ImshowBorder','tight')
                             try
                                removeToolbarExplorationButtons(app.main_panel)
                             end

                             try
                                tb = axtoolbar( findobj(app.main_panel,'Type','axes'), 'Visible','off');
                             end
        
                        case 2 % Create t-1,z figure window
                             titletext = '   t-1, z';
                             scale = get(app.tm1_slider,'Value');
                             B = imresize(blank, scale/100);
                             [iH,iW] = size(B);
                             pos = [sW-150-iW sH-iH/2 iW iH];
                             h = figure('CloseRequestFcn',{app.FigFuncHandles{5,1},app},...
                                        'menubar','none','KeyPressFcn',{app.FigFuncHandles{4,1},app},'Resize','off','Position',pos,'numbertitle','off',...
                                        'name',titletext,'color',[0 0 0],'Units','pixels');
                             imshow(B)
                             app.tm1_z_panel = h;
                             set(gca,'Units','normalized')
                             iptsetpref('ImshowBorder','tight')
                             try
                                removeToolbarExplorationButtons(app.tm1_z_panel)
                             end

                             try
                                tb = axtoolbar( findobj(app.tm1_z_panel,'Type','axes'), 'Visible','off');
                             end
        
                        case 3 % Create t+1,z figure window
                             titletext = '   t+1, z';
                             scale = get(app.tp1_slider,'Value');
                             B = imresize(blank, scale/100);
                             [iH,iW] = size(B);
                             pos = [sW+150 sH-iH/2 iW iH];
                             h = figure('CloseRequestFcn',{app.FigFuncHandles{5,1},app},...
                                        'menubar','none','KeyPressFcn',{app.FigFuncHandles{4,1},app},'Resize','off','Position',pos,'numbertitle','off',...
                                        'name',titletext,'color',[0 0 0],'Units','pixels');
                             imshow(B)
                             app.tp1_z_panel = h;
                             set(gca,'Units','normalized')
                             iptsetpref('ImshowBorder','tight')

                             try
                                removeToolbarExplorationButtons(app.tp1_z_panel)
                             end

                             try
                                tb = axtoolbar( findobj(app.tp1_z_panel,'Type','axes'), 'Visible','off');
                             end
        
                        case 4 % Create Horizontal Cross-Section figure windows
                             if ishandle(app.main_panel)
                                  %set(app.main_panel,'Pointer','fullcross')
                                  p = get(app.main_panel,'Position');
                                  titletext = '';
                                  CSR = app.cross_section_window_width/100;
                                  [H,W,D] = size(app.RGB_T);
                                  nZ = app.nZ;
                                  CSW = round(nZ*CSR);
                                  Bv = zeros(H,CSW,'uint8');
                                  Bh = zeros(CSW,W,'uint8');
        
                                  if app.TB
                                      posh = [p(1) p(2)+p(4)+37 W CSW];
                                  else
                                      posh = [p(1) p(2)-CSW-37 W CSW];
                                  end
        
                                  h2 = figure('CloseRequestFcn',{app.FigFuncHandles{5,1},app},...
                                              'menubar','none','KeyPressFcn',{app.FigFuncHandles{4,1},app},'Resize','off','Position',posh,'numbertitle','off',...
                                              'name','H','color',[0 0 0],'Units','pixels');
                                  
                                  iptsetpref('ImshowBorder','tight')
                                  iptsetpref('ImshowAxesVisible','off')
                                  app.horizontal_cs_panel = h2;
                                  try
                                    removeToolbarExplorationButtons(app.horizontal_cs_panel)
                                  end  
                             end

        
                        case 5 % Create Vertical Cross-Section figure Window
                            if ishandle(app.main_panel)
                                  %set(app.main_panel,'Pointer','fullcross')
                                  p = get(app.main_panel,'Position');
                                  titletext = '';
                                  CSR = app.cross_section_window_width/100;
                                  [H W D] = size(app.RGB_T);
                                  nZ = app.nZ;
                                  CSW = round(nZ*CSR);
                                  Bv = zeros(H,CSW,'uint8');
                                  Bh = zeros(CSW,W,'uint8');
        
                                  if app.LR
                                      posv = [p(1)-CSW-10, p(2), max([120;CSW]), H]; % 120 is the minimum width of a figure
                                  else
                                      posv = [p(1)+p(3)+10, p(2), max([120;CSW]), H];
                                  end
        
                                  h1 = figure('CloseRequestFcn',{app.FigFuncHandles{5,1},app},...
                                              'menubar','none','KeyPressFcn',{app.FigFuncHandles{4,1},app},'Resize','off','Position',posv,'numbertitle','off',...
                                              'name','V','color',[0 0 0],'Units','pixels');
                                  
                                  iptsetpref('ImshowBorder','tight')
                                  iptsetpref('ImshowAxesVisible','off')        
                                  app.vertical_cs_panel = h1;
                                  
                            end
                    end
                    
        end
        
%-----------------------------------------------------------------------------------------------------------------------------------------           
        function DigitIm = digits(app, varargin)
            
                    %
                    %  DigitIm = digits(Number)
                    %  DigitIm = digits(Number,Weight)
                    %  DigitIm = digits(Number,Weight,Size)
                    %
                    %  Number = String example: '130', or '0.345'
                    %  Weight = 'normal' or 'bold' [string]
                    %  Size = 'small' or 'medium' or 'large' [string]
                    %
                    %  Keith Carney 10/05/2009
                    %
            
                    nA = length(varargin);
                    switch nA
                        case 1
                            NUMBER = varargin{1};
                            WEIGHT = 'normal';
                            SIZE = 'small';
                        case 2
                            NUMBER = varargin{1};
                            WEIGHT = varargin{2};
                            SIZE = 'small';
                        case 3
                            NUMBER = varargin{1};
                            WEIGHT = varargin{2};
                            SIZE   = varargin{3};
                    end
            
                    DigitIm = [];
                    L = length(NUMBER);
            
                    for k = 1:L
            
                            A = true;
                            num = double(NUMBER(1,k));
                            B = false(20,12);
                            switch num
                                case 46 % .
                                    B = false(20,5);
                                    B(18,3)      = A;
                                case 58 % :
                                    B = false(20,5);
                                    B(7,3)       = A;
                                    B(14,3)      = A;
                                case 48 % 0
                                    B = false(20,12);
                                    B(5:16,3)    = A;
                                    B(5:16,10)   = A;
                                    B(17,4)      = A;
                                    B(17,9)      = A;
                                    B(4,4)       = A;
                                    B(4,9)       = A;
                                    B(3,5:8)     = A;
                                    B(18,5:8)    = A;
                                case 49 % 1
                                    B = false(20,12);
                                    B(18,6:8)    = A;
                                    B(3:17,7)    = A;
                                    B(4,6)       = A;
                                case 50 % 2
                                    B = false(20,12);
                                    B(18,3:10)   = A;
                                    B(15:17,3)   = A;
                                    B(14,4)      = A;
                                    B(13,5)      = A;
                                    B(12,6)      = A;
                                    B(11,7)      = A;
                                    B(10,8)      = A;
                                    B(9,9)       = A;
                                    B(5:8,10)    = A;
                                    B(4,9)       = A;
                                    B(3,5:8)     = A;
                                    B(4,4)       = A;
                                    B(5:7,3)     = A;
                                case 51 % 3
                                    B = false(20,12);
                                    B(10,7:8)    = A;
                                    B(9,9)       = A;
                                    B(5:8,10)    = A;
                                    B(4,9)       = A;
                                    B(3,5:8)     = A;
                                    B(4,4)       = A;
                                    B(5:6,3)     = A;
                                    B(11,9)      = A;
                                    B(12:16,10)  = A;
                                    B(17,9)      = A;
                                    B(18,5:8)    = A;
                                    B(17,4)      = A;
                                    B(14:16,3)   = A;
                                case 52 % 4
                                    B = false(20,12);
                                    B(3:18,9)    = A;
                                    B(5:10,3)    = A;
                                    B(11,3:10)   = A;
                                case 53 % 5
                                    B = false(20,12);
                                    B(3:11,3)     = A;
                                    B(3,4:10)     = A;
                                    B(10,4)       = A;
                                    B(9,5:8)      = A;
                                    B(10,9)       = A;
                                    B(11:16,10)   = A;
                                    B(17,9)       = A;
                                    B(18,4:8)     = A;
                                    B(17,3)       = A;
                                case 54 % 6
                                    B = false(20,12);
                                    B(5:16,3)     = A;
                                    B(12:16,10)   = A;
                                    B(17,9)       = A;
                                    B(18,5:8)     = A;
                                    B(17,4)       = A;
                                    B(4,4)        = A;
                                    B(3,5:8)      = A;
                                    B(4,9)        = A;
                                    B(5,10)       = A;
                                    B(11,9)       = A;
                                    B(10,5:8)     = A;
                                    B(11,4)       = A;
                                case 55 % 7
                                    B = false(20,12);
                                    B(3,3:10)     = A;
                                    B(4,3)        = A;
                                    B(4:5,10)     = A;
                                    B(6:7,9)      = A;
                                    B(8:9,8)      = A;
                                    B(10:13,7)    = A;
                                    B(14:18,6)    = A;
                                case 56 % 8
                                    B = false(20,12);
                                    B(10,5:8)     = A;
                                    B(9,4)        = A;
                                    B(5:8,3)      = A;
                                    B(9,9)        = A;
                                    B(5:8,10)     = A;
                                    B(4,4)        = A;
                                    B(4,9)        = A;
                                    B(3,5:8)      = A;
                                    B(11,4)       = A;
                                    B(11,9)       = A;
                                    B(12:16,3)    = A;
                                    B(12:16,10)   = A;
                                    B(17,4)       = A;
                                    B(17,9)       = A;
                                    B(18,5:8)     = A;
                                case 57 % 9
                                    B = false(20,12);
                                    B(12:16,10)   = A;
                                    B(17,9)       = A;
                                    B(18,4:8)     = A;
                                    B(17,3)       = A;
                                    B(4,4)        = A;
                                    B(5:9,3)      = A;
                                    B(10,4)       = A;
                                    B(3,5:8)      = A;
                                    B(4,9)        = A;
                                    B(5:16,10)    = A;
                                    B(10,9)       = A;
                                    B(11,5:8)     = A;
                            end
            
                            switch SIZE
                                case 'small'
                                    TempDigitIm = B;
                                case 'medium'
                                    TempDigitIm = imresize(B,2,'nearest');
                                case 'large'
                                    TempDigitIm = imresize(B,4,'nearest');
                            end
            
            
                            if isequal('bold',WEIGHT)
                                TempDigitIm  = bwmorph(TempDigitIm,'dilate');
                            end
            
                            if isempty(DigitIm)
                                DigitIm = TempDigitIm;
                            else
                                DigitIm = [DigitIm,TempDigitIm];
                            end
                    end
        end
        
%-----------------------------------------------------------------------------------------------------------------------------------------           
        function doCancel(app, cancel_btn, evd, listbox)
                            ad.value = 0;
                            ad.selection = [];
                            setappdata(0,'ListDialogAppData__',ad)
                            delete(gcbf);
        end
        
%-----------------------------------------------------------------------------------------------------------------------------------------           
        function doKeypress(app, src, evd, listbox)
                            switch evd.Key
                             case 'escape'
                              doCancel(app, [],[],listbox);
                            end
        end
        
%-----------------------------------------------------------------------------------------------------------------------------------------           
        function doListboxClick(app, listbox, evd, selectall_btn)
                            % if this is a doubleclick, doOK
                            if strcmp(get(gcbf,'SelectionType'),'open')
                                doOK(app, [],[],listbox);
                            elseif nargin == 3
                                if length(get(listbox,'Value'))==length(get(listbox,'value'))
                                    set(selectall_btn,'enable','off')
                                else
                                    set(selectall_btn,'enable','on')
                                end
                            end
        end
        
%-----------------------------------------------------------------------------------------------------------------------------------------           
        function doOK(app, ok_btn, evd, listbox)
                            if (~isappdata(0, 'ListDialogAppData__'))
                                ad.value = 1;
                                ad.selection = get(listbox,'value');
                                setappdata(0,'ListDialogAppData__',ad);
                                delete(gcbf);
                            end
        end
        
%-----------------------------------------------------------------------------------------------------------------------------------------           
        function doSelectAll(app, selectall_btn, evd, listbox)
                            set(selectall_btn,'enable','off')
                            set(listbox,'value',1:length(get(listbox,'Value')));
        end
        
%-----------------------------------------------------------------------------------------------------------------------------------------           
        function [files, folders] = files_and_folders(app, directory)
            
                        %  ______________________________________________
                        %  Grab and sort files and folders from directory
                        %
                        %  [files, folders] = files_and_folders(directory)
                        %
                        %  Keith R Carney  10/30/2008
                        %  www.cellimagingcore.org/www.keithcarney.com
                        %  kcarney@genetics.utah.edu
                        %
            
                        DirInfo = dir(directory); % struct array with fields: name, date, bytes, isdir, datenum
                        DirInfoCell = struct2cell(DirInfo); % ([name date bytes isdir datenum],N)
                        names = DirInfoCell(1,:)'; % Grab Names only
                        designation = DirInfoCell(5,:)'; % TRUE = Folder/Dir, FALSE = File
            
                        % List names of hidden files and folders that are operating system specific (PC and MAC)
                        flag1 =  strcmp('.',names);
                        flag2 =  strcmp('..',names);
                        flag3 =  strcmp('Thumbs.db',names);
                        flag4 =  strcmp('.DS_Store',names);
                        flag5 =  strcmp('._.DS_Dtore',names);
                        ind = all(~[flag1, flag2, flag3, flag4, flag5],2);
            
                        % Use logical Indexing to grab only names and designations that are non-operating system specific
                        FilteredNames = names(ind,1);
                        FilteredDesignation = cell2mat(designation(ind,1)); % Convert this cell array to matrix array for logical indexing below
            
                        % Now sort into File and Folder cell arrays
                        files  = FilteredNames(~FilteredDesignation);
                        folders = FilteredNames(FilteredDesignation);
        end
        
%-----------------------------------------------------------------------------------------------------------------------------------------           
   
        

%-----------------------------------------------------------------------------------------------------------------------------------------           
        function highlight_selected_cells(app)
            
                            TimePt = app.T;
                            CellIndex = app.CellIndex;
                            SetNum  = app.SetNum;
                            TrackData = app.TrackData;
                            SFMain = size(app.RGB_T,1)/size(app.Timage,1);
                            SFTP1  = size(app.RGB_TP1,1)/size(app.TP1image,1);
                            SFTM1  = size(app.RGB_TM1,1)/size(app.TM1image,1);
                            LW = app.line_width; % Specify Line Width for drawing cell boundaries
                            [isdim, notempty] = iscelldim(app, TrackData,SetNum,TimePt,CellIndex); % Check if DIM exists
            
                            if ~app.AutoSelectWhenChangingZCheck.Value && notempty
                                if isequal(TrackData{SetNum}{TimePt}{CellIndex}.Z, app.Z)
                                    ShowSelection = true;
                                else
                                    ShowSelection = false;
                                end
                            else
                                ShowSelection = true;
                            end

                            % Add CURRENT cell selection to Figures --------------------------------------
                            if isdim && notempty && ShowSelection
                                    % Plot Selected cell on Main Figure
                                    set(0,'CurrentFigure',app.main_panel)
                                    hold on
                                        fill(SFMain*(TrackData{SetNum}{TimePt}{CellIndex}.x_perimeter-0.25),...
                                             SFMain*(TrackData{SetNum}{TimePt}{CellIndex}.y_perimeter-0.25),...
                                             app.cell_fill_color)
                                        line(SFMain*(TrackData{SetNum}{TimePt}{CellIndex}.x_perimeter-0.25),...
                                             SFMain*(TrackData{SetNum}{TimePt}{CellIndex}.y_perimeter-0.25),...
                                             'LineWidth',LW,'Color',app.cell_outline_color)
                                    hold off
            
                                    % Plot Selected cell on TP1_z Figure
                                    if ishandle(app.tp1_z_panel)
                                        set(0,'CurrentFigure',app.tp1_z_panel)
                                        hold on
                                            fill(SFTP1*(TrackData{SetNum}{TimePt}{CellIndex}.x_perimeter-0.25),...
                                                 SFTP1*(TrackData{SetNum}{TimePt}{CellIndex}.y_perimeter-0.25),...
                                                 app.cell_fill_color)
                                            line(SFTP1*(TrackData{SetNum}{TimePt}{CellIndex}.x_perimeter-.25),...
                                                 SFTP1*(TrackData{SetNum}{TimePt}{CellIndex}.y_perimeter-.25),...
                                                 'LineWidth',LW,'Color',app.cell_outline_color)
                                        hold off
                                    end
            
                                    % Plot Selected cell on TM1_Z Figure
                                    if ishandle(app.tm1_z_panel)
                                        set(0,'CurrentFigure',app.tm1_z_panel)
                                        hold on
                                            fill(SFTM1*(TrackData{SetNum}{TimePt}{CellIndex}.x_perimeter-0.25),...
                                                 SFTM1*(TrackData{SetNum}{TimePt}{CellIndex}.y_perimeter-0.25),...
                                                 app.cell_fill_color)
                                            line(SFTM1*(TrackData{SetNum}{TimePt}{CellIndex}.x_perimeter-0.25),...
                                                 SFTM1*(TrackData{SetNum}{TimePt}{CellIndex}.y_perimeter-0.25),...
                                                 'LineWidth',LW,'Color',app.cell_outline_color)
                                        hold off
                                    end
            
                            end
            
            
                            % Add Cell Selection of PREVIOUS Time point ------------------------------------------------
                            if app.show_previous
                                for tpr = 1:app.NumPrevious
                                        [isdim notempty] = iscelldim(app, TrackData,SetNum,TimePt-tpr,CellIndex); % Check if DIM exists
                                        if isdim && notempty 
                                                % Plot selected cell on Main Figure
                                                set(0,'CurrentFigure',app.main_panel)
                                                hold on
                                                    line(SFMain*(TrackData{SetNum}{TimePt-tpr}{CellIndex}.x_perimeter-.25),...
                                                         SFMain*(TrackData{SetNum}{TimePt-tpr}{CellIndex}.y_perimeter-.25),...
                                                         'LineWidth',LW,'Color',app.previous_cell_outline_color)
                                                hold off
            
                                                % Plot Selected cell on tp1_z Figure
                                                if ishandle(app.tp1_z_panel)
                                                    set(0,'CurrentFigure',app.tp1_z_panel)
                                                    hold on
                                                        line(SFTP1*(TrackData{SetNum}{TimePt-tpr}{CellIndex}.x_perimeter-.25),...
                                                             SFTP1*TrackData{SetNum}{TimePt-tpr}{CellIndex}.y_perimeter-.25,...
                                                             'LineWidth',LW,'Color',app.previous_cell_outline_color)
                                                    hold off
                                                end
            
                                                % Plot Selected cell on tm1_z Figure
                                                if ishandle(app.tm1_z_panel)
                                                    set(0,'CurrentFigure',app.tm1_z_panel)
                                                    hold on
                                                        line(SFTM1*(TrackData{SetNum}{TimePt-tpr}{CellIndex}.x_perimeter-.25),...
                                                             SFTM1*(TrackData{SetNum}{TimePt-tpr}{CellIndex}.y_perimeter-.25),...
                                                             'LineWidth',LW,'Color',app.previous_cell_outline_color)
                                                    hold off
                                                end
                                        end
                                end
                            end
                            % Add Cell Selection of POST Time point --------------------------------------------------
                            if app.show_post
                                for tpo = 1:app.NumPost
                                    [isdim notempty] = iscelldim(app, TrackData,SetNum,TimePt+tpo,CellIndex); % Check if DIM exists
                                    if isdim && notempty %&& app.show_post
                                            % Plot selected cell on Main Figure
                                            set(0,'CurrentFigure',app.main_panel)
                                            hold on
                                                line(SFMain*(TrackData{SetNum}{TimePt+tpo}{CellIndex}.x_perimeter-.25),...
                                                     SFMain*(TrackData{SetNum}{TimePt+tpo}{CellIndex}.y_perimeter-.25),...
                                                     'LineWidth',LW,'Color',app.post_cell_outline_color)
                                            hold off
            
                                            % Plot Selected cell on tp1_z Figure
                                            if ishandle(app.tp1_z_panel)
                                                set(0,'CurrentFigure',app.tp1_z_panel)
                                                hold on
                                                    line(SFTP1*(TrackData{SetNum}{TimePt+tpo}{CellIndex}.x_perimeter-.25),...
                                                         SFTP1*(TrackData{SetNum}{TimePt+tpo}{CellIndex}.y_perimeter-.25),...
                                                         'LineWidth',LW,'Color',app.post_cell_outline_color)
                                                hold off
                                            end
            
                                            % Plot Selected cell on tm1_z Figure
                                            if ishandle(app.tm1_z_panel)
                                                set(0,'CurrentFigure',app.tm1_z_panel)
                                                hold onosii
                                                    line(SFTM1*(TrackData{SetNum}{TimePt+tpo}{CellIndex}.x_perimeter-.25),...
                                                         SFTM1*(TrackData{SetNum}{TimePt+tpo}{CellIndex}.y_perimeter-.25),...
                                                         'LineWidth',LW,'Color',app.post_cell_outline_color)
                                                hold off
                                            end
                                    end
                                end
                            end
            
                            % Add Selected Cells from summary list -----------------------------------------------------------
                            if ishandle(app.CellSummaryWindow)
                                if app.DisplaySelectedCellsFromList
                                    N = length(app.SelectedSetNumFromList);
                                    for k = 1:N
                                        [isdim2,notempty2] = iscelldim(app, TrackData,app.SelectedSetNumFromList(k),TimePt,app.SelectedCellNumFromList(k));
                                        if isdim2 && notempty2
                                            set(0,'CurrentFigure',app.main_panel)
                                            hold on
                                            line(SFMain*(TrackData{app.SelectedSetNumFromList(k)}{TimePt}{app.SelectedCellNumFromList(k)}.x_perimeter-0.25),...
                                                 SFMain*(TrackData{app.SelectedSetNumFromList(k)}{TimePt}{app.SelectedCellNumFromList(k)}.y_perimeter-0.25),...
                                                 'LineWidth',LW,'Color',app.selected_cell_color)
                                            hold off
                                        end
                                    end
                                end
                            end
        end

        
%-----------------------------------------------------------------------------------------------------------------------------------------           
        function [isdim, notempty] = iscelldim(app, TrackData, SetNum, Time, CellIndex)

                    % Check existence of dimension of the nested cell array TrackData,
                    % and if dimension exists, determine if it's empty
                
                        isdim = false;
                        notempty = false;
                
                        if SetNum > 0 && Time > 0 && CellIndex > 0
                            LTD = length(TrackData);
                            if SetNum <= LTD
                                LT = length(TrackData{SetNum});
                                if Time <= LT
                                    LC = length(TrackData{SetNum}{Time});
                                    if CellIndex <= LC
                                        isdim = true;
                                        if ~isempty(TrackData{SetNum}{Time}{CellIndex})
                                            notempty = true;
                                        end
                                    end
                                end
                            end
                        end

        end
        
%-----------------------------------------------------------------------------------------------------------------------------------------           
        function [m, b] = line_equation(app, x1, x2, y1, y2)
                %  [m,b] = line_equation(x1,x2,y1,y2)
            
                m = (y2-y1)/(x2-x1);
                b = y1 - m* x1;
        end
        
  %-----------------------------------------------------------------------------------------------------------------------------------------         
        function BI = line_mask(app, x1, y1, x2, y2, R, C)
            
                % Make sure slope is not infinite
                if ~isequal(x2-x1,0)
                    % Now figure out which point is the furthest left
                    if x1 < x2
                        X1 = x1;
                        Y1 = y1;
                        X2 = x2;
                        Y2 = y2;
                    else
                        X1 = x2;
                        Y1 = y2;
                        X2 = x1;
                        Y2 = y1;
                    end
            
                    [m,b] = line_equation(app, X1,X2,Y1,Y2); %#ok<NASGU>
                    BI = true(R,C);
                    % First make sure rise and run are both less than 1 so that we're
                    % incrementing in steps smaller or equal to one pixel
                    if abs(m) <= 1
                        rise = m;
                        run = 1;
                    elseif abs(m) > 1
                        rise = m/abs(m);
                        run = 1/abs(m);
                    end
            
            
                    if isequal(rise,0)
                        N = abs(round((X2-X1)/run));
                    else
                        N = abs(round((Y2-Y1)/rise));
                    end
            
                    for k = 1:N
                            r = round(Y1+rise*k);
                            c = round(X1+run*k);
                            BI(r,c) = false;
                    end
                end
        end      

        
%-----------------------------------------------------------------------------------------------------------------------------------------           
        function load_images(app)
                       
                       if isequal(app.nT,1)
                            app.TM1image(:,:,:) = zeros(app.ImHeight,app.ImWidth,3,'uint8');
                            app.Timage(:,:,:)   = uint8(imread( fullfile(app.directory, app.filenames{app.T  ,1}), app.Z) );
                            app.TP1image(:,:,:) = zeros(app.ImHeight,app.ImWidth,3,'uint8');
                            
                       elseif isequal(app.T,1)
                            app.TM1image(:,:,:) = zeros(app.ImHeight,app.ImWidth,3,'uint8');
                            app.Timage(:,:,:)   = uint8(imread( fullfile(app.directory, app.filenames{app.T  ,1}), app.Z) );
                            app.TP1image(:,:,:) = uint8(imread( fullfile(app.directory, app.filenames{app.T+1,1}), app.Z) );

                       elseif isequal(app.T,app.nT)
                            app.TM1image(:,:,:) = uint8(imread( fullfile(app.directory, app.filenames{app.T-1,1}), app.Z) );
                            app.Timage(:,:,:)   = uint8(imread( fullfile(app.directory, app.filenames{app.T  ,1}), app.Z) );
                            app.TP1image(:,:,:) = zeros(app.ImHeight,app.ImWidth,3,'uint8');
                            
                       else
                            app.TM1image(:,:,:) = uint8(imread( fullfile(app.directory, app.filenames{app.T-1,1}), app.Z) );
                            app.Timage(:,:,:)   = uint8(imread( fullfile(app.directory, app.filenames{app.T  ,1}), app.Z) );
                            app.TP1image(:,:,:) = uint8(imread( fullfile(app.directory, app.filenames{app.T+1,1}), app.Z) );
                       end
        end
        
 %-----------------------------------------------------------------------------------------------------------------------------------------          
        function Ys = smooth(app, y, n)
            
            % Ys = smooth(y,n)
            % y = data to be smoothed using a running average forwards and backwards (convolution method)
            % n = the width of the smoothing window (must be odd)
            
            [r c] = size(y);
            if ~isequal(c,1);
                  y = y';
            end
            
            d = n/2 - floor(n/2); % Check to see if n is odd
            
            if ~isequal(d,0)
                  n = abs(n);
                  window = ones(n)./n;
                  Ys = convn(y,window,'same');
            else
                  error('n must be an odd integer')
            end
        end
        
%-----------------------------------------------------------------------------------------------------------------------------------------           
        function ImOut = threshold(app, Tval, ImIn)
            
                if isa(ImIn,'uint8')
                    BW = im2bw(ImIn, Tval/255);
                    ImOut = ImIn.*uint8(BW);
                else
                    BW = im2bw(ImIn, Tval/255);
                    ImOut = ImIn.*BW;
                end
        end
        
 %-----------------------------------------------------------------------------------------------------------------------------------------          
        function TP = udigit(app, N, number)
            
                    % TP = udigit(N, number);
                    % This function returns a text string that has the desired number of digits
            
                    NUM =num2str(number);
                    k = length(NUM);
                    D = N-k;
            
                    if D <= 0;
                        TP = NUM;
                    else
                        zt = char(48*ones(1,D));
                        TP = [zt NUM];
                    end
        end
        
 
 %-----------------------------------------------------------------------------------------------------------------------------------------          
        function update_cross_section_display(app)
            
                     % Update large cross hairs on Main Display first (large crosshair option for Pointer was discontinuted after release R14b)
                     if ~isempty(app.RGB_T) && isgraphics(app.main_panel)
                         PL = get(app.main_panel,'CurrentPoint');
                         [H,W,~] = size(app.RGB_T);
                 
                         %if app.show_V_cross_section || app.show_H_cross_section
                         if isgraphics(app.vertical_cs_panel) || isgraphics(app.horizontal_cs_panel)
                                   p = get(app.main_panel,'Position');
                                   m = fspecial('average',3);
                                   Z = app.Z;
                                   nZ = app.nZ;
                                   col = 1 + floor(app.ImWidth*PL(1,1)/(W+1));
                                   row = 1 + floor(app.ImHeight - app.ImHeight*PL(1,2)/(H+1));
                                   CSR = app.cross_section_window_width/100;
                                   zresize = max([120;round(CSR*nZ)]); % 120 is minimum width of figure window                               
                
                                   [mslope,bintercept] = line_equation(app,0.5,nZ+0.5,0,zresize);
                                   za = mslope*Z + bintercept;

                                   % |||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||
                                   % Left/Right Side View ||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||

                                   if app.show_V_cross_sections_check.Value  % app.show_V_cross_section
                                           for z = 1:nZ
                                                Lfname = fullfile( app.directory, app.filenames{app.T,1} );
                                                if col > app.ImWidth || col < 1 % Just in case col is not within the right range.
                                                    col = app.ImWidth;
                                                end
                                                ZsliceL(:,:,:,z) = squeeze(imread(Lfname,z,'PixelRegion',{[1,app.ImHeight],[col,col]})); %#ok<AGROW>
                                           end
                                           sliceL = shiftdim(squeeze(ZsliceL),2);
        
                                           %----------- IF ADVANCED RGB display is selected -----------------------
                                           if app.sa
                                                   if app.show_r_channel
                                                       rL  = squeeze((sliceL(:,:,1))');
                                                       rL2 = ind2rgb(rL,app.r_color);
                                                   else
                                                       rL2 = zeros(app.ImHeight,nZ,3,'double');
                                                   end
                                                   if app.show_g_channel
                                                       %gL = imadjust( ApplyFunctions(app, squeeze((sliceL(:,:,2))')) );
                                                       gL =  ApplyFunctions(app, squeeze((sliceL(:,:,2))'));
                                                            % % Apply Threshold Function ---------------------------------
                                                            % if app.doThreshold
                                                            %     gL = threshold(app, app.threshold_value,gL);
                                                            % end
                                                            % % Apply Binarize Function ----------------------------------
                                                            % if app.doBinarize
                                                            %   gL = uint8(binarize(app, gL));
                                                            % end
                                                       gL2 = ind2rgb(gL,app.g_color);
                                                   else
                                                       gL2 = zeros(app.ImHeight,nZ,3,'double');
                                                   end
                                                   if app.show_b_channel
                                                       bL = squeeze((sliceL(:,:,3))');
                                                       bL2 = ind2rgb(bL,app.b_color);
                                                   else
                                                       bL2 = zeros(app.ImHeight,nZ,3,'double');
                                                   end
                                                   rgbL = imresize(imlincomb(1,rL2,1,gL2,1,bL2),[H,zresize],'nearest');
                                                   rgbL = imfilter(rgbL,m,'replicate');
                    
                                           else % If SIMPLE RGB display is selected
                                                   if app.show_r_channel
                                                       rL = squeeze((sliceL(:,:,1))');
                                                   else
                                                       rL = zeros(app.ImHeight,nZ,'uint8');
                                                   end
                                                   if app.show_g_channel
                                                       %gL = imadjust(ApplyFunctions(app, squeeze((sliceL(:,:,2))')));
                                                       gL = ApplyFunctions(app, squeeze((sliceL(:,:,2))'));
%                                                        % Apply Threshold Function ---------------------------------
%                                                        if app.doThreshold
%                                                            gL = threshold(app, app.threshold_value,gL);
%                                                        end
%                                                        % Apply Binarize Function ----------------------------------
%                                                        if app.doBinarize
%                                                          gL = uint8(binarize(app, gL));
%                                                        end
                                                   else
                                                       gL = zeros(app.ImHeight,nZ,'uint8');
                                                   end
                                                   if app.show_b_channel
                                                       bL = squeeze((sliceL(:,:,3))');
                                                   else
                                                       bL = zeros(app.ImHeight,nZ,'uint8');
                                                   end
                                                   rgbL = imresize(cat(3,rL,gL,bL),[H,zresize],'nearest');
                                                   rgbL = imfilter(rgbL,m,'replicate');
                                           end
                    
                    
                                           if isgraphics(app.vertical_cs_panel)
                                               if app.do_magnet % If magnet function is selected then put figures in the right location
                                                   if app.LR 
                                                       set(0,'CurrentFigure',app.vertical_cs_panel)
                                                       posv = [p(1)-zresize-16, p(2), max([120;zresize]), H];
                                                       set(app.vertical_cs_panel,'Position',posv)
                                                   else
                                                       set(0,'CurrentFigure',app.vertical_cs_panel)
                                                       drawnow
                                                       posv = [p(1)+p(3)+16, p(2), max([120;zresize]), H];
                                                       set(app.vertical_cs_panel,'Position',posv)
                                                   end
                                               else
                                                   set(0,'CurrentFigure',app.vertical_cs_panel)
                                               end
                                           else
                                               app.vertical_cs_panel = -1;
                                           end
                                           set(gca,'Units','normalized','Position',[0,0,1,1])
                                           imshow(rgbL,'Border','tight','InitialMagnification',100)
                                           
                                           hold on; % Add z-line to cross-section view
                                                 plot([1 zresize],[H+1-PL(1,2), H+1-PL(1,2)],'-w');
                                                 line([za za],[1 H],'Color',app.z_color)
                                                 plot(za,H+1-PL(1,2),'.r','MarkerSize',10);
                                           hold off
                                   end

                                   % |||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||
                                   % Top/Bottom Side View ||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||

                                   if app.show_H_cross_sections_check.Value % app.show_H_cross_section
                                           for z = 1:nZ
                                                Bfname = fullfile( app.directory, app.filenames{app.T,1} );
                                                if row > app.ImHeight || row < 1 % Just in case row is not within the right range
                                                    row = app.ImHeight;
                                                end
                                                ZsliceB(:,:,:,z) = squeeze(imread(Bfname,z,'PixelRegion',{[row,row],[1,app.ImWidth]})); %#ok<AGROW>
                                           end
                                           sliceB = shiftdim(squeeze(ZsliceB),2);
                    
                                           if app.sa % IF ADVANCED RGB display is selected -------------
                                                   if app.show_r_channel
                                                       rB = squeeze((sliceB(:,:,1)));
                                                       rB2 = ind2rgb(rB,app.r_color);
                                                   else
                                                       rB2 = zeros(nZ,app.ImWidth,3,'double');
                                                   end
                                                   if app.show_g_channel
                                                       gB = ApplyFunctions(app, squeeze((sliceB(:,:,2)))) ;
%                                                             % Apply Threshold Function ---------------------------------
%                                                             if app.doThreshold
%                                                                 gB = threshold(app, app.threshold_value,gB);
%                                                             end
%                                                             % Apply Binarize Function ----------------------------------
%                                                             if app.doBinarize
%                                                                 gB = uint8(binarize(app, gB));
%                                                             end
                                                       gB2 = ind2rgb(gB,app.g_color);
                                                   else
                                                       gB2 = zeros(nZ,app.ImWidth,3,'double');
                                                   end
                                                   if app.show_b_channel
                                                       bB = squeeze((sliceB(:,:,3)));
                                                       bB2 = ind2rgb(bB,app.b_color);
                                                   else
                                                       bB2 = zeros(nZ,app.ImWidth,3,'double');
                                                   end
                                                   rgbB = imresize(imlincomb(1,rB2,1,gB2,1,bB2),[zresize W],'nearest');
                                                   rgbB = imfilter(rgbB,m,'replicate');
                    
                                           else  % If SIMPLE RGB display is selected
                                                   if app.show_r_channel
                                                       rB = squeeze((sliceB(:,:,1)));
                                                   else
                                                       rB = zeros(nZ,app.ImWidth,'uint8');
                                                   end
                                                   if app.show_g_channel
                                                       gB = ApplyFunctions(app, squeeze((sliceB(:,:,2))));
%                                                             % Apply Threshold Function ---------------------------------
%                                                             if app.doThreshold
%                                                                 gB = threshold(app, app.threshold_value,gB);
%                                                             end
%                                                             % Apply Binarize Function ----------------------------------
%                                                             if app.doBinarize
%                                                                 gB = uint8(binarize(app, gB));
%                                                             end
                                                   else
                                                       gB = zeros(nZ,app.ImWidth,'uint8');
                                                   end
                                                   if app.show_b_channel
                                                       bB = squeeze((sliceB(:,:,3)));
                                                   else
                                                       bB = zeros(nZ,app.ImWidth,'uint8');
                                                   end
                                                   rgbB = imresize(cat(3,rB,gB,bB),[zresize W],'nearest');
                                                   rgbB = imfilter(rgbB,m,'replicate');
                                           end
        
                                           % Align cross section views with main window ---------------- 
                                           if isgraphics(app.horizontal_cs_panel)
                                               if app.do_magnet   % If magnet function is selected then put figures in the right location
                                                    if app.TB
                                                        set(0,'CurrentFigure',app.horizontal_cs_panel)
                                                        posh = [p(1), p(2)+p(4)+38, W, zresize];
                                                        set(app.horizontal_cs_panel,'Position',posh)
                                                    else
                                                        set(0,'CurrentFigure',app.horizontal_cs_panel)
                                                        posh = [p(1), p(2)-zresize-38, W, zresize];
                                                        set(app.horizontal_cs_panel,'Position',posh)
                                                    end
                                               else
                                                    set(0,'CurrentFigure',app.horizontal_cs_panel)
                                               end
                                           else
                                               app.horizontal_cs_panel = -1;
                                           end
        
                                           set(gca,'Units','normalized','Position',[0,0,1,1])
                                           imshow(rgbB,'Border','tight','InitialMagnification',100)
                                           hold on;
                                                 plot([PL(1,1),PL(1,1)],[1,zresize],'-w');
                                                 line([1,W],[za,za],'Color',app.z_color)
                                                 plot(PL(1,1),za,'.r','MarkerSize',10);
                                           hold off
                                   end
                                  % disp( [get(app.main_panel,'CurrentPoint'),Z])
                          end
                     end
                     
                     
        end % Function end
        
 %-----------------------------------------------------------------------------------------------------------------------------------------          
        function update_display(app)
            
                %------------------------------------------------------------------------------
                % Don't try to add code to make the side cross section panels come
                % to the front without having to click on them. It causes all kinds of problems
                %------------------------------------------------------------------------------
            
                    T = app.T;
                    Z = app.Z;
                    CellIndex = app.CellIndex;
            
                    if app.sa % IF ADVANCED RGB display is selected ||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||
                            % Create RED Channel Image and apply Functions
                            if ~app.show_r_channel % if red channel is turned off create empty channel
                                   app.red_T   = zeros(app.ImHeight,app.ImWidth,3,'uint8');
                                   app.red_TM1 = zeros(app.ImHeight,app.ImWidth,3,'uint8');
                                   app.red_TP1 = zeros(app.ImHeight,app.ImWidth,3,'uint8');
                            else
                                   app.red_T   = uint8(255*ind2rgb(app.Timage(:,:,1),  app.r_color));
                                   app.red_TM1 = uint8(255*ind2rgb(app.TM1image(:,:,1),app.r_color));
                                   app.red_TP1 = uint8(255*ind2rgb(app.TP1image(:,:,1),app.r_color));
                            end
            
                            % Create GREEN Channel Image and apply Functions
                            if ~app.show_g_channel % If green channel is turned off create empty channel
                                   app.green_T = zeros(app.ImHeight,app.ImWidth,3,'uint8');
                                   app.green_TM1 = zeros(app.ImHeight,app.ImWidth,3,'uint8');
                                   app.green_TP1 = zeros(app.ImHeight,app.ImWidth,3,'uint8');
                            else
                                   % Create Green Channel images for T
                                   if ~isempty(app.line_break_mask) % Apply Line Break Function if it's turned on
                                       LB = imadjust(uint8(app.line_break_mask));
                                       app.GREEN = LB.*ApplyFunctions(app, app.Timage(:,:,2)); % This image is used with SELECT CELL
                                       app.green_T = uint8(255*ind2rgb(app.GREEN,app.g_color)); % This one is for display purposes
                                   else
                                       app.GREEN = ApplyFunctions(app, app.Timage(:,:,2)); % This image is used with SELECT CELL
                                       app.green_T = uint8(255*ind2rgb(app.GREEN,app.g_color));
                                   end
                                   
                                   % Create Green Channel images for T-1
                                   if app.show_tm1 && app.ApplyAll
                                       app.green_TM1 = ind2rgb( ApplyFunctions(app, app.TM1image(:,:,2)) ,app.g_color) ;
                                   elseif app.show_tm1
                                       app.green_TM1 = ind2rgb(app.TM1image(:,:,2),app.g_color);
                                   else
                                       app.green_TM1 = zeros(app.ImHeight,app.ImWidth,3,'uint8');
                                   end
                                   
                                   % Create Green Channel images for T+1
                                   if app.show_tp1 && app.ApplyAll
                                       app.green_TP1 = ind2rgb( ApplyFunctions(app,app.TP1image(:,:,2)) ,app.g_color) ;
                                   elseif app.show_tp1
                                       app.green_TP1 = ind2rgb(app.TP1image(:,:,2),app.g_color) ;
                                   else
                                       app.green_TP1 = zeros(app.ImHeight,app.ImWidth,3,'uint8');
                                   end
                            end
                            % Create BLUE Channel Image and apply Functions
                            if ~app.show_b_channel % If blue channel is turned off create empty channel
                                   app.blue_T   = zeros(app.ImHeight,app.ImWidth,3,'uint8');
                                   app.blue_TM1 = zeros(app.ImHeight,app.ImWidth,3,'uint8');
                                   app.blue_TP1 = zeros(app.ImHeight,app.ImWidth,3,'uint8');
                            else
                                   app.blue_T   = uint8(255*ind2rgb(app.Timage  (:,:,3),app.b_color));
                                   app.blue_TM1 = uint8(255*ind2rgb(app.TM1image(:,:,3),app.b_color));
                                   app.blue_TP1 = uint8(255*ind2rgb(app.TP1image(:,:,3),app.b_color));
                            end
                            %--------------------------------------------------------------------------------------------------------------------------------------
                            % Combine Red Green and Blue channels and create RGB images, and resize as well.
                            app.RGB_T   = imresize( imlincomb(1,app.red_T,  1,app.green_T,  1,app.blue_T),  app.main_window_size/100, 'nearest');
                            app.RGB_TM1 = imresize( imlincomb(1,app.red_TM1,1,app.green_TM1,1,app.blue_TM1),app.tm1_z_window_size/100,'nearest');
                            app.RGB_TP1 = imresize( imlincomb(1,app.red_TP1,1,app.green_TP1,1,app.blue_TP1),app.tp1_z_window_size/100,'nearest');

                    else  % Otherwise do SIMPLE RGB display |||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||
                            % Create RED Channel Image and apply Functions
                            if ~app.show_r_channel % if red channel is turned off create empty channel
                                   app.red_T   = zeros(app.ImHeight,app.ImWidth,1,'uint8');
                                   app.red_TM1 = zeros(app.ImHeight,app.ImWidth,1,'uint8');
                                   app.red_TP1 = zeros(app.ImHeight,app.ImWidth,1,'uint8');
                            else
                                   app.red_T   = app.Timage  (:,:,1);
                                   app.red_TM1 = app.TM1image(:,:,1);
                                   app.red_TP1 = app.TP1image(:,:,1);
                            end
                            % Create GREEN Channel Image and apply Functions
                            if ~app.show_g_channel % If green channel is turned off create empty channel
                                   app.green_T   = zeros(app.ImHeight,app.ImWidth,1,'uint8');
                                   app.green_TM1 = zeros(app.ImHeight,app.ImWidth,1,'uint8');
                                   app.green_TP1 = zeros(app.ImHeight,app.ImWidth,1,'uint8');
                            else
                                   % Create Green Channel images for T   -------------------------------------------------
                                   if ~isempty(app.line_break_mask)
                                       LB = uint8(app.line_break_mask);
                                       app.green_T = LB.*ApplyFunctions(app, app.Timage(:,:,2));
                                       app.GREEN   = app.green_T;
                                   else
                                       app.green_T = ApplyFunctions(app, app.Timage(:,:,2));
                                       app.GREEN   = app.green_T;
                                   end
                                   
                                   % Create Green Channel images for T-1 -------------------------------------------------
                                   if app.show_tm1 && app.ApplyAll
                                        app.green_TM1 = ApplyFunctions(app, app.TM1image(:,:,2));
                                   elseif app.show_tm1
                                        app.green_TM1 = app.TM1image(:,:,2);
                                   else
                                        app.green_TM1 = zeros(app.ImHeight,app.ImWidth,1,'uint8');
                                   end
                                   
                                   % Create Green Channel images for T+1 -------------------------------------------------
                                   if app.show_tp1 && app.ApplyAll
                                        app.green_TP1 = ApplyFunctions(app, app.TP1image(:,:,2)) ;
                                   elseif app.show_tp1
                                        app.green_TP1 = app.TP1image(:,:,2);
                                   else
                                        app.green_TP1 = zeros(app.ImHeight,app.ImWidth,1,'uint8');
                                   end
                            end
            
                            % Create BLUE Channel Image and apply Functions
                            if ~app.show_b_channel % If blue channel is turned off create empty channel
                                   app.blue_T   = zeros(app.ImHeight,app.ImWidth,1,'uint8');
                                   app.blue_TM1 = zeros(app.ImHeight,app.ImWidth,1,'uint8');
                                   app.blue_TP1 = zeros(app.ImHeight,app.ImWidth,1,'uint8');
                            else
                                   app.blue_T   = app.Timage  (:,:,3);
                                   app.blue_TM1 = app.TM1image(:,:,3);
                                   app.blue_TP1 = app.TP1image(:,:,3);
                            end
            
                            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                            app.RGB_T   = imresize( cat(3,app.red_T,  app.green_T,  app.blue_T)  ,app.main_window_size /100,'nearest');
                            app.RGB_TM1 = imresize( cat(3,app.red_TM1,app.green_TM1,app.blue_TM1),app.tm1_z_window_size/100,'nearest');
                            app.RGB_TP1 = imresize( cat(3,app.red_TP1,app.green_TP1,app.blue_TP1),app.tp1_z_window_size/100,'nearest');
                    end
            
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % update scale factors
                    [RM,CM,DM] = size(app.RGB_T);
                    app.SFMain = RM/app.ImHeight;
                    S = groot;
                    SH = S.ScreenSize(4);

                    % Update main window figure --------------------------
                    if ~isgraphics(app.main_panel)
                            create_figure(app,1);
                            set(0,'CurrentFigure',app.main_panel)    
                            [H,W,~] = size(app.RGB_T);
                            pos = get(app.main_panel,'Position'); % 'Position',[pos(1,1:2) W H],
                            set(app.main_panel,'name',['   t = ',num2str(T),'   z = ',num2str(Z),...
                                               '        set: ',num2str(app.SetNum),'     cell: ',...
                                                        num2str(CellIndex),'  of  ',num2str(app.nCells)])
                            
                            set(gca,'Units','normalized','Position',[0,0,1,1])
    
                            % UPDATE IMAGE IN MAIN PANEL the first time using "imshow" to set proper axis/figure visual properties.
                            imshow(app.RGB_T,'Border','tight','InitialMagnification',100)
                            set(app.main_panel,'Position',[pos(1,1:2),W,H]) % set(app.main_panel,'Position',[pos(1,1),SH-(H+100),W,H])

                    else
                            set(0,'CurrentFigure',app.main_panel)                        
                            [H,W,~] = size(app.RGB_T); % 'Position',[pos(1,1:2) W H],
                            set(app.main_panel,'name',['   t = ',num2str(T),'   z = ',num2str(Z),...
                                               '        set: ',num2str(app.SetNum),'     cell: ',...
                                                         num2str(CellIndex),'  of  ',num2str(app.nCells)])
                            PrePos = get(app.main_panel,'Position');
                            SideDist = PrePos(1);
                            HeightDist = SH-(PrePos(2)+PrePos(4));
                            
                            %set(gca,'Units','normalized','Position',[0,0,1,1])
        
                            % UPDATE IMAGE IN MAIN PANEL
                            % Remove old graphics and update CData with new image ---------------------------------------
                            delete( [findobj(app.main_panel,'Type','Patch');  findobj(app.main_panel,'Type','Line')] )   
                            ImHandle = findobj(app.main_panel,'Type','Image');
                            [PH,PW,~] = size(ImHandle.CData); % Previous image size
                            [CH,CW,~] = size(app.RGB_T);      % Current image size
                            %disp([PH,PW,CH,CW])
                            if PH ~= CH
                                pos = get(app.main_panel,'Position'); 
                                imshow(app.RGB_T,'Border','tight','InitialMagnification',100)
                                set(app.main_panel,'Position',[pos(1,1:2),CW,CH]) 
                            else
                                ImHandle.CData = app.RGB_T;
                                pos = get(app.main_panel,'Position');
                                set(app.main_panel,'Position',[pos(1,1:2),CW,CH])
                                %set(app.main_panel,'Position',[pos(1,1:2),CM,RM])
                            end
                    end
            
                    % Update Full CrossHairs ----------------------------------------------
            %         PL = get(app.main_panel,'CurrentPoint');
            %         hold on
            %         plot([1 W],[H+1 - PL(1,2) H+1 - PL(1,2)],'-w')
            %         plot([PL(1,1) PL(1,1)],[1 H],'-w');
            %         hold off
                    %-----------------------------------------------------------------------
            
                    % Update t+1,z figure --------------------------------
                    if app.show_tp1
                        if ~isgraphics(app.tp1_z_panel)
                            create_figure(app,3);
                            set(0,'CurrentFigure',app.tp1_z_panel)

                            imshow(app.RGB_TP1)
                        else
                            set(0,'CurrentFigure',app.tp1_z_panel)
                            pos = get(app.tp1_z_panel,'Position');
                            [H,W,~] = size(app.RGB_TP1);

                            delete( [findobj(app.tp1_z_panel,'Type','Patch');  findobj(app.tp1_z_panel,'Type','Line')] )   
                            ImHandle = findobj(app.tp1_z_panel,'Type','Image');
                            ImHandle.CData = app.RGB_TP1;

                            set(app.tp1_z_panel,'Position',[pos(1,1:2),W,H])                            
                        end
                    end
            
                    % Update t-1,z figure --------------------------------
                    if app.show_tm1
                        if ~isgraphics(app.tm1_z_panel)
                            create_figure(app,2);
                            set(0,'CurrentFigure',app.tm1_z_panel)
                            imshow(app.RGB_TM1)
                        else
                            set(0,'CurrentFigure',app.tm1_z_panel)
                            pos = get(app.tm1_z_panel,'Position');
                            [H,W,~] = size(app.RGB_TM1);

                            delete( [findobj(app.tm1_z_panel,'Type','Patch');  findobj(app.tm1_z_panel,'Type','Line')] )   
                            ImHandle = findobj(app.tm1_z_panel,'Type','Image');
                            ImHandle.CData = app.RGB_TM1;

                            set(app.tm1_z_panel,'Position',[pos(1,1:2),W,H])
                        end
                    end
                    if app.show_g_channel
                        highlight_selected_cells(app)
                    end
                    drawnow


                    try
                        tb = axtoolbar( findobj(app.main_panel,'Type','axes'), 'Visible','off');
                    end

        end
        
        
%-----------------------------------------------------------------------------------------------------------------------------------------           
        function update_full_cross_on_main(app)
            
                    PL = get(app.main_panel,'CurrentPoint');
                    [H,W,D] = size(app.RGB_T);
                    set(0,'CurrentFigure',app.main_panel)
                    imshow(app.RGB_T)
                    hold on
                    plot([1 W],[H+1 - PL(1,2) H+1 - PL(1,2)],'-w')
                    plot([PL(1,1) PL(1,1)],[1 H],'-w');
                    hold off
        end


%-----------------------------------------------------------------------------------------------------------------------------------------         
%-----------------------------------------------------------------------------------------------------------------------------------------  
    end   % this is the end for the line:  methods (Access = private)
    
    
%|||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||
    methods (Access = public)


    end
    
%|||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function longtracker_OpeningFcn(app, varargin)
        
                  %------------------------------------------------------------------

                  app.FigFuncHandles = FigureFunctionHandles(app);

                  LongTrackerWindowPosition = get(app.main,'Position');
                  S = groot;
                  ScreenHeight = S.ScreenSize(4);
                  AppHeight = 675;
                  StartPosition = [200,ScreenHeight-(AppHeight+200),LongTrackerWindowPosition(3:4)];
                  set(app.main,'Position',StartPosition,'Name','LongTracker',...
                               'WindowButtonDownFcn',  {app.FigFuncHandles{3,1},app},...
                               'WindowScrollWheelFcn', {app.FigFuncHandles{2,1},app}); %,... 
                              % 'KeyPressFcn',{app.FigFuncHandles{4,1},app})


                  app.TabGroup.SelectedTab = app.TabGroup.Children(2); % Make "Data I/O" the selected tab when the app opens
                  %%%%%%%%%%%
                  set(app.nZ_display,'Visible','off')
                  %%%%%%%%%%%
                  set(app.turnon_threshold,'Value',0,'Text','Threshold')
                  set(app.threshold_slider,'Limits',[0,255],'Value',100,'Enable','off')
                  set(app.turnon_binarize,'Value',0)
                  set(app.turnon_erode,'Value',0,'Text','Erode')
                  set(app.erode_slider,'Limits',[1,5],'Value',1,'Enable','off')
                  set(app.turnon_hbreak,'Value',0)
                  %%%%%%%%%%%
                  set(app.show_previous_check,'Value',0)
                  set(app.show_previous_display,'Value','1')
                  set(app.show_post_check,'Value',0)
                  set(app.show_post_display,'Value','1')
                  set(app.cell_fill_display,'BackgroundColor',app.cell_fill_color)
                  set(app.prev_cell_color_display,'BackgroundColor',app.previous_cell_outline_color)
                  set(app.cell_outline_color_display,'BackgroundColor',app.cell_outline_color)
                  set(app.post_cell_color_display,'BackgroundColor',app.post_cell_outline_color)
                  set(app.show_V_cross_sections_check,'Value',0)
                  set(app.show_H_cross_sections_check,'Value',0)
                  set(app.cross_section_width_text,'Enable','off')
                  set(app.cross_section_width_slider,'Limits',[50,1000],'Value',150,'Enable','off')
                  set(app.LR_placement,'Value',1,'Enable','off')
                  set(app.TB_placement,'Value',1,'Enable','off')
                  set(app.ncells_display,'Value','1')
                  set(app.set_num_display,'Value','1')
                  %%%%%%%%%%%
                  set(app.t_display,'Value','1')
                  set(app.z_display,'Value','1')
                  set(app.main_window_slider,'Limits',[50,300],'Value',100,'Enable','on')
                  set(app.auto_load_z_check,'Value',1)
                  set(app.AutoSelectWhenChangingZCheck,'Value',0)
                  %%%%%%%%%%%
                  set(app.apply_to_all,'Value',0)
                  set(app.tp1_figure,'Value',0)
                  set(app.tm1_figure,'Value',0)
                  set(app.tp1_slider,'Limits',[50,200],'Value',100,'Enable','off')
                  set(app.tm1_slider,'Limits',[50,200],'Value',100,'Enable','off')
                  %%%%%%%%%%%
                  set(app.sa_rgb_check,'Value',0)
                  set(app.turnon_red_channel,'Value',1)
                  set(app.turnon_green_channel,'Value',1)
                  set(app.turnon_blue_channel,'Value',0)
                  set(app.r_channel_color_button,'Enable','off')
                  set(app.g_channel_color_button,'Enable','off')
                  set(app.b_channel_color_button,'Enable','off')
                  set(app.turnon_magnet_function,'Value',1,'Text','Magnet ON')
                  
                  set(app.red_color_display,  'BackgroundColor',[1 0 0])%,'ButtonDownFcn',@app.ShortCuts,'Visible','off')
                  set(app.green_color_display,'BackgroundColor',[0 1 0])%,'ButtonDownFcn',@app.ShortCuts,'Visible','off')
                  set(app.blue_color_display, 'BackgroundColor',[0 0 1])%,'ButtonDownFcn',@app.ShortCuts,'Visible','off')
              
                  set(app.line_width_listbox,'Items',compose('%0.1f',(1:0.5:4)'),'ItemsData',(1:7),'Value',1)
                  %%%%%%%%%%%

                  set(app.microns_per_pixel_display,'Value','-1')
                  set(app.z_section_step_size_display,'Value','-1')
                  set(app.time_interval_display,'Value','-1')

                  app.SetNum = 1;
                  app.nCells = 1;
                  app.CellIndex = 1;
                  app.T = 1;
                  app.Tprev = 1;
                  app.Z = 1;


                  ColumnNames = {'T','X','Y','Z'};                  
                  RT = table(0,0,0,0,'VariableNames',ColumnNames);                
                  app.Recorded_TXYZ_values.Data = RT;
                  app.Recorded_TXYZ_values.ColumnWidth = {'1x','2x','2x','2x'};
                  app.Recorded_TXYZ_values.ColumnEditable = [false,true,true,true];
                  

                  % Intialize track data variable ==========================
                  for T = 1:app.nT
                        app.TrackData{app.SetNum}{T} = cell(1);
                  end

            
                  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                  %%%%%%% INITIALIZE Start-up view, and variables %%%%%%%%%%%%%%%%%%%%
                      
                       set(app.functions,'Visible','off')
                       set(app.tracking_panel,'Visible','off')
                       set(app.time_and_z_panel,'Visible','off')
                       set(app.other_figures_panel,'Visible','off')
                       set(app.rgb_panel,'Visible','off')
                       set(app.cross_section_display_panel,'Visible','off')
                       set(app.cell_color_panel,'Visible','off')
                       set(app.reset_button,'Enable','off')
                       set(app.save_button,'Enable','off')
                       set(app.saveas_button,'Enable','off')
                       %set(app.cell_data_editor,'Enable','off')
                       set(app.export_button,'Enable','off')
            
                       set(app.microns_per_pixel_display,'Enable','off')
                       set(app.microns_per_pixel_text,'Enable','off')
                       set(app.z_section_step_size_text,'Enable','off')
                       set(app.z_section_step_size_display,'Enable','off')
                       set(app.time_interval_display,'Enable','off')
                       set(app.time_interval_text,'Enable','off')
                       set(app.cell_summary_button,'Enable','off')
            
                  %%%%%%% Reset/Initialize Variables %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


        end

        % Value changed function: LR_placement
        function LR_placement_Callback(app, event)
           
                app.LR = logical(get(app.LR_placement,'Value'));
                update_cross_section_display(app)
        end

        % Value changed function: TB_placement
        function TB_placement_Callback(app, event)
          
                 app.TB = logical(get(app.TB_placement,'Value'));
                 update_cross_section_display(app)
                 
        end

        % Button pushed function: about_longtracker_button
        function about_longtracker_button_Callback(app, event)
                      
            
                      default_color = [0.8 0.8 0.8];
            
                      message = [{' Longtracker (2010)'};...
                                 {'            '};...
                                 {'         Programming and Design:'};...
                                 {'            '};...
                                 {'                  Keith R. Carney'};...
                                 {'                  Software Engineer,'};...
                                 {'                  University of Utah'};
                                 {'            '};...
                                 {'         Requirement Analysis & Function Design:'};...
                                 {'            '};...
                                 {'                  Hideo Otsuna, Ph.D.'};...
                                 {'                  Department of Human Genetics, '};...
                                 {'                  University of Utah'};...
                                 {'                  2008-2015     '};...
                                 {'            '};...
                                 {'         Special Thanks to:'};...
                                 {'            '};...
                                 {'                  Chi-Bin Chien, Ph.D.'};...
                                 {'                  Department Neurobiology and Anatomy,'};...
                                 {'                  University of Utah,'};... 
                                 {'                  1998-2011    '};...
                                 {'                    '};...
                                 {'                  Christopher Rodesch, Ph.D.'};...
                                 {'                  Fluorescence Microscopy Core Facility,'}; ...
                                 {'                  University of Utah,'};... 
                                 {'                  2000-2019    '};...
                                 {'           '};...
                                 {'         Contact:        '}; ...
                                 {'           '};...
                                 {'                  Kristen M. Kwan, Ph.D. '};...
                                 {'                  kmkwan@genetics.utah.edu'};...
                                 {'                  Department of Human Genetics, '};...
                                 {'                  University of Utah'};...
                                 {'               '};...
                                 {'               '};...
                                 {'         Fluorescence Microscopy Core Facility,'};...
                                 {'         University of Utah School of Medicine,'};...
                                 {'         Department of Core Facilities.'};...
                                 {'           '};...
                                 {'           '}];
            
                     f = figure('NumberTitle','off','Name','About Longtracker','Position',[200,200,520,620],...
                          'Resize','off','Toolbar','none','WindowStyle','normal','MenuBar','none');
            
                     uicontrol('Parent',f,'Style','text','FontName','FixedWidth','FontWeight','bold',...
                          'FontSize',10,'String',message,'Position',[15,15,500,590],'HorizontalAlignment','Left');

                     
        end

        % Value changed function: apply_to_all
        function apply_to_all_Callback(app, event)
                      
                      app.ApplyAll = logical(get(app.apply_to_all,'Value'));
                      update_display(app)
            
        end

        % Value changed function: auto_load_z_check
        function auto_load_z_check_Callback(app, event)
            
                app.auto_load_z = logical(get(app.auto_load_z_check,'Value'));
                update_display(app)
                
        end

        % Button pushed function: b_channel_color_button
        function b_channel_color_button_Callback(app, event)
            
                 c = uisetcolor;
                 if ~isequal(c,0)
                      v = (0:(1/255):1)';
                      app.b_color = v*c;
                      set(app.blue_color_display,'BackgroundColor',c)
                      update_display(app)
                      update_cross_section_display(app)
                 end
                 
        end

        % Button pushed function: cell_fill_color_button
        function cell_fill_color_button_Callback(app, event)
           
                     c = uisetcolor;
                     if ~isequal(c,0)
                          app.cell_fill_color = c;
                          set(app.cell_fill_display,'BackgroundColor',c)
                          update_display(app)
                     end
                 
        end

        % Button pushed function: cell_outline_color_button
        function cell_outline_color_button_Callback(app, event)
           
                 c = uisetcolor;
                 if ~isequal(c,0)
                      app.cell_outline_color = c;
                      set(app.cell_outline_color_display,'BackgroundColor',c)
                      update_display(app)
                 end
                 
        end

        % Button pushed function: cell_summary_button
        function cell_summary_button_Callback(app, event)
           
                    drawnow nocallbacks

                    if ~isgraphics(app.CellSummaryWindow)
                        DS = SummarizeTrackData(app);
                        SetCellList = CreateCompleteCellList(app, DS);

                        h1 = CellSummaryDisplay(app, SetCellList);

                        app.CellSummaryWindow = h1.CellSummaryMainFigure;
                        app.SelectedCellNumFromList = [];
                        app.SelectedSetNumFromList = [];
                        app.DisplaySelectedCellsFromList = false;
                    end
                    
                    drawnow

        end

        % Value changed function: cross_section_width_slider
        function cross_section_width_slider_Callback(app, event)
                        
                      v = get(app.cross_section_width_slider,'Value');
                      v = 10*round(v/10); % Round size to nearest 10 percent
                      app.cross_section_window_width = v;
                      set(app.cross_section_width_slider,'Value',v)
                      set(app.cross_section_width_text,'Text',['CS Width: ' num2str(v) '%'])
                      update_cross_section_display(app)
                  
        end

        % Button pushed function: drawline_button
        function drawline_button_Callback(app, event)
                        
                 try
            
                        H = size(app.RGB_T,1);
                        set(0,'CurrentFigure',app.main_panel)
                        set(app.main_panel,'WindowButtonDownFcn','',...% Temporarily turn off ButtonDownFunction for Main Figure
                                           'WindowButtonMotionFcn','',... % Temporairily turn off roll-over cross-section updates for Main Figure
                                           'WindowStyle','Modal',...
                                           'name','   Click first point then click second point')
                
                        %[x1,y1] = ginput2(app, 1);
                        [x1,y1] = ginput_lgtkr(1);
                        app.FirstPoint = [x1,y1];
                        set(app.main_panel,'WindowButtonMotionFcn',{app.FigFuncHandles{6,1},app},...   % @app.DrawLine,...
                                           'WindowButtonDownFcn',  {app.FigFuncHandles{7,1},app})      % @app.GrabSecondPoint)
                
                        waitfor(app.main_panel,'WindowButtonMotionFcn',{app.FigFuncHandles{1,1},app})
                                    
                        BI = line_mask(app, app.FirstPoint(1)/app.SFMain,...
                                       app.FirstPoint(2)/app.SFMain,...
                                       app.SecondPoint(1)/app.SFMain,...
                                  (H - app.SecondPoint(2))/app.SFMain,...
                                       app.ImHeight,app.ImWidth);
                
                        if ~isempty(app.line_break_mask)
                            app.line_break_mask = BI.*app.line_break_mask; % Add new lines to previous lines
                        else
                            app.line_break_mask = BI;
                        end
                
                        % Turn Figure functions back on and reset figure Title
                        set(app.main_panel,'WindowButtonDownFcn',  {app.FigFuncHandles{3,1},app},...
                                           'WindowScrollWheelFcn', {app.FigFuncHandles{2,1},app},...
                                           'WindowButtonMotionFcn',{app.FigFuncHandles{1,1},app},'WindowStyle','Normal')
                
                        [isdim, notempty] = iscelldim(app, app.TrackData,app.SetNum,app.T,app.CellIndex);
                
                        if notempty
                            feval(app.FigFuncHandles{3,1},'normal',1,app)
                            %SelectCell('normal',1,app)
                        else
                            update_display(app)
                            update_cross_section_display(app)
                        end

                catch
                    set(app.main_panel,'WindowButtonDownFcn',  {app.FigFuncHandles{3,1},app},...
                                       'WindowScrollWheelFcn', {app.FigFuncHandles{2,1},app},...
                                       'WindowButtonMotionFcn',{app.FigFuncHandles{1,1},app},'WindowStyle','Normal')
                end
            
                update_display(app)
                update_cross_section_display(app)

                try
                    tb = axtoolbar( findobj(app.main_panel,'Type','axes'), 'Visible','off');
                end
                
        end

        % Button pushed function: erase_line_breaks
        function erase_line_breaks_Callback(app, event)
                       
                app.line_break_mask = [];
                update_display(app)
                update_cross_section_display(app)
                [isdim, notempty] = iscelldim(app, app.TrackData,app.SetNum,app.T,app.CellIndex);
                if notempty
                    feval(app.FigFuncHandles{3,1},'EraseLine','normal',app)
                    %SelectCell('EraseLine', 'normal',app)
                else
                    update_display(app)
                end
                
        end

        % Value changed function: erode_slider
        function erode_slider_Callback(app, event)
                
                 v = round(get(app.erode_slider,'Value'));
                 set(app.erode_slider,'Value',v)
                 app.nErode = v;
                 app.SE = strel('disk',v);
                 set(app.turnon_erode,'Text',['Erode: ', num2str(v)])
                 update_display(app);
                 if isfield(app,'position')
                     feval(app.FigFuncHandles{3,1},'normal',event,app) 
                     %SelectCell('ErodeSlider', 'normal',app)
                 end
                 update_display(app)
                 update_cross_section_display(app)

                 
        end

        % Button pushed function: export_button
        function export_button_Callback(app, event)
           
            
            if ~isempty(app.TrackData)
                        TrackData = app.TrackData;
                        DS = SummarizeTrackData(app);
                        % [DataSelection, ColorSelection, ExportFormat] = SelectExportFormat(app,DS);
                        SEFhandle = SelectExportFormat(app,DS);
                        uiwait(SEFhandle.SEFmain)
                            DataSelection  = app.SEFoutput{1}; 
                            ColorSelection = app.SEFoutput{2};
                            ExportFormat   = app.SEFoutput{3};
                        app.microns_per_pixel = str2double(get(app.microns_per_pixel_display,'Value'));
                        app.z_step_size = str2double(get(app.z_section_step_size_display,'Value'));
                        app.time_interval = str2double(get(app.time_interval_display,'Value'));
            
                        if ~isequal(DataSelection,-1) && ~isequal(ColorSelection,-1) && ~isequal(ExportFormat,-1)
            
                                    switch ExportFormat
                                        case 1 % nMultitiff = nT ------------------------------------------------------------------------------------------------
                                                ES = CreateExportDataSummary(app, DataSelection);
                                                ImHeight = app.ImHeight;
                                                ImWidth = app.ImWidth;
                                                nZ = app.nZ;
                                                [FileName,PathName,FI] = uiputfile('*.tif','Save Location for Cell Track Exporting','CellTracks');  %#ok<NASGU>
            
                                                if ~isequal(FileName,0)
                                                            TrackData = app.TrackData;
                                                            Ntracks  = length(TrackData);
                                                            MinStartTime = min(ES(:,3));
                                                            MaxEndTime = max(ES(:,4));
                                                            f = waitbar(0,'Wait...'); %progressbar(app, 0)
                                                            Ttotal = MaxEndTime - MinStartTime + 1;
                                                            Tindex = 0;
                                                            nCells = size(ES,1);
                                                            for TimePt = MinStartTime:MaxEndTime
                                                                    ZStack = zeros(ImHeight,ImWidth,3,nZ,'uint8'); % Intialize blank RGB multitif stack (nZ)
                                                                    for N = 1:nCells
                                                                            S = ES(N,1);
                                                                            cell = ES(N,2);
                                                                            cmap = uint8(255*ColorSelection(N,:));
                                                                            [isdim, notempty] = iscelldim(app,TrackData,S,TimePt,cell);
                                                                            if isdim && notempty
                                                                                    %cmap = uint8(255*ColorSelection(cIndex,:)); %v*SetColors(cIndex,:);
                                                                                    TempImj = zeros(ImHeight,ImWidth,'uint8');
                                                                                    z = TrackData{S}{TimePt}{cell}.Z; % Grab the Z number
                                                                                    
                                                                                    Xperim = TrackData{S}{TimePt}{cell}.x_perimeter; % Grab X Perimeter coords
                                                                                    Yperim = TrackData{S}{TimePt}{cell}.y_perimeter; % Grab Y Perimeter coords
                                                                                    linind = sub2ind([ImHeight,ImWidth],Yperim,Xperim);
                                                                                    TempImj(linind) = uint8(255); % Mark cell outline in blank grayscale image
                                                                                    TempImj         = imfill(TempImj,'holes'); % fill in perimeter so that we now have a solid mask for the cell
                                                                                    [row,col]       = find(TempImj); % Grab new row and column indices for solid cell mask
                                                                                  
                                                                                    % Convert solid cell mask row and col indices into linear indices of 4-D array ZStack for each RGB color channel
                                                                                    Rind = sub2ind( size(ZStack), row, col, 1*ones(length(row),1), z*ones(length(row),1) );
                                                                                    Gind = sub2ind( size(ZStack), row, col, 2*ones(length(row),1), z*ones(length(row),1) );
                                                                                    Bind = sub2ind( size(ZStack), row, col, 3*ones(length(row),1), z*ones(length(row),1) );

                                                                                    % disp(num2str([S,cell]))
                                                                                    % disp(['   Z: ',num2str(z)])

                                                                                    ZStack(Rind) = cmap(1); % Assign red color to red channel
                                                                                    ZStack(Gind) = cmap(2); % Assign green color to green channel
                                                                                    ZStack(Bind) = cmap(3); % Assign blue color to blue channel
                                                                            end
                                                                    end

                                                                    
                                                                    % Now Create RGB Multitif for this time Point
                                                                    for zslice = 1:nZ
                                                                        Zimage = squeeze(ZStack(:,:,:,zslice));
                                                                        if zslice == 1
                                                                            imwrite(Zimage,fullfile(PathName,[FileName(1:end-4),'_T',sprintf('%05d',TimePt),'.tif']),'Compression','none','WriteMode','overwrite','RowsPerStrip',size(Zimage,1));
                                                                        else
                                                                            imwrite(Zimage,fullfile(PathName,[FileName(1:end-4),'_T',sprintf('%05d',TimePt),'.tif']),'Compression','none','WriteMode','append','RowsPerStrip',size(Zimage,1));
                                                                        
                                                                        end
                                                                    end
                                                                    Tindex = Tindex + 1;
                                                                    waitbar(Tindex/Ttotal,f,['Progress = ',sprintf('%0.1f',100*Tindex/Ttotal),'%']); % progressbar(app, Tindex/Ttotal)
                                                            end
                                                            
                                                            if logical(exist('f'))
                                                                if isgraphics(f)
                                                                    close(f)
                                                                end
                                                            end
                                                            % Write Cell Number and RGB value text file
                                                            % create a matrix y, with two rows
            
                                                            y = [ES 255.*ColorSelection]';
            
                                                            % open a file for writing
                                                            fid = fopen([PathName FileName(1:end-4) 'Cell_and_RGB_info.txt'], 'w');
            
                                                            % print a title, followed by a blank line
                                                            fprintf(fid, 'SetNum\t CellNum\t StartTime\t EndTime\t R\t G\t B\t \n\n');
            
                                                            % print values in column order
                                                            % two values appear on each row of the file
                                                            fprintf(fid, '%u\t %u\t %u\t %u\t %u\t %u\t %u\n',y);
                                                            fclose(fid);
                                                            
                                                end
                                        case 2 % nMultitiff = nSets ---------------------------------------------------------------------------------------------
                                                ES = CreateExportDataSummary(app, DataSelection);
                                                ImHeight = app.ImHeight;
                                                ImWidth  = app.ImWidth;
                                                nZ = app.nZ;
                                                [FileName,PathName,FI] = uiputfile('*.tif','Save Location for Cell Track Exporting','CellTracks');
            
                                                if ~isequal(FileName,0) % Check to make sure user selected a SAVE Name and Path
                                                        SetNums = unique(ES(:,1))'; % Find the Set/Track numbers without dupication
                                                        f = waitbar(0,'Wait...'); %progressbar(app, 0)
                                                        SetCount = 0;
                                                        SetTotal = length(SetNums);
                                                        answer = questdlg('Would you like to apply an image closing?','Image closing?','Yes','Skip','Skip');
                                                        if strcmp(answer,'Yes')
                                                            opts.Interpreter = 'tex'; 
                                                            answer2 = inputdlg('\fontsize{10}Specify size of structuring element (default = 3).     \color{red}\bfSize must be non-zero!','Structuring element size',[1,70],{'3'},opts);
                                                        end

                                                        for S = SetNums
                                                                SetIndices = find(ES(:,1) == S);
                                                                ZStack = zeros(ImHeight,ImWidth,3,nZ,'uint8'); % Intialize blank RGB multitif stack (nZ)
                                                                MinStartTime = min(ES(SetIndices,3));
                                                                MaxEndTime   = max(ES(SetIndices,4));
                                                                CellNumbers  = ES(SetIndices,2)';
                                                                SetColors = ColorSelection(SetIndices(1):SetIndices(end),:);
                                                                for TimePt = MinStartTime:MaxEndTime
                                                                        cIndex = 0;
                                                                        for cell = CellNumbers
                                                                                cIndex = cIndex + 1;
                                                                                [isdim, notempty] = iscelldim(app, TrackData,S,TimePt,cell);
                                                                                if isdim && notempty
                                                                                    cmap = uint8(255*SetColors(cIndex,:)); %v*SetColors(cIndex,:);
                                                                                    TempImj = zeros(ImHeight,ImWidth,'uint8');
                                                                                    z = TrackData{S}{TimePt}{cell}.Z; % Grab the Z number
                                                                                    
                                                                                    Xperim = TrackData{S}{TimePt}{cell}.x_perimeter; % Grab X Perimeter coords
                                                                                    Yperim = TrackData{S}{TimePt}{cell}.y_perimeter; % Grab Y Perimeter coords
                                                                                    linind = sub2ind([ImHeight,ImWidth],Yperim,Xperim);
                                                                                    TempImj(linind) = uint8(255); % Mark cell outline in blank grayscale image
                                                                                    TempImj   = imfill(TempImj,'holes'); % fill in perimeter so that we now have a solid mask for the cell
                                                                                    [row,col] = find(TempImj); % Grab new row and column indices for solid cell mask
                                                                                    
                                                                                    % Convert solid cell mask row and col indices into linear indices of 4-D array ZStack for each RGB color channel
                                                                                    Rind = sub2ind( size(ZStack), row, col, 1*ones(length(row),1), z*ones(length(row),1) );
                                                                                    Gind = sub2ind( size(ZStack), row, col, 2*ones(length(row),1), z*ones(length(row),1) );
                                                                                    Bind = sub2ind( size(ZStack), row, col, 3*ones(length(row),1), z*ones(length(row),1) );
                                                                                    
                                                                                    ZStack(Rind) = cmap(1); % Assign red color to red channel
                                                                                    ZStack(Gind) = cmap(2); % Assign green color to green channel
                                                                                    ZStack(Bind) = cmap(3); % Assign blue color to blue channel
                                                                                end
                                                                        end
                                                                end
                                                                

                                                                % ASK USER TO APPLY IMAGE CLOSING IF ONE CELL IS EXPORTED ||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||
                                                                nCellsExported = size(ES,1);

                                                                % Ask user if they'd like to apply if only one cell is being exported  
                                                                if strcmp(answer,'Yes')
                                                                    if isempty(answer2)
                                                                        SEsize = 3;
                                                                    else
                                                                        SEsize = str2double(answer2{1});
                                                                        if isempty(SEsize)
                                                                            SEsize = 3;
                                                                        end
                                                                    end    
                                                                    % Apply image/volume CLOSING to close z-spaces between timepoints --------------------------
                                                                    SE1 = strel("sphere",SEsize);
                                                                    ZStackR = imclose( squeeze(ZStack(:,:,1,:)),SE1);
                                                                    ZStackG = imclose( squeeze(ZStack(:,:,2,:)),SE1);
                                                                    ZStackB = imclose( squeeze(ZStack(:,:,3,:)),SE1);
    
                                                                    ZStack(:,:,1,:) = ZStackR;
                                                                    ZStack(:,:,2,:) = ZStackG;
                                                                    ZStack(:,:,3,:) = ZStackB;
                                                                    %--------------------------------------------------------------------------------------------
                                                                end

                                                                %|||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||
                                                                % Now Create RGB Multitif for this Track
                                                                for zslice = 1:nZ
                                                                    Zimage = squeeze(ZStack(:,:,:,zslice));
                                                                    if zslice == 1
                                                                        imwrite(Zimage,fullfile(PathName,[FileName(1:end-4),'_Set',sprintf('%04d',S),'.tif']),'Compression','none','WriteMode','overwrite','RowsPerStrip',size(Zimage,1))
                                                                    else
                                                                        imwrite(Zimage,fullfile(PathName,[FileName(1:end-4),'_Set',sprintf('%04d',S),'.tif']),'Compression','none','WriteMode','append','RowsPerStrip',size(Zimage,1))
                                                                    end
                                                                end
                                                                SetCount = SetCount + 1;
                                                                waitbar(SetCount/SetTotal,f,['Progress = ',sprintf('%0.1f',100*SetCount/SetTotal),'%']); %progressbar(app, SetCount/SetTotal)
                                                        end
                                                        
                                                        if logical(exist('f'))
                                                            if isgraphics(f)
                                                                close(f)
                                                            end
                                                        end

                                                        % Write Cell Number and RGB value text file
                                                        % create a matrix y, with two rows
                                                        y = [ES 255.*ColorSelection]';
                                                        % open a file for writing
                                                        fid = fopen([PathName FileName(1:end-4) 'Cell_and_RGB_info.txt'], 'w');
                                                        % print a title, followed by a blank line
                                                        fprintf(fid, 'SetNum\t CellNum\t StartTime\t EndTime\t R\t G\t B\t \n\n');
                                                        % print values in column order two values appear on each row of the file
                                                        fprintf(fid, '%u\t %u\t %u\t %u\t %u\t %u\t %u\n',y);
                                                        fclose(fid);
                                                end
                                        case 3 % nMultitiff = 1 -------------------------------------------------------------------------------------------------
                                                ES = CreateExportDataSummary(app, DataSelection);
                                                ImHeight = app.ImHeight;
                                                ImWidth = app.ImWidth;
                                                nZ = app.nZ;
                                                [FileName,PathName,FI] = uiputfile('*.tif','Save Location for Cell Track Exporting','CellTracks');
            
                                                if ~isequal(FileName,0)
                                                    nCells = size(ES,1);
                                                    f = waitbar(0,'Wait...'); % progressbar(app, 0)
                                                    ZStack = zeros(ImHeight,ImWidth,3,nZ,'uint8'); % Intialize blank RGB multitif stack
            
                                                    for CellIndex = 1:nCells
                                                            SetNum = ES(CellIndex,1);
                                                            CellNum = ES(CellIndex,2);
                                                            CellStartTime = ES(CellIndex,3);
                                                            CellEndTime   = ES(CellIndex,4);
                                                            for TimePt = CellStartTime:CellEndTime
                                                                    [isdim, notempty] = iscelldim(app, TrackData,SetNum,TimePt,CellNum);
                                                                    if isdim && notempty
                                                                            cmap = uint8(255*ColorSelection(CellIndex,:)); %v*SetColors(cIndex,:);
                                                                            TempImj = zeros(ImHeight,ImWidth,'uint8');
                                                                            z = TrackData{SetNum}{TimePt}{CellNum}.Z; % Grab the Z number
                                                                            
                                                                            Xperim = TrackData{SetNum}{TimePt}{CellNum}.x_perimeter; % Grab X Perimeter coords
                                                                            Yperim = TrackData{SetNum}{TimePt}{CellNum}.y_perimeter; % Grab Y Perimeter coords
                                                                            linind = sub2ind([ImHeight,ImWidth],Yperim,Xperim);
                                                                            TempImj(linind) = uint8(255); % Mark cell outline in blank grayscale image
                                                                            TempImj   = imfill(TempImj,'holes'); % fill in perimeter so that we now have a solid mask for the cell
                                                                            [row,col] = find(TempImj); % Grab new row and column indices for solid cell mask
                                                                            
                                                                            % Convert solid cell mask row and col indices into linear indices of 4-D array ZStack for each RGB color channel
                                                                            Rind = sub2ind( size(ZStack), row, col, 1*ones(length(row),1), z*ones(length(row),1) );
                                                                            Gind = sub2ind( size(ZStack), row, col, 2*ones(length(row),1), z*ones(length(row),1) );
                                                                            Bind = sub2ind( size(ZStack), row, col, 3*ones(length(row),1), z*ones(length(row),1) );
                                                                            
                                                                            ZStack(Rind) = cmap(1); % Assign red color to red channel
                                                                            ZStack(Gind) = cmap(2); % Assign green color to green channel
                                                                            ZStack(Bind) = cmap(3); % Assign blue color to blue channel
                                                                    end
                                                            end
                                                            waitbar(CellIndex/nCells,f,['Progress = ',sprintf('%0.1f',100*CellIndex/nCells),'%']); 
                                                            %progressbar(app, CellIndex/nCells)
                                                    end
                                                    
                                                    if logical(exist('f'))
                                                        if isgraphics(f)
                                                            close(f)
                                                        end
                                                    end


                                                    answer = questdlg('Would you like to apply an image closing?','Image closing?','Yes','Skip','Skip');
                                                    if strcmp(answer,'Yes')
                                                        opts.Interpreter = 'tex'; 
                                                        answer2 = inputdlg('\fontsize{10}Specify size of structuring element (default = 3).     \color{red}\bfSize must be non-zero!','Structuring element size',[1,70],{'3'},opts);
                                                        if isempty(answer2)
                                                            SEsize = 3;
                                                        else
                                                            SEsize = str2double(answer2{1});
                                                            if isempty(SEsize)
                                                                SEsize = 3;
                                                            end
                                                        end    
                                                        % Apply image/volume CLOSING to close z-spaces between timepoints --------------------------
                                                        SE1 = strel("sphere",SEsize);
                                                        ZStackR = imclose( squeeze(ZStack(:,:,1,:)),SE1);
                                                        ZStackG = imclose( squeeze(ZStack(:,:,2,:)),SE1);
                                                        ZStackB = imclose( squeeze(ZStack(:,:,3,:)),SE1);

                                                        ZStack(:,:,1,:) = ZStackR;
                                                        ZStack(:,:,2,:) = ZStackG;
                                                        ZStack(:,:,3,:) = ZStackB;
                                                        %--------------------------------------------------------------------------------------------
                                                    end

                                                    
                                                    % Now Create RGB Multitif for this Track
                                                    for zslice = 1:nZ
                                                        Zimage = squeeze(ZStack(:,:,:,zslice));
                                                        if zslice==1 
                                                            imwrite(Zimage,fullfile(PathName,[FileName(1:end-4) '.tif']),'Compression','none','WriteMode','overwrite','RowsPerStrip',size(Zimage,1))
                                                        else
                                                            imwrite(Zimage,fullfile(PathName,[FileName(1:end-4) '.tif']),'Compression','none','WriteMode','append','RowsPerStrip',size(Zimage,1))
                                                        end
                                                    end
                                                    % Write Cell Number and RGB value text file
                                                    % create a matrix y, with two rows
                                                    y = [ES 255.*ColorSelection]';
                                                    % open a file for writing
                                                    fid = fopen([PathName FileName(1:end-4) 'Cell_and_RGB_info.txt'], 'w');
                                                    % print a title, followed by a blank line
                                                    fprintf(fid, 'SetNum\t CellNum\t StartTime\t EndTime\t R\t G\t B\t \n\n');
                                                    % print values in column order two values appear on each row of the file
                                                    fprintf(fid, '%u\t %u\t %u\t %u\t %u\t %u\t %u\n',y);
                                                    fclose(fid);
                                                end

                                        case 4 % nfiles = 1 TextFile (XYZ) --------------------------------------------------------------------------------------
            
                                                [FileName,PathName,FI] = uiputfile('*.txt','Save Location for Cell Track Exporting','CellTracks');  %#ok<NASGU>
                            
                                                if ~isequal(FileName,0)
                                                        QuestString = {'What units should this be in?','(For Locus, choose pixels)'};
                                                        answer = questdlg(QuestString,'Units','pixels','microns','pixels');
                                                        if strcmp(answer,'pixels')
                                                            Convert = false;
                                                        else
                                                            Convert = true;
                                                        end
                                                        ES = CreateExportDataSummary(app, DataSelection);
                                                        nCells = size(ES,1);
                                                        fid = fopen([PathName FileName],'w');
                                                            
                                                        if Convert     % isequal(app.microns_per_pixel,-1)
                                                            xLabel = 'X(microns)'; 
                                                            yLabel = 'Y(microns)'; meanLabel = 'MeanPixelValue'; 
                                                            zLabel = 'Z(microns)';                                                            
                                                        else 
                                                            xLabel = 'X(pixels)';  
                                                            yLabel = 'Y(pixels)';  meanLabel = 'MeanPixelValue'; 
                                                            zLabel = 'Z(z-slice)';
                                                        end
            
            
                                                        delimiter = char(9);
            
                                                        header = ['SetNumber' delimiter 'TimePoint' delimiter 'CellNumber' delimiter xLabel delimiter yLabel delimiter zLabel];
                                                        fprintf(fid,'%s\n',header);

                                                        for CellIndex = 1:nCells
                                                                SetNum = ES(CellIndex,1);
                                                                CellNum = ES(CellIndex,2);
                                                                CellStartTime = ES(CellIndex,3);
                                                                CellEndTime = ES(CellIndex,4);
                                                                for TimePt = CellStartTime:CellEndTime
                                                                    [isdim, notempty] = iscelldim(app, TrackData,SetNum,TimePt,CellNum);
                                                                    if isdim && notempty
                                                                     
                                                                        if Convert    % isequal(app.microns_per_pixel,-1)
                                                                                Xcen = app.microns_per_pixel .* TrackData{SetNum}{TimePt}{CellNum}.x_centroid; % Grab X Centroid coords
                                                                                Ycen = app.microns_per_pixel .* TrackData{SetNum}{TimePt}{CellNum}.y_centroid; % Grab Y Centroid coords
                                                                                z    = app.z_step_size .* TrackData{SetNum}{TimePt}{CellNum}.Z; % Grab the Z number
                                                                        else
                                                                                Xcen = TrackData{SetNum}{TimePt}{CellNum}.x_centroid; % Grab X Centroid coords
                                                                                Ycen = TrackData{SetNum}{TimePt}{CellNum}.y_centroid; % Grab Y Centroid coords
                                                                                z =    TrackData{SetNum}{TimePt}{CellNum}.Z; % Grab the Z number
                                                                        end
            
                                                                        A = [num2str(SetNum),       delimiter...
                                                                             num2str(TimePt),       delimiter...
                                                                             num2str(CellNum),      delimiter...
                                                                             num2str(Xcen,'%0.3f'), delimiter...
                                                                             num2str(Ycen,'%0.3f'), delimiter...
                                                                             num2str(z,'%0.3f'),    delimiter];

                                                                        fprintf(fid,'%s\n',A');
                                                                    end
                                                                end
                                                        end
                                                        fic = fclose(fid); %#ok<NASGU>
                                                end
            
            
                                        %----------------------------------------------------------------------------------------------------------------------------
                                        case 5 % nfiles = nT x nCells x nTrack ----------------------------------------------------------------------------------
                                                ImHeight = app.ImHeight;
                                                ImWidth = app.ImWidth;
                                                [FileName,PathName,FI] = uiputfile('*.tif','Save Location for Cell Set Exporting','CellSets');  %#ok<NASGU>
                                                ES = CreateExportDataSummary(app, DataSelection); % Export Data Summary
                                                if ~isequal(FileName,0)
                                                             nCells = size(ES,1);
                                                             f = waitbar(0,'Wait...'); % progressbar(app, 0)
                                                             for CellIndex = 1:nCells
                                                                        SetNum  = ES(CellIndex,1);
                                                                        CellNum = ES(CellIndex,2);
                                                                        CellStartTime = ES(CellIndex,3);
                                                                        CellEndTime   = ES(CellIndex,4);
                                                                        for TimePt = CellStartTime:CellEndTime
                                                                                [isdim, notempty] = iscelldim(app, TrackData,SetNum,TimePt,CellNum);
                                                                                if isdim && notempty
                                                                                        GrayScaleIm = zeros(ImHeight,ImWidth,'uint8'); % Intialize blank GrayScale Image
                                                                                        z = TrackData{SetNum}{TimePt}{CellNum}.Z; % Grab the Z number
                                                                                        Xperim = TrackData{SetNum}{TimePt}{CellNum}.x_perimeter; % Grab Perimeter coords
                                                                                        Yperim = TrackData{SetNum}{TimePt}{CellNum}.y_perimeter;
                                                                                        linind = sub2ind([ImHeight ImWidth],Yperim,Xperim);
                                                                                        GrayScaleIm(linind) = uint8(255);
                                                                                        GrayScaleIm = uint8(255*imfill(GrayScaleIm,'holes')); % Fill in cell contour
                                                                                        filename = fullfile(PathName, [FileName(1:end-4),'_set',sprintf('%04d',SetNum),'_cell',sprintf('%04d',CellNum),...
                                                                                                    '_time',sprintf('%04d',TimePt),'_z',sprintf('%04d',z),'.tif']);
                                                                                        imwrite(GrayScaleIm,filename,'Compression','none','WriteMode','overwrite','RowsPerStrip',size(GrayScaleIm,1))
                                                                                end
                                                                        end
                                                                        waitbar(CellIndex/nCells,f,['Progress = ',sprintf('%0.1f',100*CellIndex/nCells),'%']); 
                                                             end
                                                             
                                                        if logical(exist('f'))
                                                            if isgraphics(f)
                                                                close(f)
                                                            end
                                                        end
                                                end


                                        case 6 % Time, nMultitiff = nSets ---------------------------------------------------------------------------------------
                                                ES = CreateExportDataSummary(app, DataSelection);
                                                ImHeight = app.ImHeight;
                                                ImWidth = app.ImWidth;
                                                nZ = app.nZ;
                                                [FileName,PathName,FI] = uiputfile('*.tif','Save Location for Cell Track Exporting','CellData');  %#ok<NASGU>
            
                                                if ~isequal(FileName,0) % Check to make sure user selected a SAVE Name and Path
                                                        SetNums = unique(ES(:,1))'; % Find the Set/Track numbers without dupication
                                                        f = waitbar(0,'Wait...'); %progressbar(app, 0)
                                                        SetCount = 0;
                                                        SetTotal = length(SetNums);
                                                        MinTimeOverall = min(ES(:,3));
                                                        MaxTimeOverall = max(ES(:,4));
                                                        nColors = MaxTimeOverall - MinTimeOverall + 1;
                                                        Colors = flipud(turbo(nColors));
                                                        
                                                        answer = questdlg('Would you like to apply an image closing?','Image closing?','Yes','Skip','Skip');
                                                        if strcmp(answer,'Yes')
                                                            opts.Interpreter = 'tex'; 
                                                            answer2 = inputdlg('\fontsize{10}Specify size of structuring element (default = 3).     \color{red}\bfSize must be non-zero!','Structuring element size',[1,70],{'3'},opts);
                                                        end

                                                        for S = SetNums
                                                                SetIndices   = find(ES(:,1) == S);
                                                                ZStack       = zeros(ImHeight,ImWidth,3,nZ,'uint8'); % Intialize blank RGB multitif stack (nZ)
                                                                MinStartTime = min(ES(SetIndices,3));
                                                                MaxEndTime   = max(ES(SetIndices,4));
                                                                % SetColors = jet(MaxEndTime-MinStartTime+1); %(jet(256));
                                                                % SetColors = flipud(SetColors);
                                                                % [m,b] = line_equation(app, MinStartTime,MaxEndTime,1,256);
                                                                CellNumbers = ES(SetIndices,2)';
            
                                                                for TimePt = MinStartTime:MaxEndTime
                                                                        %cIndex = round(m*TimePt + b);
                                                                        for cell = CellNumbers
                                                                                [isdim, notempty] = iscelldim(app, TrackData,S,TimePt,cell);
                                                                                if isdim && notempty
                                                                                        cmap = uint8(255*Colors(TimePt-MinTimeOverall+1,:)); %v*SetColors(cIndex,:);
                                                                                        TempImj = zeros(ImHeight,ImWidth,'uint8');
                                                                                        z = TrackData{S}{TimePt}{cell}.Z; % Grab the Z number

                                                                                        Xperim = TrackData{S}{TimePt}{cell}.x_perimeter; % Grab X Perimeter coords
                                                                                        Yperim = TrackData{S}{TimePt}{cell}.y_perimeter; % Grab Y Perimeter coords
                                                                                        linind = sub2ind([ImHeight,ImWidth],Yperim,Xperim);
                                                                                        TempImj(linind) = uint8(255); % Mark cell outline in blank grayscale image
                                                                                        TempImj   = imfill(TempImj,'holes'); % fill in perimeter so that we now have a solid mask for the cell
                                                                                        [row,col] = find(TempImj); % Grab new row and column indices for solid cell mask
                                                                                        
                                                                                        % Convert solid cell mask row and col indices into linear indices of 4-D array ZStack for each RGB color channel
                                                                                        Rind = sub2ind( size(ZStack), row, col, 1*ones(length(row),1), z*ones(length(row),1) );
                                                                                        Gind = sub2ind( size(ZStack), row, col, 2*ones(length(row),1), z*ones(length(row),1) );
                                                                                        Bind = sub2ind( size(ZStack), row, col, 3*ones(length(row),1), z*ones(length(row),1) );
                                                                                       
                                                                                        ZStack(Rind) = cmap(1); % Assign red color to red channel
                                                                                        ZStack(Gind) = cmap(2); % Assign green color to green channel
                                                                                        ZStack(Bind) = cmap(3); % Assign blue color to blue channel
                                                                                end 
                                                                        end
                                                                end                                                                

                                                                
                                                                % Ask user if they'd like to apply if only one cell is being exported  
                                                                if strcmp(answer,'Yes')
                                                                    if isempty(answer2)
                                                                        SEsize = 3;
                                                                    else
                                                                        SEsize = str2double(answer2{1});
                                                                        if isempty(SEsize)
                                                                            SEsize = 3;
                                                                        end
                                                                    end    
                                                                    % Apply image/volume CLOSING to close z-spaces between timepoints --------------------------
                                                                    SE1 = strel("sphere",SEsize);
                                                                    ZStackR = imclose( squeeze(ZStack(:,:,1,:)),SE1);
                                                                    ZStackG = imclose( squeeze(ZStack(:,:,2,:)),SE1);
                                                                    ZStackB = imclose( squeeze(ZStack(:,:,3,:)),SE1);
    
                                                                    ZStack(:,:,1,:) = ZStackR;
                                                                    ZStack(:,:,2,:) = ZStackG;
                                                                    ZStack(:,:,3,:) = ZStackB;
                                                                    %--------------------------------------------------------------------------------------------
                                                                end


                                                                % Now Create RGB Multitif for this Track
                                                                for zslice = 1:nZ
                                                                    Zimage = squeeze(ZStack(:,:,:,zslice));
                                                                    if zslice == 1
                                                                        imwrite(Zimage,fullfile(PathName,[FileName(1:end-4) '_Set' udigit(app, 4,S) '_TimeData.tif']),'Compression','none','WriteMode','overwrite','RowsPerStrip',size(Zimage,1))
                                                                    else
                                                                        imwrite(Zimage,fullfile(PathName,[FileName(1:end-4) '_Set' udigit(app, 4,S) '_TimeData.tif']),'Compression','none','WriteMode','append','RowsPerStrip',size(Zimage,1))
                                                                    end
                                                                end
                                                                SetCount = SetCount + 1;
                                                                waitbar(SetCount/SetTotal,f,['Progress = ',sprintf('%0.1f',100*SetCount/SetTotal),'%']); %progressbar(app, SetCount/SetTotal)
                                                        end
                                                        
                                                        if logical(exist('f'))
                                                            if isgraphics(f)
                                                                close(f)
                                                            end
                                                        end
                                                end


                                        case 7 % Time, nMultitiff = 1 -------------------------------------------------------------------------------------------
            
                                                ES = CreateExportDataSummary(app, DataSelection);
                                                ImHeight = app.ImHeight;
                                                ImWidth = app.ImWidth;
                                                nZ = app.nZ;
                                                [FileName,PathName,FI] = uiputfile('*.tif','Save Location for Cell Track Exporting','CellData');  %#ok<NASGU>
            
                                                if ~isequal(FileName,0)
                                                        nCells = size(ES,1);
                                                        f = waitbar(0,''); %progressbar(app, 0)
                                                        ZStack = zeros(ImHeight,ImWidth,3,nZ,'uint8'); % Intialize blank RGB multitif stack
                                                        MinStartTime = min(ES(:,3));
                                                        MaxEndTime = max(ES(:,4));
                                                        MinTimeOverall = min(ES(:,3));
                                                        MaxTimeOverall = max(ES(:,4));
                                                        nColors = MaxTimeOverall - MinTimeOverall + 1;
                                                        Colors = flipud(turbo(nColors));
                                                        %ColorSelection = (jet(256));
                                                        [m,b] = line_equation(app, MinStartTime,MaxEndTime,1,256);

                                                        answer = questdlg('Would you like to apply an image closing?','Image closing?','Yes','Skip','Skip');
                                                        if strcmp(answer,'Yes')
                                                            opts.Interpreter = 'tex'; 
                                                            answer2 = inputdlg('\fontsize{10}Specify size of structuring element (default = 3).     \color{red}\bfSize must be non-zero!','Structuring element size',[1,70],{'3'},opts);
                                                        end

                                                        for CellIndex = 1:nCells
                                                                SetNum = ES(CellIndex,1);
                                                                CellNum = ES(CellIndex,2);
                                                                CellStartTime = ES(CellIndex,3);
                                                                CellEndTime = ES(CellIndex,4);
                                                                for TimePt = CellStartTime:CellEndTime
                                                                        [isdim, notempty] = iscelldim(app, TrackData,SetNum,TimePt,CellNum);
                                                                        if isdim && notempty
                                                                                    cmap = uint8(255*Colors(TimePt-MinTimeOverall+1,:)); %v*SetColors(cIndex,:);
                                                                                    TempImj = zeros(ImHeight,ImWidth,'uint8');
                                                                                    z = TrackData{SetNum}{TimePt}{CellNum}.Z; % Grab the Z number

                                                                                    Xperim = TrackData{SetNum}{TimePt}{CellNum}.x_perimeter; % Grab X Perimeter coords
                                                                                    Yperim = TrackData{SetNum}{TimePt}{CellNum}.y_perimeter; % Grab Y Perimeter coords
                                                                                    linind = sub2ind([ImHeight,ImWidth],Yperim,Xperim);
                                                                                    TempImj(linind) = uint8(255); % Mark cell outline in blank grayscale image
                                                                                    TempImj   = imfill(TempImj,'holes'); % fill in perimeter so that we now have a solid mask for the cell
                                                                                    [row,col] = find(TempImj); % Grab new row and column indices for solid cell mask
                                                                                    
                                                                                    % Convert solid cell mask row and col indices into linear indices of 4-D array ZStack for each RGB color channel
                                                                                    Rind = sub2ind( size(ZStack), row, col, 1*ones(length(row),1), z*ones(length(row),1) );
                                                                                    Gind = sub2ind( size(ZStack), row, col, 2*ones(length(row),1), z*ones(length(row),1) );
                                                                                    Bind = sub2ind( size(ZStack), row, col, 3*ones(length(row),1), z*ones(length(row),1) );
                                                                                   
                                                                                    ZStack(Rind) = cmap(1); % Assign red color to red channel
                                                                                    ZStack(Gind) = cmap(2); % Assign green color to green channel
                                                                                    ZStack(Bind) = cmap(3); % Assign blue color to blue channel
                                                                        end
                                                                end
                                                                waitbar(CellIndex/nCells,f,['Progress = ',sprintf('%0.1f',100*CellIndex/nCells),'%']);
                                                        end


                                                        if strcmp(answer,'Yes')
                                                            if isempty(answer2)
                                                                SEsize = 3;
                                                            else
                                                                SEsize = str2double(answer2{1});
                                                                if isempty(SEsize)
                                                                    SEsize = 3;
                                                                end
                                                            end    
                                                            % Apply image/volume CLOSING to close z-spaces between timepoints --------------------------
                                                            SE1 = strel("sphere",SEsize);
                                                            ZStackR = imclose( squeeze(ZStack(:,:,1,:)),SE1);
                                                            ZStackG = imclose( squeeze(ZStack(:,:,2,:)),SE1);
                                                            ZStackB = imclose( squeeze(ZStack(:,:,3,:)),SE1);

                                                            ZStack(:,:,1,:) = ZStackR;
                                                            ZStack(:,:,2,:) = ZStackG;
                                                            ZStack(:,:,3,:) = ZStackB;
                                                            %--------------------------------------------------------------------------------------------
                                                        end


                                                        % Now Create RGB Multitif for this Track
                                                        for zslice = 1:nZ
                                                            Zimage = squeeze(ZStack(:,:,:,zslice));
                                                            if zslice == 1
                                                                imwrite(Zimage,fullfile(PathName,[FileName(1:end-4) '_TimeData.tif']),'Compression','none','WriteMode','overwrite','RowsPerStrip',size(Zimage,1))
                                                            else
                                                                imwrite(Zimage,fullfile(PathName,[FileName(1:end-4) '_TimeData.tif']),'Compression','none','WriteMode','append','RowsPerStrip',size(Zimage,1))
                                                            end
                                                        end
                                                        

                                                        if logical(exist('f'))
                                                            if isgraphics(f)
                                                                close(f)
                                                            end
                                                        end
                                                end


                                        case 8 % Speed, nMultitiff = nSets --------------------------------------------------------------------------------------
                                                    if ~isequal(app.microns_per_pixel,-1) && ~isequal(app.z_step_size,-1) && ~isequal(app.time_interval,-1)
                                                        [FileName,PathName,FI] = uiputfile('*.tif','Save Location for Cell Track Exporting','CellData');  %#ok<NASGU>
            
                                                        if ~isequal(FileName,0) % Check to make sure user selected a SAVE Name and Path
                                                                ES = CreateExportDataSummary(app, DataSelection);
                                                                ImHeight = app.ImHeight;
                                                                ImWidth  = app.ImWidth;
                                                                nZ = app.nZ;
            
                                                                answer = questdlg('Would you like to apply an image closing?','Image closing?','Yes','Skip','Skip');
                                                                if strcmp(answer,'Yes')
                                                                    opts.Interpreter = 'tex'; 
                                                                    answer2 = inputdlg('\fontsize{10}Specify size of structuring element (default = 3).     \color{red}\bfSize must be non-zero!','Structuring element size',[1,70],{'3'},opts);
                                                                end

                                                                %------------------------------------------------------------------------------
                                                                % Calculate Velocity values for each cell over all time points first
                                                                nCells = size(ES,1);
                                                                speed = nan(app.nT,nCells);
                                                                for CellIndex = 1:nCells
                                                                    SetNum = ES(CellIndex,1);
                                                                    CellNum = ES(CellIndex,2);
                                                                    CellStartTime = ES(CellIndex,3);
                                                                    CellEndTime = ES(CellIndex,4);
                                                                    % Speed values are between XYZ positions so we'll create this time vector and interpolate the desired values later
                                                                    for T = CellStartTime:CellEndTime-1
                                                                        [isdim1, notempty1] = iscelldim(app, TrackData,SetNum,T,CellNum);
                                                                        [isdim2, notempty2] = iscelldim(app, TrackData,SetNum,T+1,CellNum);
                                                                        if all([isdim1 notempty1 isdim2 notempty2])
                                                                            X1 = app.microns_per_pixel .* TrackData{SetNum}{T}{CellNum}.x_centroid; % Grab X,t Centroid coords
                                                                            Y1 = app.microns_per_pixel .* TrackData{SetNum}{T}{CellNum}.y_centroid; % Grab Y,t Centroid coords
                                                                            Z1 = app.z_step_size .* TrackData{SetNum}{T}{CellNum}.Z;                % Grab the Z,t number
        
                                                                            X2 = app.microns_per_pixel .* TrackData{SetNum}{T+1}{CellNum}.x_centroid; % Grab X,t+1 Centroid coords
                                                                            Y2 = app.microns_per_pixel .* TrackData{SetNum}{T+1}{CellNum}.y_centroid; % Grab Y,t+1 Centroid coords
                                                                            Z2 = app.z_step_size .* TrackData{SetNum}{T+1}{CellNum}.Z;                % Grab the Z,t+1 number
        
                                                                            distance = sqrt((X2-X1)^2 + (Y2-Y1)^2 + (Z2-Z1)^2);
                                                                            speed(T,CellIndex) = distance/app.time_interval; %#ok<AGROW>
                                                                        end
                                                                    end
                                                                end
                                                                %  --------------------------------------------------
                                                                
                                                                % Interpolate missing points and Low-pass filter speed measurements ------------------------------------------
                                                                FilteredSpeed = nan(app.nT,nCells);
                                                                t = (1:app.nT)';

                                                                VFAhandle = VelocityFilteringApp(app,t,speed); % Ask user how they want to filter
                                                                uiwait(VFAhandle.VFAmain)
                                                                nPoints  = app.VFAoutput{1}; 

                                                                if app.VFAoutput{3} == 1
                                                                    SF = 60; % Conversion factor for microns/min
                                                                    UnitsText = 'MicronPerMin';
                                                                else
                                                                    SF = 1000; % conversion factor for nm/s
                                                                    UnitsText = 'NanometerPerSec';
                                                                end

                                                                for n = 1:nCells
                                                                    Sidx = find( ~isnan(speed(:,n)),1,'first');
                                                                    Eidx = find( ~isnan(speed(:,n)),1,'last');
                                                                    speed(Sidx:Eidx,n) = SF*fillmissing(speed(Sidx:Eidx,n),'linear');   % Fillmissing values and scale to correct units
                                                                    InterpSpeed = interp1( t(Sidx:Eidx)+diff(t(Sidx:Sidx+1))/2, speed(Sidx:Eidx,n), t, 'linear', 'extrap'); 
                                                                    FilteredSpeed(:,n)  = smoothdata(InterpSpeed,'movmean',nPoints);
                                                                end
                                                                
                                                                %-------------------------------------------------------------------------------
                                                                
                                                                SetNumbers = unique(ES(:,1)); % Find the Set/Track numbers without dupication
                                                                MinSpeed = app.VFAoutput{2}(1); % min(FilteredSpeed(:),[],'omitnan');
                                                                MaxSpeed = app.VFAoutput{2}(2); % max(FilteredSpeed(:),[],'omitnan');
                                                                CM = turbo(110); 
                                                                CM = CM(6:105,:);
                                                                Colors = flipud(CM);
                                                                SCV = linspace(MinSpeed,MaxSpeed,100)'; % Speed and Color Vector 
                                                                CellIndex = 0;    
                                                                f = waitbar(0,''); %progressbar(app, 0)
                                                                for SetNum = SetNumbers'
                                                                        SetIndices = find( ES(:,1) == SetNum );
                                                                        ZStack = zeros(ImHeight,ImWidth,3,nZ,'uint8'); % Intialize blank RGB multitif stack (nZ)
                                                                        CellNumbers = ES(SetIndices,2);
                                                                        for C = 1:length(CellNumbers)
                                                                                CellIndex = CellIndex + 1;
                                                                                ESindex = find( ES(:,1)==SetNum & ES(:,2)==CellNumbers(C) );
                                                                                StartTime = ES(ESindex,3);
                                                                                EndTime   = ES(ESindex,4);
                                                                                CellNum   = CellNumbers(C);
                                                                                for TimePt = StartTime:EndTime
                                                                                        [isdim, notempty] = iscelldim(app,TrackData,SetNum,TimePt,CellNum);
                                                                                        if isdim && notempty
                                                                                                [~,Cidx] = min( abs(SCV-speed(TimePt,ESindex)) );
                                                                                                cmap = uint8(255*Colors(Cidx(1),:)); %v*SetColors(cIndex,:);
                                                                                                TempImj = zeros(ImHeight,ImWidth,'uint8');
                                                                                                z = TrackData{SetNum}{TimePt}{CellNum}.Z; % Grab the Z number
            
                                                                                                Xperim = TrackData{SetNum}{TimePt}{CellNum}.x_perimeter; % Grab X Perimeter coords
                                                                                                Yperim = TrackData{SetNum}{TimePt}{CellNum}.y_perimeter; % Grab Y Perimeter coords
                                                                                                linind = sub2ind([ImHeight,ImWidth],Yperim,Xperim);
                                                                                                TempImj(linind) = uint8(255); % Mark cell outline in blank grayscale image
                                                                                                TempImj   = imfill(TempImj,'holes'); % fill in perimeter so that we now have a solid mask for the cell
                                                                                                [row,col] = find(TempImj); % Grab new row and column indices for solid cell mask
                                                                                                
                                                                                                % Convert solid cell mask row and col indices into linear indices of 4-D array ZStack for each RGB color channel
                                                                                                Rind = sub2ind( size(ZStack), row, col, 1*ones(length(row),1), z*ones(length(row),1) );
                                                                                                Gind = sub2ind( size(ZStack), row, col, 2*ones(length(row),1), z*ones(length(row),1) );
                                                                                                Bind = sub2ind( size(ZStack), row, col, 3*ones(length(row),1), z*ones(length(row),1) );
                                                                                               
                                                                                                ZStack(Rind) = cmap(1); % Assign red color to red channel
                                                                                                ZStack(Gind) = cmap(2); % Assign green color to green channel
                                                                                                ZStack(Bind) = cmap(3); % Assign blue color to blue channel
                                                                                        end
                                                                                end
                                                                                waitbar(CellIndex/nCells,f,['Progress = ',sprintf('%0.1f',100*CellIndex/nCells),'%']);
                                                                                % progressbar(app, CellIndex/nCells)
                                                                        end

                                                                        % Ask user if they'd like to apply if only one cell is being exported  
                                                                        if strcmp(answer,'Yes')
                                                                            if isempty(answer2)
                                                                                SEsize = 3;
                                                                            else
                                                                                SEsize = str2double(answer2{1});
                                                                                if isempty(SEsize)
                                                                                    SEsize = 3;
                                                                                end
                                                                            end    
                                                                            % Apply image/volume CLOSING to close z-spaces between timepoints --------------------------
                                                                            SE1 = strel("sphere",SEsize);
                                                                            ZStackR = imclose( squeeze(ZStack(:,:,1,:)),SE1);
                                                                            ZStackG = imclose( squeeze(ZStack(:,:,2,:)),SE1);
                                                                            ZStackB = imclose( squeeze(ZStack(:,:,3,:)),SE1);
            
                                                                            ZStack(:,:,1,:) = ZStackR;
                                                                            ZStack(:,:,2,:) = ZStackG;
                                                                            ZStack(:,:,3,:) = ZStackB;
                                                                            %--------------------------------------------------------------------------------------------
                                                                        end


                                                                        % Now Create RGB Multitif for this Track
                                                                        for zslice = 1:nZ
                                                                            Zimage = squeeze(ZStack(:,:,:,zslice));
                                                                            if zslice == 1
                                                                                imwrite(Zimage,[PathName FileName(1:end-4) '_Set' udigit(app, 4,SetNum) '_SpeedData.tif'],'Compression','none','WriteMode','overwrite','RowsPerStrip',size(Zimage,1))
                                                                            else
                                                                                imwrite(Zimage,[PathName FileName(1:end-4) '_Set' udigit(app, 4,SetNum) '_SpeedData.tif'],'Compression','none','WriteMode','append','RowsPerStrip',size(Zimage,1))
                                                                            end
                                                                        end
                                                                end
            
                                                                % Create ColorMap Image
                                                                MinNumIm = uint8(255*digits(app, num2str(MinSpeed),'bold','small'));
                                                                MaxNumIm = uint8(255*digits(app, num2str(MaxSpeed),'bold','small'));
                                                                width = max([size(MaxNumIm,2) size(MinNumIm,2)]);
                                                                height = size(MinNumIm,1);
                                                                if ~isequal(size(MaxNumIm,2),width); dif = width-size(MaxNumIm,2); MaxNumIm = [MaxNumIm, zeros(height,dif,'uint8')];end
                                                                if ~isequal(size(MinNumIm,2),width); dif = width-size(MinNumIm,2); MinNumIm = [MinNumIm, zeros(height,dif,'uint8')];end
            
                                                                rgb = zeros([size(Colors,1) 1 size(Colors,2)],'double');
                                                                rgb(:,1,:) = Colors;
                                                                RGBim = repmat(rgb,[1,width,1]);
                                                                ColorBarImage = imresize(RGBim, [1024 width],'nearest');
                                                                MinIm = cat(3,MinNumIm,MinNumIm,MinNumIm);
                                                                MaxIm = cat(3,MaxNumIm,MaxNumIm,MaxNumIm);
                                                                FinalImage = cat(1,MaxIm,uint8(255*ColorBarImage),MinIm);
                                                                imwrite(FinalImage,fullfile(PathName, [FileName(1:end-4), '_SpeedColorMap_UnitsAre_',UnitsText,'.tif']),'Compression','none','RowsPerStrip',size(FinalImage,1))
                                                        end

                                                        if logical(exist('f'))
                                                            if isgraphics(f)
                                                                close(f)
                                                            end
                                                        end

                                                    else
                                                        h = warndlg('Must Define XY scale, Z step, and Time interval','Define XYZT!');
                                                    end
                                                    

                                        case 9 % Speed, nMultitiff = 1 ------------------------------------------------------------------------------------------
                                               if ~isequal(app.microns_per_pixel,-1) && ~isequal(app.z_step_size,-1) && ~isequal(app.time_interval,-1)
                                                        [FileName,PathName,FI] = uiputfile('*.tif','Save Location for Cell Track Exporting','CellData');  %#ok<NASGU>
            
                                                        if ~isequal(FileName,0) % Check to make sure user selected a SAVE Name and Path
                                                                ES = CreateExportDataSummary(app, DataSelection);
                                                                ImHeight = app.ImHeight;
                                                                ImWidth  = app.ImWidth;
                                                                nZ = app.nZ;

                                                                answer = questdlg('Would you like to apply an image closing?','Image closing?','Yes','Skip','Skip');
                                                                if strcmp(answer,'Yes')
                                                                    opts.Interpreter = 'tex'; 
                                                                    answer2 = inputdlg('\fontsize{10}Specify size of structuring element (default = 3).     \color{red}\bfSize must be non-zero!','Structuring element size',[1,70],{'3'},opts);
                                                                end

                                                                %------------------------------------------------------------------------------
                                                                % Calculate Velocity values for each cell over all time points first
                                                                nCells = size(ES,1);
                                                                speed = nan(app.nT,nCells);
                                                                for CellIndex = 1:nCells
                                                                    SetNum = ES(CellIndex,1);
                                                                    CellNum = ES(CellIndex,2);
                                                                    CellStartTime = ES(CellIndex,3);
                                                                    CellEndTime = ES(CellIndex,4);
                                                                    % Speed values are between XYZ positions so we'll create this time vector and interpolate the desired values later
                                                                    for T = CellStartTime:CellEndTime-1
                                                                        [isdim1, notempty1] = iscelldim(app, TrackData,SetNum,T,CellNum);
                                                                        [isdim2, notempty2] = iscelldim(app, TrackData,SetNum,T+1,CellNum);
                                                                        if all([isdim1 notempty1 isdim2 notempty2])
                                                                            X1 = app.microns_per_pixel .* TrackData{SetNum}{T}{CellNum}.x_centroid; % Grab X,t Centroid coords
                                                                            Y1 = app.microns_per_pixel .* TrackData{SetNum}{T}{CellNum}.y_centroid; % Grab Y,t Centroid coords
                                                                            Z1 = app.z_step_size .* TrackData{SetNum}{T}{CellNum}.Z;                % Grab the Z,t number
        
                                                                            X2 = app.microns_per_pixel .* TrackData{SetNum}{T+1}{CellNum}.x_centroid; % Grab X,t+1 Centroid coords
                                                                            Y2 = app.microns_per_pixel .* TrackData{SetNum}{T+1}{CellNum}.y_centroid; % Grab Y,t+1 Centroid coords
                                                                            Z2 = app.z_step_size .* TrackData{SetNum}{T+1}{CellNum}.Z;                % Grab the Z,t+1 number
        
                                                                            distance = sqrt((X2-X1)^2 + (Y2-Y1)^2 + (Z2-Z1)^2);
                                                                            speed(T,CellIndex) = distance/app.time_interval; %#ok<AGROW>
                                                                        end
                                                                    end
                                                                end
                                                                %  --------------------------------------------------
                                                                
                                                                % Interpolate missing points and Low-pass filter speed measurements ------------------------------------------
                                                                FilteredSpeed = nan(app.nT,nCells);
                                                                t = (1:app.nT)';

                                                                VFAhandle = VelocityFilteringApp(app,t,speed); % Ask user how they want to filter
                                                                uiwait(VFAhandle.VFAmain)
                                                                nPoints  = app.VFAoutput{1}; 

                                                                if app.VFAoutput{3} == 1
                                                                    SF = 60; % Conversion factor for microns/min
                                                                    UnitsText = 'MicronPerMin';
                                                                else
                                                                    SF = 1000; % conversion factor for nm/s
                                                                    UnitsText = 'NanometerPerSec';
                                                                end

                                                                for n = 1:nCells
                                                                    Sidx = find( ~isnan(speed(:,n)),1,'first');
                                                                    Eidx = find( ~isnan(speed(:,n)),1,'last');
                                                                    speed(Sidx:Eidx,n) = SF*fillmissing(speed(Sidx:Eidx,n),'linear');   % Fillmissing values and scale to correct units
                                                                    InterpSpeed = interp1( t(Sidx:Eidx)+diff(t(Sidx:Sidx+1))/2, speed(Sidx:Eidx,n), t, 'linear', 'extrap'); 
                                                                    FilteredSpeed(:,n)  = smoothdata(InterpSpeed,'movmean',nPoints);
                                                                end
                                                                %-------------------------------------------------------------------------------

                                                                SetNumbers = unique(ES(:,1)); % Find the Set/Track numbers without dupication
                                                                MinSpeed = app.VFAoutput{2}(1); %min(FilteredSpeed(:),[],'omitnan');
                                                                MaxSpeed = app.VFAoutput{2}(2); %max(FilteredSpeed(:),[],'omitnan');
                                                                CM = turbo(110); 
                                                                CM = CM(6:105,:);
                                                                Colors = flipud(CM);
                                                                SCV = linspace(MinSpeed,MaxSpeed,100)'; % Speed and Color Vector 
                                                                
                                                                nCells = size(ES,1);
                                                                f = waitbar(0,''); %progressbar(app, 0)
                                                                ZStack = zeros(ImHeight,ImWidth,3,nZ,'uint8'); % Intialize blank RGB multitif stack (nZ)
                                                                for CellIndex = 1:nCells
                                                                        SetNum  = ES(CellIndex,1);
                                                                        CellNum = ES(CellIndex,2);
                                                                        CellStartTime = ES(CellIndex,3);
                                                                        CellEndTime   = ES(CellIndex,4);
                                                                        for TimePt = CellStartTime:CellEndTime
                                                                                [isdim, notempty] = iscelldim(app,TrackData,SetNum,TimePt,CellNum);
                                                                                if isdim && notempty
                                                                                        [~,Cidx] = min( abs(SCV-speed(TimePt,CellIndex)) );
                                                                                        cmap = uint8(255*Colors(Cidx(1),:)); %v*SetColors(cIndex,:);
                                                                                        TempImj = zeros(ImHeight,ImWidth,'uint8');
                                                                                        z = TrackData{SetNum}{TimePt}{CellNum}.Z; % Grab the Z number
    
                                                                                        Xperim = TrackData{SetNum}{TimePt}{CellNum}.x_perimeter; % Grab X Perimeter coords
                                                                                        Yperim = TrackData{SetNum}{TimePt}{CellNum}.y_perimeter; % Grab Y Perimeter coords
                                                                                        linind = sub2ind([ImHeight,ImWidth],Yperim,Xperim);
                                                                                        TempImj(linind) = uint8(255); % Mark cell outline in blank grayscale image
                                                                                        TempImj   = imfill(TempImj,'holes'); % fill in perimeter so that we now have a solid mask for the cell
                                                                                        [row,col] = find(TempImj); % Grab new row and column indices for solid cell mask
                                                                                        
                                                                                        % Convert solid cell mask row and col indices into linear indices of 4-D array ZStack for each RGB color channel
                                                                                        Rind = sub2ind( size(ZStack), row, col, 1*ones(length(row),1), z*ones(length(row),1) );
                                                                                        Gind = sub2ind( size(ZStack), row, col, 2*ones(length(row),1), z*ones(length(row),1) );
                                                                                        Bind = sub2ind( size(ZStack), row, col, 3*ones(length(row),1), z*ones(length(row),1) );
                                                                                       
                                                                                        ZStack(Rind) = cmap(1); % Assign red color to red channel
                                                                                        ZStack(Gind) = cmap(2); % Assign green color to green channel
                                                                                        ZStack(Bind) = cmap(3); % Assign blue color to blue channel
                                                                                end
                                                                        end
                                                                        waitbar(CellIndex/nCells,f,['Progress = ',sprintf('%0.1f',100*CellIndex/nCells),'%']);
                                                                end


                                                                % Ask user if they'd like to apply if only one cell is being exported  
                                                                if strcmp(answer,'Yes')
                                                                    if isempty(answer2)
                                                                        SEsize = 3;
                                                                    else
                                                                        SEsize = str2double(answer2{1});
                                                                        if isempty(SEsize)
                                                                            SEsize = 3;
                                                                        end
                                                                    end    
                                                                    % Apply image/volume CLOSING to close z-spaces between timepoints --------------------------
                                                                    SE1 = strel("sphere",SEsize);
                                                                    ZStackR = imclose( squeeze(ZStack(:,:,1,:)),SE1);
                                                                    ZStackG = imclose( squeeze(ZStack(:,:,2,:)),SE1);
                                                                    ZStackB = imclose( squeeze(ZStack(:,:,3,:)),SE1);
    
                                                                    ZStack(:,:,1,:) = ZStackR;
                                                                    ZStack(:,:,2,:) = ZStackG;
                                                                    ZStack(:,:,3,:) = ZStackB;
                                                                    %--------------------------------------------------------------------------------------------
                                                                end


                                                                % Now Create RGB Multitif for this Track
                                                                for zslice = 1:nZ
                                                                    Zimage = squeeze(ZStack(:,:,:,zslice));
                                                                    if zslice == 1
                                                                        imwrite(Zimage,[PathName FileName(1:end-4),'_SpeedData.tif'],'Compression','none','WriteMode','overwrite','RowsPerStrip',size(Zimage,1))
                                                                    else
                                                                        imwrite(Zimage,[PathName FileName(1:end-4),'_SpeedData.tif'],'Compression','none','WriteMode','append','RowsPerStrip',size(Zimage,1))
                                                                    end
                                                                end
                                                                
                                                                if logical(exist('f'))
                                                                    if isgraphics(f)
                                                                        close(f)
                                                                    end
                                                                end
            
                                                                % Create ColorMap Image
                                                                MinNumIm = uint8(255*digits(app, num2str(MinSpeed),'bold','small'));
                                                                MaxNumIm = uint8(255*digits(app, num2str(MaxSpeed),'bold','small'));
                                                                width = max([size(MaxNumIm,2) size(MinNumIm,2)]);
                                                                height = size(MinNumIm,1);
                                                                if ~isequal(size(MaxNumIm,2),width); dif = width-size(MaxNumIm,2); MaxNumIm = [MaxNumIm, zeros(height,dif,'uint8')];end
                                                                if ~isequal(size(MinNumIm,2),width); dif = width-size(MinNumIm,2); MinNumIm = [MinNumIm, zeros(height,dif,'uint8')];end
            

                                                                rgb = zeros([size(Colors,1) 1 size(Colors,2)],'double');
                                                                rgb(:,1,:) = Colors;
                                                                RGBim = repmat(rgb,[1,width,1]);
                                                                ColorBarImage = imresize(RGBim, [1024 width],'nearest');
                                                                MinIm = cat(3,MinNumIm,MinNumIm,MinNumIm);
                                                                MaxIm = cat(3,MaxNumIm,MaxNumIm,MaxNumIm);
                                                                FinalImage = cat(1,MaxIm,uint8(255*ColorBarImage),MinIm);
                                                                imwrite(FinalImage,fullfile(PathName, [FileName(1:end-4), '_SpeedColorMap_UnitsAre_',UnitsText,'.tif']),'Compression','none','RowsPerStrip',size(FinalImage,1) )
                                                        end

                                               else
                                                        h = warndlg('Must Define XY scale, Z step, and Time interval','Define XYZT!');
                                               end

                                        case 10 % nMultitiff = nT (cumulative time) ----------------------------------------------------------------------------------------------
                                                ES = CreateExportDataSummary(app, DataSelection);
                                                ImHeight = app.ImHeight;
                                                ImWidth = app.ImWidth;
                                                nZ = app.nZ;
                                                [FileName,PathName,FI] = uiputfile('*.tif','Save Location for Cell Track Exporting','CellTracks');  %#ok<NASGU>
            
                                                if ~isequal(FileName,0)
                                                            TrackData = app.TrackData;
                                                            Ntracks  = length(TrackData);
                                                            MinStartTime = min(ES(:,3));
                                                            MaxEndTime = max(ES(:,4));
                                                            f = waitbar(0,'Wait...'); %progressbar(app, 0)
                                                            Ttotal = MaxEndTime - MinStartTime + 1;
                                                            Tindex = 0;
                                                            nCells = size(ES,1);

                                                            answer = questdlg('Would you like to apply an image closing?','Image closing?','Yes','Skip','Skip');
                                                            if strcmp(answer,'Yes')
                                                                opts.Interpreter = 'tex'; 
                                                                answer2 = inputdlg('\fontsize{10}Specify size of structuring element (default = 3).     \color{red}\bfSize must be non-zero!','Structuring element size',[1,70],{'3'},opts);
                                                            end

                                                            for TimePt = MinStartTime:MaxEndTime
                                                                    ZStack = zeros(ImHeight,ImWidth,3,nZ,'uint8'); % Intialize blank RGB multitif stack (nZ)
                                                                    for N = 1:nCells
                                                                            S = ES(N,1);
                                                                            cell = ES(N,2);
                                                                            cmap = uint8(255*ColorSelection(N,:));                                                                            
                                                                            for TT = 1:TimePt % Add masks from all timepoints previous to TimePT as well
                                                                                    [isdim, notempty] = iscelldim(app,TrackData,S,TT,cell);
                                                                                    if isdim && notempty
                                                                                            %cmap = uint8(255*ColorSelection(cIndex,:)); %v*SetColors(cIndex,:);  
                                                                                            TempImj = zeros(ImHeight,ImWidth,'uint8');
                                                                                            z = TrackData{S}{TT}{cell}.Z; % Grab the Z number
                                                                                            
                                                                                            Xperim = TrackData{S}{TT}{cell}.x_perimeter; % Grab X Perimeter coords
                                                                                            Yperim = TrackData{S}{TT}{cell}.y_perimeter; % Grab Y Perimeter coords
                                                                                            linind = sub2ind([ImHeight,ImWidth],Yperim,Xperim);
                                                                                            TempImj(linind) = uint8(255); % Mark cell outline in blank grayscale image
                                                                                            TempImj         = imfill(TempImj,'holes'); % fill in perimeter so that we now have a solid mask for the cell
                                                                                            [row,col]       = find(TempImj); % Grab new row and column indices for solid cell mask                                                                                          
                                                                                            % Convert solid cell mask row and col indices into linear indices of 4-D array ZStack for each RGB color channel
                                                                                            Rind = sub2ind( size(ZStack), row, col, 1*ones(length(row),1), z*ones(length(row),1) );
                                                                                            Gind = sub2ind( size(ZStack), row, col, 2*ones(length(row),1), z*ones(length(row),1) );
                                                                                            Bind = sub2ind( size(ZStack), row, col, 3*ones(length(row),1), z*ones(length(row),1) );
        
                                                                                            % disp(num2str([S,cell]))
                                                                                            % disp(['   Z: ',num2str(z)])
        
                                                                                            ZStack(Rind) = cmap(1); % Assign red color to red channel
                                                                                            ZStack(Gind) = cmap(2); % Assign green color to green channel
                                                                                            ZStack(Bind) = cmap(3); % Assign blue color to blue channel
                                                                                    end
                                                                            end
                                                                    end


                                                                    if strcmp(answer,'Yes')
                                                                        if isempty(answer2)
                                                                            SEsize = 3;
                                                                        else
                                                                            SEsize = str2double(answer2{1});
                                                                            if isempty(SEsize)
                                                                                SEsize = 3;
                                                                            end
                                                                        end    
                                                                        % Apply image/volume CLOSING to close z-spaces between timepoints --------------------------
                                                                        SE1 = strel("sphere",SEsize);
                                                                        ZStackR = imclose( squeeze(ZStack(:,:,1,:)),SE1);
                                                                        ZStackG = imclose( squeeze(ZStack(:,:,2,:)),SE1);
                                                                        ZStackB = imclose( squeeze(ZStack(:,:,3,:)),SE1);
        
                                                                        ZStack(:,:,1,:) = ZStackR;
                                                                        ZStack(:,:,2,:) = ZStackG;
                                                                        ZStack(:,:,3,:) = ZStackB;
                                                                        %--------------------------------------------------------------------------------------------
                                                                    end


                                                                    % Now Create RGB Multitif for this time Point
                                                                    for zslice = 1:nZ
                                                                        Zimage = squeeze(ZStack(:,:,:,zslice));
                                                                        if zslice == 1
                                                                            imwrite(Zimage,fullfile(PathName,[FileName(1:end-4),'_T',sprintf('%05d',TimePt),'.tif']),'Compression','none','WriteMode','overwrite','RowsPerStrip',size(Zimage,1));
                                                                        else
                                                                            imwrite(Zimage,fullfile(PathName,[FileName(1:end-4),'_T',sprintf('%05d',TimePt),'.tif']),'Compression','none','WriteMode','append','RowsPerStrip',size(Zimage,1));
                                                                        end
                                                                    end
                                                                    Tindex = Tindex + 1;
                                                                    waitbar(Tindex/Ttotal,f,['Progress = ',sprintf('%0.1f',100*Tindex/Ttotal),'%']); % progressbar(app, Tindex/Ttotal)
                                                            end
                                                            
                                                            if logical(exist('f'))
                                                                if isgraphics(f)
                                                                    close(f)
                                                                end
                                                            end
                                                            % Write Cell Number and RGB value text file
                                                            % create a matrix y, with two rows
            
                                                            y = [ES 255.*ColorSelection]';
            
                                                            % open a file for writing
                                                            fid = fopen([PathName FileName(1:end-4) 'Cell_and_RGB_info.txt'], 'w');
            
                                                            % print a title, followed by a blank line
                                                            fprintf(fid, 'SetNum\t CellNum\t StartTime\t EndTime\t R\t G\t B\t \n\n');
            
                                                            % print values in column order
                                                            % two values appear on each row of the file
                                                            fprintf(fid, '%u\t %u\t %u\t %u\t %u\t %u\t %u\n',y);
                                                            fclose(fid);                                                            
                                                end


                                    end
                        end
            
            end


        end

        % Button pushed function: g_channel_color_button
        function g_channel_color_button_Callback(app, event)
            
                 c = uisetcolor;
                 if ~isequal(c,0)
                      v = (0:(1/255):1)';
                      app.g_color = v*c;
                      set(app.green_color_display,'BackgroundColor',c)
                      update_display(app)
                      update_cross_section_display(app)
                 end

                 
        end

        % Button pushed function: keyboard_short_cuts_button
        function keyboard_short_cuts_button_Callback(app, event)
                
            % Create GUIDE-style callback args - Added by Migration Tool
                [hObject, eventdata, app] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
                
                     default_color = [0.8 0.8 0.8];
                
                     message = [{'KEYBOARD'};...
                                {'    UP or W      Decrease Z'};...
                                {'    DOWN or S    Increase Z'};...
                                {'    RIGHT or D   Increase Time'};...
                                {'    LEFT or A    Decrease Time'};...
                                {'    >            Increase Threshold Value'};...
                                {'    <            Decrease Threshold Value'};...
                                {'    P            Toggles: Show Previous Cell'};...
                                {'    L            Toggles: Show Post Cell'};...
                                {'    SHIFT        Draw Line Function'};...
                                {'    +            Increase Cell Index'};...
                                {'    -            Decrease Cell Index'};...
                                {'    R            toggle red channel on/off'};...
                                {'    G            toggle green channel on/off'};...
                                {'    B            toggle blue channel on/off'};...
                                {'                             '};...
                                {'MOUSE'};...
                                {'    LEFT-Click   Select Cell'};...
                                {'    RIGHT-Click  Increase Cell Number'};...
                                {'    WHEEL        Increase/Decrease Z'}];
                
                    f = figure('NumberTitle','off','Name','LongTracker Short Cuts','Position',[200 200 400 400],...
                         'Resize','off','Toolbar','none','WindowStyle','normal','MenuBar','none');
                
                    uicontrol('Parent',f,'Style','text','FontName','FixedWidth','FontWeight','bold',...
                         'FontSize',10,'String',message,'Position',[15 15 370 350],'HorizontalAlignment','Left');

                
        end

        % Value changed function: line_width_listbox
        function line_width_listbox_Callback(app, event)
                        
                    val = get(app.line_width_listbox,'Value');
                    app.line_width = val*0.5+0.5;
            
                    update_display(app)
                    
        end

        % Button pushed function: load_button
        function load_button_Callback(app, event)
                                  

                     [LoadFileName,LoadPathName] = uigetfile({'*.lgtkr';'.mat'},'Select the Longtracker file (.lgtkr)');
            
                     if ~isequal(LoadFileName,0)


                                      % Delete windows that may be from a different data set and reset main control checkboxes ------------------------------
                                        if ishandle(app.main_panel);           delete(app.main_panel);             app.main_panel = -1;            end
                                        if ishandle(app.tm1_z_panel);          delete(app.tm1_z_panel);            app.tm1_z_panel = -1;           end
                                        if ishandle(app.tp1_z_panel);          delete(app.tp1_z_panel);            app.tp1_z_panel = -1;           end
                                        if ishandle(app.vertical_cs_panel);    delete(app.vertical_cs_panel);      app.vertical_cs_panel = -1;     end
                                        if ishandle(app.horizontal_cs_panel);  delete(app.horizontal_cs_panel);    app.horizontal_cs_panel = -1;   end
                                        if ishandle(app.CellSummaryWindow);    delete(app.CellSummaryWindow);      app.CellSummaryWindow = -1; end
                                            set(app.show_H_cross_sections_check,'Value',0);
                                            set(app.show_V_cross_sections_check,'Value',0);
                                            set(app.LR_placement,'Enable','off')
                                            set(app.TB_placement,'Enable','off')
                                            set(app.cross_section_width_text,'Enable','off')
                                            set(app.cross_section_width_slider,'Enable','off')
                                            set(app.tp1_figure,'Value',0);
                                            set(app.tp1_slider,'Enable','off')
                                            set(app.tp1_figure,'Text','t+1, z')
                                            set(app.tm1_figure,'Value',0);
                                            set(app.tm1_slider,'Enable','off')
                                            set(app.tm1_figure,'Text','t+1, z')
                                            app.show_tp1 = false;
                                            app.show_tm1 = false;
                                      %-----------------------------------------------------------------------------------------------------------------------
                                      
                                              cd(LoadPathName)
                                              %temp = load(LoadFileName);
                                              temp = load( fullfile(LoadPathName,LoadFileName),'-mat');
                                              app.savename = fullfile(LoadPathName,LoadFileName);
                                                
                                              if temp.HANDLES.directory(end) == '/' || temp.HANDLES.directory(end) == '\' % Bug fix for when a separator was added to the end of the data directory location
                                                  temp.HANDLES.directory = temp.HANDLES.directory(1:end-1);
                                              end
                                              app.directory = temp.HANDLES.directory;
                                              app.filenames = temp.HANDLES.filenames;
            
                                              % Fix for older version
                                              if ~isfield(temp.HANDLES,'selected_cell_color')
                                                    temp.HANDLES.selected_cell_color = [1 0 1];
                                              end
            
                                              % Check to see if directory is still
                                              % the same by checking to see if the
                                              % first image file is there.
                                              readin = false;
                                              fid = fopen( fullfile(app.directory, app.filenames{1}) );
                                              if isequal(-1,fid)
                                                    status = false;
                                                    while isequal(status,false)
                                                        qstring = [{'Image data was not found using the following directory:'};{'  '};...
                                                                   {['   ', app.directory]};{' '};{'Would you like to locate the new directory'};...
                                                                   {'where the exact same image data is located?'}];
                                                        button = questdlg(qstring','Image data is not there!','Yes','No','Yes');
                                                        if isequal(button,'No')
                                                            readin = false;
                                                            status = true;
                                                        else
                                                            app.directory = uigetdir('','Select image data folder');
                                                            fid = fopen( fullfile(app.directory,app.filenames{1}));
                                                            if ~isequal(-1,fid)
                                                               status = true;
                                                               readin = true;
                                                            end
                                                        end
                                                    end
                                              else
                                                    fic = fclose(fid); %#ok<NASGU>
                                                    readin = true;
                                              end
                        %-------------------------------------------------------------------------------------------------
                                              if readin
                                                          app.doThreshold = temp.HANDLES.doThreshold;
                                                           app.doBinarize = temp.HANDLES.doBinarize;
                                                              app.doErode = temp.HANDLES.doErode;
                                                             app.doHbreak = temp.HANDLES.doHbreak;
                                                              if isfield(temp.HANDLES,'TrackNum')
                                                                    app.SetNum = temp.HANDLES.TrackNum;
                                                              else
                                                                    app.SetNum = temp.HANDLES.SetNum;
                                                              end
%                                                                app.nCells = temp.HANDLES.nCells;
%                                                             app.CellIndex = temp.HANDLES.CellIndex;
                                                            app.TrackData = temp.HANDLES.TrackData;
                                                                    app.T = temp.HANDLES.T;
                                                                    app.Z = temp.HANDLES.Z;
                                                                   app.LR = temp.HANDLES.LR;
                                                                   app.TB = temp.HANDLES.TB;
                                                      app.threshold_value = temp.HANDLES.threshold_value;
                                                               app.nErode = temp.HANDLES.nErode;
                                                                   app.SE = temp.HANDLES.SE;
                                                        app.show_previous = temp.HANDLES.show_previous;
                                                            app.show_post = false; % temp.HANDLES.show_post;
                                                 app.show_V_cross_section = false; % temp.HANDLES.show_V_cross_section;
                                                 app.show_H_cross_section = false; % temp.HANDLES.show_H_cross_section;
                                           app.cross_section_window_width = temp.HANDLES.cross_section_window_width;
                                                     app.main_window_size = 125; %temp.HANDLES.main_window_size;
                                                    app.tp1_z_window_size = temp.HANDLES.tp1_z_window_size;
                                                             app.show_tp1 = false; % temp.HANDLES.show_tp1;
            
                                                    app.tm1_z_window_size = temp.HANDLES.tm1_z_window_size;
                                                             app.show_tm1 = temp.HANDLES.show_tm1;
                                                             app.ApplyAll = temp.HANDLES.ApplyAll;
                                                       app.show_r_channel = temp.HANDLES.show_r_channel;
                                                       app.show_g_channel = temp.HANDLES.show_g_channel;
                                                       app.show_b_channel = temp.HANDLES.show_b_channel;
                                                            app.do_magnet = temp.HANDLES.do_magnet;
                                                                   app.sa = temp.HANDLES.sa;
            
                                                              app.r_color = temp.HANDLES.r_color;
                                                              app.g_color = temp.HANDLES.g_color;
                                                              app.b_color = temp.HANDLES.b_color;
                                                              app.z_color = temp.HANDLES.z_color;
                                                      app.cell_fill_color = temp.HANDLES.cell_fill_color;
                                                   app.cell_outline_color = temp.HANDLES.cell_outline_color;
                                          app.previous_cell_outline_color = temp.HANDLES.previous_cell_outline_color;
                                              app.post_cell_outline_color = temp.HANDLES.post_cell_outline_color;
                                                  app.selected_cell_color = temp.HANDLES.selected_cell_color;
                                                  
                                                      app.line_break_mask = temp.HANDLES.line_break_mask;
                                                          app.auto_load_z = temp.HANDLES.auto_load_z;
                                                             %app.savename = temp.HANDLES.savename;
                                                           app.line_width = temp.HANDLES.line_width;
                                                                   app.nT = temp.HANDLES.nT;
                                                                   app.nZ = temp.HANDLES.nZ;
                                                              app.ImWidth = temp.HANDLES.ImWidth;
                                                             app.ImHeight = temp.HANDLES.ImHeight;
                                                                app.Tprev = temp.HANDLES.Tprev;

                                                        if isfield(temp.HANDLES,'Recorded_TXYZ_data')
                                                              app.Recorded_TXYZ_values.Data = temp.HANDLES.Recorded_TXYZ_data;
                                                        else
                                                              Trec = (1:app.nT)';
                                                              Xrec = NaN(app.nT,1);
                                                              Yrec = NaN(app.nT,1);
                                                              Zrec = NaN(app.nT,1);
                                                              ColumnNames = {'T','X','Y','Z'};       
                                                              RT = table(Trec,Xrec,Yrec,Zrec,'VariableNames',ColumnNames);                
                                                              app.Recorded_TXYZ_values.Data = RT;
                                                              app.Recorded_TXYZ_values.ColumnWidth = {'1x','2x','2x','2x'};
                                                        end

                                                        warning off
                                            %-------------------------------------------------------------------------------------------
                                            % This is a fix for the old TrackData format. Should have no effect on new format TrackData
                                                         FillInMissingCellsWithEmptyCells(app)
                                            %-------------------------------------------------------------------------------------------
                                                 
                                                             app.position = []; %temp.HANDLES.position;
          
                                                          app.NumPrevious = temp.HANDLES.NumPrevious;
                                                              app.NumPost = temp.HANDLES.NumPost;
                                                    app.microns_per_pixel = temp.HANDLES.microns_per_pixel;
                                                          app.z_step_size = temp.HANDLES.z_step_size;
                                                        app.time_interval = temp.HANDLES.time_interval;
            
                                                    if ~isempty(app.TrackData)
                                                        TS = SummarizeTrackData(app);
                                                        app.SetNum = 1; 
                                                        app.nCells = TS.Data{app.SetNum}.MaxNumCells;
                                                    end
                                                    app.CellIndex = 1;
                                                    set(app.ncells_display,'Value',num2str(app.nCells))
            
                                                    set(app.turnon_threshold,'Value',app.doThreshold)
                                                    if app.doThreshold
                                                        set(app.turnon_threshold,'Text',['Threshold: ',num2str(app.threshold_value)])
                                                        set(app.threshold_slider,'Value',app.threshold_value,'Enable','on')
                                                    end
            
                                                    set(app.turnon_binarize,'Value',app.doBinarize)
                                                    set(app.turnon_erode,'Value',app.doErode)
                                                    if app.doErode
                                                        set(app.turnon_erode,'Text',['Erode: ',num2str(app.nErode)])
                                                        set(app.erode_slider,'Value',app.nErode,'Enable','on')
                                                    end
            
                                                    set(app.turnon_hbreak,'Value',app.doHbreak)
                                                    set(app.ncells_display,'Value',num2str(app.nCells))
                                                    set(app.set_num_display,'Value',num2str(app.SetNum))
                                                    set(app.z_display,'Value',num2str(app.Z))
                                                    set(app.nZ_display,'Text',['nZ = ',num2str(app.nZ)])
                                                    set(app.t_display,'Value',num2str(app.T))
                                                    set(app.images_directory_display,'Text',app.directory)
            
            
                                                    set(app.cell_fill_display,'BackgroundColor',app.cell_fill_color)
                                                    set(app.prev_cell_color_display,'BackgroundColor',app.previous_cell_outline_color)
                                                    set(app.cell_outline_color_display,'BackgroundColor',app.cell_outline_color)
                                                    set(app.post_cell_color_display,'BackgroundColor',app.post_cell_outline_color)
                                                    set(app.line_width_listbox,'Value',(app.line_width-0.5)/0.5);
                                                    set(app.sa_rgb_check,'Value',app.sa)
                                                    set(app.z_line_color_display,'BackgroundColor',app.z_color)
                                                    set(app.auto_load_z_check,'Value',app.auto_load_z)
                                                    set(app.tm1_figure,'Value',app.show_tm1)
                                                    set(app.tp1_figure,'Value',app.show_tp1)
                                                    set(app.apply_to_all,'Value',app.ApplyAll)
                                                    set(app.set_num_display,'Value',num2str(app.SetNum))
                                                    set(app.show_H_cross_sections_check,'Value',false)%app.show_H_cross_section)
                                                    set(app.show_V_cross_sections_check,'Value',app.show_V_cross_section)
            
                                                    if app.show_H_cross_section || app.show_V_cross_section
                                                        set(app.cross_section_width_text,'Text',['CS Width: ' num2str(app.cross_section_window_width) '%'],'Enable','on')
                                                        set(app.cross_section_width_slider,'Value',app.cross_section_window_width,'Enable','on')
                                                    else
                                                        set(app.cross_section_width_text,'Text','CS Width','Enable','off')
                                                        set(app.cross_section_width_slider,'Enable','off','Value',app.cross_section_window_width)
                                                    end
            
                                                    if app.show_H_cross_section
                                                        set(app.TB_placement,'Enable','on')
                                                    else
                                                        set(app.TB_placement,'Enable','off')
                                                    end
            
                                                    if app.show_V_cross_section
                                                        set(app.LR_placement,'Enable','on')
                                                    else
                                                        set(app.LR_placement,'Enable','off')
                                                    end
            
                                                    set(app.TB_placement,'Value',app.TB)
                                                    set(app.LR_placement,'Value',app.LR)
                                                    set(app.show_previous_check,'Value',app.show_previous)
                                                    set(app.show_previous_display,'Value',num2str(app.NumPrevious))
                                                    if app.show_previous
                                                         set(app.show_previous_increase_button,'Enable','on')
                                                         set(app.show_previous_decrease_button,'Enable','on')
                                                         set(app.show_previous_display,'Enable','on')
                                                    else
                                                         set(app.show_previous_increase_button,'Enable','off')
                                                         set(app.show_previous_decrease_button,'Enable','off')
                                                         set(app.show_previous_display,'Enable','off')
                                                    end
                                                    set(app.show_post_check,'Value',app.show_post)
                                                    set(app.show_post_display,'Value',num2str(app.NumPost))
                                                    if app.show_post
                                                         set(app.show_post_increase_button,'Enable','on')
                                                         set(app.show_post_decrease_button,'Enable','on')
                                                         set(app.show_post_display,'Enable','on')
                                                    else
                                                         set(app.show_post_increase_button,'Enable','off')
                                                         set(app.show_post_decrease_button,'Enable','off')
                                                         set(app.show_post_display,'Enable','off')
                                                    end
%                                                     set(app.main_window_slider_text,'Text',['Main Figure: ' num2str(app.main_window_size)])
%                                                     set(app.main_window_slider,'Value',app.main_window_size)
                                                    
                                                    set(app.main_window_slider,'Value',100);
                                                    app.main_window_size = 100;
                                                    set(app.main_window_slider_text,'Text',['Main Figure: ' num2str(100) '%'])

                                                    if app.show_tp1
                                                        set(app.tp1_slider,'Enable','on')
                                                    else
                                                        set(app.tp1_slider,'Enable','off')
                                                    end
            
                                                    if app.show_tm1
                                                        set(app.tm1_slider,'Enable','on')
                                                    else
                                                        set(app.tm1_slider,'Enable','off')
                                                    end
            
                                                    %Advanced RGB Display
                                                    if app.sa
                                                        set(app.r_channel_color_button,'Enable','on')
                                                        set(app.g_channel_color_button,'Enable','on')
                                                        set(app.b_channel_color_button,'Enable','on')
                                                        set(app.red_color_display,'Visible','on','BackgroundColor',app.r_color(256,:))
                                                        set(app.green_color_display,'Visible','on','BackgroundColor',app.g_color(256,:))
                                                        set(app.blue_color_display,'Visible','on','BackgroundColor',app.b_color(256,:))
                                                    else
                                                        set(app.r_channel_color_button,'Enable','off')
                                                        set(app.g_channel_color_button,'Enable','off')
                                                        set(app.b_channel_color_button,'Enable','off')
                                                        set(app.red_color_display,'Visible','off')
                                                        set(app.green_color_display,'Visible','off')
                                                        set(app.blue_color_display,'Visible','off')
            
                                                    end
            
                                                  set(app.turnon_red_channel,'Value',app.show_r_channel);
                                                  set(app.turnon_green_channel,'Value',app.show_g_channel);
                                                  set(app.turnon_blue_channel,'Value',app.show_b_channel);
            
                                                % Make panels visible if user chose some files %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
                                                  set(app.functions,'Visible','on')
                                                  set(app.tracking_panel,'Visible','on')
                                                  set(app.time_and_z_panel,'Visible','on')
                                                  set(app.other_figures_panel,'Visible','on')
                                                  set(app.rgb_panel,'Visible','on')
                                                  set(app.cross_section_display_panel,'Visible','on')
                                                  set(app.cell_color_panel,'Visible','on')
            
                                                  set(app.reset_button,'Enable','on')
                                                  set(app.save_button,'Enable','on')
                                                  set(app.saveas_button,'Enable','on')
                                                  set(app.export_button,'Enable','on')
                                                  %set(app.cell_data_editor,'Enable','on')
                                                  set(app.microns_per_pixel_display,'Enable','on','Value',num2str(app.microns_per_pixel))
                                                  set(app.microns_per_pixel_text,'Enable','on')
                                                  set(app.z_section_step_size_display,'Enable','on','Value',num2str(app.z_step_size))
                                                  set(app.z_section_step_size_text,'Enable','on')
                                                  set(app.time_interval_display,'Enable','on','Value',num2str(app.time_interval))
                                                  set(app.time_interval_text,'Enable','on')
                                                  set(app.cell_summary_button,'Enable','on')


                                                  % Reset/Initialize Variables
                                                  %----------------------------------------------------------------------------
                                                  load_images(app);
                                                  update_display(app);
                                                  drawnow
                                                  %----------------------------------------------------------------------------
                                                  if app.show_H_cross_section && ~ishandle(app.horizontal_cs_panel)
                                                        create_figure(app,4);
                                                  end
                                                  %----------------------------------------------------------------------------
                                                  if app.show_V_cross_section && ~ishandle(app.vertical_cs_panel)
                                                        create_figure(app,5);
                                                  end
                                                  %----------------------------------------------------------------------------
                                                  if app.show_V_cross_section || app.show_H_cross_section
                                                      update_cross_section_display(app)
                                                  end
            
                                                  % Update CELL SUMMARY window if it's open
                                                   if isgraphics(app.CellSummaryWindow)
                                                        DS = SummarizeTrackData(app);
                                                        SetCellList = CreateCompleteCellList(app,DS);
%                                                         CellSummaryWindowapp = guidata(app.CellSummaryWindow);
%                                                         CellSummaryWindowapp.selected_cell_color = app.selected_cell_color;
                                                        set(CellSummaryWindowapp.selected_cell_color_display,'BackgroundColor',app.selected_cell_color)
                                                        set(CellSummaryWindowapp.Cell_Summary_Display,'Value',SetCellList,'Value',1)
                                                   end
                                              end
                     end

        end

        % Button pushed function: location_button
        function location_button_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, app] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
                        
                     directory_name = uigetdir([],'Select image data folder');
            
                     if ~isequal(directory_name,0)
                            %[files,~] = files_and_folders(app, directory_name);
                            F = struct2cell( dir(directory_name) )';
                            IsFile = ~cell2mat(F(:,5)); 
                            
                            files = F(IsFile,1); % Grab all the files in the selected directory
                            
                            [Selection, ok] = listdlg('ListString',files,'ListSize',[250 400],'PromptString','Select image files','Name','Select image files');
                            
                            if isequal(ok,1)

                                      % Delete windows that may be from a different data set and reset main control checkboxes ------------------------------
                                        if ishandle(app.main_panel);           delete(app.main_panel);             app.main_panel = -1;            end
                                        if ishandle(app.tm1_z_panel);          delete(app.tm1_z_panel);            app.tm1_z_panel = -1;           end
                                        if ishandle(app.tp1_z_panel);          delete(app.tp1_z_panel);            app.tp1_z_panel = -1;           end
                                        if ishandle(app.vertical_cs_panel);    delete(app.vertical_cs_panel);      app.vertical_cs_panel = -1;     end
                                        if ishandle(app.horizontal_cs_panel);  delete(app.horizontal_cs_panel);    app.horizontal_cs_panel = -1;   end
                                        if ishandle(app.CellSummaryWindow);    delete(app.CellSummaryWindow);      app.CellSummaryWindow = -1;     end
                                            set(app.show_H_cross_sections_check,'Value',0);
                                            set(app.show_V_cross_sections_check,'Value',0);
                                            set(app.LR_placement,'Enable','off')
                                            set(app.TB_placement,'Enable','off')
                                            set(app.cross_section_width_text,'Enable','off')
                                            set(app.cross_section_width_slider,'Enable','off')
                                            set(app.tp1_figure,'Value',0);
                                            set(app.tp1_slider,'Enable','off')
                                            set(app.tp1_figure,'Text','t+1, z')
                                            set(app.tm1_figure,'Value',0);
                                            set(app.tm1_slider,'Enable','off')
                                            set(app.tm1_figure,'Text','t+1, z')
                                            app.show_tp1 = false;
                                            app.show_tm1 = false;
                                      %-----------------------------------------------------------------------------------------------------------------------

                                      app.directory = directory_name; % Record directory
                                      N = length(Selection);
                                      app.nT = N;
                                      app.filenames = files(Selection,1);
                                      app.filenames = MakeSureImageFilesAreSortedByTimestamp(app,app.filenames); 
                                      info = imfinfo(fullfile(app.directory, app.filenames{1,1})); % Count number of images in first multitiff file
                                      if ~strcmp(info(1).ColorType,'grayscale')
                                              app.nZ = length(info);   % Record number of Z
                                              app.ImWidth  = info(1).Width;
                                              app.ImHeight = info(1).Height;
                    
                                              % Make panels visible if user chose some files %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                                              set(app.functions,'Visible','on')
                                              set(app.tracking_panel,'Visible','on')
                                              set(app.time_and_z_panel,'Visible','on')
                                              set(app.other_figures_panel,'Visible','on')
                                              set(app.rgb_panel,'Visible','on')
                                              set(app.cross_section_display_panel,'Visible','on')
                                              set(app.cell_color_panel,'Visible','on')
                    
                                              set(app.reset_button,'Enable','on')
                                              set(app.save_button,'Enable','off')
                                              set(app.saveas_button,'Enable','on')
                                              set(app.export_button,'Enable','on')
                                              %set(app.cell_data_editor,'Enable','on')
                    
                                              set(app.microns_per_pixel_display,'Enable','on')
                                              set(app.microns_per_pixel_text,'Enable','on')
                                              set(app.z_section_step_size_text,'Enable','on')
                                              set(app.z_section_step_size_display,'Enable','on')
                                              set(app.time_interval_display,'Enable','on')
                                              set(app.time_interval_text,'Enable','on')
                                              set(app.cell_summary_button,'Enable','on')
                                              % Reset/Initialize Variables %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    
                                              app.SetNum = 1;
                                              app.nCells = 1;
                                              app.CellIndex = 1;
                                              app.T = 1;
                                              app.Tprev = 1;
                                              app.Z = 1;
        
                                              % Intialize track data variable ==========================
                                              for T = 1:app.nT
                                                    app.TrackData{app.SetNum}{T} = cell(1);
                                              end
                                              
                                              app.vertical_cs_panel = -1;
                                              app.horizontal_cs_panel = -1;
        
                                                 app.Timage    = uint8([]);
                                                 app.TP1image  = uint8([]);
                                                 app.TM1image  = uint8([]);
                                                 app.red_T     = uint8([]);
                                                 app.red_TM1   = uint8([]);
                                                 app.red_TP1   = uint8([]);
                                                 app.green_T   = uint8([]);
                                                 app.GREEN     = uint8([]);
                                                 app.green_TM1 = uint8([]);
                                                 app.green_TP1 = uint8([]);
                                                 app.blue_T    = uint8([]);
                                                 app.blue_TM1  = uint8([]);
                                                 app.blue_TP1  = uint8([]);
                                                 app.RGB_T     = uint8([]);
                                                 app.RGB_TM1   = uint8([]);
                                                 app.RGB_TP1   = uint8([]);


                                                  Trec = (1:app.nT)';
                                                  Xrec = NaN(app.nT,1);
                                                  Yrec = NaN(app.nT,1);
                                                  Zrec = NaN(app.nT,1);
                                                  ColumnNames = {'T','X','Y','Z'};       
                                                  RT = table(Trec,Xrec,Yrec,Zrec,'VariableNames',ColumnNames);                
                                                  app.Recorded_TXYZ_values.Data = RT;
                                                  app.Recorded_TXYZ_values.ColumnWidth = {'1x','2x','2x','2x'};

                                              set(app.main_window_slider,'Value',100);
                                              app.main_window_size = 100;
                                              set(app.main_window_slider_text,'Text',['Main Figure: ' num2str(100) '%'])
        
                                              set(app.t_display,'Value','1')
                                              set(app.z_display,'Value','1')
                                              set(app.ncells_display,'Value','1')
                                              set(app.set_num_display,'Value','1')
                    
                                              set(app.nZ_display,'Text',['nZ = ' num2str(app.nZ)],'Visible','on')
                                              set(app.images_directory_display,'Text',app.directory)
                                              drawnow
                                              load_images(app)
                                              update_display(app)
                                              %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                                      
                                      else
                                              warndlg('Longtracker image data is not RGB!','Not-RGB!');
                                              set(app.functions,'Visible','off')
                                              set(app.tracking_panel,'Visible','off')
                                              set(app.time_and_z_panel,'Visible','off')
                                              set(app.other_figures_panel,'Visible','off')
                                              set(app.rgb_panel,'Visible','off')
                                              set(app.cross_section_display_panel,'Visible','off')
                                              set(app.cell_color_panel,'Visible','off')
                                      end
            
                            end
                     end
                    
        end

        % Close request function: main
        function main_CloseRequestFcn(app, event)
           
                     
            answer = questdlg({'Are you sure you sure you want to quit Longtracker?','Be sure to save any new cell tracks before quitting!'},'Quit Longtracker?', 'Yes','No','No');      

            if strcmp(answer,'Yes')
            
                    if ishandle(app.main_panel);   delete(app.main_panel);     end
                    if ishandle(app.tm1_z_panel);  delete(app.tm1_z_panel);    end
                    if ishandle(app.tp1_z_panel);  delete(app.tp1_z_panel);    end
                    if ishandle(app.vertical_cs_panel);     delete(app.vertical_cs_panel);    end
                    if ishandle(app.horizontal_cs_panel);   delete(app.horizontal_cs_panel);  end
                    if ishandle(app.CellSummaryWindow);     delete(app.CellSummaryWindow);    end
            
                    delete(app.main)
            end
                    
        end

        % Value changed function: main_window_slider
        function main_window_slider_Callback(app, event)
                     

                      v = get(app.main_window_slider,'Value');
                      v = 10*round(v/10); % Round size to nearest 10 percent
                      app.main_window_size = v;
                      set(app.main_window_slider,'Value',v)
                      set(app.main_window_slider_text,'Text',['Main Figure: ' num2str(v) '%'])

                            update_display(app)
                            update_cross_section_display(app)

                      
        end

        % Value changed function: microns_per_pixel_display
        function microns_per_pixel_display_Callback(app, event)
                       
                val = str2double(get(app.microns_per_pixel_display,'Value'));
            
                if ~isempty(val)
                    app.microns_per_pixel = val;
                else
                    set(app.microns_per_pixel_display,'Value',num2str(app.microns_per_pixel))
                end

                
        end

        % Button pushed function: ncells_down
        function ncells_down_Callback(app, event)
            

                      if app.nCells > 1
                                answer = questdlg(['Are you sure you sure you want to reduce the number of cells for this set? Any cell track information contained in cell ',...
                                                    num2str(app.nCells),' of set ',num2str(app.SetNum),' will be deleted.'], 'Reduce nCells for this set?', 'Yes','No','No');  
                                if strcmp(answer,'Yes')
                                    app.nCells = app.nCells - 1;
                                    for T = 1:app.nT
                                        app.TrackData{app.SetNum}{T} = app.TrackData{app.SetNum}{T}(1:app.nCells);
                                    end
                                    set(app.ncells_display,'Value',num2str(app.nCells))
                                end

                                if isgraphics(app.CellSummaryWindow)
                                    DS = SummarizeTrackData(app);
                                    CellSummaryList = CreateCompleteCellList(app, DS);
                                    CellSummaryListBoxHandle = findobj(app.CellSummaryWindow.Children,'Tag','Cell_Summary_Display');
                                    set(CellSummaryListBoxHandle,'Items',CellSummaryList,'ItemsData',1:length(CellSummaryList),'Value',1)
                                end      
                      end
                      update_display(app)

                      
        end

        % Button pushed function: ncells_up
        function ncells_up_Callback(app, event)
                     
                      app.nCells = app.nCells + 1;
                      for T = 1:app.nT
                           app.TrackData{app.SetNum}{T} = [app.TrackData{app.SetNum}{T}, cell(1)];
                      end
                      set(app.ncells_display,'Value',num2str(app.nCells))

                      update_display(app)

                      if isgraphics(app.CellSummaryWindow)
                          DS = SummarizeTrackData(app);
                          CellSummaryList = CreateCompleteCellList(app, DS);
                          CellSummaryListBoxHandle = findobj(app.CellSummaryWindow.Children,'Tag','Cell_Summary_Display');
                          set(CellSummaryListBoxHandle,'Items',CellSummaryList,'ItemsData',1:length(CellSummaryList),'Value',1)
                      end                        

                      
        end

        % Button pushed function: post_cell_color_button
        function post_cell_color_button_Callback(app, event)
           
                 c = uisetcolor;
                 if ~isequal(c,0)
                      app.post_cell_outline_color = c;
                      set(app.post_cell_color_display,'BackgroundColor',c)
                      update_display(app)
                 end
                 
        end

        % Button pushed function: prev_cell_color_button
        function prev_cell_color_button_Callback(app, event)
          
                 c = uisetcolor;
                 if ~isequal(c,0)
                      app.previous_cell_outline_color = c;
                      set(app.prev_cell_color_display,'BackgroundColor',c)
                      update_display(app)
                 end
                 
        end

        % Button pushed function: r_channel_color_button
        function r_channel_color_button_Callback(app, event)
           
                 c = uisetcolor;
                 if ~isequal(c,0)
                      v = (0:(1/255):1)';
                      app.r_color = v*c;
                      set(app.red_color_display,'BackgroundColor',c)
                      update_display(app)
                      update_cross_section_display(app)
                 end
                 
        end

        % Button pushed function: reset_button
        function reset_button_Callback(app, event)
                       
                    button = questdlg('Do you really want to clear all your cell tracking data?','Reset?','Yes','No','No');
                    if isequal(button,'Yes')
                        app.T = 1;
                       % app.Told = app.T;
                        set(app.t_display,'Value','1')
                        app.Z = 1;
                       % app.Zold = app.Z;
                        set(app.z_display,'Value','1')
                        app.SetNum = 1;
                        set(app.set_num_display,'Value',num2str(app.SetNum))
                        app.CellIndex = 1;
                        app.nCells = 1;
                        set(app.ncells_display,'Value',num2str(app.CellIndex))
                        app.TrackData = [];
                        load_images(app)
                        update_display(app)
                    end
                    
        end

        % Value changed function: sa_rgb_check
        function sa_rgb_check_Callback(app, event)
          
                app.sa = logical(get(app.sa_rgb_check,'Value'));
            
                if app.sa
                    set(app.r_channel_color_button,'Enable','on')
                    set(app.g_channel_color_button,'Enable','on')
                    set(app.b_channel_color_button,'Enable','on')
                    set(app.red_color_display,'Visible','on')
                    set(app.green_color_display,'Visible','on')
                    set(app.blue_color_display,'Visible','on')
                else
                    set(app.r_channel_color_button,'Enable','off')
                    set(app.g_channel_color_button,'Enable','off')
                    set(app.b_channel_color_button,'Enable','off')
                    set(app.red_color_display,'Visible','off')
                    set(app.green_color_display,'Visible','off')
                    set(app.blue_color_display,'Visible','off')
            
                end
            
                update_display(app)
                update_cross_section_display(app)
            
        end

        % Button pushed function: save_button
        function save_button_Callback(app, event)
                       
                    
                    %------------------------------------------------------------------------------
                    HANDLES = [];
                                            HANDLES.directory = app.directory;
                                            HANDLES.filenames = app.filenames;

                                          HANDLES.doThreshold = app.doThreshold;
                                           HANDLES.doBinarize = app.doBinarize;
                                              HANDLES.doErode = app.doErode;
                                             HANDLES.doHbreak = app.doHbreak;
                                               HANDLES.SetNum = app.SetNum;
                                             HANDLES.TrackNum = app.SetNum; % Just incase the saved file is read into the older version of longtracker
                                               HANDLES.nCells = app.nCells;
                                            HANDLES.CellIndex = app.CellIndex;
                                            HANDLES.TrackData = app.TrackData;
                                                    HANDLES.T = app.T;
                                                    HANDLES.Z = app.Z;
                                                   HANDLES.LR = app.LR;
                                                   HANDLES.TB = app.TB;
                                      HANDLES.threshold_value = app.threshold_value;
                                               HANDLES.nErode = app.nErode;
                                                   HANDLES.SE = app.SE;
                                        HANDLES.show_previous = app.show_previous;
                                            HANDLES.show_post = app.show_post;
                                 HANDLES.show_V_cross_section = app.show_V_cross_section;
                                 HANDLES.show_H_cross_section = app.show_H_cross_section;
                           HANDLES.cross_section_window_width = app.cross_section_window_width;
                                     HANDLES.main_window_size = app.main_window_size;
                                    HANDLES.tp1_z_window_size = app.tp1_z_window_size;
                                             HANDLES.show_tp1 = app.show_tp1;

                                    HANDLES.tm1_z_window_size = app.tm1_z_window_size;
                                             HANDLES.show_tm1 = app.show_tm1;
                                             HANDLES.ApplyAll = app.ApplyAll;
                                       HANDLES.show_r_channel = app.show_r_channel;
                                       HANDLES.show_g_channel = app.show_g_channel;
                                       HANDLES.show_b_channel = app.show_b_channel;
                                            HANDLES.do_magnet = app.do_magnet;
                                                   HANDLES.sa = app.sa;

                                              HANDLES.r_color = app.r_color;
                                              HANDLES.g_color = app.g_color;
                                              HANDLES.b_color = app.b_color;
                                              HANDLES.z_color = app.z_color;
                                      HANDLES.cell_fill_color = app.cell_fill_color;
                                   HANDLES.cell_outline_color = app.cell_outline_color;
                          HANDLES.previous_cell_outline_color = app.previous_cell_outline_color;
                              HANDLES.post_cell_outline_color = app.post_cell_outline_color;
                                  HANDLES.selected_cell_color = app.selected_cell_color;
                                      
                                      HANDLES.line_break_mask = app.line_break_mask;
                                          HANDLES.auto_load_z = app.auto_load_z;
                                             HANDLES.savename = app.savename;
                                           HANDLES.line_width = app.line_width;
                                                   HANDLES.nT = app.nT;
                                                   HANDLES.nZ = app.nZ;
                                              HANDLES.ImWidth = app.ImWidth;
                                             HANDLES.ImHeight = app.ImHeight;
                                                HANDLES.Tprev = app.Tprev;

                                          HANDLES.NumPrevious = app.NumPrevious;
                                              HANDLES.NumPost = app.NumPost;
                                    HANDLES.microns_per_pixel = app.microns_per_pixel;
                                          HANDLES.z_step_size = app.z_step_size;
                                        HANDLES.time_interval = app.time_interval;
                                     HANDLES.main_window_size = app.main_window_size;

                                   HANDLES.Recorded_TXYZ_data = app.Recorded_TXYZ_values.Data;

                    %save(app.savename,'HANDLES')
                    

                            dlg = msgbox('Wait, save operation in progress...');

                            save(app.savename,'HANDLES','-v7.3')

                            if ishghandle(dlg)
                                delete(dlg);
                            end  

                    
        end

        % Button pushed function: saveas_button
        function saveas_button_Callback(app, event)
              
                    [SaveFileName, SavePathName, ~] = uiputfile('*.lgtkr','Save Data','CellTrackData');
            
                    if ~isequal(SaveFileName,0)
                            app.savename = fullfile(SavePathName,SaveFileName);
                            set(app.save_button,'Enable','on')
            
                            %------------------------------------------------------------------------------
                                                      HANDLES = [];

                                            HANDLES.directory = app.directory;
                                            HANDLES.filenames = app.filenames;
                                            
                                          HANDLES.doThreshold = app.doThreshold;
                                           HANDLES.doBinarize = app.doBinarize;
                                              HANDLES.doErode = app.doErode;
                                             HANDLES.doHbreak = app.doHbreak;
                                               HANDLES.SetNum = app.SetNum;
                                             HANDLES.TrackNum = app.SetNum; % Just incase the saved file is read into the older version of longtracker
                                               HANDLES.ncells = app.nCells;
                                            HANDLES.CellIndex = app.CellIndex;
                                            HANDLES.TrackData = app.TrackData;
                                                    HANDLES.T = app.T;
                                                    HANDLES.Z = app.Z;
                                                   HANDLES.LR = app.LR;
                                                   HANDLES.TB = app.TB;
                                      HANDLES.threshold_value = app.threshold_value;
                                               HANDLES.nErode = app.nErode;
                                                   HANDLES.SE = app.SE;
                                        HANDLES.show_previous = app.show_previous;
                                            HANDLES.show_post = app.show_post;
                                 HANDLES.show_V_cross_section = app.show_V_cross_section;
                                 HANDLES.show_H_cross_section = app.show_H_cross_section;
                           HANDLES.cross_section_window_width = app.cross_section_window_width;
                                     HANDLES.main_window_size = app.main_window_size;
                                    HANDLES.tp1_z_window_size = app.tp1_z_window_size;
                                             HANDLES.show_tp1 = app.show_tp1;

                                    HANDLES.tm1_z_window_size = app.tm1_z_window_size;
                                             HANDLES.show_tm1 = app.show_tm1;
                                             HANDLES.ApplyAll = app.ApplyAll;
                                       HANDLES.show_r_channel = app.show_r_channel;
                                       HANDLES.show_g_channel = app.show_g_channel;
                                       HANDLES.show_b_channel = app.show_b_channel;
                                            HANDLES.do_magnet = app.do_magnet;
                                                   HANDLES.sa = app.sa;

                                              HANDLES.r_color = app.r_color;
                                              HANDLES.g_color = app.g_color;
                                              HANDLES.b_color = app.b_color;
                                              HANDLES.z_color = app.z_color;
                                      HANDLES.cell_fill_color = app.cell_fill_color;
                                   HANDLES.cell_outline_color = app.cell_outline_color;
                          HANDLES.previous_cell_outline_color = app.previous_cell_outline_color;
                              HANDLES.post_cell_outline_color = app.post_cell_outline_color;
                                  HANDLES.selected_cell_color = app.selected_cell_color;
                                      
                                      HANDLES.line_break_mask = app.line_break_mask;
                                          HANDLES.auto_load_z = app.auto_load_z;
                                             HANDLES.savename = app.savename;
                                           HANDLES.line_width = app.line_width;
                                                   HANDLES.nT = app.nT;
                                                   HANDLES.nZ = app.nZ;
                                              HANDLES.ImWidth = app.ImWidth;
                                             HANDLES.ImHeight = app.ImHeight;
                                                HANDLES.Tprev = app.Tprev;

                                          HANDLES.NumPrevious = app.NumPrevious;
                                              HANDLES.NumPost = app.NumPost;
                                    HANDLES.microns_per_pixel = app.microns_per_pixel;
                                          HANDLES.z_step_size = app.z_step_size;
                                        HANDLES.time_interval = app.time_interval;

                                   HANDLES.Recorded_TXYZ_data = app.Recorded_TXYZ_values.Data;

                            % save(app.savename,'HANDLES')

                            dlg = msgbox('Wait, save operation in progress...');

                            save(app.savename,'HANDLES','-v7.3')

                            if ishghandle(dlg)
                                delete(dlg);
                            end  
                    end
                  
        end

        % Value changed function: show_H_cross_sections_check
        function show_H_cross_sections_check_Callback(app, event)


                if app.nZ > 1 % Only show vertical cross-sections if nZ > 1
                         drawnow nocallbacks
                         app.show_H_cross_section = logical(get(app.show_H_cross_sections_check,'Value'));
                         switch app.show_H_cross_section
                              case false
                                   if ~app.show_V_cross_section
                                        set(app.cross_section_width_text,'Enable','off')
                                        set(app.cross_section_width_slider,'Enable','off')
                                   end
                    
                                   if ishandle(app.horizontal_cs_panel)
                                        delete(app.horizontal_cs_panel);
                                   end
                    
                                   app.horizontal_cs_panel = -1;
                                   set(app.TB_placement,'Enable','off')
        
                             case true
                                   set(app.cross_section_width_text,'Enable','on','Text',['CS Width: ' num2str(app.cross_section_window_width) '%'])
                                   set(app.cross_section_width_slider,'Enable','on')
                                   set(app.TB_placement,'Enable','on')
                                   create_figure(app, 4)
                                   update_cross_section_display(app)
                         end
                         drawnow
                else
                        
                        app.show_H_cross_sections_check.Value = false;
                        errordlg("Cross-section only shows if nZ > 1","nZ = 1");

                end

              
        end

        % Value changed function: show_V_cross_sections_check
        function show_V_cross_sections_check_Callback(app, event)
                 

                if app.nZ > 1 % Only show vertical cross-sections if nZ > 1
                         drawnow nocallbacks
                         app.show_V_cross_section = logical(get(app.show_V_cross_sections_check,'Value'));
                         switch app.show_V_cross_section
                              case false
                    
                                   if ~app.show_H_cross_section
                                        set(app.cross_section_width_text,'Enable','off')
                                        set(app.cross_section_width_slider,'Enable','off')
                                   end
                    
                                   if ishandle(app.vertical_cs_panel)
                                        delete(app.vertical_cs_panel)
                                   end
                    
                                   app.vertical_cs_panel = -1;
                                   set(app.LR_placement,'Enable','off')
                    
                             case true
                                 
                                   set(app.cross_section_width_text,'Enable','on','Text',['CS Width: ' num2str(app.cross_section_window_width) '%'])
                                   set(app.cross_section_width_slider,'Enable','on')
                                   set(app.LR_placement,'Enable','on')
                                   create_figure(app,5)
                                   update_cross_section_display(app)
                         end
                         drawnow
                else
                        
                        app.show_V_cross_sections_check.Value = false;
                        errordlg("Cross-section only shows if nZ > 1","nZ = 1");

                end


        end

        % Value changed function: show_post_check
        function show_post_check_Callback(app, event)
           
            
                 app.show_post = logical(get(app.show_post_check,'Value'));
                 if app.show_post
                     set(app.show_post_increase_button,'Enable','on')
                     set(app.show_post_decrease_button,'Enable','on')
                     set(app.show_post_display,'Enable','on')
                 else
                     set(app.show_post_increase_button,'Enable','off')
                     set(app.show_post_decrease_button,'Enable','off')
                     set(app.show_post_display,'Enable','off')
                 end
            
                 update_display(app)
                 
        end

        % Button pushed function: show_post_decrease_button
        function show_post_decrease_button_Callback(app, event)
            
            
                    if app.NumPost > 1
                        app.NumPost = app.NumPost - 1;
                        set(app.show_post_display,'Value',num2str(app.NumPost))
                        update_display(app)
                    else
                        set(app.show_post_display,'Value',num2str(app.NumPost))
                    end
                   
        end

        % Value changed function: show_post_display
        function show_post_display_Callback(app, event)
                        
                    val = str2double(get(app.show_post_display,'Value'));
                     if val > 0
                         app.NumPost = ceil(val);
                         set(app.show_post_display,'Value',num2str(val));
                         update_display(app);
                     else
                         set(app.show_post_display,'Value',num2str(app.NumPost))
                     end
                     
        end

        % Button pushed function: show_post_increase_button
        function show_post_increase_button_Callback(app, event)
                                 
                    app.NumPost = app.NumPost + 1;
                    set(app.show_post_display,'Value',num2str(app.NumPost))
                    update_display(app)
                   
        end

        % Value changed function: show_previous_check
        function show_previous_check_Callback(app, event)
                    
                 app.show_previous = logical(get(app.show_previous_check,'Value'));
                 if app.show_previous
                     set(app.show_previous_increase_button,'Enable','on')
                     set(app.show_previous_decrease_button,'Enable','on')
                     set(app.show_previous_display,'Enable','on')
                 else
                     set(app.show_previous_increase_button,'Enable','off')
                     set(app.show_previous_decrease_button,'Enable','off')
                     set(app.show_previous_display,'Enable','off')
                 end
            
                 update_display(app)
                 
        end

        % Button pushed function: show_previous_decrease_button
        function show_previous_decrease_button_Callback(app, event)
                       
                    if app.NumPrevious > 1
                        app.NumPrevious = app.NumPrevious - 1;
                        set(app.show_previous_display,'Value',num2str(app.NumPrevious))
                        update_display(app);
                    else
                        set(app.show_previous_display,'Value',num2str(app.NumPrevious))
                    end
                   
        end

        % Value changed function: show_previous_display
        function show_previous_display_Callback(app, event)
                        
                     val = str2double(get(app.show_previous_display,'Value'));
                     if val > 0
                         app.NumPrevious = ceil(val);
                         set(app.show_previous_display,'Value',num2str(val));
                         update_display(app);
                     else
                         set(app.show_previous_display,'Value',num2str(app.NumPrevious))
                     end
                     
        end

        % Button pushed function: show_previous_increase_button
        function show_previous_increase_button_Callback(app, event)
           
                        app.NumPrevious = app.NumPrevious + 1;
                        set(app.show_previous_display,'Value',num2str(app.NumPrevious))
                        update_display(app)
                  
        end

        % Value changed function: t_display
        function t_display_Callback(app, event)
                        
                      v = get(app.t_display,'Value');
                      if ~all(isletter(v)) % Test if string is numbers and not letters
                           v = round(str2double(v)); % Then convert string to actual number
                           if v >= 1 && v <= app.nT
                                app.Tprev = app.T;
                                app.T = v;
                               % app.Told = v;
                                set(app.t_display,'Value',num2str(v))
                                load_images(app);
                                update_display(app);
                                update_cross_section_display(app);
                           else
                                set(app.t_display,'Value',num2str(app.T))
                           end
                      else
                           set(app.t_display,'Value',num2str(app.T))
                      end
                      
        end

        % Button pushed function: t_down
        function t_down_Callback(app, event)
           
                      T = app.T;
                          % Told = app.Told; % 'Told' is a check to make sure the Function has completed execution before executing again. Do to pressing keys too fast
            
                          % if isequal(T,Told)
            
                          if app.T > 1
                               app.Tprev = app.T;
                               app.T = app.T - 1;
                               set(app.t_display,'Value',num2str(app.T))
                               app.line_break_mask = [];
                          end
            
                          if app.auto_load_z
                            [isdim,notempty] = iscelldim(app, app.TrackData,app.SetNum,app.T,app.CellIndex);
                            if isdim && notempty
                                app.Z = app.TrackData{app.SetNum}{app.T}{app.CellIndex}.Z;
                                set(app.z_display,'Value',num2str(app.Z))
                            elseif app.T > 1
                                [isdim,notempty] = iscelldim(app, app.TrackData,app.SetNum,app.T-1,app.CellIndex);
                                if isdim && notempty
                                    app.Z = app.TrackData{app.SetNum}{app.T-1}{app.CellIndex}.Z;
                                    set(app.z_display,'Value',num2str(app.Z))
                                end
                            end
                          end
            
                          load_images(app)
                          update_display(app)
                          update_cross_section_display(app)


        end

        % Button pushed function: t_up
        function t_up_Callback(app, event)
                      
                      T = app.T;
                      %Told = app.Told; % 'Told' is a check to make sure the Function has completed execution before executing again. Do to pressing keys too fast
            
                      %if isequal(T,Told)
                          if app.T < app.nT
                               app.Tprev = app.T;
                               app.T = app.T + 1;
                               set(app.t_display,'Value',num2str(app.T))
                               app.line_break_mask = [];
                          end
            
                          if app.auto_load_z
                                [isdim,notempty] = iscelldim(app, app.TrackData,app.SetNum,app.T,app.CellIndex);
                                if isdim && notempty
                                        app.Z = app.TrackData{app.SetNum}{app.T}{app.CellIndex}.Z;
                                        set(app.z_display,'Value',num2str(app.Z))
                                elseif app.T > 1
                                        [isdim,notempty] = iscelldim(app, app.TrackData,app.SetNum,app.T-1,app.CellIndex);
                                        if isdim && notempty
                                            app.Z = app.TrackData{app.SetNum}{app.T-1}{app.CellIndex}.Z;
                                            set(app.z_display,'Value',num2str(app.Z))
                                        end
                                end
                          end
            
                          load_images(app)
                          update_display(app)
                          update_cross_section_display(app)


        end

        % Value changed function: threshold_slider
        function threshold_slider_Callback(app, event)
                      
                 v = round(get(app.threshold_slider,'Value'));
                 set(app.threshold_slider,'Value',v)
                 app.threshold_value = v;
                 set(app.turnon_threshold,'Text',['Threshold: ' num2str(v)])
                 update_display(app)
                 
                 if isfield(app,'position')
                     feval(app.FigFuncHandles{3,1},'normal',event,app) 
                     %SelectCell(app,'normal','event')
                 end
                 
                 update_display(app)
                 update_cross_section_display(app)
                
        end

        % Value changed function: time_interval_display
        function time_interval_display_Callback(app, event)
                       
                val = str2double(get(app.time_interval_display,'Value'));
            
                if ~isempty(val)
                    app.time_interval = val;
                else
                    set(app.time_interval_display,'Value',num2str(app.time_interval))
                end
                
        end

        % Value changed function: tm1_figure
        function tm1_figure_Callback(app, event)
              
            drawnow nocallbacks     
                      v = get(app.tm1_figure,'Value');
                      switch v
                           case 0
                                app.show_tm1 = false;
                                if isgraphics(app.tm1_z_panel)
                                    delete(app.tm1_z_panel)
                                end
                                app.tm1_z_panel = -1;
                                set(app.tm1_slider,'Enable','off')
                                set(app.tm1_figure,'Text','t-1, z')
                           case 1
                                app.show_tm1 = true;
                                set(app.tm1_slider,'Enable','on')
                                set(app.tm1_figure,'Text',['t-1, z: ' num2str(app.tm1_z_window_size) '%'])
                      end
            
                      update_display(app)
             drawnow
                      
        end

        % Value changed function: tm1_slider
        function tm1_slider_Callback(app, event)
                       
                      v = get(app.tm1_slider,'Value');
                      v = 10*round(v/10);
                      set(app.tm1_slider,'Value',v)
                      app.tm1_z_window_size = v;
                      set(app.tm1_figure,'Text',['t-1, z: ' num2str(app.tm1_z_window_size) '%'])
            
                      update_display(app)
                      
        end

        % Value changed function: tp1_figure
        function tp1_figure_Callback(app, event)
                       
                    drawnow nocallbacks

                      v = get(app.tp1_figure,'Value');
                      switch v
                           case 0
                                app.show_tp1 = false;
                                if isgraphics(app.tp1_z_panel)
                                    delete(app.tp1_z_panel)
                                end
                                app.tp1_z_panel = -1;
                                set(app.tp1_slider,'Enable','off')
                                set(app.tp1_figure,'Text','t+1, z')
                           case 1
                                app.show_tp1 = true;
                                set(app.tp1_slider,'Enable','on')
                                set(app.tp1_figure,'Text',['t+1, z: ' num2str(app.tp1_z_window_size) '%'])
                      end
                        
                      update_display(app)

                      drawnow
                      
        end

        % Value changed function: tp1_slider
        function tp1_slider_Callback(app, event)
                       
                      v = get(app.tp1_slider,'Value');
                      v = 10*round(v/10);
                      set(app.tp1_slider,'Value',v)
                      app.tp1_z_window_size = v;
                      set(app.tp1_figure,'Text',['t+1, z: ' num2str(app.tp1_z_window_size) '%'])
                      
                      update_display(app)
                      
        end

        % Button pushed function: set_index_down
        function set_index_down_Callback(app, event)
           
                      TS = SummarizeTrackData(app);
            
                      if app.SetNum > 1
                           app.SetNum = app.SetNum - 1;
                           app.CellIndex = 1;
                           app.nCells = TS.Data{app.SetNum}.MaxNumCells;
                           
                           set(app.set_num_display,'Value',num2str(app.SetNum))
                           set(app.ncells_display, 'Value',num2str(app.nCells))
                           set(app.ncells_display, 'Value',num2str(app.nCells))
                      end
            
                      update_display(app)
                      
        end

        % Button pushed function: set_index_up
        function set_index_up_Callback(app, event)
                       
                      TS = SummarizeTrackData(app);
            
                      if  app.SetNum >= TS.nSets
                          app.SetNum = app.SetNum + 1;
                          app.nCells = 1;
                          app.CellIndex = 1;
                          % Add empty cells at each time point ----------
                          app.TrackData{app.SetNum} = cell(1,app.nT);
                          for T = 1:app.nT
                                app.TrackData{app.SetNum}{T} = cell(1);
                          end
                          % ---------------------------------------------
                      else
                          app.SetNum = app.SetNum + 1;
                          app.nCells = TS.Data{app.SetNum}.MaxNumCells;
                          app.CellIndex = 1;
                      end
                      set(app.set_num_display,'Value',num2str(app.SetNum))
                      set(app.ncells_display, 'Value',num2str(app.nCells))
            
                      update_display(app)

                      if isgraphics(app.CellSummaryWindow)
                          DS = SummarizeTrackData(app);
                          CellSummaryList = CreateCompleteCellList(app, DS);
                          CellSummaryListBoxHandle = findobj(app.CellSummaryWindow.Children,'Tag','Cell_Summary_Display');
                          set(CellSummaryListBoxHandle,'Items',CellSummaryList,'ItemsData',1:length(CellSummaryList),'Value',1)
                      end  

                      
        end

        % Value changed function: turnon_binarize
        function turnon_binarize_Callback(app, event)
                        
                 v = get(app.turnon_binarize,'Value');
                 app.doBinarize = logical(v);
                 update_display(app);
                 if isfield(app,'position')
                     feval(app.FigFuncHandles{3,1},'normal',event,app) 
                     % SelectCell(app,'normal','event')
                 end
                 update_display(app)
                 update_cross_section_display(app)
                 
        end

        % Value changed function: turnon_blue_channel
        function turnon_blue_channel_Callback(app, event)
                       
                 v = get(app.turnon_blue_channel,'Value');
                 app.show_b_channel = logical(v);
                 update_display(app)
                 update_cross_section_display(app)
                 
        end

        % Value changed function: turnon_erode
        function turnon_erode_Callback(app, event)
                       
            
                 v = get(app.turnon_erode,'Value');
            
                 switch v
                      case 0
                           app.doErode = false;
                           set(app.turnon_erode,'Text','Erode')
                           set(app.erode_slider,'Enable','off')
                           update_display(app);
                           if isfield(app,'position')
                               feval(app.FigFuncHandles{3,1},'normal',event,app) 
                               % SelectCell(app,'normal','event')
                           end
                           update_display(app)
                           update_cross_section_display(app)
                      case 1
                           app.doErode = true;
                           set(app.turnon_erode,'Text',['Erode: ' num2str(app.nErode)])
                           set(app.erode_slider,'Enable','on')
                           update_display(app);
                           if isfield(app,'position')
                               feval(app.FigFuncHandles{3,1},'normal',event,app) 
                               % SelectCell(app,'normal','event')
                           end
                           update_display(app)
                           update_cross_section_display(app)
                 end

            
        end

        % Value changed function: turnon_green_channel
        function turnon_green_channel_Callback(app, event)
                       
                 v = get(app.turnon_green_channel,'Value');
                 app.show_g_channel = logical(v);
                 update_display(app)
                 update_cross_section_display(app)
                 
        end

        % Value changed function: turnon_hbreak
        function turnon_hbreak_Callback(app, event)
                     
                    app.doHbreak = logical(get(app.turnon_hbreak,'Value'));
                    update_display(app)
                    if isfield(app,'position')
                        feval(app.FigFuncHandles{3,1},'normal',event,app) 
                        %SelectCell(app, 'normal','event')
                    end
                    update_display(app)
                    update_cross_section_display(app)

            
        end

        % Value changed function: turnon_magnet_function
        function turnon_magnet_function_Callback(app, event)
                       
                 v = get(app.turnon_magnet_function,'Value');
                 switch v
                      case 0
                           set(app.turnon_magnet_function,'Value',0,'Text','Magnet OFF')
                           app.do_magnet = false;
                      case 1
                           set(app.turnon_magnet_function,'Value',1,'Text','Magnet ON')
                           app.do_magnet = true;
                 end
                 
        end

        % Value changed function: turnon_red_channel
        function turnon_red_channel_Callback(app, event)
           
                 v = get(app.turnon_red_channel,'Value');
                 app.show_r_channel = logical(v);
                 update_display(app)
                 update_cross_section_display(app)
                 
        end

        % Value changed function: turnon_threshold
        function turnon_threshold_Callback(app, event)
                       
                 v = get(app.turnon_threshold,'Value');
            
                 switch v
                      case 0
                           app.doThreshold = false;
                           set(app.turnon_threshold,'Text','Threshold')
                           set(app.threshold_slider,'Enable','off')
                           update_display(app)
                           if isfield(app,'position')
                                feval(app.FigFuncHandles{3,1},'normal','event',app)
                                %SelectCell(app, 'normal','event')
                           end
                           update_display(app)
                           update_cross_section_display(app)
                      case 1
                           app.doThreshold = true;
                           set(app.turnon_threshold,'Text',['Threshold: ' num2str(app.threshold_value)])
                           set(app.threshold_slider,'Enable','on')
                           update_display(app)
                           if isfield(app,'position')
                                feval(app.FigFuncHandles{3,1},'normal','event',app)
                                %SelectCell(app, 'normal','event')
                           end
                           update_display(app)
                           update_cross_section_display(app)
                 end
                
        end

        % Value changed function: z_display
        function z_display_Callback(app, event)
          
                      v = get(app.z_display,'Value');
                      if ~all(isletter(v)) % Test if string is numbers and not letters
                           v = round(str2double(v)); % Then convert string to actual number
                           if v >= 1 && v <= app.nZ
                                app.Z = v;
                                %app.Zold = v;
                                set(app.z_display,'Value',num2str(v))
                                load_images(app)
                                update_display(app)
                                update_cross_section_display(app)
                           else
                                set(app.z_display,'Value',num2str(app.Z))
                           end
                      else
                           set(app.z_display,'Value',num2str(app.Z))
                      end
                      
        end

        % Button pushed function: z_down
        function z_down_Callback(app, event)
                     
                      Z = app.Z;
                      %Zold = app.Zold;
            
                      %if isequal(Z,Zold)
                              if app.Z > 1
                                   app.Z = app.Z - 1;
                                   set(app.z_display,'Value',num2str(app.Z))
                                   app.line_break_mask = [];
                              end
                
                              load_images(app)
                              update_display(app)
                              update_cross_section_display(app)
                
                              [~, notempty] = iscelldim(app, app.TrackData,app.SetNum,app.T,app.CellIndex);
                
                              if notempty
                                  feval(app.FigFuncHandles{3,1},'normal',event,app) 
                                  %SelectCell(app,'normal',1)
                              end


        end

        % Button pushed function: z_line_color_button
        function z_line_color_button_Callback(app, event)
          
                 c = uisetcolor;
                 if ~isequal(c,0)
                      app.z_color = c;
                      set(app.z_line_color_display,'BackgroundColor',c)
                      update_display(app)
                      update_cross_section_display(app)
                 end
                 
        end

        % Value changed function: z_section_step_size_display
        function z_section_step_size_display_Callback(app, event)
                      
                val = str2double(get(app.z_section_step_size_display,'Value'));
            
                if ~isempty(val)
                    app.z_step_size = val;
                else
                    set(app.z_section_step_size_display,'Value',num2str(app.z_step_size))
                end
            
        end

        % Button pushed function: z_up
        function z_up_Callback(app, event)
                                
                      Z = app.Z;
                      %Zold = app.Zold;
            
                      %if isequal(Z,Zold)
            
                          if app.Z < app.nZ
                               app.Z = app.Z + 1;
                               set(app.z_display,'Value',num2str(app.Z))
                               app.line_break_mask = [];
                          end
            
                          load_images(app)
                          update_display(app)
                          update_cross_section_display(app)
            
                          [~,notempty] = iscelldim(app, app.TrackData,app.SetNum,app.T,app.CellIndex);
            
                          if notempty
                              feval(app.FigFuncHandles{3,1},'zUpArrow',event,app) 
                              % SelectCell(app, 'normal',1)
                          end
                      
            
        end

        % Value changed function: set_num_display
        function set_num_displayValueChanged(app, event)
            
            value = app.set_num_display.Value;

        end

        % Value changed function: CellIndexSpinner
        function CellIndexSpinnerValueChanged(app, event)
            
             %LogicArraySTC = create_STC_logical_array(app);
             val = app.CellIndexSpinner.Value;
           % val = round(str2num(get(app.CellIndexSpinner,'Value')));
                  TrackData = app.TrackData;
                  if val <= app.nCells
                      app.CellIndex = val;
                      [isdim notempty] = iscelldim(app, TrackData,app.SetNum,app.T,app.CellIndex);
                      if isdim && notempty
                            app.Z = TrackData{app.SetNum}{app.T}{app.CellIndex}.Z;
                            set(app.z_display,'Value',num2str(app.Z))
                      end
                      set(app.CellIndexSpinner,'Value',(val))
                      load_images(app)
                      update_display(app)
                  else
                      app.CellIndex = app.nCells;
                      set(app.CellIndexSpinner,'Value',app.nCells)
                  end


        end

        % Button pushed function: ImagedatapreprocessingButton
        function ImagedatapreprocessingButtonPushed(app, event)
            
            try
                % Lgtker_ImageDataPreProcessing    
                ImagePreProcessing
            catch
                warndlg('Image pre-processing app not found.','Warning!');
            end

        end

        % Value changed function: ncells_display
        function ncells_displayValueChanged(app, event)
            
            value = app.ncells_display.Value;
            
        end

        % Callback function: main, main
        function mainKeyPress(app, event)
            
            % disp([event.Modifier,event.Character])
            % app.main.KeyPressFcn = [];
            % app.main.WindowKeyPressFcn = [];
            
            while app.CallbackPlayCount == 0  % This will only allow this KeyPressFcn to execute once   
                    app.CallbackPlayCount = app.CallbackPlayCount + 1; 

                    if isgraphics(app.main_panel)
                  
                                        drawnow nocallbacks % This prevents another Buttondown callback to queue while this one is still executing.
                                                            % This resolved the problem of timepoints skipping too fast while the arrow button is pressed.
                                                            % callbacks are allowed again after "drawnow" is called again at the end of this function.
        
                                     
                                        % set(app.main_panel,'WindowButtonDownFcn',  {app.FigFuncHandles{3,1},app},... % {@SelectCell,app},...
                                        %                    'WindowButtonMotionFcn',{app.FigFuncHandles{1,1},app},... % {@MainPanelRollOver,app},...
                                        %                    'WindowScrollWheelFcn', {app.FigFuncHandles{2,1},app},... % {@MouseWheel,app},... % Only allow main window to have "cell selection"
                                        %                    'KeyPressFcn',          '');   % {@ShortCuts,app}
        
                                       
                                        if ~isempty(event) && any(strcmp(fieldnames(event),'Character'))
                                             key = double(lower(event.Character));
                                
                                            if     isequal(key, 97) || isequal(key,65) || strcmp(event.Key,'leftarrow');  key = 28; % Add 'A' and 'a' to Left Arrow shortcut
                                            elseif isequal(key,100) || isequal(key,68) || strcmp(event.Key,'rightarrow'); key = 29; % Add 'D' and 'd' to Right Arrow shortcut
                                            elseif isequal(key,119) || isequal(key,87) || strcmp(event.Key,'uparrow');    key = 30; % Add 'W' and 'w' to Up Arrow shortcut
                                            elseif isequal(key,115) || isequal(key,83) || strcmp(event.Key,'downarrow');  key = 31; % Add 'S' and 's' to Down Arrow shortcut
                                            elseif isequal(key,61); key = 43;   % Add '+' to '=' shortcut
                                            elseif isequal(key,45); key = 95;   % Add '_' to '-' shortcut
                                            elseif strcmp(event.Key,'shift'); key = 1000; 
                                            end
                                
        
                                            if isnumeric(key) && ~isempty(key)
                                                switch key
        
                                                    case 114 % toggle red channel
                                                         v = get(app.turnon_red_channel,'Value');
                                                         v = ~v;
                                                         set(app.turnon_red_channel,'Value',v)
                                                         app.show_r_channel = v;
                                                         update_display(app)
                                                         update_cross_section_display(app)
        
                                                    case 103 % toggle green channel
                                                         v = get(app.turnon_green_channel,'Value');
                                                         v = ~v;
                                                         set(app.turnon_green_channel,'Value',v)
                                                         app.show_g_channel = v;
                                                         update_display(app)
                                                         update_cross_section_display(app)
        
                                                    case 98 % toggle blue channel
                                                         v = get(app.turnon_blue_channel,'Value');
                                                         v = ~v;
                                                         set(app.turnon_blue_channel,'Value',v)
                                                         app.show_b_channel = v;
                                                         update_display(app)
                                                         update_cross_section_display(app)
        
                                                    case 28 %Left Arrow - Decrease T
                                                        T = app.T;
                                                        if T > 1
                                                            T = T - 1;
                                                            app.T = T;
                                                            set(app.t_display,'Value',num2str(T))
                                                            app.line_break_mask = [];
                                                        end
                    
                                                        if app.auto_load_z
                                                            [isdim, notempty] = iscelldim(app, app.TrackData,app.SetNum,T,app.CellIndex);
                                                            if isdim && notempty
                                                                app.Z = app.TrackData{app.SetNum}{T}{app.CellIndex}.Z;
                                                                set(app.z_display,'Value',num2str(app.Z))
                                                            elseif T > 1
                                                                [isdim, notempty] = iscelldim(app, app.TrackData,app.SetNum,T-1,app.CellIndex);
                                                                if isdim && notempty
                                                                    app.Z = app.TrackData{app.SetNum}{T-1}{app.CellIndex}.Z;
                                                                    set(app.z_display,'Value',num2str(app.Z))
                                                                end
                                                            end
                                                        end
                    
                                                        load_images(app)
                                                        update_display(app)
                                                        update_cross_section_display(app)
                                                        
                                                case 29 %Right Arrow - Increase T
                                                        T = app.T;
                                                        nT = app.nT;
                                                        if T < nT
                                                            T = T + 1;
                                                            app.T = T;
                                                            set(app.t_display,'Value',num2str(T))
                                                            app.line_break_mask = [];
                                                        end
                    
                                                        if app.auto_load_z
                                                            [isdim, notempty] = iscelldim(app, app.TrackData,app.SetNum,T,app.CellIndex);
                                                            if isdim && notempty
                                                                app.Z = app.TrackData{app.SetNum}{T}{app.CellIndex}.Z;
                                                                set(app.z_display,'Value',num2str(app.Z))
                                                            elseif T > 1
                                                                [isdim, notempty] = iscelldim(app, app.TrackData,app.SetNum,T-1,app.CellIndex);
                                                                if isdim && notempty
                                                                    app.Z = app.TrackData{app.SetNum}{T-1}{app.CellIndex}.Z;
                                                                    set(app.z_display,'Value',num2str(app.Z))
                                                                end
                                                            end
                                                        end
                    
                                                        load_images(app)
                                                        update_display(app)
                                                        update_cross_section_display(app)
                                                        
                                                case 31 %Up Arrow - Increase Z
                                                        Z = app.Z;
                                                        nZ = app.nZ;
        
                                                        if Z < nZ
                                                            Z = Z + 1;
                                                            app.Z = Z;
                                                            set(app.z_display,'Value',num2str(Z))
                                                            app.line_break_mask = [];
                                                        end
        
                                                        load_images(app)
                                                        update_display(app)
                                                        update_cross_section_display(app)
        
                                                        [isdim, notempty] = iscelldim(app, app.TrackData,app.SetNum,app.T,app.CellIndex);
        
                                                        if notempty && app.AutoSelectWhenChangingZCheck.Value
                                                            app.FigFuncHandles{3,1}('UpArrow', 'normal',app) %SelectCell(app, 'normal',1)
                                                        end
                                                        %-------------------------------------------
                                                        
                                                case 30 % Down Arrow - Decrase Z
                                                        Z = app.Z;
        
                                                        if Z > 1
                                                            Z = Z - 1;
                                                            app.Z = Z;
                                                            set(app.z_display,'Value',num2str(Z))
                                                            app.line_break_mask = [];
                                                        end
        
                                                        load_images(app)
                                                        update_display(app)
                                                        update_cross_section_display(app)
        
                                                        [isdim, notempty] = iscelldim(app, app.TrackData,app.SetNum,app.T,app.CellIndex);
        
                                                        if notempty && app.AutoSelectWhenChangingZCheck.Value
                                                            app.FigFuncHandles{3,1}('DownArrow', 'normal',app) % SelectCell(app, 'normal',1)
                                                        end
                            
                                                        
                                                case 44  % Increase Threshold ">" key
                                                        if logical(get(app.turnon_threshold,'Value')) && app.threshold_value < 255
                                                            app.threshold_value = app.threshold_value + 5;
                                                            app.threshold_value(app.threshold_value > 255) = 255;
                                                            set(app.threshold_slider,'Value',app.threshold_value)
                                                            set(app.turnon_threshold,'Text',['Threshold: ' num2str(app.threshold_value)])
                                                        end
                                                       update_display(app)
                                                       update_cross_section_display(app)
                                                       
                                                case 46 % Decrease Threshold  "<" key
                                                        if logical(get(app.turnon_threshold,'Value')) && app.threshold_value > 0
                                                            app.threshold_value = app.threshold_value - 5;
                                                            app.threshold_value(app.threshold_value < 0) = 0;
                                                            set(app.threshold_slider,'Value',app.threshold_value)
                                                            set(app.turnon_threshold,'Text',['Threshold: ' num2str(app.threshold_value)])
                                                        end
                                                        update_display(app)
                                                        update_cross_section_display(app)
                                                        
                                                case 112 % Toggle Show Previous Cell - "P" key
                                                        val = logical(get(app.show_previous_check,'Value'));
                                                        if val
                                                            set(app.show_previous_check,'Value',0)
                                                            app.show_previous = false;
                                                        else
                                                            set(app.show_previous_check,'Value',1)
                                                            app.show_previous = true;
                                                        end
                                                        update_display(app)
                                                        update_cross_section_display(app)
                                                        
                                                case 108 % Toggle Show Post Cell - "L" key
                                                        val = logical(get(app.show_post_check,'Value'));
                                                        if val
                                                            set(app.show_post_check,'Value',0)
                                                            app.show_post = false;
                                                        else
                                                            set(app.show_post_check,'Value',1)
                                                            app.show_post = true;
                                                        end
                                                        update_display(app)
                                                        update_cross_section_display(app)
                                                        
                                                case 1000 % Initiate Draw Line Function
                                                        %drawline_button_Callback(app.drawline_button, [], app)
                                                        drawline_button_Callback(app, event)
        
                                                case 43 % Increase Cell Index
                                                        T = app.T;
                                                        Z = app.Z;
                                                        Ncells = app.nCells;
                                                        CellIndex = app.CellIndex;
                                                        SetNum  = app.SetNum;
                                                        TrackData = app.TrackData;
                            
                                                        % Cancel Line mask if it exists
                                                        if ~isempty(app.line_break_mask)
                                                            LB = uint8(app.line_break_mask);
                                                        else
                                                            LB = ones(app.ImHeight,app.ImWidth,'uint8');
                                                        end
                            
                                                        % Increase Cell Index ////////////////////////////////
                                                        if isequal(Ncells,CellIndex) || CellIndex > Ncells
                                                            app.CellIndex = 1;
                                                            CellIndex = 1;
                                                            app.CellIndexSpinner.Value = app.CellIndex;
                                                        else
                                                            app.CellIndex = CellIndex + 1;
                                                            CellIndex = app.CellIndex;
                                                            app.CellIndexSpinner.Value = app.CellIndex;
                                                        end
                                                        % Apply Auto Load Z option
                                                        if app.auto_load_z
                                                            [isdim notempty] = iscelldim(app, TrackData,SetNum,T,CellIndex);
                                                            if isdim && notempty
                                                                app.Z = TrackData{SetNum}{T}{CellIndex}.Z;
                                                                set(app.z_display,'Value',num2str(app.Z))
                                                            elseif T > 1 % If we advance the cell number and no cell has been picked yet, load in the z from the
                                                                % previous time point for that cell number
                                                                [isdim notempty] = iscelldim(app, TrackData,SetNum,T-1,CellIndex);
                                                                if isdim && notempty 
                                                                    app.Z = TrackData{SetNum}{T-1}{CellIndex}.Z;
                                                                    set(app.z_display,'Value',num2str(app.Z))
                                                                end
                                                            end
                                                        end
                            
                                                        load_images(app)
                                                        update_display(app)
                                                        update_cross_section_display(app)
                                                        
                                                case 95 % Decrease Cell Index
                                                        T = app.T;
                                                        Z = app.Z;
                                                        Ncells = app.nCells;
                                                        CellIndex = app.CellIndex;
                                                        SetNum = app.SetNum;
                                                        TrackData = app.TrackData;
                            
                                                        % Cancel Line mask if it exists
                                                        if ~isempty(app.line_break_mask)
                                                            LB = uint8(app.line_break_mask);
                                                        else
                                                            LB = ones(app.ImHeight,app.ImWidth,'uint8');
                                                        end
                            
                                                        % Increase Cell Index ////////////////////////////////
                                                        if isequal(1,CellIndex) || CellIndex < 0
                                                            app.CellIndex = Ncells;
                                                            CellIndex = Ncells;
                                                            app.CellIndexSpinner.Value = app.CellIndex;
                                                        else
                                                            app.CellIndex = CellIndex - 1;
                                                            CellIndex = app.CellIndex;
                                                            app.CellIndexSpinner.Value = app.CellIndex;
                                                        end
                                                        % Apply Auto Load Z option
                                                        if app.auto_load_z
                                                            [isdim, notempty] = iscelldim(app, TrackData,SetNum,T,CellIndex);
                                                            if isdim && notempty
                                                                app.Z = TrackData{SetNum}{T}{CellIndex}.Z;
                                                                set(app.z_display,'Value',num2str(app.Z))
                                                            elseif T > 1 % If we decrease the cell number and no cell has been picked yet, load in the z from the
                                                                % post time point for that cell number
                                                                [isdim, notempty] = iscelldim(app, TrackData,SetNum,T+1,CellIndex);
                                                                if isdim && notempty
                                                                    app.Z = TrackData{SetNum}{T+1}{CellIndex}.Z;
                                                                    set(app.z_display,'Value',num2str(app.Z))
                                                                end
                                                            end
                                                        end
                            
                                                        load_images(app)
                                                        update_display(app)
                                                        update_cross_section_display(app)
                                                end
                                            end
                                        end
                                        
                                        drawnow
        
                                        set(groot,'CurrentFigure',app.main_panel)
                                        shg
                    end
            end


        end

        % Button pushed function: ChangeImageFolderButton
        function ChangeImageFolderButtonPushed(app, event)
            
               
            msg = 'You may select a new image data directory: File names may be different, but all data measurements must be the same. After directory selection, resave longtracker file.';
            selection = questdlg(msg,"Confirm changing image data directory","Continue","Cancel","Cancel");

            if strcmp(selection,'Continue')
                dname = uigetdir([],'Select new image data directory');                
                if ~isequal(dname,0)
                        F = struct2cell( dir(dname) )';
                        IsFile = ~cell2mat(F(:,5)); 
                        files = F(IsFile,1); % Grab all the files in the selected directory
                            
                        [SELECTION, ok] = listdlg('ListString',files,'ListSize',[250 400],'PromptString','Select image files','Name','Select image files');

                        if isequal(ok,1)
                                      N = length(SELECTION);
                                      if app.nT == N
                                          app.directory = dname; % Record directory
                                          app.filenames = files(SELECTION,1);
                                          app.filenames = MakeSureImageFilesAreSortedByTimestamp(app,app.filenames);
                                          set(app.images_directory_display,'Text',app.directory)
                                      else
                                          warndlg('Number of timepoints does not match current image data set!','Warning');
                                      end
                        end
                        % ---------------------------------------------------------------------------
                end
            end


        end

        % Window key release function: main
        function mainWindowKeyRelease(app, event)
            
            app.CallbackPlayCount = 0;


        end

        % Button pushed function: COPY_TXYZ_array
        function COPY_TXYZ_arrayButtonPushed(app, event)


                data = app.Recorded_TXYZ_values.Data;

                % Convert table/cell/numeric → cell array of strings
                if istable(data)
                    data = table2cell(data);
                end
            
                data = string(data);
                data(ismissing(data)) = "";
            
                % Convert to tab-delimited text
                txt = join(data, char(9), 2);
                txt = join(txt, newline);
            
                clipboard("copy", txt);


        end

        % Button pushed function: CLEAR_TXYZ_array
        function CLEAR_TXYZ_arrayButtonPushed(app, event)
            
            answer = questdlg('Are you sure you sure you want clear TXYZ data?','Clear YXYZ data?', 'Yes','No','No');      

            if strcmp(answer,'Yes')  
                    try
                      Trec = (1:app.nT)';
                      Xrec = NaN(app.nT,1);
                      Yrec = NaN(app.nT,1);
                      Zrec = NaN(app.nT,1);
                      ColumnNames = {'T','X','Y','Z'};       
                      RT = table(Trec,Xrec,Yrec,Zrec,'VariableNames',ColumnNames);                
                      app.Recorded_TXYZ_values.Data = RT;
                      app.Recorded_TXYZ_values.ColumnWidth = {'1x','2x','2x','2x'};
                    end
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.main = uifigure('Visible', 'off');
            colormap(app.main, 'parula');
            app.main.Name = 'longtracker';
            app.main.CloseRequestFcn = createCallbackFcn(app, @main_CloseRequestFcn, true);
            app.main.WindowKeyPressFcn = createCallbackFcn(app, @mainKeyPress, true);
            app.main.WindowKeyReleaseFcn = createCallbackFcn(app, @mainWindowKeyRelease, true);
            app.main.KeyPressFcn = createCallbackFcn(app, @mainKeyPress, true);
            app.main.Tag = 'main';
            app.TabGroup = uitabgroup(app.main);
            app.TrackingTab = uitab(app.TabGroup);
            app.TrackingTab.Title = 'Tracking';
            app.functions = uipanel(app.TrackingTab);
            app.functions.Tag = 'functions';
            app.turnon_threshold = uicheckbox(app.functions);
            app.turnon_threshold.ValueChangedFcn = createCallbackFcn(app, @turnon_threshold_Callback, true);
            app.turnon_threshold.Tag = 'turnon_threshold';
            app.turnon_threshold.Text = 'Threshold: 100';
            app.threshold_slider = uislider(app.functions);
            app.threshold_slider.Limits = [0 1];
            app.threshold_slider.MajorTicks = [];
            app.threshold_slider.ValueChangedFcn = createCallbackFcn(app, @threshold_slider_Callback, true);
            app.threshold_slider.MinorTicks = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1];
            app.threshold_slider.Tag = 'threshold_slider';
            app.turnon_binarize = uicheckbox(app.functions);
            app.turnon_binarize.ValueChangedFcn = createCallbackFcn(app, @turnon_binarize_Callback, true);
            app.turnon_binarize.Tag = 'turnon_binarize';
            app.turnon_binarize.Text = 'Binarize & Fill';
            app.turnon_erode = uicheckbox(app.functions);
            app.turnon_erode.ValueChangedFcn = createCallbackFcn(app, @turnon_erode_Callback, true);
            app.turnon_erode.Tag = 'turnon_erode';
            app.turnon_erode.Text = 'Erode: 2';
            app.erode_slider = uislider(app.functions);
            app.erode_slider.Limits = [0 1];
            app.erode_slider.MajorTicks = [];
            app.erode_slider.ValueChangedFcn = createCallbackFcn(app, @erode_slider_Callback, true);
            app.erode_slider.MinorTicks = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1];
            app.erode_slider.Tag = 'erode_slider';
            app.turnon_hbreak = uicheckbox(app.functions);
            app.turnon_hbreak.ValueChangedFcn = createCallbackFcn(app, @turnon_hbreak_Callback, true);
            app.turnon_hbreak.Tag = 'turnon_hbreak';
            app.turnon_hbreak.Text = 'Break';
            app.drawline_button = uibutton(app.functions, 'push');
            app.drawline_button.ButtonPushedFcn = createCallbackFcn(app, @drawline_button_Callback, true);
            app.drawline_button.Tag = 'drawline_button';
            app.drawline_button.Text = 'Draw';
            app.erase_line_breaks = uibutton(app.functions, 'push');
            app.erase_line_breaks.ButtonPushedFcn = createCallbackFcn(app, @erase_line_breaks_Callback, true);
            app.erase_line_breaks.Tag = 'erase_line_breaks';
            app.erase_line_breaks.Text = 'Erase';
            app.cell_summary_button = uibutton(app.functions, 'push');
            app.cell_summary_button.ButtonPushedFcn = createCallbackFcn(app, @cell_summary_button_Callback, true);
            app.cell_summary_button.Tag = 'cell_summary_button';
            app.cell_summary_button.Text = 'Cell Summary';
            app.time_and_z_panel = uipanel(app.TrackingTab);
            app.time_and_z_panel.Tag = 'time_and_z_panel';
            app.t_down = uibutton(app.time_and_z_panel, 'push');
            app.t_down.ButtonPushedFcn = createCallbackFcn(app, @t_down_Callback, true);
            app.t_down.Tag = 't_down';
            app.t_down.Text = 'T-';
            app.t_up = uibutton(app.time_and_z_panel, 'push');
            app.t_up.ButtonPushedFcn = createCallbackFcn(app, @t_up_Callback, true);
            app.t_up.Tag = 't_up';
            app.t_up.Text = 'T+';
            app.z_down = uibutton(app.time_and_z_panel, 'push');
            app.z_down.ButtonPushedFcn = createCallbackFcn(app, @z_down_Callback, true);
            app.z_down.Tag = 'z_down';
            app.z_down.Text = 'Z-';
            app.z_up = uibutton(app.time_and_z_panel, 'push');
            app.z_up.ButtonPushedFcn = createCallbackFcn(app, @z_up_Callback, true);
            app.z_up.Tag = 'z_up';
            app.z_up.Text = 'Z+';
            app.t_display = uieditfield(app.time_and_z_panel, 'text');
            app.t_display.ValueChangedFcn = createCallbackFcn(app, @t_display_Callback, true);
            app.t_display.Tag = 't_display';
            app.t_display.Value = '1000';
            app.z_display = uieditfield(app.time_and_z_panel, 'text');
            app.z_display.ValueChangedFcn = createCallbackFcn(app, @z_display_Callback, true);
            app.z_display.Tag = 'z_display';
            app.z_display.Value = '1000';
            app.main_window_slider_text = uilabel(app.time_and_z_panel);
            app.main_window_slider_text.Tag = 'main_window_slider_text';
            app.main_window_slider_text.Text = 'Main Figure: 100%';
            app.main_window_slider = uislider(app.time_and_z_panel);
            app.main_window_slider.Limits = [50 300];
            app.main_window_slider.MajorTicks = [];
            app.main_window_slider.ValueChangedFcn = createCallbackFcn(app, @main_window_slider_Callback, true);
            app.main_window_slider.MinorTicks = [50 60 70 80 90 100 110 120 130 140 150 160 170 180 190 200 210 220 230 240 250 260 270 280 290 300];
            app.main_window_slider.Tag = 'main_window_slider';
            app.main_window_slider.Value = 100;
            app.auto_load_z_check = uicheckbox(app.time_and_z_panel);
            app.auto_load_z_check.ValueChangedFcn = createCallbackFcn(app, @auto_load_z_check_Callback, true);
            app.auto_load_z_check.Tag = 'auto_load_z_check';
            app.auto_load_z_check.Text = 'Auto display Z of selected cell';
            app.ncells_up = uibutton(app.time_and_z_panel, 'push');
            app.ncells_up.ButtonPushedFcn = createCallbackFcn(app, @ncells_up_Callback, true);
            app.ncells_up.Tag = 'ncells_up';
            app.ncells_up.Text = '';
            app.ncells_down = uibutton(app.time_and_z_panel, 'push');
            app.ncells_down.ButtonPushedFcn = createCallbackFcn(app, @ncells_down_Callback, true);
            app.ncells_down.Tag = 'ncells_down';
            app.ncells_down.Text = '';
            app.ncells_display = uieditfield(app.time_and_z_panel, 'text');
            app.ncells_display.ValueChangedFcn = createCallbackFcn(app, @ncells_displayValueChanged, true);
            app.ncells_display.Tag = 'ncells_display';
            app.ncells_display.Editable = 'off';
            app.ncells_display.Value = '200';
            app.ncells_text = uilabel(app.time_and_z_panel);
            app.ncells_text.Tag = 'ncells_text';
            app.ncells_text.Text = 'nCells';
            app.set_index_up = uibutton(app.time_and_z_panel, 'push');
            app.set_index_up.ButtonPushedFcn = createCallbackFcn(app, @set_index_up_Callback, true);
            app.set_index_up.Tag = 'track_index_up';
            app.set_index_up.Text = '';
            app.set_index_down = uibutton(app.time_and_z_panel, 'push');
            app.set_index_down.ButtonPushedFcn = createCallbackFcn(app, @set_index_down_Callback, true);
            app.set_index_down.Tag = 'track_index_down';
            app.set_index_down.Text = '';
            app.set_num_display = uieditfield(app.time_and_z_panel, 'text');
            app.set_num_display.ValueChangedFcn = createCallbackFcn(app, @set_num_displayValueChanged, true);
            app.set_num_display.Tag = 'track_num_display';
            app.set_num_display.Editable = 'off';
            app.set_num_display.Value = '200';
            app.track_num_text = uilabel(app.time_and_z_panel);
            app.track_num_text.Tag = 'track_num_text';
            app.track_num_text.Text = 'Set#';
            app.CellIndexSpinnerLabel = uilabel(app.time_and_z_panel);
            app.CellIndexSpinnerLabel.Text = 'Cell Index:';
            app.CellIndexSpinner = uispinner(app.time_and_z_panel);
            app.CellIndexSpinner.Limits = [1 Inf];
            app.CellIndexSpinner.ValueChangedFcn = createCallbackFcn(app, @CellIndexSpinnerValueChanged, true);
            app.CellIndexSpinner.Value = 1;
            app.AutoSelectWhenChangingZCheck = uicheckbox(app.time_and_z_panel);
            app.AutoSelectWhenChangingZCheck.Tag = 'auto_load_z_check';
            app.AutoSelectWhenChangingZCheck.Text = 'Auto select when changing Z';
            app.tracking_panel = uipanel(app.TrackingTab);
            app.tracking_panel.Tag = 'tracking_panel';
            app.show_previous_check = uicheckbox(app.tracking_panel);
            app.show_previous_check.ValueChangedFcn = createCallbackFcn(app, @show_previous_check_Callback, true);
            app.show_previous_check.Tag = 'show_previous_check';
            app.show_previous_check.Text = 'Show Previous';
            app.show_post_check = uicheckbox(app.tracking_panel);
            app.show_post_check.ValueChangedFcn = createCallbackFcn(app, @show_post_check_Callback, true);
            app.show_post_check.Tag = 'show_post_check';
            app.show_post_check.Text = 'Show Post';
            app.show_V_cross_sections_check = uicheckbox(app.tracking_panel);
            app.show_V_cross_sections_check.ValueChangedFcn = createCallbackFcn(app, @show_V_cross_sections_check_Callback, true);
            app.show_V_cross_sections_check.Tag = 'show_V_cross_sections_check';
            app.show_V_cross_sections_check.Text = 'Show Vertical Cross-Section';
            app.cross_section_width_slider = uislider(app.tracking_panel);
            app.cross_section_width_slider.Limits = [0 1];
            app.cross_section_width_slider.MajorTicks = [];
            app.cross_section_width_slider.ValueChangedFcn = createCallbackFcn(app, @cross_section_width_slider_Callback, true);
            app.cross_section_width_slider.MinorTicks = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1];
            app.cross_section_width_slider.Tag = 'cross_section_width_slider';
            app.cross_section_width_text = uilabel(app.tracking_panel);
            app.cross_section_width_text.Tag = 'cross_section_width_text';
            app.cross_section_width_text.Text = 'CS Width:  410%';
            app.LR_placement = uicheckbox(app.tracking_panel);
            app.LR_placement.ValueChangedFcn = createCallbackFcn(app, @LR_placement_Callback, true);
            app.LR_placement.Tag = 'LR_placement';
            app.LR_placement.Text = 'L/R';
            app.TB_placement = uicheckbox(app.tracking_panel);
            app.TB_placement.ValueChangedFcn = createCallbackFcn(app, @TB_placement_Callback, true);
            app.TB_placement.Tag = 'TB_placement';
            app.TB_placement.Text = 'T/B';
            app.show_H_cross_sections_check = uicheckbox(app.tracking_panel);
            app.show_H_cross_sections_check.ValueChangedFcn = createCallbackFcn(app, @show_H_cross_sections_check_Callback, true);
            app.show_H_cross_sections_check.Tag = 'show_H_cross_sections_check';
            app.show_H_cross_sections_check.Text = {'Show Horizontal Cross-Section'; ''};
            app.show_previous_increase_button = uibutton(app.tracking_panel, 'push');
            app.show_previous_increase_button.ButtonPushedFcn = createCallbackFcn(app, @show_previous_increase_button_Callback, true);
            app.show_previous_increase_button.Tag = 'show_previous_increase_button';
            app.show_previous_increase_button.Text = '';
            app.show_previous_decrease_button = uibutton(app.tracking_panel, 'push');
            app.show_previous_decrease_button.ButtonPushedFcn = createCallbackFcn(app, @show_previous_decrease_button_Callback, true);
            app.show_previous_decrease_button.Tag = 'show_previous_decrease_button';
            app.show_previous_decrease_button.Text = '';
            app.show_previous_display = uieditfield(app.tracking_panel, 'text');
            app.show_previous_display.ValueChangedFcn = createCallbackFcn(app, @show_previous_display_Callback, true);
            app.show_previous_display.Tag = 'show_previous_display';
            app.show_previous_display.Value = '1';
            app.show_post_increase_button = uibutton(app.tracking_panel, 'push');
            app.show_post_increase_button.ButtonPushedFcn = createCallbackFcn(app, @show_post_increase_button_Callback, true);
            app.show_post_increase_button.Tag = 'show_post_increase_button';
            app.show_post_increase_button.Text = '';
            app.show_post_decrease_button = uibutton(app.tracking_panel, 'push');
            app.show_post_decrease_button.ButtonPushedFcn = createCallbackFcn(app, @show_post_decrease_button_Callback, true);
            app.show_post_decrease_button.Tag = 'show_post_decrease_button';
            app.show_post_decrease_button.Text = '';
            app.show_post_display = uieditfield(app.tracking_panel, 'text');
            app.show_post_display.ValueChangedFcn = createCallbackFcn(app, @show_post_display_Callback, true);
            app.show_post_display.Tag = 'show_post_display';
            app.show_post_display.Value = '1';
            app.other_figures_panel = uipanel(app.TrackingTab);
            app.other_figures_panel.Tag = 'other_figures_panel';
            app.tm1_figure = uicheckbox(app.other_figures_panel);
            app.tm1_figure.ValueChangedFcn = createCallbackFcn(app, @tm1_figure_Callback, true);
            app.tm1_figure.Tag = 'tm1_figure';
            app.tm1_figure.Text = 't-1, z';
            app.tp1_figure = uicheckbox(app.other_figures_panel);
            app.tp1_figure.ValueChangedFcn = createCallbackFcn(app, @tp1_figure_Callback, true);
            app.tp1_figure.Tag = 'tp1_figure';
            app.tp1_figure.Text = 't+1, z';
            app.tp1_slider = uislider(app.other_figures_panel);
            app.tp1_slider.Limits = [0 1];
            app.tp1_slider.MajorTicks = [];
            app.tp1_slider.ValueChangedFcn = createCallbackFcn(app, @tp1_slider_Callback, true);
            app.tp1_slider.MinorTicks = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1];
            app.tp1_slider.Tag = 'tp1_slider';
            app.tm1_slider = uislider(app.other_figures_panel);
            app.tm1_slider.Limits = [0 1];
            app.tm1_slider.MajorTicks = [];
            app.tm1_slider.ValueChangedFcn = createCallbackFcn(app, @tm1_slider_Callback, true);
            app.tm1_slider.MinorTicks = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1];
            app.tm1_slider.Tag = 'tm1_slider';
            app.apply_to_all = uicheckbox(app.other_figures_panel);
            app.apply_to_all.ValueChangedFcn = createCallbackFcn(app, @apply_to_all_Callback, true);
            app.apply_to_all.Tag = 'apply_to_all';
            app.apply_to_all.Text = 'Apply Functions to Figures:';
            app.TabGroup2 = uitabgroup(app.TrackingTab);
            app.AppearanceTab = uitab(app.TabGroup2);
            app.AppearanceTab.Title = 'Appearance';
            app.cross_section_display_panel = uipanel(app.AppearanceTab);
            app.cross_section_display_panel.Tag = 'cross_section_display_panel';
            app.turnon_magnet_function = uibutton(app.cross_section_display_panel, 'state');
            app.turnon_magnet_function.ValueChangedFcn = createCallbackFcn(app, @turnon_magnet_function_Callback, true);
            app.turnon_magnet_function.Tag = 'turnon_magnet_function';
            app.turnon_magnet_function.Text = 'Magnet ON';
            app.z_line_color_button = uibutton(app.cross_section_display_panel, 'push');
            app.z_line_color_button.ButtonPushedFcn = createCallbackFcn(app, @z_line_color_button_Callback, true);
            app.z_line_color_button.Tag = 'z_line_color_button';
            app.z_line_color_button.Text = 'Z Line Color';
            app.z_line_color_display = uipanel(app.cross_section_display_panel);
            app.z_line_color_display.Tag = 'z_line_color_display';
            app.rgb_panel = uipanel(app.AppearanceTab);
            app.rgb_panel.Tag = 'rgb_panel';
            app.sa_rgb_check = uicheckbox(app.rgb_panel);
            app.sa_rgb_check.ValueChangedFcn = createCallbackFcn(app, @sa_rgb_check_Callback, true);
            app.sa_rgb_check.Tag = 'sa_rgb_check';
            app.sa_rgb_check.Text = 'Advanced RGB display';
            app.blue_color_display = uipanel(app.rgb_panel);
            app.blue_color_display.Tag = 'blue_color_display';
            app.green_color_display = uipanel(app.rgb_panel);
            app.green_color_display.Tag = 'green_color_display';
            app.red_color_display = uipanel(app.rgb_panel);
            app.red_color_display.Tag = 'red_color_display';
            app.b_channel_color_button = uibutton(app.rgb_panel, 'push');
            app.b_channel_color_button.ButtonPushedFcn = createCallbackFcn(app, @b_channel_color_button_Callback, true);
            app.b_channel_color_button.Tag = 'b_channel_color_button';
            app.b_channel_color_button.Text = 'B Color';
            app.g_channel_color_button = uibutton(app.rgb_panel, 'push');
            app.g_channel_color_button.ButtonPushedFcn = createCallbackFcn(app, @g_channel_color_button_Callback, true);
            app.g_channel_color_button.Tag = 'g_channel_color_button';
            app.g_channel_color_button.Text = 'G Color';
            app.r_channel_color_button = uibutton(app.rgb_panel, 'push');
            app.r_channel_color_button.ButtonPushedFcn = createCallbackFcn(app, @r_channel_color_button_Callback, true);
            app.r_channel_color_button.Tag = 'r_channel_color_button';
            app.r_channel_color_button.Text = 'R Color';
            app.turnon_blue_channel = uicheckbox(app.rgb_panel);
            app.turnon_blue_channel.ValueChangedFcn = createCallbackFcn(app, @turnon_blue_channel_Callback, true);
            app.turnon_blue_channel.Tag = 'turnon_blue_channel';
            app.turnon_blue_channel.Text = 'B Channel';
            app.turnon_green_channel = uicheckbox(app.rgb_panel);
            app.turnon_green_channel.ValueChangedFcn = createCallbackFcn(app, @turnon_green_channel_Callback, true);
            app.turnon_green_channel.Tag = 'turnon_green_channel';
            app.turnon_green_channel.Text = 'G Channel (track)';
            app.turnon_red_channel = uicheckbox(app.rgb_panel);
            app.turnon_red_channel.ValueChangedFcn = createCallbackFcn(app, @turnon_red_channel_Callback, true);
            app.turnon_red_channel.Tag = 'turnon_red_channel';
            app.turnon_red_channel.Text = 'R Channel';
            app.cell_color_panel = uipanel(app.AppearanceTab);
            app.cell_color_panel.Tag = 'cell_color_panel';
            app.outline_width_text = uilabel(app.cell_color_panel);
            app.outline_width_text.Tag = 'outline_width_text';
            app.outline_width_text.Text = 'Line Width';
            app.cell_outline_color_button = uibutton(app.cell_color_panel, 'push');
            app.cell_outline_color_button.ButtonPushedFcn = createCallbackFcn(app, @cell_outline_color_button_Callback, true);
            app.cell_outline_color_button.Tag = 'cell_outline_color_button';
            app.cell_outline_color_button.Text = 'OutLine';
            app.cell_outline_color_display = uipanel(app.cell_color_panel);
            app.cell_outline_color_display.Tag = 'cell_outline_color_display';
            app.cell_fill_color_button = uibutton(app.cell_color_panel, 'push');
            app.cell_fill_color_button.ButtonPushedFcn = createCallbackFcn(app, @cell_fill_color_button_Callback, true);
            app.cell_fill_color_button.Tag = 'cell_fill_color_button';
            app.cell_fill_color_button.Text = 'Fill';
            app.post_cell_color_button = uibutton(app.cell_color_panel, 'push');
            app.post_cell_color_button.ButtonPushedFcn = createCallbackFcn(app, @post_cell_color_button_Callback, true);
            app.post_cell_color_button.Tag = 'post_cell_color_button';
            app.post_cell_color_button.Text = 'PostCell';
            app.prev_cell_color_button = uibutton(app.cell_color_panel, 'push');
            app.prev_cell_color_button.ButtonPushedFcn = createCallbackFcn(app, @prev_cell_color_button_Callback, true);
            app.prev_cell_color_button.Tag = 'prev_cell_color_button';
            app.prev_cell_color_button.Text = 'PrevCell';
            app.cell_fill_display = uipanel(app.cell_color_panel);
            app.cell_fill_display.Tag = 'cell_fill_display';
            app.post_cell_color_display = uipanel(app.cell_color_panel);
            app.post_cell_color_display.Tag = 'post_cell_color_display';
            app.prev_cell_color_display = uipanel(app.cell_color_panel);
            app.prev_cell_color_display.Tag = 'prev_cell_color_display';
            app.line_width_listbox = uidropdown(app.cell_color_panel);
            app.line_width_listbox.Items = {'1', '1.5', '2', '2.5', '3', '3.5', '4'};
            app.line_width_listbox.ValueChangedFcn = createCallbackFcn(app, @line_width_listbox_Callback, true);
            app.line_width_listbox.Tag = 'line_width_listbox';
            app.line_width_listbox.Value = '1';
            app.XYZTrecordTab = uitab(app.TabGroup2);
            app.XYZTrecordTab.Title = 'XYZT record';
            app.CLEAR_TXYZ_array = uibutton(app.XYZTrecordTab, 'push');
            app.CLEAR_TXYZ_array.ButtonPushedFcn = createCallbackFcn(app, @CLEAR_TXYZ_arrayButtonPushed, true);
            app.CLEAR_TXYZ_array.Text = 'Clear all';
            app.COPY_TXYZ_array = uibutton(app.XYZTrecordTab, 'push');
            app.COPY_TXYZ_array.ButtonPushedFcn = createCallbackFcn(app, @COPY_TXYZ_arrayButtonPushed, true);
            app.COPY_TXYZ_array.Text = 'Copy';
            app.Recorded_TXYZ_values = uitable(app.XYZTrecordTab);
            app.Recorded_TXYZ_values.ColumnName = {'T'; 'X'; 'Y'; 'Z'};
            app.Recorded_TXYZ_values.RowName = {};
            app.PressckeytorecordTXYZpointLabel = uilabel(app.XYZTrecordTab);
            app.PressckeytorecordTXYZpointLabel.Text = 'Press "c" key to record TXYZ point.';
            app.DataIOTab = uitab(app.TabGroup);
            app.DataIOTab.Title = 'Data I/O';
            app.save_panel = uipanel(app.DataIOTab);
            app.save_panel.Tag = 'save_panel';
            app.reset_button = uibutton(app.save_panel, 'push');
            app.reset_button.ButtonPushedFcn = createCallbackFcn(app, @reset_button_Callback, true);
            app.reset_button.Tag = 'reset_button';
            app.reset_button.Text = 'Reset';
            app.load_button = uibutton(app.save_panel, 'push');
            app.load_button.ButtonPushedFcn = createCallbackFcn(app, @load_button_Callback, true);
            app.load_button.Tag = 'load_button';
            app.load_button.Text = 'Load';
            app.saveas_button = uibutton(app.save_panel, 'push');
            app.saveas_button.ButtonPushedFcn = createCallbackFcn(app, @saveas_button_Callback, true);
            app.saveas_button.Tag = 'saveas_button';
            app.saveas_button.Text = 'Save As';
            app.save_button = uibutton(app.save_panel, 'push');
            app.save_button.ButtonPushedFcn = createCallbackFcn(app, @save_button_Callback, true);
            app.save_button.Tag = 'save_button';
            app.save_button.Text = 'Save';
            app.export_button = uibutton(app.save_panel, 'push');
            app.export_button.ButtonPushedFcn = createCallbackFcn(app, @export_button_Callback, true);
            app.export_button.Tag = 'export_button';
            app.export_button.Text = 'Export';
            app.microns_per_pixel_display = uieditfield(app.save_panel, 'text');
            app.microns_per_pixel_display.ValueChangedFcn = createCallbackFcn(app, @microns_per_pixel_display_Callback, true);
            app.microns_per_pixel_display.Tag = 'microns_per_pixel_display';
            app.microns_per_pixel_display.Value = '5000';
            app.microns_per_pixel_text = uilabel(app.save_panel);
            app.microns_per_pixel_text.Tag = 'microns_per_pixel_text';
            app.microns_per_pixel_text.Text = {'XY scale (microns/pixel) '; '(-1 for no conversion)'};
            app.z_section_step_size_display = uieditfield(app.save_panel, 'text');
            app.z_section_step_size_display.ValueChangedFcn = createCallbackFcn(app, @z_section_step_size_display_Callback, true);
            app.z_section_step_size_display.Tag = 'z_section_step_size_display';
            app.z_section_step_size_display.Value = '5000';
            app.z_section_step_size_text = uilabel(app.save_panel);
            app.z_section_step_size_text.Tag = 'z_section_step_size_text';
            app.z_section_step_size_text.Text = {'Z step size (microns) '; '(-1 for no conversion)'};
            app.time_interval_display = uieditfield(app.save_panel, 'text');
            app.time_interval_display.ValueChangedFcn = createCallbackFcn(app, @time_interval_display_Callback, true);
            app.time_interval_display.Tag = 'time_interval_display';
            app.time_interval_display.Value = '5000';
            app.time_interval_text = uilabel(app.save_panel);
            app.time_interval_text.Tag = 'time_interval_text';
            app.time_interval_text.Text = {'Time Interval (sec) '; '(-1 for no conversion)'};
            app.images_directory_display = uilabel(app.DataIOTab);
            app.images_directory_display.Tag = 'images_directory_display';
            app.images_directory_display.Text = '';
            app.location_button = uibutton(app.DataIOTab, 'push');
            app.location_button.ButtonPushedFcn = createCallbackFcn(app, @location_button_Callback, true);
            app.location_button.Tag = 'location_button';
            app.location_button.Text = 'Find Data';
            app.nZ_display = uilabel(app.DataIOTab);
            app.nZ_display.Tag = 'nZ_display';
            app.nZ_display.Text = 'nZ = 200';
            app.keyboard_short_cuts_button = uibutton(app.DataIOTab, 'push');
            app.keyboard_short_cuts_button.ButtonPushedFcn = createCallbackFcn(app, @keyboard_short_cuts_button_Callback, true);
            app.keyboard_short_cuts_button.Tag = 'keyboard_short_cuts_button';
            app.keyboard_short_cuts_button.Text = 'Short Cuts';
            app.about_longtracker_button = uibutton(app.DataIOTab, 'push');
            app.about_longtracker_button.ButtonPushedFcn = createCallbackFcn(app, @about_longtracker_button_Callback, true);
            app.about_longtracker_button.Tag = 'about_longtracker_button';
            app.about_longtracker_button.Text = 'About LongTracker';
            app.Panel = uipanel(app.DataIOTab);
            app.ImagedatapreprocessingButton = uibutton(app.Panel, 'push');
            app.ImagedatapreprocessingButton.ButtonPushedFcn = createCallbackFcn(app, @ImagedatapreprocessingButtonPushed, true);
            app.ImagedatapreprocessingButton.Text = 'Image data pre-processing';
            app.microns_per_pixel_text_2 = uilabel(app.Panel);
            app.microns_per_pixel_text_2.Tag = 'microns_per_pixel_text';
            app.microns_per_pixel_text_2.Text = 'Be sure to install Java on computer before pre-processing image data.';
            app.ChangeImageFolderButton = uibutton(app.DataIOTab, 'push');
            app.ChangeImageFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeImageFolderButtonPushed, true);
            app.ChangeImageFolderButton.Tag = 'export_button';
            app.ChangeImageFolderButton.Text = 'Change image directory';
            % Show the figure after all components are created
            app.main.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = longtracker(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.main)

                % Execute the startup function
                runStartupFcn(app, @(app)longtracker_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.main)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.main)
        end
    end
end